classdef brain_vis_tool_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SetUpTab                        matlab.ui.container.Tab
        DatanotloadedLamp               matlab.ui.control.Lamp
        DatanotloadedLampLabel          matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        DateDatePicker                  matlab.ui.control.DatePicker
        DateDatePickerLabel             matlab.ui.control.Label
        NotesTextArea                   matlab.ui.control.TextArea
        NotesTextAreaLabel              matlab.ui.control.Label
        SexEditField                    matlab.ui.control.EditField
        SexEditFieldLabel               matlab.ui.control.Label
        AgeEditField                    matlab.ui.control.EditField
        AgeEditFieldLabel               matlab.ui.control.Label
        IDEditField                     matlab.ui.control.EditField
        IDEditFieldLabel                matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        SamplingRateEditField           matlab.ui.control.NumericEditField
        SamplingRateEditFieldLabel      matlab.ui.control.Label
        EpochsEditField                 matlab.ui.control.NumericEditField
        EpochsEditFieldLabel            matlab.ui.control.Label
        FramesPerEpochEditField         matlab.ui.control.NumericEditField
        FramesPerEpochEditFieldLabel    matlab.ui.control.Label
        NChannelsEditField              matlab.ui.control.NumericEditField
        NChannelsEditFieldLabel         matlab.ui.control.Label
        InitializeEEGLabButton          matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        EEGLabFolderEditField           matlab.ui.control.EditField
        EEGLabFolderEditFieldLabel      matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        DataInEditField                 matlab.ui.control.EditField
        DataInEditFieldLabel            matlab.ui.control.Label
        TimeDomainTab                   matlab.ui.container.Tab
        ChannelsListBoxLabel_2          matlab.ui.control.Label
        typeofstim                      matlab.ui.control.ListBox
        PeakExtractionPanel             matlab.ui.container.Panel
        ClearButton                     matlab.ui.control.Button
        ExtactpeaksButton               matlab.ui.control.Button
        numberofpeakstoextractEditField  matlab.ui.control.NumericEditField
        numberofpeakstoextractEditFieldLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        TrialSelectionLabel             matlab.ui.control.Label
        PlorttingParametersPanel        matlab.ui.container.Panel
        SignalEndmsEditField            matlab.ui.control.NumericEditField
        SignalEndmsEditFieldLabel       matlab.ui.control.Label
        SignalStartmsEditField          matlab.ui.control.NumericEditField
        SignalStartmsEditFieldLabel     matlab.ui.control.Label
        YmaxEditField                   matlab.ui.control.NumericEditField
        YmaxEditFieldLabel              matlab.ui.control.Label
        YminEditField                   matlab.ui.control.NumericEditField
        YminLabel                       matlab.ui.control.Label
        TimeHighmsEditField             matlab.ui.control.NumericEditField
        TimeHighmsEditFieldLabel        matlab.ui.control.Label
        TimeLowmsEditField              matlab.ui.control.NumericEditField
        TimeLowmsEditFieldLabel         matlab.ui.control.Label
        Tree                            matlab.ui.container.CheckBoxTree
        TabGroup4                       matlab.ui.container.TabGroup
        AverageTab                      matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        TrialTab                        matlab.ui.container.Tab
        UIAxes8                         matlab.ui.control.UIAxes
        UIAxes7                         matlab.ui.control.UIAxes
        LowpassSwitch                   matlab.ui.control.Switch
        LowpassSwitchLabel              matlab.ui.control.Label
        PlotSingleTrialsSwitch          matlab.ui.control.Switch
        PlotSingleTrialsLabel           matlab.ui.control.Label
        TrialOverlayListBox             matlab.ui.control.ListBox
        ChannelsListBox                 matlab.ui.control.ListBox
        EpochsLabel                     matlab.ui.control.Label
        ChannelsListBoxLabel            matlab.ui.control.Label
        TimeFrequencyTab                matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        TFsettingsTab                   matlab.ui.container.Tab
        ConversionSwitchLabel           matlab.ui.control.Label
        SpacingSwitchLabel              matlab.ui.control.Label
        ConversionSwitch                matlab.ui.control.Switch
        SpacingSwitch                   matlab.ui.control.Switch
        offrequencybinsEditField        matlab.ui.control.NumericEditField
        offrequencybinsEditFieldLabel   matlab.ui.control.Label
        timehigherEditField             matlab.ui.control.NumericEditField
        timehigherEditFieldLabel        matlab.ui.control.Label
        timelowerEditField              matlab.ui.control.NumericEditField
        timelowerEditFieldLabel         matlab.ui.control.Label
        ofcycleshigherEditField         matlab.ui.control.NumericEditField
        ofcycleshigherEditFieldLabel    matlab.ui.control.Label
        ofcycleslowerEditField          matlab.ui.control.NumericEditField
        ofcycleslowerEditFieldLabel     matlab.ui.control.Label
        baselinehigherendEditField      matlab.ui.control.NumericEditField
        baselinehigherendEditFieldLabel  matlab.ui.control.Label
        baselinelowerendEditField       matlab.ui.control.NumericEditField
        baselinelowerendEditFieldLabel  matlab.ui.control.Label
        maxfreqEditField                matlab.ui.control.NumericEditField
        maxfreqEditFieldLabel           matlab.ui.control.Label
        minfreqEditField                matlab.ui.control.NumericEditField
        minfreqEditFieldLabel           matlab.ui.control.Label
        BadEpochsEditField_2            matlab.ui.control.NumericEditField
        BadChannelsTBFEditField_2       matlab.ui.control.EditField
        BadEpochsEditField_2Label       matlab.ui.control.Label
        BadChannelsTBFEditField_2Label  matlab.ui.control.Label
        EpochsListBox_2                 matlab.ui.control.ListBox
        EpochsListBox_2Label            matlab.ui.control.Label
        ChannelsListBox_2               matlab.ui.control.ListBox
        ChannelsListBox_2Label          matlab.ui.control.Label
        DataSelectionTab                matlab.ui.container.Tab
        UITable2                        matlab.ui.control.Table
        Image2                          matlab.ui.control.Image
        ExtractInfoButton               matlab.ui.control.Button
        FreqHighEditField               matlab.ui.control.NumericEditField
        FreqHighEditFieldLabel          matlab.ui.control.Label
        FreqLowEditField                matlab.ui.control.NumericEditField
        FreqLowEditFieldLabel           matlab.ui.control.Label
        TimeHighEditField               matlab.ui.control.NumericEditField
        TimeHighEditFieldLabel          matlab.ui.control.Label
        TimeLowEditField                matlab.ui.control.NumericEditField
        TimeLowEditFieldLabel           matlab.ui.control.Label
        selectrectanglemanuallyButton   matlab.ui.control.Button
        StatisticsTab                   matlab.ui.container.Tab
        npermutationsEditField          matlab.ui.control.NumericEditField
        npermutationsEditFieldLabel     matlab.ui.control.Label
        IdentifysignificantresponsesButton  matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        TotalTab                        matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        PhaselockedTab                  matlab.ui.container.Tab
        UIAxes2_5                       matlab.ui.control.UIAxes
        NonphaselockedTab               matlab.ui.container.Tab
        UIAxes2_2                       matlab.ui.control.UIAxes
        ITPCTab                         matlab.ui.container.Tab
        UIAxes2_4                       matlab.ui.control.UIAxes
        ERPpowerTab                     matlab.ui.container.Tab
        UIAxes2_3                       matlab.ui.control.UIAxes
        SignificantPowerTab             matlab.ui.container.Tab
        UIAxes2_6                       matlab.ui.control.UIAxes
        WaveletfilteringTab             matlab.ui.container.Tab
        ClearButton_2                   matlab.ui.control.Button
        numberofpeakstoextractEditField_2  matlab.ui.control.NumericEditField
        numberofpeakstoextractEditField_2Label  matlab.ui.control.Label
        UITable3                        matlab.ui.control.Table
        ExtractSingletrialPeaksTBFButton  matlab.ui.control.Button
        npermutationsEditField_2        matlab.ui.control.NumericEditField
        npermutationsEditField_2Label   matlab.ui.control.Label
        wavelet_filterButton            matlab.ui.control.Button
        UIAxes7_2                       matlab.ui.control.UIAxes
        UIAxes2_7                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        extraction_state = 0% Has data been extracted?
        file_type = 0 % type of file you want to load
        plot_single_trial = 0; % Description
        low_pass_toggle = 0; % apply low pass filter for time domain analysis
        i_peaks = 0; % Description
        n_roi = 0; % Description
        i_peaks_st =  0 % Description
    end
    
    methods (Access = private)
        
        function [EEG_dat,trials2plot] = plot_tdat(app)
            EEG = evalin('base','EEG');
            %EEG = preprocc_eeg(app,EEG);
   

            % extract parameters to plot the extaction protocol

            x = EEG.times;
            chan2plot = string(app.ChannelsListBox.Value);


            % check if its keypoint
            if app.file_type == 3 
                ALLEEG = evalin('base','ALLEEG');
                % figure out which dermatome is being trying to be plotted
                % and load the correct EEG file and the right chanloc index
                % relative to that file
                for i_EEG = 1:length(ALLEEG)
                    EEG = ALLEEG(i_EEG);
                    current_chanlocs = EEG.chanlocs;
                    for j = 1:length(current_chanlocs)
                        if string(current_chanlocs(j).labels) == string(chan2plot)
                            EEG_idx = i_EEG;
                            chan_idx = j;
                            break
                        end
                    end
                end
                EEG = ALLEEG(EEG_idx);
            else
                for i = 1:length(EEG.chanlocs)
                    if chan2plot == string(EEG.chanlocs(i).labels)
                        chan_idx = i;
                    end
                end
            end

            EEG_dat = squeeze(EEG.data(chan_idx,:,:));


            % determine the trials to plot based on the tree box
            if ~isempty(app.Tree.CheckedNodes)  % if the nodes are not empty
                if length(app.Tree.CheckedNodes) > 1 % if its not only a single trial selected
                    if app.Tree.CheckedNodes(1).Text =="All Trials" % if all trials are selected
                        trials2plot = 1:size(EEG_dat,2);
                    else % if more than 1 trial is selected
                        trials2plot = zeros(1,length(app.Tree.CheckedNodes));
                        for i = 1: length(app.Tree.CheckedNodes)
                            trials2plot(i) = str2double(app.Tree.CheckedNodes(i).Text);
                        end
                    end
                else % if its a single trial
                trials2plot = str2double(app.Tree.CheckedNodes.Text);
                end
                % subset only trials that needed
                EEG_dat = squeeze(EEG_dat(:,trials2plot));
                
                if app.low_pass_toggle == 1
                    EEG_dat = lowpass(EEG_dat,25,EEG.srate,'ImpulseResponse','iir'); %% can change to change the smoothing
                end


                [sd2,SNR] = calc_params_peak_extract(app,EEG_dat,x);


                avg2plot = squeeze(mean(EEG_dat,2));

                % find pre baseline noise
                % define baseline noise
                [~,low_base] = (min(abs(x - app.TimeLowmsEditField.Value)));
                [~,zero_base] = (min(abs(x - 0)));


                cla(app.UIAxes) 
                
                rectangle(app.UIAxes,'Position', [...
                    app.TimeLowmsEditField.Value,...
                    -sd2,...
                    app.TimeHighmsEditField.Value - app.TimeLowmsEditField.Value,...
                    2*sd2], ...
                    'FaceColor', [.5, .5, .5, 0.5], ...
                    'EdgeColor', [1, 1, 1, 1]);

                peps_range = [0 500];
                cheps_range = [200 600];
                leps_range = [150 550];
                ceps_range = [150 700];

                if app.typeofstim.Value == "CHEPs"
                    stim_range = cheps_range;
                elseif app.typeofstim.Value == "CEPs"
                    stim_range = ceps_range;
                elseif app.typeofstim.Value == "LEPs" 
                    stim_range = leps_range;
                elseif app.typeofstim.Value == "PEPs"
                    stim_range = peps_range;
                end

                hold(app.UIAxes,"on");
                rectangle(app.UIAxes,'Position', [...
                    app.TimeLowmsEditField.Value,...
                    -sd2,...
                    app.TimeHighmsEditField.Value - app.TimeLowmsEditField.Value,...
                    2*sd2], ...
                    'FaceColor', [.5, .5, .5, 0.2], ...
                    'EdgeColor', [1, 1, 1, 1]);                
                rectangle(app.UIAxes,'Position', [...
                    stim_range(1),...
                    app.YminEditField.Value,...
                    stim_range(2)- stim_range(1),...
                    app.YmaxEditField.Value-app.YminEditField.Value], ...
                    'FaceColor', [218/255,73/255,48/255, 0.8], ...
                    'EdgeColor', [1, 1, 1, 1], ...
                    'Curvature',[0.2, 0.2]);
                plot(app.UIAxes,x,avg2plot,'LineWidth',2,"Color","k");
                set(app.UIAxes, 'YDir','reverse')
                xlim(app.UIAxes, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])
                ylim(app.UIAxes, [app.YminEditField.Value app.YmaxEditField.Value])
                xline(app.UIAxes,0,'--');
                yline(app.UIAxes,sd2)
                yline(app.UIAxes,-sd2)
                
                if app.plot_single_trial ==1
                    trials2plot = cellfun(@str2num,app.TrialOverlayListBox.Value);
                    y = squeeze(EEG_dat(:,trials2plot));
                    plot(app.UIAxes,x,y);
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,x,y);
                    new_min = min(min(y));
                    new_max = max(max(y));
                    app.YminEditField.Value = new_min + new_min*0.1;
                    app.YmaxEditField.Value = new_max + new_max*0.1;
                end


                hold(app.UIAxes,'off')


            else % if the nodes are empty 
                trials2plot = [];
                cla(app.UIAxes) 
                xlim(app.UIAxes, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])
                ylim(app.UIAxes, [app.YminEditField.Value app.YmaxEditField.Value])
                xline(app.UIAxes,0,'--');

            end


        end
        
        function [tf , frex,x,convol_pwr_ind,sing_wt,total_convol_result,cwt_outputs] = calc_tf_data(app)

            EEG = evalin('base','EEG');
            
    
            chan2plot = string(app.ChannelsListBox_2.Value);

            % check if its keypoint
            if app.file_type == 3 
                ALLEEG = evalin('base','ALLEEG');
                % figure out which dermatome is being trying to be plotted
                % and load the correct EEG file and the right chanloc index
                % relative to that file
                for i_EEG = 1:length(ALLEEG)
                    EEG = ALLEEG(i_EEG);
                    current_chanlocs = EEG.chanlocs;
                    for j = 1:length(current_chanlocs)
                        if string(current_chanlocs(j).labels) == string(chan2plot)
                            EEG_idx = i_EEG;
                            chan_idx = j;
                        end
                    end
                end
                EEG = ALLEEG(EEG_idx);
            else
                for i = 1:length(EEG.chanlocs)
                    if chan2plot == string(EEG.chanlocs(i).labels)
                        chan_idx = i;
                    end
                end
            end


            time_l = app.timelowerEditField.Value;
            time_h = app.timehigherEditField.Value;
            x = EEG.times;
            freq_lims = [app.minfreqEditField.Value app.maxfreqEditField.Value];
            
            % subset eeg data to decrease computing time
            [~,i_low] = (min(abs(x - time_l)));
            [~,i_high] = (min(abs(x - time_h)));
            i_high = i_high-1;
            data = EEG.data(:,:,:);


             data = squeeze(data(chan_idx,:,:));
            
            %avg2plot = squeeze(mean(EEG.data(chan_idx,:,:),3));
            
            trials2plot = cellfun(@str2num,app.EpochsListBox_2.Value);  
            data2plot = squeeze(data(:,trials2plot));
            dat_size = size(data2plot);
            chanlocs = EEG.chanlocs;
            n_trials = dat_size(2);


            % compute ERP
            erp = squeeze(mean(data,2));
            test = upsample(erp,n_trials);

            % compute induced power by subtracting ERP from each trial
            induced_EEG = squeeze(data - repmat(erp,1,n_trials));

            % perform wavelet transformations
            % for erp
            [erp_wt,frex,erp_scalingcfs]  = cwt(erp,'amor',EEG.srate,FrequencyLimits = freq_lims);

            cwt_outputs.erp.wt = erp_wt;
            cwt_outputs.erp.frex = frex;
            cwt_outputs.erp.scalingcfs = erp_scalingcfs; 

            % for single trials
            for i = 1:n_trials
                dat2convert =data(:,i);
                [c_w,frex,sing_scalingcfs] = cwt(dat2convert,'amor',EEG.srate,FrequencyLimits = freq_lims);
                if i == 1
                    sing_wt = zeros(length(frex),length(dat2convert),n_trials);
                    sing_wt(:,:,i) = c_w;
                else
                    sing_wt(:,:,i)  = c_w;
                end
            end

            sing_wt = sing_wt(:,i_low:i_high,:); % cut to epoch size
            cwt_outputs.single_trial.wt = sing_wt;
            cwt_outputs.single_trial.frex = frex;
            cwt_outputs.single_trial.scalingcfs = sing_scalingcfs; 

            % for induced
            for i = 1:n_trials
                dat2convert =induced_EEG(:,i);
                [c_w,frex,sing_scalingcfs] = cwt(dat2convert,'amor',EEG.srate,FrequencyLimits = freq_lims);
                if i == 1
                    ind_wt = zeros(length(frex),length(dat2convert),n_trials);
                    ind_wt(:,:,i) = c_w;
                else
                    ind_wt(:,:,i)  = c_w;
                end
            end


            % calculating power and baseline correcting

            % convert baseline from ms to indices
            x = x(i_low:i_high);
            [~,baseidx(1)] = min(abs(x-app.baselinelowerendEditField.Value));
            [~,baseidx(2)] = min(abs(x-app.baselinehigherendEditField.Value));

            % for erp power
            pow_erp = abs(erp_wt).^2;
            pow_erp = pow_erp(:,i_low:i_high);

            % for total
            pow_total = mean(abs(sing_wt).^2,3);
            convol_pwr_ind = abs(sing_wt).^2;
            total_convol_result = mean(sing_wt,3);

            % non-phase locked
            pow_npl = pow_total - pow_erp;

            % phase-locked power
            pow_plp = pow_total - pow_npl;

            % ITPC 
            ITPC = abs(mean(exp(1i*angle(sing_wt)),3));

            %% initiate TF structure 

            tf = zeros(5,size(pow_erp,1),size(pow_erp,2));
            tf(1,:,:) = pow_total;
            tf(2,:,:) = pow_npl;
            tf(3,:,:) = pow_erp;
            tf(4,:,:) = ITPC;
            tf(5,:,:) = pow_plp;

            % baseline correct
            if string(app.ConversionSwitch.Value) == "dB" %log scaled data

                tf = 10*log10(tf ./ mean(tf(:,:,baseidx(1):baseidx(2)),3));

            elseif string(app.SpacingSwitch.Value) == "percent change" %log scaled data
                my_baseline = mean(tf(:,:,baseidx(1):baseidx(2)),3);

                tf = ((tf-my_baseline)./my_baseline)*100;
            end

            % chunk into correct size
            % re introduce non_normalized ITPC,plp and erp power
            tf(4,:,:) = ITPC;
            tf(3,:,:) = pow_erp;
            tf(5,:,:) = pow_plp;
            
            %tf = tf(:,:,i_low:i_high);
            %ind_wt = ind_wt(:,i_low:i_high,:);
            %convol_pwr_ind = convol_pwr_ind(:,i_low:i_high,:);
            %total_convol_result = total_convol_result(:,i_low:i_high,:);
            %times = x(i_low:i_high);

%             for i = 1:5
%                 figure
%                 imagesc(times,frex,squeeze(tf(i,:,:)))
%                 set(gca, 'YDir','normal')
%             end

            EEG.frequencies = frex;
            EEG.tf_times = x;
            EEG.tf = tf;
            EEG.cwt_outputs = cwt_outputs;


            assignin('base','tf',tf)
            assignin('base','EEG',EEG)
            %assignin('base','ALLEEG',ALLEEG)
            assignin('base','cwt_outputs',cwt_outputs)
            
            
        end
        
        
        function plot_tfdata(app,tf,frequencies)
            EEG = evalin('base','EEG');

            chan2plot  = app.ChannelsListBox_2.Value;

            % check if its keypoint
            if app.file_type == 3 
                ALLEEG = evalin('base','ALLEEG');
                % figure out which dermatome is being trying to be plotted
                % and load the correct EEG file and the right chanloc index
                % relative to that file
                for i_EEG = 1:length(ALLEEG)
                    EEG = ALLEEG(i_EEG);
                    current_chanlocs = EEG.chanlocs;
                    for j = 1:length(current_chanlocs)
                        if string(current_chanlocs(j).labels) == string(chan2plot)
                            EEG_idx = i_EEG;
                            chan_idx = j;
                        end
                    end
                end
                EEG = ALLEEG(EEG_idx);
            else
                for i = 1:length(EEG.chanlocs)
                    if chan2plot == string(EEG.chanlocs(i).labels)
                        chan_idx = i;
                    end
                end
            end


            % get climits for plotting
            [~,low_index] = (min(abs(EEG.times - app.timelowerEditField.Value)));
            [~,high_index] = (min(abs(EEG.times - app.timehigherEditField.Value)));
            high_index = high_index -1;
            
            
            min_vect = min(min(tf(:,:,:,:),[],2),[],3);
            max_vect = max(max(tf(:,:,:),[],2),[],3);
           % itcp_ceil = max_vect(4);
            c_vector = max([abs(min_vect),abs(max_vect)],[],2);
            % temp
            min_vect = -c_vector;
            min_vect(4) = 0; % for ITPC
            % for emg
            % min_vect(1) = 0; % for ITPC
            % min_vect(2) = 0; % for ITPC
            % min_vect(3) = 0; % for ITPC
            % min_vect(4) = 0; % for ITPC

            max_vect= c_vector;
            %max_vect(4) = itcp_ceil;
            
            %analysis_labels = {'Total';'Non-phase-locked';'ERP power';'ITPC';'Phase-locked'};
            if string(app.ConversionSwitch.Value) == "dB"
                cbar_labels = {'dB';'Power (mV2)';'dB';'ITPC';'Power (mV2)'};
            elseif string(app.ConversionSwitch.Value) == "percent change"
                cbar_labels = {'% change';'% change';'% change';'ITPC';'% change'};
            end
            
            % total
            lims = [min_vect(1) max_vect(1)];
            contourf(app.UIAxes2,EEG.times(low_index:high_index),frequencies,squeeze(tf(1,:,:)),40,'linecolor','none')
            hold(app.UIAxes2,'on')
          
            if string(app.SpacingSwitch.Value) == "Log" %log scaled data
                set(app.UIAxes2,'clim',lims,'xlim',[app.timelowerEditField.Value app.timehigherEditField.Value],...
                    'xtick',[round(app.timelowerEditField.Value:100:-100,-1) 0 round(100:100:app.timehigherEditField.Value,-1)],...
                    'yscale','log','ytick',logspace(log10(app.minfreqEditField.Value),log10(app.maxfreqEditField.Value),6),...
                    'yticklabel',round(logspace(log10(app.minfreqEditField.Value),log10(app.maxfreqEditField.Value),6)*10)/10);
            elseif string(app.SpacingSwitch.Value) == "Linear" % not log scaled data
                set(app.UIAxes2,'clim',lims,'xlim',[app.timelowerEditField.Value app.timehigherEditField.Value],...
                    'xtick',[round(app.timelowerEditField.Value:100:-100,-1) 0 round(100:100:app.timehigherEditField.Value,-1)],...
                    'yscale','linear','ytick',linspace(app.minfreqEditField.Value,app.maxfreqEditField.Value,6),...
                    'yticklabel',round(linspace(app.minfreqEditField.Value,app.maxfreqEditField.Value,6)*10)/10);
            end
            c = colorbar(app.UIAxes2,"east");
            colormap(app.UIAxes2,"jet");
            c.Label.String = string(cbar_labels(1));
            hold(app.UIAxes2,'off')
            
            
             % non-phase locked
            lims = [min_vect(2) max_vect(2)];
            contourf(app.UIAxes2_2,EEG.times(low_index:high_index),frequencies,squeeze(tf(2,:,:)),40,'linecolor','none')
            hold(app.UIAxes2_2,'on')
          
            if string(app.SpacingSwitch.Value) == "Log" %log scaled data
                set(app.UIAxes2_2,'clim',lims,'xlim',[app.timelowerEditField.Value app.timehigherEditField.Value],...
                    'xtick',[round(app.timelowerEditField.Value:100:-100,-1) 0 round(100:100:app.timehigherEditField.Value,-1)],...
                    'yscale','log','ytick',logspace(log10(app.minfreqEditField.Value),log10(app.maxfreqEditField.Value),6),...
                    'yticklabel',round(logspace(log10(app.minfreqEditField.Value),log10(app.maxfreqEditField.Value),6)*10)/10);
            elseif string(app.SpacingSwitch.Value) == "Linear" % not log scaled data
                set(app.UIAxes2_2,'clim',lims,'xlim',[app.timelowerEditField.Value app.timehigherEditField.Value],...
                    'xtick',[round(app.timelowerEditField.Value:100:-100,-1) 0 round(100:100:app.timehigherEditField.Value,-1)],...
                    'yscale','linear','ytick',linspace(app.minfreqEditField.Value,app.maxfreqEditField.Value,6),...
                    'yticklabel',round(linspace(app.minfreqEditField.Value,app.maxfreqEditField.Value,6)*10)/10);
            end
            c = colorbar(app.UIAxes2_2,"east");
            colormap(app.UIAxes2_2,"jet");
            c.Label.String = string(cbar_labels(2));
            hold(app.UIAxes2_2,'off')
            
            
            % ERP power
            lims = [min_vect(3) max_vect(3)];
            contourf(app.UIAxes2_3,EEG.times(low_index:high_index),frequencies,squeeze(tf(3,:,:)),40,'linecolor','none')
            hold(app.UIAxes2_3,'on')
          
            if string(app.SpacingSwitch.Value) == "Log" %log scaled data
                set(app.UIAxes2_3,'clim',lims,'xlim',[app.timelowerEditField.Value app.timehigherEditField.Value],...
                    'xtick',[round(app.timelowerEditField.Value:100:-100,-1) 0 round(100:100:app.timehigherEditField.Value,-1)],...
                    'yscale','log','ytick',logspace(log10(app.minfreqEditField.Value),log10(app.maxfreqEditField.Value),6),...
                    'yticklabel',round(logspace(log10(app.minfreqEditField.Value),log10(app.maxfreqEditField.Value),6)*10)/10);
            elseif string(app.SpacingSwitch.Value) == "Linear" % not log scaled data
                set(app.UIAxes2_3,'clim',lims,'xlim',[app.timelowerEditField.Value app.timehigherEditField.Value],...
                    'xtick',[round(app.timelowerEditField.Value:100:-100,-1) 0 round(100:100:app.timehigherEditField.Value,-1)],...
                    'yscale','linear','ytick',linspace(app.minfreqEditField.Value,app.maxfreqEditField.Value,6),...
                    'yticklabel',round(linspace(app.minfreqEditField.Value,app.maxfreqEditField.Value,6)*10)/10);
            end
            c = colorbar(app.UIAxes2_3,"east");
            colormap(app.UIAxes2_3,"jet");
            c.Label.String = string(cbar_labels(3));
            hold(app.UIAxes2_3,'off')
            
            % ITPC
            lims = [min_vect(4) max_vect(4)];
            contourf(app.UIAxes2_4,EEG.times(low_index:high_index),frequencies,squeeze(tf(4,:,:)),40,'linecolor','none')
            hold(app.UIAxes2_4,'on')
          
            if string(app.SpacingSwitch.Value) == "Log" %log scaled data
                set(app.UIAxes2_4,'clim',lims,'xlim',[app.timelowerEditField.Value app.timehigherEditField.Value],...
                    'xtick',[round(app.timelowerEditField.Value:100:-100,-1) 0 round(100:100:app.timehigherEditField.Value,-1)],...
                    'yscale','log','ytick',logspace(log10(app.minfreqEditField.Value),log10(app.maxfreqEditField.Value),6),...
                    'yticklabel',round(logspace(log10(app.minfreqEditField.Value),log10(app.maxfreqEditField.Value),6)*10)/10);
            elseif string(app.SpacingSwitch.Value) == "Linear" % not log scaled data
                set(app.UIAxes2_4,'clim',lims,'xlim',[app.timelowerEditField.Value app.timehigherEditField.Value],...
                    'xtick',[round(app.timelowerEditField.Value:100:-100,-1) 0 round(100:100:app.timehigherEditField.Value,-1)],...
                    'yscale','linear','ytick',linspace(app.minfreqEditField.Value,app.maxfreqEditField.Value,6),...
                    'yticklabel',round(linspace(app.minfreqEditField.Value,app.maxfreqEditField.Value,6)*10)/10);
            end
            c = colorbar(app.UIAxes2_4,"east");
            colormap(app.UIAxes2_4,"jet");
            c.Label.String = string(cbar_labels(4));
            hold(app.UIAxes2_4,'off')
            
            % Phased locked power
            lims = [min_vect(5) max_vect(5)];
            contourf(app.UIAxes2_5,EEG.times(low_index:high_index),frequencies,squeeze(tf(5,:,:)),40,'linecolor','none')
            hold(app.UIAxes2_5,'on')
          
            if string(app.SpacingSwitch.Value) == "Log" %log scaled data
                set(app.UIAxes2_5,'clim',lims,'xlim',[app.timelowerEditField.Value app.timehigherEditField.Value],...
                    'xtick',[round(app.timelowerEditField.Value:100:-100,-1) 0 round(100:100:app.timehigherEditField.Value,-1)],...
                    'yscale','log','ytick',logspace(log10(app.minfreqEditField.Value),log10(app.maxfreqEditField.Value),6),...
                    'yticklabel',round(logspace(log10(app.minfreqEditField.Value),log10(app.maxfreqEditField.Value),6)*10)/10);
            elseif string(app.SpacingSwitch.Value) == "Linear" % not log scaled data
                set(app.UIAxes2_5,'clim',lims,'xlim',[app.timelowerEditField.Value app.timehigherEditField.Value],...
                    'xtick',[round(app.timelowerEditField.Value:100:-100,-1) 0 round(100:100:app.timehigherEditField.Value,-1)],...
                    'yscale','linear','ytick',linspace(app.minfreqEditField.Value,app.maxfreqEditField.Value,6),...
                    'yticklabel',round(linspace(app.minfreqEditField.Value,app.maxfreqEditField.Value,6)*10)/10);
            end
            c = colorbar(app.UIAxes2_5,"east");
            colormap(app.UIAxes2_5,"jet");
            c.Label.String = string(cbar_labels(5));
            hold(app.UIAxes2_5,'off')


        end
        
        function my_output = selectDatapoints(app,UIAX_name)
            
            
            % Remove any pre-existing rectanlges (if any)
            p_UI = ["UIAxes2","UIAxes2_5","UIAxes2_2","UIAxes2_4","UIAxes2_3"];
            delete(findall(app.UIAxes2, 'Type',  'images.roi.Rectangle'))
            delete(findall(app.UIAxes2_5, 'Type',  'images.roi.Rectangle'))
            delete(findall(app.UIAxes2_2, 'Type',  'images.roi.Rectangle'))
            delete(findall(app.UIAxes2_4, 'Type',  'images.roi.Rectangle'))
            delete(findall(app.UIAxes2_3, 'Type',  'images.roi.Rectangle'))


            delete(findall(eval(['app.' char(UIAX_name)]), 'Type',  'images.roi.Rectangle'))
            % Get coordinates of all data points in the axes
%             xyobj = findall(eval(['app.' char(UIAX_name) '.Children']), '-Property','xData'); 
%             xydata = get(xyobj, {'XData','YData'});
%             xydataMat = cell2mat(xydata'); 
%     
            % change title of axes to instructions, in red
            %originalTitle = get(eval(['app.' char(UIAX_name) '.Title']), {'String', 'Color'}); 
            %set(eval(['app.' char(UIAX_name) '.Title']), 'String', 'Draw rectangle around desired datapoints', 'Color', 'r')
            % allow user to draw rectangle; see more options:
            % https://www.mathworks.com/help/images/ref/drawrectangle.html
            pan(eval(['app.' char(UIAX_name)]), 'off') %turn off panning so the interaction doesn't drag the data.
            
            fig = figure(); 
            appAxes = eval(['app.' char(UIAX_name)]); 
            ax = copyobj(appAxes, fig); 
            % positioning figure in correct dimentions
            my_pos = appAxes.Position;
            my_pos(1) = my_pos(3)/3;
            my_pos(2)= my_pos(4)/3;
            fig.Position = my_pos;

            temp_roi = drawrectangle(ax,'StripeColor','b','InteractionsAllowed','none');
            roi = copyobj(temp_roi, appAxes);
            delete(fig)
 

            %roi = drawrectangle(eval(['app.' char(UIAX_name)])); 
            roi = drawrectangle((app.UIAxes2),'Position',roi.Position,'StripeColor','r'); 
            roi = drawrectangle((app.UIAxes2_5),'Position',roi.Position,'StripeColor','r'); 
            roi = drawrectangle((app.UIAxes2_2),'Position',roi.Position,'StripeColor','r'); 
            roi = drawrectangle((app.UIAxes2_4),'Position',roi.Position,'StripeColor','r'); 
            roi = drawrectangle((app.UIAxes2_3),'Position',roi.Position,'StripeColor','r'); 


            % quit if figure is closed
            if ~isvalid(roi)
                x = []; 
                y = []; 
            return
            end
            
            my_output = roi.Position;
            % Return original title
            %set(ax.Title, 'String', originalTitle{1}, 'Color', originalTitle{2})
    
%             % determine which coordinates are within the ROI
%             isIn = xydataMat(1,:) >= roi.Position(1) & xydataMat(1,:) <= sum(roi.Position([1,3])) ...
%                 & xydataMat(2,:) >= roi.Position(2) & xydataMat(2,:) <= sum(roi.Position([2,4])); 
%     
            % Delete ROI
            %delete(roi)
    
%             % Return outputs
%             x = xydataMat(1,isIn); 
%             y = xydataMat(2,isIn);    
        end


        function [my_output, extraction_state]= extract_vals(app,tf,frequencies,x)
            x_min = app.TimeLowEditField.Value;
            x_max = app.TimeHighEditField.Value;
            y_min = app.FreqLowEditField.Value;
            y_max = app.FreqHighEditField.Value;

            % getting indices
            % check if frequencies are inverted and if they are flip them
%             if frequencies(end) < frequencies(1)
%                 frequencies = flip(frequencies);
%             end


            [~,low_x] = (min(abs(x - x_min)));
            [~,high_x] = (min(abs(x - x_max)));
            [~,low_y] = (min(abs(frequencies- y_min)));
            [~,high_y] = (min(abs(frequencies- y_max)));

            % cutting data
            data_cut = tf(:,high_y:low_y,low_x:high_x);

            % extracting important values 
            max_vect = max(max(data_cut,[],3),[],2);
            mean_vect = mean(mean(data_cut,3),2);
            min_vect = min(min(data_cut,[],3),[],2);

            % extracting indices where the min and max on the cut data
            temp = squeeze(max(data_cut,[],3));
            [ ~ , maxif_vect] = max(temp,[],2); % for frequency it works
            temp = squeeze(min(data_cut,[],3));
            [ ~ , minif_vect] = min(temp,[],2); % for freqency it works
            temp = squeeze(max(data_cut,[],2));
            [ ~ , maxit_vect] = max(temp,[],2); % for time it works
            temp = squeeze(min(data_cut,[],2));
            [ ~ , minit_vect] = min(temp,[],2); % for time it works


            % caclulating peak time and freq for min and max on the whole
            x_sub = x(low_x:high_x);
            y_sub = frequencies(high_y:low_y);
            peak_t_vector = zeros(1,5);
            min_t_vector = zeros(1,5);
            peak_f_vector = zeros(1,5);
            min_f_vector = zeros(1,5);
           
            for i = 1:5
                peak_t_vector(i) = x_sub(maxit_vect(i));
                min_t_vector(i) = x_sub(minit_vect(i));

                peak_f_vector(i) = y_sub(maxif_vect(i));
                min_f_vector(i) = y_sub(minif_vect(i));
            end

            my_output.mean_activity = mean_vect;
            my_output.max_activity = max_vect;
            my_output.min_activity = min_vect;
            my_output.peak_frequency = peak_f_vector;
            my_output.lowest_frequency = min_f_vector;
            my_output.peak_time = peak_t_vector;
            my_output.lowest_time = min_t_vector;
            my_output.metric = app.TabGroup2.SelectedTab.Title;

            extraction_state = 1;
            
        end
        
        function poulate_extraction_vals(app,my_output)
            c_tab = string(app.TabGroup2.SelectedTab.Title);
            p_tabs = ["Total","Phase locked","Non-phase locked","ITPC","ERP power"];
            tab_order = [1,5,2,4,3]; % depending on the output order of calc_tf_data function
            for i = 1:length(p_tabs)
                if p_tabs(i) == c_tab
                    tab_idx = tab_order(i);
                end
            end
                        
            prompt = {'Enter name of ROI'};
            dlgtitle = 'Name of ROI';
            dims = [1 35];
            definput = {['ROI ' num2str(app.n_roi+1)],'hsv'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            
             my_table = app.UITable2.Data;
             m_data.Channel = string(app.ChannelsListBox_2.Value);
             m_data.Name = string(answer);
             m_data.mean_act = round(my_output.mean_activity(tab_idx),3);
             m_data.peak_act = round(my_output.max_activity(tab_idx),3);
             m_data.peak_freq = round(my_output.peak_frequency(tab_idx),3);
             m_data.peak_lat = round(my_output.peak_time(tab_idx),3);
             m_data.low_act = round(my_output.min_activity(tab_idx),3);
             m_data.low_freq = round(my_output.lowest_frequency(tab_idx),3);
             m_data.low_lat = round(my_output.lowest_time(tab_idx),3);
             m_data.metric = string(my_output.metric);
             
             
%             app.meanactivitydBEditField.Value = round(my_output.mean_activity(tab_idx),3);
%             app.peakactivitydBEditField.Value = round(my_output.max_activity(tab_idx),3);
%             app.peakfrequencyHzEditField.Value = round(my_output.peak_frequency(tab_idx),3);
%             app.peaklatencymsEditField.Value = round(my_output.peak_time(tab_idx),3);
%             app.lowestactivitydBEditField.Value = round(my_output.min_activity(tab_idx),3);
%             app.minfrequencyHzEditField.Value = round(my_output.lowest_frequency(tab_idx),3);
%             app.minlatencymsEditField.Value = round(my_output.lowest_time(tab_idx),3);
%             
                if app.n_roi > 0
                    my_table_add = struct2table(m_data);
                    my_table_add.Properties.VariableNames = ["Channel" ,"Name","Mean Activity (dB)","Peak Activity (dB)","Peak Frequency (Hz)","Peak Latency (ms)","Lowest Activity (ms)","Min Frequency (Hz)","Min Latency(ms)","Metric"];
                    my_table = [my_table;my_table_add];
                else
                    my_table = struct2table(m_data);
                    my_table.Properties.VariableNames = ["Channel" ,"Name","Mean Activity (dB)","Peak Activity (dB)","Peak Frequency (Hz)","Peak Latency (ms)","Lowest Activity (ms)","Min Frequency (Hz)","Min Latency(ms)","Metric"];
                    
                end
                app.UITable2.Data = my_tachan_infble;    
                
% 
%             app.meanactivitydBEditField.Value = round(my_output.mean_activity(tab_idx),3);
%             app.peakactivitydBEditField.Value = round(my_output.max_activity(tab_idx),3);
%             app.peakfrequencyHzEditField.Value = round(my_output.peak_frequency(tab_idx),3);
%             app.peaklatencymsEditField.Value = round(my_output.peak_time(tab_idx),3);
%             app.lowestactivitydBEditField.Value = round(my_output.min_activity(tab_idx),3);
%             app.minfrequencyHzEditField.Value = round(my_output.lowest_frequency(tab_idx),3);
%             app.minlatencymsEditField.Value = round(my_output.lowest_time(tab_idx),3);
             app.n_roi = app.n_roi +1;

            
        end
        
        function EEG = convert_txt_to_eeg_struct(app,data_in,chan_id,srate,baseline_low)
            load('chan_info.mat',"chanlocs");
            
            EEG.data = data_in;
            dims_in = size(data_in);
            
            %calculate the time of the stim
            l_data = length(data_in);
            t_data = l_data/srate*1000; %in ms
            my_baseline = baseline_low; %in ms
            time_v = linspace(my_baseline,t_data+my_baseline,length(data_in));


            for k = 1:length(chanlocs)
                C_chan = lower(string(chanlocs(k).labels));
                if C_chan == lower(chan_id)
                    chan1_IDX=k;
                end
            end

            % find the correct chanlocs id and change the names 


            temp_data = zeros(1,dims_in(1),dims_in(2));
            temp_data(1,:,:) = data_in;
            EEG.data = temp_data;
            new_chanlocs = chanlocs(chan1_IDX);
            EEG.chanlocs = new_chanlocs;
            EEG.srate = srate;
            EEG.times = time_v; 
            [filepath,name,ext] = fileparts(app.DataInEditField.Value); 
            EEG.setname = name;
            EEG.filename = [name,ext];
            EEG.filepath = filepath;
            EEG.comments = ['Original file: ' filepath, filesep(), name, ext, ". Converted from text file." ];
            EEG.nbchan = size(EEG.data,1);
            EEG.trials = size(EEG.data,3);
            EEG.pnts = size(EEG.data,2);
            EEG.xmin = min(EEG.times);
            EEG.xmax = max(EEG.times);
            EEG.icawinv = [];
            EEG.icaweights = [];
            EEG.icasphere = [];


        end
        
        function extract_peaks(app)
           EEG = evalin('base','EEG');
           n_peaks = app.numberofpeakstoextractEditField.Value;
           fig = figure(); 
           appAxes = app.UIAxes;
           ax = copyobj(appAxes, fig); 
           % positioning figure in correct dimentions
           my_pos = appAxes.Position;
           my_pos(1) = my_pos(3)/2;
           my_pos(2)= my_pos(4)/2;
           fig.Position = my_pos;

           chan2plot = string(app.ChannelsListBox.Value);
             for i = 1:length(EEG.chanlocs)
                 if chan2plot == string(EEG.chanlocs(i).labels)
                     chan_idx = i;
                 end
             end
           
            
           for i  = 1:n_peaks
               app.i_peaks = app.i_peaks +1;
               prompt = {'Enter name of peak'};
               dlgtitle = 'Name of peak';
               dims = [1 35];
               definput = {'name','hsv'};
               answer = inputdlg(prompt,dlgtitle,dims,definput);


                temp_roi = drawrectangle(ax,'StripeColor','b','InteractionsAllowed','none');
                position = temp_roi.Position;  
                x_min = position(1);
                x_max  = position(1) + position(3);
                [~,low_index] = (min(abs(EEG.times - x_min)));
                [~,high_index] = (min(abs(EEG.times - x_max)));
                
                data= squeeze(mean(EEG.data(chan_idx,low_index:high_index,:),3));

                % check if its lowpass is toggled and filter if it is
                if app.low_pass_toggle == 1
                    data = lowpass(data,25,EEG.srate,'ImpulseResponse','iir'); %% can change to change the smoothing
                end

                % determining if its a negative or a positive peak 
                [highest_point , highest_index]  = max(data);
                [lowest_point , lowest_index]  = min(data);
                
                pgn = abs(highest_point) > abs(lowest_point); % check to see if the highest positive point has a greater magnitude than the lowerst negative point. reurn zero if it is true, and return 1 if it is not true.
                
                if pgn == 0 % if my positive point is not greater than negative point
                    % check that the negative dip is not in the end
                    if and(lowest_index ~= 0,lowest_index ~= length(data))
                        pont = 0; % positiv or negative toggle (1 = positive peak, -0 = negative peak)
                    elseif and(highest_index ~= 0,highest_index ~= length(data)) % if it is in the end, check to see if the positive is in the end or not 
                        pont  = 1;
                    else 
                        warning("The region that you selected did not contain a peak - please try again ")
                    end
                elseif pgn == 1 % if my positive point is greater than my negative point
                    % check tha the positive dip is not at the end
                    if and(highest_index ~= 0,highest_index ~= length(data))
                        pont = 1;
                    elseif and(lowest_index ~= 0,lowest_index ~= length(data)) 
                        pont = 0;
                    else
                        warning("The region that you selected did not contain a peak - please try again ")
                    end
                    
                end
                
                if pont == 1
                    my_peak = highest_point ;
                    my_latency = EEG.times(low_index+highest_index);
                elseif pont == 0
                    my_peak = lowest_point ;
                    my_latency = EEG.times(low_index+lowest_index);
                else
                    warning("Please re select the region")
                end
                
                my_table = app.UITable.Data;
                m_data.Channel = string(app.ChannelsListBox.Value);
                m_data.Name = string(answer);
                m_data.Amplitude =my_peak;
                m_data.Latency =  my_latency;
                if app.i_peaks > 1
                    my_table = [my_table;struct2table(m_data)];
                else
                    my_table = struct2table(m_data);
                end
                app.UITable.Data = my_table;    

           end
           
           close(fig)
                      
            
        end
        
        function mark_peaks_in_fig(app)
            hold(app.UIAxes,"on")
            table_info = app.UITable.Data;
            for i = 1: size(table_info,1)
                
                name = table_info.Name(i);
                amp = table_info.Amplitude(i);
                lat = table_info.Latency(i);
                plot(app.UIAxes,lat,amp,'r*','LineWidth',5)
                text(app.UIAxes,lat,amp,name,'FontSize',20)
            end
            hold(app.UIAxes,"off");    
        end
        
        
        function [zmap, zmapthresh, zmapthresh_plc, zmapthresh_clc, tfv_time, v_freq] = npp_stb_2(app,input_mat,n_permutes,v_time,v_freq,time_window,baseline_range,plotting_input,my_title)

            %% Description:
            % Takes a time frequency matrix (dimensions = n_subs, frequencies, times)
            % and peforms the non perametric permutation comparing the signal to the
            % baseline. Inputs include input mat, number of permutations (n_permutes),
            % vectors for time and frequency(v_time and v_freq), limits in the time 
            % axis where you want to perform the analysis (time_window), and the
            % plotting_input variable, in which if is set to 1, it will plot the 
            % responses. The output consist of the z_map, the logical mask for the 
            % zmap uncorrected for multple comparisons (zmapthresh), the logical mask
            % with a pixel level correction for multiple comparisons (zmapthresh_plc),
            % and the logical threshold map with cluster correction for mutliple
            % comparisons (zmapthesh_clc). The last output is the new time vector
            % based on time_window. 
            
            
            %% getting parameters for permutations
            time_s = dsearchn(v_time',baseline_range(1)); % start time for tf window
            time_0 = dsearchn(v_time',baseline_range(2));
            time_e = dsearchn(v_time',time_window(2)); % end zerostime for tf window
            tfv_time  = v_time(time_s:time_e);
            nTimepoints = numel(tfv_time);
            avg_mat =squeeze(mean(input_mat));
            temp = size(input_mat);
            n_subs = temp(1);
            
            % statistical significant values for voxel and cluster analysis
            voxel_pval   = 0.05;
            cluster_pval = 0.05;
            mcc_voxel_pval = 0.05; % mcc = multiple comparisons correction
            mcc_cluster_pval = 0.05;
            %% Running actual T test on data
            % need to run it per frequency bin
            
            mat_size = size(avg_mat(:,time_s:time_e));
            t_mat = zeros(mat_size);
            
            for i = 1:mat_size(1) %run per frequency bin
                prestim_estimate = mean(input_mat(:,i,time_s:time_0),3);
                poststim_points = squeeze(input_mat(:,i,time_s:time_e));
                prestim_points = repmat(prestim_estimate, 1,length(poststim_points));
                [~,~,~,stats] = ttest(poststim_points,prestim_points);
                results = stats.tstat;
                t_mat(i,:) = results;
            end
            
            %% running the premutations
            
            % initialize null hypothesis matrices
            %permuted_maxvals = zeros(n_permutes,2,length(v_freq));
            max_clust_info   = zeros(n_permutes,1);
            max_pixel_pvals = zeros(n_permutes,2);
            %% permutation loop 
            curr_perm = zeros(mat_size); % initializing a variable to temporary store the results of current permutation before reshaping back into tf spectograph
            permuted_vals = zeros([n_permutes, mat_size]);
            
            % waitbar
            x_wait = 0;
            f = waitbar(x_wait,'Performing the permutations...','Name','npp_stb',...
                'CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
            
            setappdata(f,'canceling',0);
            
            
            for permi=1:n_permutes
                 if getappdata(f,'canceling')
                    delete(f)
                    msg = 'Error, user terminated the function.';
                    error(msg)
                 end
                
                x_wait = permi/n_permutes;
                waitbar(x_wait,f,'performing the permutations...');
                for i = 1:mat_size(1) % per frequency bin 
                    prestim_estimate = squeeze(input_mat(:,i,time_s:time_0));
                    poststim_points = squeeze(input_mat(:,i,time_s:time_e));
                    pre_stim_estimate_r = zeros([n_subs,1]);
                    temp1 = size(prestim_estimate);
                    for m = 1:n_subs
                        r_index = randi([1,temp1(2)]);
                        pre_stim_estimate_r(m) = prestim_estimate(m,r_index);
                    end
                    %prestim_points = repmat(pre_stim_estimate_r, 1,length(poststim_points));
                    shuffled_data_1 = zeros(size(poststim_points));
                    shuffled_data_2 = zeros(size(poststim_points));
                    for j = 1:mat_size(2) % per timepoint
                        c_data = poststim_points(:,j);
                        c_data_out_1 = zeros(size(c_data));
                        c_baseline = pre_stim_estimate_r;
                        c_data_out_2 = zeros(size(c_data));
                        conditions = randi([1, 2], n_subs,1); %% create a vector that randomly assings a 1 or a 2 to each subject
                        for k = 1:n_subs % switching labels per subject
                            if conditions(k) == 1 % if the subject is assigned condition 1
                                c_data_out_1(k) = c_data(k);
                                c_data_out_2(k) = c_baseline(k);
                            elseif conditions(k) == 2
                                c_data_out_1(k) = c_baseline(k);
                                c_data_out_2(k) = c_data(k);
                            end
                        end
                        shuffled_data_1(:,j) = c_data_out_1;
                        shuffled_data_2(:,j) = c_data_out_2;   
                    end
                    [~,~,~,stats] = ttest(shuffled_data_1,shuffled_data_2);
                    curr_perm(i,:) = stats.tstat;
                end
                permuted_vals(permi,:,:) = curr_perm;
            
                
                % saving max for correction for multiple comparisons
                max_pixel_pvals(permi,:) = [ min(curr_perm(:)) max(curr_perm(:)) ];
            
                % for cluster correction, apply uncorrected threshold and get maximum cluster sizes
                % note that here, clusters were obtained by parametrically thresholding
                % the t-maps
                curr_perm(abs(curr_perm)<tinv(1-voxel_pval,n_subs-1))=0;
                clustinfo = bwconncomp(curr_perm);
                max_clust_info(permi) = max([ 0 cellfun(@numel,clustinfo.PixelIdxList) ]); % notes: cellfun is superfast, and the zero accounts for empty maps
            end
            delete(f)
            
            %% getting zmaps
            zmap = (t_mat-squeeze(mean(permuted_vals,1)))./squeeze(std(permuted_vals));
            zmapthresh = zmap;
            % appliying uncorrected threshold
            zmapthresh(abs(zmapthresh)<norminv(1-voxel_pval))=false;
            zmapthresh=logical(zmapthresh);
            
            
            % apply pixel-level corrected threshold
            lower_threshold = prctile(max_pixel_pvals(:,1),    mcc_voxel_pval*100/2);
            upper_threshold = prctile(max_pixel_pvals(:,2),100-mcc_voxel_pval*100/2);
            zmapthresh_plc = zmap;
            mask1 = zmap>lower_threshold;
            mask2 = zmap<upper_threshold;
            mask = logical(mask1 + mask2-1);
            zmapthresh_plc(mask) = 0;
            
            %% apply cluster-level corrected threshold
            zmapthresh_clc = zmap;
            % apply uncorrected pixel-level threshold
            zmapthresh_clc(abs(zmapthresh_clc)<norminv(1-cluster_pval))=false;
            % find islands and remove those smaller than cluster size threshold
            clustinfo = bwconncomp(zmapthresh_clc);
            clust_info = cellfun(@numel,clustinfo.PixelIdxList);
            clust_threshold = prctile(max_clust_info,100-mcc_cluster_pval*100);
            
            % identify clusters to remove
            whichclusters2remove = find(clust_info<clust_threshold);
            
            % remove clusters
            for i=1:length(whichclusters2remove)
                zmapthresh_clc(clustinfo.PixelIdxList{whichclusters2remove(i)})=0;
            end
            my_clims = [-9 9];
            if plotting_input == 1
                figure
                my_lxlim = time_window(1)+10;
                my_hxlim = time_window(2);
                subplot(2,2,1)
                contourf(tfv_time,v_freq,zmap,40,'linecolor','none')
                axis square
                set(gca,'clim',my_clims,'xlim',[my_lxlim my_hxlim])
                title('Unthresholded Z map')
                xlabel('Time (ms)'), ylabel('Frequency (Hz)')
                colormap jet
                c = colorbar;
                c.Label.String = 'Z score';
            
                subplot(2,2,2)
                contourf(tfv_time,v_freq,zmap,40,'linecolor','none')
                hold on
                contour(tfv_time,v_freq,zmapthresh,1,'linecolor','k')
                c = colorbar;
                c.Label.String = 'Z score';
                axis square
                set(gca,'clim',my_clims,'xlim',[my_lxlim my_hxlim])
                title('Uncorrected threshold Z map')
                xlabel('Time (ms)'), ylabel('Frequency (Hz)')
            
            
                subplot(2,2,3)
                contourf(tfv_time,v_freq,zmapthresh_plc,40,'linecolor','none')
                axis square
                set(gca,'clim',my_clims,'xlim',[my_lxlim my_hxlim])
                title('Pixel-corrected Z map')
                xlabel('Time (ms)'), ylabel('Frequency (Hz)')
                c = colorbar;
                c.Label.String = 'Z score';
            
                subplot(2,2,4)
                contourf(tfv_time,v_freq,zmapthresh_clc,40,'linecolor','none')
                axis square
                set(gca,'clim',my_clims,'xlim',[my_lxlim my_hxlim])
                title('Cluster-corrected Z map')
                xlabel('Time (ms)'), ylabel('Frequency (Hz)')
                c = colorbar;
                c.Label.String = 'Z score';
                %colormap(rbw_good_one_2)
                sgtitle(my_title)
            end

            
        end
        
        
        function plot_trial_data(app)
            EEG = evalin('base','EEG');
            data2plot =evalin('base','EEG_dat_plotted');
            load('new_color_map_2.mat');


            x = EEG.times;
       
%            avg2plot = squeeze(mean(EEG.data(chan_idx,:,:),3));
%            d1 = designfilt("lowpassiir",FilterOrder=12, ...
%                 HalfPowerFrequency=0.15,DesignMethod="butter",SampleRate = EEG.srate);
%            d2 = designfilt("lowpassiir",FilterOrder=12, ...
%                 HalfPowerFrequency=0.15,DesignMethod="butter");
%     
%             avg2plot = filtfilt(d2,avg2plot);

            
          %data2plot = squeeze(EEG.data(chan_idx,:,:));
          %filt_data = lowpass(data2plot,25,EEG.srate,'ImpulseResponse','iir'); %% can change to change the smoothing
          
          % finding the max to plot
          [~,low_index] = (min(abs(EEG.times - app.TimeLowmsEditField.Value)));
          [~,high_index] = (min(abs(EEG.times - app.TimeHighmsEditField.Value)));
          subset_data = data2plot(low_index:high_index,:);
          max_sign = max(max(abs(subset_data)));
          x = EEG.times(low_index:high_index);
          trials = size(data2plot,2);
          imagesc(app.UIAxes7,x,1:trials,subset_data')
          hold(app.UIAxes7,'off')
          colormap(app.UIAxes7,new_color_map_2)
          caxis(app.UIAxes7,[-max_sign*0.8 max_sign*0.8]);
          c = colorbar(app.UIAxes7,"east");
          c.Label.String = "Amplitude (mV)";
          hold(app.UIAxes7,'off')
          xlim(app.UIAxes7, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])
          ylim(app.UIAxes7, [0 max(trials)+1])
          
          avg_data = squeeze(mean(subset_data,2));
          plot(app.UIAxes8,x,avg_data)
          xlim(app.UIAxes8, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])
          set(app.UIAxes8, 'YDir','reverse');


        end
        
        function my_settings = compile_settings(app)
            %time domain settings
            %%% plotting parementers
            ttlow = {'Time Low', app.TimeLowEditField};

            %time frequency settings 
            
        end
        
        function results = extract_single_trial_data(app)
           EEG = evalin('base','EEG');
           n_peaks = app.numberofpeakstoextractEditField_2.Value;
           fig = figure(); 
           plot(EEG.WARM_filter.time,EEG.WARM_filter.mean_waveform)
           set(gca, 'YDir','reverse')
           hold on
%            
%            appAxes = app.UIAxes2_7;
%            
%            ax = copyobj(appAxes, fig); 


           for i  = 1:n_peaks
               prompt = {'Enter name of peak'};
               dlgtitle = 'Name of peak';
               dims = [1 35];
               definput = {['Peak ',num2str(i)],'hsv'};
               answer = inputdlg(prompt,dlgtitle,dims,definput);


                temp_roi = drawrectangle(fig.CurrentAxes,'StripeColor','b','InteractionsAllowed','none');
                position = temp_roi.Position;  
                x_min = position(1);
                x_max  = position(1) + position(3);
                [~,low_index] = (min(abs(EEG.WARM_filter.time - x_min)));
                [~,high_index] = (min(abs(EEG.WARM_filter.time - x_max)));
                % determining if its a negative or a positive peak 
                erp = EEG.WARM_filter.mean_waveform;
                erp_cut = erp(low_index:high_index);
                [highest_point , highest_index]  = max(erp_cut);
                [lowest_point , lowest_index]  = min(erp_cut);
                
                pgn = abs(highest_point) > abs(lowest_point); % check to see if the highest positive point has a greater magnitude than the lowerst negative point. reurn zero if it is true, and return 1 if it is not true.
                
                if pgn == 0 % if my positive point is not greater than negative point
                    % check that the negative dip is not in the end
                    if and(lowest_index ~= 0,lowest_index ~= length(erp_cut))
                        pont = 0; % positiv or negative toggle (1 = positive peak, -0 = negative peak)
                    elseif and(highest_index ~= 0,highest_index ~= length(erp_cut)) % if it is in the end, check to see if the positive is in the end or not 
                        pont  = 1;
                    else 
                        warning("The region that you selected did not contain a peak - please try again ")
                    end
                elseif pgn == 1 % if my positive point is greater than my negative point
                    % check tha the positive dip is not at the end
                    if and(highest_index ~= 0,highest_index ~= length(erp_cut))
                        pont = 1;
                    elseif and(lowest_index ~= 0,lowest_index ~= length(erp_cut)) 
                        pont = 0;
                    else
                        warning("The region that you selected did not contain a peak - please try again ")
                    end
                    
                end
                
                if pont == 1
                    my_peak = highest_point ;
                    my_latency = EEG.WARM_filter.time(low_index+highest_index);
                elseif pont == 0
                    my_peak = lowest_point ;
                    my_latency = EEG.WARM_filter.time(low_index+lowest_index);
                else
                    warning("Please re select the region")
                end
                
                my_table = app.UITable3.Data;

                % Extracting for single trial data
                st_data = EEG.WARM_filter.single_trial_waveforms;
                % window in ms
                wind = 0;

                m_data.Channel = string(app.ChannelsListBox.Value);
                m_data.Name = string(answer);
                m_data.Amplitude =my_peak;
                m_data.Latency =  my_latency;
                if app.i_peaks_st >= 1
                    my_table = [my_table;struct2table(m_data)];
                else
                    my_table = struct2table(m_data);
                end
                app.UITable3.Data = my_table; 

                app.i_peaks_st = app.i_peaks_st + 1;

           end
           
           close(fig)


        end
        
        function app_setup(app)
            app.extraction_state = 0;% Has data been extracted?
            app.Tree.CheckedNodes = [];% unselect everything
            a = app.Tree.Children;
            a.delete; % delete all nodes on tree to avoid overpopulating them 


            data_in_info = app.DataInEditField.Value;
            [filepath,name,ext] = fileparts(data_in_info); 

            if app.file_type == 0 % for .set file
                EEG = pop_loadset('filename',[name, ext],'filepath',filepath); %loads the specified file into eeglab
                assignin('base','EEG',EEG)
                ALLEEG = EEG;
                assignin('base','ALLEEG',ALLEEG);
            elseif app.file_type == 1 % for .mat files
                load([filepath filesep() name ext],"EEG");
                assignin('base','EEG',EEG)
                ALLEEG = EEG;
                assignin('base','ALLEEG',ALLEEG);
            elseif app.file_type == 2 % for txt files
                %getting name of channel
                my_headers = readtable([filepath filesep() name ext],'HeaderLines', 2);
                temp = char(my_headers.Properties.VariableNames(1));
                C = strsplit(temp,'_');
                chan_name = char(C(1));
                if string(chan_name) == "N1"
                    chan_name = "C3";
                end
               
                srate = 2000; % need a way to define this
                baseline_low =-1000; % need a way to define this. In ms.
                my_data = table2array(readtable([filepath filesep() name ext],'HeaderLines', 3));

                % Amplipfy it so thats its in mV not volts
                my_data = my_data *1000000;
                EEG = convert_txt_to_eeg_struct(app,my_data,chan_name,srate,baseline_low);
                ALLEEG = EEG;

                assignin('base','EEG',EEG)
                assignin('base','ALLEEG',ALLEEG) 

            elseif app.file_type == 3 % for keypoint files

                [ALLEEG, EEG] = load_fnct_keypont(app,[filepath filesep() name]);
                assignin('base','EEG',EEG) 
                assignin('base','ALLEEG',ALLEEG) 

            end
            
            size_data = size(EEG.data);
            
           if length(size_data) == 2 % if it is only a single channel 
                n_trials = size_data(2);
                n_points = size_data(1);
                n_channels = 1;
                %n_chans = 1;
                %sensoridx = 1;
   
            elseif length(size_data) == 3
                n_trials = size_data(3);
                n_points = size_data(2);
                n_channels = size_data(1);
                %sensoridx = find(strcmpi(chan2plot,{chanlocs.labels}));
           end
           
            app.SamplingRateEditField.Value = EEG.srate;
            app.EpochsEditField.Value = n_trials;
            app.FramesPerEpochEditField.Value = n_points;
            app.NChannelsEditField.Value = n_channels;


            if app.file_type == 3
                chanloc_count = 1;
                for i  = 1:length(ALLEEG)
                    EEG = ALLEEG(i);
                    for j = 1:length(EEG.chanlocs)
                        my_chanlocs(chanloc_count) = cellstr(EEG.chanlocs(j).labels);
                        chanloc_count = chanloc_count+1;

                    end
                end
                app.ChannelsListBox.Items =my_chanlocs;

            else
                for i  = 1:length(EEG.chanlocs)
                    my_chanlocs(i) = cellstr(EEG.chanlocs(i).labels); 
                end
                app.ChannelsListBox.Items =my_chanlocs;

            end

            % create nodes for all the data
            p = uitreenode(app.Tree,"Text","All Trials");
            for i = 1:n_trials
                c1 = uitreenode(p,"Text",string(i));
            end

            % expand the nodes
            expand(p);

            % select all nodes by default
           app.Tree.CheckedNodes = app.Tree.Children;          

            app.TrialOverlayListBox.Items = string(1: n_trials);
            app.ChannelsListBox_2.Items =my_chanlocs;
            app.EpochsListBox_2.Items = string(1: n_trials);
            app.EpochsListBox_2.Value = app.EpochsListBox_2.Items;
            [tf , frequencies] = calc_tf_data(app);
            is_f_chan_empt = max(isnan(tf(:))); % check if first channel is empty
            if is_f_chan_empt == 1
                app.ChannelsListBox.Value = char(app.ChannelsListBox.Items(2));
                app.ChannelsListBox_2.Value = char(app.ChannelsListBox_2.Items(2));
                [tf , frequencies] = calc_tf_data(app);
            end
            
            
            EEG = ALLEEG(1); % index the first eeg structure again
            x = EEG.times;
            chan2plot = string(app.ChannelsListBox.Value);
            for i = 1:length(EEG.chanlocs)
                if chan2plot == string(EEG.chanlocs(i).labels)
                    chan_idx = i;
                end
            end
            [~,low_index] = (min(abs(EEG.times - app.TimeLowmsEditField.Value)));
            [~,high_index] = (min(abs(EEG.times - app.TimeHighmsEditField.Value)));
            
            avg2plot = squeeze(mean(EEG.data(chan_idx,low_index:high_index,:),3));
            
            my_lim = max(abs(avg2plot)) + max(abs(avg2plot))*0.10 ;
            app.YmaxEditField.Value = double(my_lim);
            app.YminEditField.Value = double(-my_lim);
            
            
            plot_tfdata(app,tf,frequencies)  
            EEG_dat = plot_tdat(app);
            EEG_dat_plotted = EEG_dat;
            assignin("base","EEG_dat_plotted",EEG_dat_plotted)
            plot_trial_data(app);
  

        end
        
        function adjust_yaxis(app)
            EEG = evalin('base','EEG');
            chan2plot = string(app.ChannelsListBox.Value);


            % check if its keypoint
            if app.file_type == 3 
                ALLEEG = evalin('base','ALLEEG');
                % figure out which dermatome is being trying to be plotted
                % and load the correct EEG file and the right chanloc index
                % relative to that file
                for i_EEG = 1:length(ALLEEG)
                    EEG = ALLEEG(i_EEG);
                    current_chanlocs = EEG.chanlocs;
                    for j = 1:length(current_chanlocs)
                        if string(current_chanlocs(j).labels) == string(chan2plot)
                            EEG_idx = i_EEG;
                            chan_idx = j;
                        end
                    end
                end
                EEG = ALLEEG(EEG_idx);
            else
                for i = 1:length(EEG.chanlocs)
                    if chan2plot == string(EEG.chanlocs(i).labels)
                        chan_idx = i;
                    end
                end
            end

     
            [~,low_index] = (min(abs(EEG.times - app.TimeLowmsEditField.Value)));
            [~,high_index] = (min(abs(EEG.times - app.TimeHighmsEditField.Value)));
            
            avg2plot = squeeze(mean(EEG.data(chan_idx,low_index:high_index,:),3));
            
            my_lim = max(abs(avg2plot)) + max(abs(avg2plot))*0.10 ;
            app.YmaxEditField.Value = double(my_lim);
            app.YminEditField.Value = double(-my_lim);

        end
        
        function [EEG, erp, filt_recon,filt_resampled_ind_data,resampled_time] = warm_filter(app,EEG)

            
            [~ , frequencies,x,convol_pwr_ind,individual_convol_result,total_convol_result] = calc_tf_data(app);

            % resample everything and put into same time scale
            time_window = [app.timelowerEditField.Value app.timehigherEditField.Value]; % time window that you want to analyze 
            [~,i_low] = (min(abs(x - time_window(1))));
            [~,i_high] = (min(abs(x - time_window(2))));
            %x1 = x(i_low:i_high); % cut and resampled time 


            % convol power individual 
            [p,q] = rat(floor(app.SamplingRateEditField.Value/10)/app.SamplingRateEditField.Value);
            resampled_pwr_ind = resample(double(convol_pwr_ind),p,q,'Dimension',2);
            resampled_time = linspace(min(x),max(x),size(resampled_pwr_ind,2));

            % find chan IDX
            chan2plot = string(app.ChannelsListBox_2.Value);
            for i = 1:length(EEG.chanlocs)
                if chan2plot == string(EEG.chanlocs(i).labels)
                    chan_idx = i;
                end
            end

            % calculate ERP
            erp = squeeze(mean(EEG.data(chan_idx,:,:),3));  
            [~,i_low_erp] = (min(abs(EEG.times - time_window(1))));
            [~,i_high_erp] = (min(abs(EEG.times - time_window(2))));
            erp = erp(i_low_erp:i_high_erp-1);
            erp = resample(double(erp),p,q,'Dimension',1);

            % some of the input parameters for the function
            input_mat = permute(resampled_pwr_ind,[3 1 2]);% rearange input matrix containing all the trials
            n_permutes = app.npermutationsEditField_2.Value; % number of permutations
            v_time = resampled_time; % vector of time
            v_freq = frequencies; % vector of frequencies in 
            plotting_input = 0; % make 1 if you want to plot the responses or zero if you don't.
            baseline_range = [app.baselinelowerendEditField.Value app.baselinehigherendEditField.Value];
        
            my_title = ['testing function']; % required for the npp_stb_2 function
            
            % plotting the significant responses
            cla(app.UIAxes2_6) ; % dont know if i need this? clearing signficant response

            % check if the zmaps haveapp_s been done
            if ~isfield(EEG,'zmaps')
                 [zmap, zmapthresh, zmapthresh_plc, zmapthresh_clc, tfv_time, v_freq] = npp_stb_2(app,input_mat...
                    ,n_permutes,v_time,v_freq,time_window,baseline_range,plotting_input,my_title);
            
                EEG.zmaps.zmapthresh_clc = zmapthresh_clc;
                EEG.zmaps.times = tfv_time;
                EEG.zmaps.frequencies = v_freq;
                EEG.zmaps.zmap = zmap;
                EEG.zmaps.zmapthresh = zmapthresh;
                EEG.zmaps.zmapthresh_plc = zmapthresh_plc;

                assignin('base','EEG',EEG)  
            else
                zmapthresh_clc = EEG.zmaps.zmapthresh_clc; % read clc zmaps from eeg structure
            end


            % transform the average ERP 
            % first resample
            resampled_convol_mean = resample(double(total_convol_result),p,q,'Dimension',2);

            freq_lims= [app.minfreqEditField.Value app.maxfreqEditField.Value];
            % do i need the next line?
            [~,~,resamp_scalingf] = cwt(erp,'amor',floor(EEG.srate/10),FrequencyLimits = freq_lims); % do it on a single trial to get the scaling factors 

            % apply the cwt filter based on the zmap
            % convert mask into logical
            logical_clc_zmap = zmapthresh_clc ~= 0;
            % multiply convol matrix in tf domain to filter out non
            % significant regions
            filtered_mean_convol = resampled_convol_mean .* double(logical_clc_zmap);
            % reconstruct filtered convol signal in the time domain
            filt_recon= icwt(filtered_mean_convol,"amor");

            % repeat the same thing with the individual trials
           resampled_ind_convol = resample(double(individual_convol_result),p,q,'Dimension',2);
           filt_resampled_ind_data = zeros(length(filtered_mean_convol),size(resampled_ind_convol,3));
           resampled_ind_data = zeros(length(filtered_mean_convol),size(resampled_ind_convol,3));

           % loop through each trial to reconstruct filtered time signal
           for i = 1:size(resampled_ind_convol,3)
               c_dat = squeeze(resampled_ind_convol(:,:,i));
               filtered_convol = c_dat .* double(logical_clc_zmap);
               recon_ind = icwt(c_dat,"amor");
               filt_recon_ind= icwt(filtered_convol,"amor");
               filt_resampled_ind_data(:,i) =filt_recon_ind';
               resampled_ind_data(:,i) =recon_ind';

           end

                       
           %% save in EEG structure
           EEG.WARM_filter.single_trial_waveforms = filt_resampled_ind_data;
           EEG.WARM_filter.mean_waveform = filt_recon;
           EEG.WARM_filter.time = resampled_time;
            
        end
        
        function [ALLEEG,EEG] = load_fnct_keypont(app,file_path)
            header_info = readtable([file_path, filesep(),'Header.txt']);
            load('chan_info.mat',"chanlocs");

            % check sampling frequency
            if std(header_info.SmplFrq) == 0
                sr = header_info.SmplFrq(1);
            else
            end

            % check length of files
            if std(header_info.Samples) == 0
                l = header_info.Samples(1);
            else
            end

            % check trigger offset
            if std(header_info.TrigOff) == 0
                t_off = header_info.TrigOff(1);
            else
            end

             
            % create time vector
            x = linspace(0,(1000/sr)*l,l)-t_off/sr*1000; % in ms

            % load and organize all the text files into EEG structure
            %%% get list of dermatomes

            files = header_info.File;
            
            temp = split(string(files),"__");
            temp = split(temp(:,1),"-");
            chans_in = unique(temp(:,3)); %chanel names
            derms_in = unique(temp(:,4)); %derms tested

            % create an EEGLAB structure per dermatome
            for i= 1:length(derms_in) % iterate for all dermatomes 
                c_derm = derms_in(i);
                tf_mask = contains(files,c_derm);
                c_files = files(tf_mask);
                data_in = zeros(length(chans_in),l,length(c_files)/length(chans_in)); % make a strucutre to store the data


                for j = 1:length(chans_in) % iterate for all channels available per dermatome
                    c_chan = chans_in(j);
                    tf_mask_chan = contains(c_files,c_chan);
                    chan_files = c_files(tf_mask_chan);% get list of channels to load
                    all_chans = blanks(length(chanlocs));

                    % get channel names 
                    for k =1:length(chanlocs)
                        if k == 1
                            all_chans = string(chanlocs(k).labels);
                        else
                            all_chans = [all_chans,string(chanlocs(k).labels)];
                        end

                    end


                     % getting the channels and adding dermatome info to them
                     if c_chan == "N1"
                         chan2id = 'C3';% choose either c3 or c4. 
                         chan_info = chanlocs(all_chans == chan2id);
                         chan_info.labels = ['N1-',char(c_derm)];
                    
                     elseif c_chan == "EOG"
                         chan2id = 'Fp1'; % choose something that would normally be chosen for blink correctio
                         chan_info = chanlocs(all_chans == chan2id);
                         chan_info.labels = ['EOG-',char(c_derm)];
                     else
                         chan_info = chanlocs(all_chans == c_chan);
                         chan_info.labels = [char(c_chan),'-',char(c_derm)];
                     end
                     

                    for k = 1:length(chan_files) % iterate through all files to load
                        data_in(j,:,k) = load([file_path,filesep(),char(chan_files(k))]);
                    end

                    if j == 1
                        chanlocs_out =chan_info;

                    else
                        chanlocs_out =[chanlocs_out;chan_info];

                    end


                end


                % getting the channels and adding dermatome info to them


                % creating the eeg structure
                EEG.setname = ['Dermatome-', char(c_derm)];
                EEG.filename = [];
                EEG.fielpath = file_path;
                EEG.nbchan = length(chans_in);
                EEG.trials = length(chan_files);
                EEG.pnts = l;
                EEG.srate = sr;
                EEG.xmin = min(x)/1000;
                EEG.xmax= max(x)/1000;
                EEG.times = x;
                EEG.data = -data_in;
                EEG.icaact = [];
                EEG.icawinv = [];
                EEG.icasphere = [];
                EEG.icaweights = [];
                EEG.icachansind = [];
                EEG.chanlocs = chanlocs_out;
                EEG.ref = "unknown";
                
               ALLEEG(i) = EEG;
            end


        end
        
        function EEG_out = preprocc_eeg(app,EEG_in)

        
            % baseline correct
            EEG_out = pop_rmbase( EEG_in, [app.TimeLowEditField.Value 0] ,[]);

            
            % occular correction
            % 


        end
        
        function [sd2,SNR]= calc_params_peak_extract(app,EEG_dat,x)


            %baseline correct each trial
            % define baseline noise
            [~,low_base] = (min(abs(x - app.TimeLowmsEditField.Value)));
            [~,zero_base] = (min(abs(x - 0)));
            [~,high_base] = (min(abs(x - app.TimeHighmsEditField.Value)));
            
            % calculate baseline
            baseline_mean = mean(EEG_dat(low_base:zero_base,:),1);

            % cut the trials data to the window of interest and also baseline
            % correct
            chan_dat = EEG_dat(low_base:high_base,:)-baseline_mean;

             % extract erp
             erp = squeeze(mean(chan_dat,2));


            % calculate 2sd from the baseline corrected data
            % new zero
            new_x = x(low_base:high_base);
            [~,new_zero] = (min(abs(new_x - 0)));

            sd2 = 2*std(erp(1:new_zero));

            % calculate SNR method 1 - at the erp level

            noise_only_trials = squeeze(chan_dat - erp);
            N = rms(mean(noise_only_trials,2));
            S = rms(erp);
            SNR = 10*log10(S/N);

            % calculate SNR method 2 - odd even method
            odd_trials = mean(chan_dat(:,1:2:size(chan_dat,2)),2);
            even_trials = mean(chan_dat(:,2:2:size(chan_dat,2)),2);

       
            noise_estimate = odd_trials-even_trials;

            N = rms(noise_estimate);
            S = rms(erp);
            SNR = 10*log10(S/N);


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if app.file_type == 0
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                [file_in,path_in] = uigetfile('*.set');
                delete(f); %delete the dummy figure

            elseif app.file_type == 1
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                [file_in,path_in] = uigetfile('*.mat');
                delete(f); %delete the dummy figure

            elseif app.file_type == 2
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                [file_in,path_in] = uigetfile('*.txt');
                delete(f); %delete the dummy figure
                
            elseif app.file_type == 3
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                path_in = uigetdir();
                delete(f); %delete the dummy figure

            end


            try
                if app.file_type == 0 || app.file_type == 1 || app.file_type == 2
                    app.DataInEditField.Value = [path_in,file_in];
                elseif app.file_type == 3
                    app.DataInEditField.Value = path_in;
                end
            catch

            end


        end

        % Value changed function: DataInEditField
        function DataInEditFieldValueChanged(app, event)

        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            d_eeglab = uigetdir('C:\');
            app.EEGLabFolderEditField.Value = d_eeglab;
            addpath app.SavingFolderEditField.Value;

        end

        % Value changed function: EEGLabFolderEditField
        function EEGLabFolderEditFieldValueChanged(app, event)
            eeglab_path = app.EEGLabFolderEditField.Value;

        end

        % Callback function
        function Button_2Pushed(app, event)
            d_save = uigetdir('C:\');
            app.SavingFolderEditField.Value = d_save;
        end

        % Callback function
        function SavingFolderEditFieldValueChanged(app, event)
            addpath app.SavingFolderEditField.Value;

        end

        % Button pushed function: InitializeEEGLabButton
        function InitializeEEGLabButtonPushed(app, event)
            addpath(app.EEGLabFolderEditField.Value);
           eeglab;
        end

        % Callback function
        function ChannelDropDownOpening(app, event)
            
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)

            try 
               app.DatanotloadedLamp.Enable = "off";
               app.DatanotloadedLampLabel.Text= "Data Not Loaded";
               app_setup(app)
               app.DatanotloadedLamp.Enable = "on";
               app.DatanotloadedLampLabel.Text= "Data Loaded";
            catch e
                fprintf(2,'The identifier was:\n%s',e.identifier);
                fprintf(2,'There was an error! The message was:\n%s',e.message);
            end

        end

        % Value changed function: TrialOverlayListBox
        function TrialOverlayListBoxValueChanged(app, event)
            EEG_dat = plot_tdat(app);
            EEG_dat_plotted = EEG_dat;
            assignin("base","EEG_dat_plotted",EEG_dat_plotted)
            plot_trial_data(app);


        end

        % Value changed function: ChannelsListBox
        function ChannelsListBoxValueChanged(app, event)
            adjust_yaxis(app);
            EEG_dat = plot_tdat(app);
            EEG_dat_plotted = EEG_dat;
            assignin("base","EEG_dat_plotted",EEG_dat_plotted)
            plot_trial_data(app);
  

        end

        % Value changed function: ChannelsListBox_2
        function ChannelsListBox_2ValueChanged(app, event)
            [tf , frequencies] = calc_tf_data(app);
            plot_tfdata(app,tf,frequencies)
        end

        % Value changed function: EpochsListBox_2
        function EpochsListBox_2ValueChanged(app, event)
            
            
        end

        % Value changed function: SpacingSwitch
        function SpacingSwitchValueChanged(app, event)
            [tf , frequencies] = calc_tf_data(app);
            plot_tfdata(app,tf,frequencies)  
        end

        % Value changed function: maxfreqEditField
        function maxfreqEditFieldValueChanged(app, event)
           [tf , frequencies] = calc_tf_data(app);
            plot_tfdata(app,tf,frequencies)  
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: ConversionSwitch
        function ConversionSwitchValueChanged(app, event)
            [tf , frequencies] = calc_tf_data(app);
            plot_tfdata(app,tf,frequencies)              
            
        end

        % Value changed function: minfreqEditField
        function minfreqEditFieldValueChanged(app, event)
            [tf , frequencies] = calc_tf_data(app);
            plot_tfdata(app,tf,frequencies)  
            
        end

        % Value changed function: baselinehigherendEditField
        function baselinehigherendEditFieldValueChanged(app, event)
            [tf , frequencies] = calc_tf_data(app);
            plot_tfdata(app,tf,frequencies)  
                
        end

        % Value changed function: baselinelowerendEditField
        function baselinelowerendEditFieldValueChanged(app, event)
            [tf , frequencies] = calc_tf_data(app);
            plot_tfdata(app,tf,frequencies)  
            
        end

        % Value changed function: ofcycleslowerEditField
        function ofcycleslowerEditFieldValueChanged(app, event)
            [tf , frequencies] = calc_tf_data(app);
            plot_tfdata(app,tf,frequencies)  
            
        end

        % Value changed function: ofcycleshigherEditField
        function ofcycleshigherEditFieldValueChanged(app, event)
            value = app.ofcycleshigherEditField.Value;
            
        end

        % Value changed function: timelowerEditField
        function timelowerEditFieldValueChanged(app, event)
            [tf , frequencies] = calc_tf_data(app);
            plot_tfdata(app,tf,frequencies)  
        end

        % Value changed function: timehigherEditField
        function timehigherEditFieldValueChanged(app, event)
            [tf , frequencies] = calc_tf_data(app);
            plot_tfdata(app,tf,frequencies)  
            
        end

        % Value changed function: offrequencybinsEditField
        function offrequencybinsEditFieldValueChanged(app, event)
            [tf , frequencies] = calc_tf_data(app);
            plot_tfdata(app,tf,frequencies)  
            
        end

        % Button pushed function: selectrectanglemanuallyButton
        function selectrectanglemanuallyButtonPushed(app, event)

            %app.SelectedtabEditField.Value  = app.TabGroup2.SelectedTab;
            c_tab = string(app.TabGroup2.SelectedTab.Title);
            p_tabs = ["Total","Phase locked","Non-phase locked","ITPC","ERP Power"];
            p_UI = ["UIAxes2","UIAxes2_5","UIAxes2_2","UIAxes2_4","UIAxes2_3"];
            for i = 1:length(p_tabs)
                if p_tabs(i) == c_tab
                    tab_idx = i;
                end
            end
            
            my_return = selectDatapoints(app,p_UI(tab_idx));
            app.TimeLowEditField.Value = my_return(1);
            app.TimeHighEditField.Value = my_return(1)+ my_return(3);
            app.FreqLowEditField.Value = my_return(2);
            app.FreqHighEditField.Value = my_return(2)+my_return(4);


            %app.test2EditField.Value =app.TabGroup2.SelectedTab;
            
       
        end

        % Callback function
        function SelectbottomrightpointButtonPushed(app, event)
           [x,y] = ginput(1);
           app.FreqHighEditField.Value =y;
           app.TimeHighEditField.Value = x;            
        end

        % Button down function: TotalTab
        function TotalTabButtonDown(app, event)
            
        end

        % Button pushed function: ExtractInfoButton
        function ExtractInfoButtonPushed(app, event)
            delete(findall(app.UIAxes2, 'Type',  'images.roi.Rectangle'))
            delete(findall(app.UIAxes2_5, 'Type',  'images.roi.Rectangle'))
            delete(findall(app.UIAxes2_2, 'Type',  'images.roi.Rectangle'))
            delete(findall(app.UIAxes2_4, 'Type',  'images.roi.Rectangle'))
            delete(findall(app.UIAxes2_3, 'Type',  'images.roi.Rectangle'))


            x_min = app.TimeLowEditField.Value;
            y_min = app.FreqLowEditField.Value;
            x_length = app.TimeHighEditField.Value - x_min;
            y_length = app.FreqHighEditField.Value - y_min;
            position_v = [x_min y_min x_length y_length];

            roi = drawrectangle((app.UIAxes2),'Position',position_v,'Color','r'); 
            roi = drawrectangle((app.UIAxes2_5),'Position',position_v,'Color','r'); 
            roi = drawrectangle((app.UIAxes2_2),'Position',position_v,'Color','r'); 
            roi = drawrectangle((app.UIAxes2_4),'Position',position_v,'Color','r'); 
            roi = drawrectangle((app.UIAxes2_3),'Position',position_v,'Color','r'); 


            [tf , frequencies , x] = calc_tf_data(app);
            [my_output,app.extraction_state] = extract_vals(app,tf,frequencies,x);

            poulate_extraction_vals(app,my_output);

        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
%             if app.extraction_state == 1
%                 [tf , frequencies , x] = calc_tf_data(app);
%                 [my_output,app.extraction_state] = extract_vals(app,tf,frequencies,x);
%                 poulate_extraction_vals(app,my_output);
%             end
            
        end

        % Callback function
        function ExportDatatoexcelButtonPushed(app, event)
            SID = app.IDEditField.Value;
            Age = str2double(app.AgeEditField.Value);
            Sex = app.SexEditField.Value;
            date = app.DateDatePicker.Value;
            
            t_range = [app.TimeLowEditField.Value, app.TimeHighEditField.Value];
            f_range = [app.FreqLowEditField.Value, app.FreqHighEditField.Value];
            mean_a = app.meanactivitydBEditField.Value;
            peak_act = app.peakactivitydBEditField.Value;
            peak_freq = app.peakfrequencyHzEditField.Value;
            peak_time = app.peaklatencymsEditField.Value;
            lowest_act = app.lowestactivitydBEditField.Value;time
            lowest_time = app.minfreqEditField.Value;
            lowest_freq= app.minfrequencyHzEditField.Value;
            notes = string(app.NotesTextArea.Value);
            
            table_time = app.UITable.Data;
            table_tf = app.UITable2.Data;
            
            C = {"Subject","Age","Sex","Date","Mean Activity (dB)","Peak Activity (dB)",...
                "Lowest Activity (dB)","Peak Frequency (Hz)","Lowest Frequency (Hz)",...
                "Peak Latency (ms)","Lowest Latency (ms)","Frequency Range Lowe End (Hz)",...
                "Frequency Range High End (Hz)","Time range Low End(ms)","Time range High End(ms)","Notes";...
                SID,Age,Sex,date,mean_a,peak_act,lowest_act,peak_freq,lowest_freq,peak_time,lowest_time,f_range(1),f_range(2),t_range(1),t_range(2),notes};
           
          
          % time frequency
          C = repmat({SID,Age,Sex,date,notes},size(table_tf,1),1);
          T = cell2table(C,...
                "VariableNames",["Subject","Age","Sex","Date","Notes"])
          output_tf = [T,table_tf];
          
          % peaks
          C = repmat({SID,Age,Sex,date,notes},size(table_time,1),1);
          T = cell2table(C,...
                "VariableNames",["Subject","Age","Sex","Date","Notes"])
          output_time = [T,table_time];


          filename = char(app.SavingNameEditField.Value);
          writecell(C,[(app.SavingFolderEditField.Value) filesep() filename '.xls']);
          writetable(output_time,[(app.SavingFolderEditField.Value) filesep() app.SavingNameEditField.Value '.xls'],'Sheet','Time Domain Features');
          writetable(output_tf,[(app.SavingFolderEditField.Value) filesep() app.SavingNameEditField.Value '.xls'],'Sheet','Time Frequency Domain Fatures');


        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.DropDown.Value;
            if string(app.DropDown.Value) == ".set"
                app.file_type = 0;
            elseif string(app.DropDown.Value) == ".mat"
                app.file_type = 1;
            elseif string(app.DropDown.Value) == ".txt"
                app.file_type = 2;
            elseif string(app.DropDown.Value) == "KeyPoint"
                app.file_type = 3;
            end

            
        end

        % Value changed function: PlotSingleTrialsSwitch
        function PlotSingleTrialsSwitchValueChanged(app, event)
            value = string(app.PlotSingleTrialsSwitch.Value);
            if value == "Off"
                app.plot_single_trial = 0;
            elseif value == "On"
                app.plot_single_trial = 1;
            end
            EEG_dat = plot_tdat(app);
            EEG_dat_plotted = EEG_dat;
            assignin("base","EEG_dat_plotted",EEG_dat_plotted)
            plot_trial_data(app);
  

        end

        % Value changed function: TimeLowmsEditField
        function TimeLowmsEditFieldValueChanged(app, event)
            EEG_dat = plot_tdat(app);
            EEG_dat_plotted = EEG_dat;
            assignin("base","EEG_dat_plotted",EEG_dat_plotted)
            plot_trial_data(app);
  

        end

        % Value changed function: TimeHighmsEditField
        function TimeHighmsEditFieldValueChanged(app, event)
            EEG_dat = plot_tdat(app);
            EEG_dat_plotted = EEG_dat;
            assignin("base","EEG_dat_plotted",EEG_dat_plotted)
            plot_trial_data(app);
  

        end

        % Value changed function: YminEditField
        function YminEditFieldValueChanged(app, event)
            EEG_dat = plot_tdat(app);
            EEG_dat_plotted = EEG_dat;
            assignin("base","EEG_dat_plotted",EEG_dat_plotted)
            plot_trial_data(app);


        end

        % Value changed function: YmaxEditField
        function YmaxEditFieldValueChanged(app, event)
            EEG_dat = plot_tdat(app);
            EEG_dat_plotted = EEG_dat;
            assignin("base","EEG_dat_plotted",EEG_dat_plotted)
            plot_trial_data(app);
  
            
        end

        % Value changed function: LowpassSwitch
        function LowpassSwitchValueChanged(app, event)
            value = app.LowpassSwitch.Value;
            if value == "Off"
                app.low_pass_toggle = 0;
            elseif value == "On"
                app.low_pass_toggle = 1;
            end
            EEG_dat = plot_tdat(app);
            EEG_dat_plotted = EEG_dat;
            assignin("base","EEG_dat_plotted",EEG_dat_plotted)
            plot_trial_data(app);


        end

        % Button pushed function: ExtactpeaksButton
        function ExtactpeaksButtonPushed(app, event)
            %clf(app.UIAxes)
            EEG_dat = plot_tdat(app);
            EEG_dat_plotted = EEG_dat;
            assignin("base","EEG_dat_plotted",EEG_dat_plotted)
            plot_trial_data(app);
            extract_peaks(app)
            mark_peaks_in_fig(app)
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.UITable.Data = {};
            app.i_peaks = 0;
            EEG_dat = plot_tdat(app);
            EEG_dat_plotted = EEG_dat;
            assignin("base","EEG_dat_plotted",EEG_dat_plotted)
            plot_trial_data(app);
  

        end

        % Button pushed function: IdentifysignificantresponsesButton
        function IdentifysignificantresponsesButtonPushed(app, event)
            EEG = evalin('base','EEG');
            [tf , frequencies,x,convol_pwr_ind,individual_convol_result,total_convol_result] = calc_tf_data(app);
            
             % resample time to reduce computing time
            [p,q] = rat(floor(app.SamplingRateEditField.Value/10)/app.SamplingRateEditField.Value);
            resampled_signals = resample(double(convol_pwr_ind),p,q,'Dimension',2);
            resampled_time = linspace(min(x),max(x),size(resampled_signals,2));


             % some of the input parameters for the function
            input_mat = permute(resampled_signals,[3 1 2]);% input matrix containing all the subjects
            n_permutes = app.npermutationsEditField.Value; % number of permutations
            v_time = resampled_time; % vector of time
            v_freq = frequencies; % vector of frequencies in 
            time_window = [app.timelowerEditField.Value app.timehigherEditField.Value]; % time window that you want to analyze 
            plotting_input = 0; % make 1 if you want to plot the responses or zero if you don't.
            baseline_range = [app.baselinelowerendEditField.Value app.baselinehigherendEditField.Value];
        
            my_title = ['testing function'];
            
            % plotting the significant responses
            cla(app.UIAxes2_6) ;
            [zmap, zmapthresh, zmapthresh_plc, zmapthresh_clc, tfv_time, v_freq] = npp_stb_2(app,input_mat...
                ,n_permutes,v_time,v_freq,time_window,baseline_range,plotting_input,my_title);

            EEG.zmaps.zmapthresh_clc = zmapthresh_clc;
            EEG.zmaps.times = tfv_time;
            EEG.zmaps.frequencies = v_freq;
            EEG.zmaps.zmap = zmap;
            EEG.zmaps.zmapthresh = zmapthresh;
            EEG.zmaps.zmapthresh_plc = zmapthresh_plc;
            assignin('base','EEG',EEG)  


            %% plotting the responses
            contourf(app.UIAxes2_6, tfv_time,v_freq,zmapthresh_clc,100,'linecolor','none')
            hold(app.UIAxes2_6,'on')
            %axis square
            set(app.UIAxes2_6,'clim',[-5 5],'xlim',[app.timelowerEditField.Value app.timehigherEditField.Value])
            %title('Cluster-corrected Z map')
            %xlabel('Time (ms)'), ylabel('Frequency (Hz)')
            c = colorbar(app.UIAxes2_6,"east");
            colormap(app.UIAxes2_6,"jet");
            c.Label.String = 'z-score';
            hold(app.UIAxes2,'off')
            
            % expand the regions to the other masks
           hold(app.UIAxes2,'on')
           [~,cont] =  contour(app.UIAxes2,tfv_time,v_freq,logical(zmapthresh_clc),1,'linecolor','k');
           cont.LineWidth = 2;
           hold(app.UIAxes2,'off')
           
           hold(app.UIAxes2_5,'on')
           [~,cont] =  contour(app.UIAxes2_5,tfv_time,v_freq,logical(zmapthresh_clc),1,'linecolor','k');
           cont.LineWidth = 2;
           hold(app.UIAxes2_5,'off')
           
           hold(app.UIAxes2_2,'on')
           [~,cont] =  contour(app.UIAxes2_2,tfv_time,v_freq,logical(zmapthresh_clc),1,'linecolor','k');
           cont.LineWidth = 2;
           hold(app.UIAxes2_2,'off')
            
           hold(app.UIAxes2_4,'on')
           [~,cont] =  contour(app.UIAxes2_4,tfv_time,v_freq,logical(zmapthresh_clc),1,'linecolor','k');
           cont.LineWidth = 2;
           hold(app.UIAxes2_4,'off')
           
           hold(app.UIAxes2_3,'on')
           [~,cont] =  contour(app.UIAxes2_3,tfv_time,v_freq,logical(zmapthresh_clc),1,'linecolor','k');
           cont.LineWidth = 2;
           hold(app.UIAxes2_3,'off')
           
            %% plot outlines in the rest of the responses
            

        end

        % Callback function
        function Export_settingsButtonPushed(app, event)
            compile_settings(app)
            export_settings(app)
        end

        % Button pushed function: wavelet_filterButton
        function wavelet_filterButtonPushed(app, event)

            % do the warm filter on the data
           EEG = evalin('base','EEG');
           [EEG, erp, filt_recon,filt_resampled_ind_data,resampled_time] = warm_filter(app,EEG);
           assignin('base','EEG',EEG);
           
           % plotting the filtered response on the app
           x = resampled_time;
           plot(app.UIAxes2_7,x,filt_recon);
           hold(app.UIAxes2_7,'on')
           plot(app.UIAxes2_7,x,erp);
           hold(app.UIAxes2_7,'off')
           legend(app.UIAxes2_7,'WARM filtered signal','original signal') 
           xlim(app.UIAxes2_7, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])
           set(app.UIAxes2_7, 'YDir','reverse')
             
             
           load('Z:\30_Oscar_Ortiz\apps\dependencies\new_color_map_2.mat'); %change to adjustable path
           
           %plot indivudal responses
           max_sign = max(max(abs(filt_resampled_ind_data)));
           %imagesc(app.UIAxes7_2,x,1:size(resampled_ind_convol,3),filt_resampled_ind_data')
           imagesc(app.UIAxes7_2,x,1:size(filt_resampled_ind_data,2),filt_resampled_ind_data')
           colormap(app.UIAxes7_2,new_color_map_2)
           caxis(app.UIAxes7_2,[-max_sign*0.8 max_sign*0.8]);
           c = colorbar(app.UIAxes7_2,"east");
           c.Label.String = "Amplitude (mV)";
           hold(app.UIAxes7_2,'off')
           xlim(app.UIAxes7_2, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])
           %ylim(app.UIAxes7_2, [0 max(size(resampled_ind_convol,3))+1])
           ylim(app.UIAxes7_2, [.5 max(size(filt_resampled_ind_data,2))+.5])


        end

        % Button pushed function: ExtractSingletrialPeaksTBFButton
        function ExtractSingletrialPeaksTBFButtonPushed(app, event)
            %clf(app.UIAxes2_7)
            %plot_tdat(app)
            extract_single_trial_data(app)
            mark_peaks_in_fig(app)
        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
            app.UITable3.Data = {};
            app.i_peaks_st = 0;
        end

        % Callback function: Tree
        function treeselectionchange(app, event)
            [EEG_dat,trials2plot] = plot_tdat(app);
            EEG_dat_plotted = EEG_dat;
            assignin("base","EEG_dat_plotted",EEG_dat_plotted)
            plot_trial_data(app);

            app.TrialOverlayListBox.Items = string(trials2plot);


        end

        % Value changed function: typeofstim
        function typeofstimValueChanged(app, event)
            EEG_dat = plot_tdat(app);
            EEG_dat_plotted = EEG_dat;
            assignin("base","EEG_dat_plotted",EEG_dat_plotted)
            plot_trial_data(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetUpTab = uitab(app.TabGroup);
            app.SetUpTab.Title = 'Set Up';
            app.DataInEditFieldLabel = uilabel(app.SetUpTab);
            app.DataInEditFieldLabel.Text = 'Data In';
            app.DataInEditField = uieditfield(app.SetUpTab, 'text');
            app.DataInEditField.ValueChangedFcn = createCallbackFcn(app, @DataInEditFieldValueChanged, true);
            app.Button = uibutton(app.SetUpTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '...';
            app.LoadDataButton = uibutton(app.SetUpTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.EEGLabFolderEditFieldLabel = uilabel(app.SetUpTab);
            app.EEGLabFolderEditFieldLabel.Text = 'EEGLab Folder';
            app.EEGLabFolderEditField = uieditfield(app.SetUpTab, 'text');
            app.EEGLabFolderEditField.ValueChangedFcn = createCallbackFcn(app, @EEGLabFolderEditFieldValueChanged, true);
            app.Button_3 = uibutton(app.SetUpTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '...';
            app.InitializeEEGLabButton = uibutton(app.SetUpTab, 'push');
            app.InitializeEEGLabButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeEEGLabButtonPushed, true);
            app.InitializeEEGLabButton.Text = 'Initialize EEGLab';
            app.NChannelsEditFieldLabel = uilabel(app.SetUpTab);
            app.NChannelsEditFieldLabel.Text = 'N Channels';
            app.NChannelsEditField = uieditfield(app.SetUpTab, 'numeric');
            app.FramesPerEpochEditFieldLabel = uilabel(app.SetUpTab);
            app.FramesPerEpochEditFieldLabel.Text = 'Frames Per Epoch';
            app.FramesPerEpochEditField = uieditfield(app.SetUpTab, 'numeric');
            app.EpochsEditFieldLabel = uilabel(app.SetUpTab);
            app.EpochsEditFieldLabel.Text = 'Epochs';
            app.EpochsEditField = uieditfield(app.SetUpTab, 'numeric');
            app.SamplingRateEditFieldLabel = uilabel(app.SetUpTab);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.SamplingRateEditField = uieditfield(app.SetUpTab, 'numeric');
            app.Image = uiimage(app.SetUpTab);
            app.Image.ImageSource = 'logo.jpg';
            app.IDEditFieldLabel = uilabel(app.SetUpTab);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.SetUpTab, 'text');
            app.AgeEditFieldLabel = uilabel(app.SetUpTab);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.SetUpTab, 'text');
            app.SexEditFieldLabel = uilabel(app.SetUpTab);
            app.SexEditFieldLabel.Text = 'Sex';
            app.SexEditField = uieditfield(app.SetUpTab, 'text');
            app.NotesTextAreaLabel = uilabel(app.SetUpTab);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.SetUpTab);
            app.DateDatePickerLabel = uilabel(app.SetUpTab);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.SetUpTab);
            app.DropDown = uidropdown(app.SetUpTab);
            app.DropDown.Items = {'.set', '.mat', '.txt', 'KeyPoint'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '.set';
            app.DatanotloadedLampLabel = uilabel(app.SetUpTab);
            app.DatanotloadedLampLabel.Text = 'Data not loaded';
            app.DatanotloadedLamp = uilamp(app.SetUpTab);
            app.DatanotloadedLamp.Enable = 'off';
            app.TimeDomainTab = uitab(app.TabGroup);
            app.TimeDomainTab.Title = 'Time Domain';
            app.ChannelsListBoxLabel = uilabel(app.TimeDomainTab);
            app.ChannelsListBoxLabel.Text = 'Channels';
            app.EpochsLabel = uilabel(app.TimeDomainTab);
            app.EpochsLabel.Text = 'Trial Overlay';
            app.ChannelsListBox = uilistbox(app.TimeDomainTab);
            app.ChannelsListBox.Items = {'None '};
            app.ChannelsListBox.ValueChangedFcn = createCallbackFcn(app, @ChannelsListBoxValueChanged, true);
            app.ChannelsListBox.Value = 'None ';
            app.TrialOverlayListBox = uilistbox(app.TimeDomainTab);
            app.TrialOverlayListBox.Items = {'None'};
            app.TrialOverlayListBox.Multiselect = 'on';
            app.TrialOverlayListBox.ValueChangedFcn = createCallbackFcn(app, @TrialOverlayListBoxValueChanged, true);
            app.TrialOverlayListBox.Value = {'None'};
            app.PlotSingleTrialsLabel = uilabel(app.TimeDomainTab);
            app.PlotSingleTrialsLabel.Text = 'Plot Single Trials';
            app.PlotSingleTrialsSwitch = uiswitch(app.TimeDomainTab, 'slider');
            app.PlotSingleTrialsSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotSingleTrialsSwitchValueChanged, true);
            app.LowpassSwitchLabel = uilabel(app.TimeDomainTab);
            app.LowpassSwitchLabel.Text = 'Low pass';
            app.LowpassSwitch = uiswitch(app.TimeDomainTab, 'slider');
            app.LowpassSwitch.ValueChangedFcn = createCallbackFcn(app, @LowpassSwitchValueChanged, true);
            app.TabGroup4 = uitabgroup(app.TimeDomainTab);
            app.AverageTab = uitab(app.TabGroup4);
            app.AverageTab.Title = 'Average';
            app.UIAxes = uiaxes(app.AverageTab);
            xlabel(app.UIAxes, 'Time (ms)')
            ylabel(app.UIAxes, 'Amplitude (mV)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.TrialTab = uitab(app.TabGroup4);
            app.TrialTab.Title = 'Trial';
            app.UIAxes7 = uiaxes(app.TrialTab);
            ylabel(app.UIAxes7, 'Trials')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.TrialTab);
            title(app.UIAxes8, 'Average')
            xlabel(app.UIAxes8, 'Time (ms)')
            ylabel(app.UIAxes8, 'Amplitdue (mV)')
            zlabel(app.UIAxes8, 'Z')
            app.Tree = uitree(app.TimeDomainTab, 'checkbox');
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @treeselectionchange, true);
            app.PlorttingParametersPanel = uipanel(app.TimeDomainTab);
            app.PlorttingParametersPanel.Title = 'Plortting Parameters';
            app.TimeLowmsEditFieldLabel = uilabel(app.PlorttingParametersPanel);
            app.TimeLowmsEditFieldLabel.Text = 'Time Low (ms)';
            app.TimeLowmsEditField = uieditfield(app.PlorttingParametersPanel, 'numeric');
            app.TimeLowmsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeLowmsEditFieldValueChanged, true);
            app.TimeLowmsEditField.Value = -200;
            app.TimeHighmsEditFieldLabel = uilabel(app.PlorttingParametersPanel);
            app.TimeHighmsEditFieldLabel.Text = 'Time High (ms)';
            app.TimeHighmsEditField = uieditfield(app.PlorttingParametersPanel, 'numeric');
            app.TimeHighmsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeHighmsEditFieldValueChanged, true);
            app.TimeHighmsEditField.Value = 1000;
            app.YminLabel = uilabel(app.PlorttingParametersPanel);
            app.YminLabel.Text = 'Y min ';
            app.YminEditField = uieditfield(app.PlorttingParametersPanel, 'numeric');
            app.YminEditField.ValueChangedFcn = createCallbackFcn(app, @YminEditFieldValueChanged, true);
            app.YminEditField.Value = -200;
            app.YmaxEditFieldLabel = uilabel(app.PlorttingParametersPanel);
            app.YmaxEditFieldLabel.Text = 'Y max';
            app.YmaxEditField = uieditfield(app.PlorttingParametersPanel, 'numeric');
            app.YmaxEditField.ValueChangedFcn = createCallbackFcn(app, @YmaxEditFieldValueChanged, true);
            app.YmaxEditField.Value = -200;
            app.SignalStartmsEditFieldLabel = uilabel(app.PlorttingParametersPanel);
            app.SignalStartmsEditFieldLabel.Text = 'Signal Start (ms)';
            app.SignalStartmsEditField = uieditfield(app.PlorttingParametersPanel, 'numeric');
            app.SignalStartmsEditField.Value = -200;
            app.SignalEndmsEditFieldLabel = uilabel(app.PlorttingParametersPanel);
            app.SignalEndmsEditFieldLabel.Text = 'Signal End (ms)';
            app.SignalEndmsEditField = uieditfield(app.PlorttingParametersPanel, 'numeric');
            app.SignalEndmsEditField.Value = -200;
            app.TrialSelectionLabel = uilabel(app.TimeDomainTab);
            app.TrialSelectionLabel.Text = 'Trial Selection';
            app.PeakExtractionPanel = uipanel(app.TimeDomainTab);
            app.PeakExtractionPanel.Title = 'Peak Extraction';
            app.UITable = uitable(app.PeakExtractionPanel);
            app.UITable.ColumnName = {'Channel'; 'Name'; 'Amplitude'; 'Latency'};
            app.UITable.RowName = {};
            app.numberofpeakstoextractEditFieldLabel = uilabel(app.PeakExtractionPanel);
            app.numberofpeakstoextractEditFieldLabel.Text = 'number of peaks to extract';
            app.numberofpeakstoextractEditField = uieditfield(app.PeakExtractionPanel, 'numeric');
            app.numberofpeakstoextractEditField.Value = 2;
            app.ExtactpeaksButton = uibutton(app.PeakExtractionPanel, 'push');
            app.ExtactpeaksButton.ButtonPushedFcn = createCallbackFcn(app, @ExtactpeaksButtonPushed, true);
            app.ExtactpeaksButton.Text = 'Extact peaks';
            app.ClearButton = uibutton(app.PeakExtractionPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.typeofstim = uilistbox(app.TimeDomainTab);
            app.typeofstim.Items = {'CHEPs', 'CEPs', 'PEPs', 'LEPs'};
            app.typeofstim.ValueChangedFcn = createCallbackFcn(app, @typeofstimValueChanged, true);
            app.typeofstim.Value = 'CHEPs';
            app.ChannelsListBoxLabel_2 = uilabel(app.TimeDomainTab);
            app.ChannelsListBoxLabel_2.Text = 'Type of stim';
            app.TimeFrequencyTab = uitab(app.TabGroup);
            app.TimeFrequencyTab.Title = 'Time Frequency';
            app.TabGroup2 = uitabgroup(app.TimeFrequencyTab);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.TotalTab = uitab(app.TabGroup2);
            app.TotalTab.Title = 'Total';
            app.TotalTab.ButtonDownFcn = createCallbackFcn(app, @TotalTabButtonDown, true);
            app.UIAxes2 = uiaxes(app.TotalTab);
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Frequency')
            zlabel(app.UIAxes2, 'Z')
            app.PhaselockedTab = uitab(app.TabGroup2);
            app.PhaselockedTab.Title = 'Phase locked';
            app.UIAxes2_5 = uiaxes(app.PhaselockedTab);
            xlabel(app.UIAxes2_5, 'Time')
            ylabel(app.UIAxes2_5, 'Frequency')
            zlabel(app.UIAxes2_5, 'Z')
            app.NonphaselockedTab = uitab(app.TabGroup2);
            app.NonphaselockedTab.Title = 'Non-phase locked';
            app.UIAxes2_2 = uiaxes(app.NonphaselockedTab);
            xlabel(app.UIAxes2_2, 'Time')
            ylabel(app.UIAxes2_2, 'Frequency')
            zlabel(app.UIAxes2_2, 'Z')
            app.ITPCTab = uitab(app.TabGroup2);
            app.ITPCTab.Title = 'ITPC';
            app.UIAxes2_4 = uiaxes(app.ITPCTab);
            xlabel(app.UIAxes2_4, 'Time')
            ylabel(app.UIAxes2_4, 'Frequency')
            zlabel(app.UIAxes2_4, 'Z')
            app.ERPpowerTab = uitab(app.TabGroup2);
            app.ERPpowerTab.Title = 'ERP power';
            app.UIAxes2_3 = uiaxes(app.ERPpowerTab);
            xlabel(app.UIAxes2_3, 'Time')
            ylabel(app.UIAxes2_3, 'Frequency')
            zlabel(app.UIAxes2_3, 'Z')
            app.SignificantPowerTab = uitab(app.TabGroup2);
            app.SignificantPowerTab.Title = 'Significant Power';
            app.UIAxes2_6 = uiaxes(app.SignificantPowerTab);
            xlabel(app.UIAxes2_6, 'Time')
            ylabel(app.UIAxes2_6, 'Frequency')
            zlabel(app.UIAxes2_6, 'Z')
            app.TabGroup3 = uitabgroup(app.TimeFrequencyTab);
            app.TFsettingsTab = uitab(app.TabGroup3);
            app.TFsettingsTab.Title = 'TF settings';
            app.ChannelsListBox_2Label = uilabel(app.TFsettingsTab);
            app.ChannelsListBox_2Label.Text = 'Channels';
            app.ChannelsListBox_2 = uilistbox(app.TFsettingsTab);
            app.ChannelsListBox_2.Items = {'None '};
            app.ChannelsListBox_2.ValueChangedFcn = createCallbackFcn(app, @ChannelsListBox_2ValueChanged, true);
            app.ChannelsListBox_2.Value = 'None ';
            app.EpochsListBox_2Label = uilabel(app.TFsettingsTab);
            app.EpochsListBox_2Label.Text = 'Epochs';
            app.EpochsListBox_2 = uilistbox(app.TFsettingsTab);
            app.EpochsListBox_2.Items = {'None'};
            app.EpochsListBox_2.Multiselect = 'on';
            app.EpochsListBox_2.ValueChangedFcn = createCallbackFcn(app, @EpochsListBox_2ValueChanged, true);
            app.EpochsListBox_2.Value = {'None'};
            app.BadChannelsTBFEditField_2Label = uilabel(app.TFsettingsTab);
            app.BadChannelsTBFEditField_2Label.Text = 'Bad Channels (TBF)';
            app.BadEpochsEditField_2Label = uilabel(app.TFsettingsTab);
            app.BadEpochsEditField_2Label.Text = 'Bad Epochs';
            app.BadChannelsTBFEditField_2 = uieditfield(app.TFsettingsTab, 'text');
            app.BadEpochsEditField_2 = uieditfield(app.TFsettingsTab, 'numeric');
            app.minfreqEditFieldLabel = uilabel(app.TFsettingsTab);
            app.minfreqEditFieldLabel.Text = 'min freq';
            app.minfreqEditField = uieditfield(app.TFsettingsTab, 'numeric');
            app.minfreqEditField.ValueChangedFcn = createCallbackFcn(app, @minfreqEditFieldValueChanged, true);
            app.minfreqEditField.Value = 1;
            app.maxfreqEditFieldLabel = uilabel(app.TFsettingsTab);
            app.maxfreqEditFieldLabel.Text = 'max freq';
            app.maxfreqEditField = uieditfield(app.TFsettingsTab, 'numeric');
            app.maxfreqEditField.ValueChangedFcn = createCallbackFcn(app, @maxfreqEditFieldValueChanged, true);
            app.maxfreqEditField.Value = 25;
            app.baselinelowerendEditFieldLabel = uilabel(app.TFsettingsTab);
            app.baselinelowerendEditFieldLabel.Text = 'baseline lower end';
            app.baselinelowerendEditField = uieditfield(app.TFsettingsTab, 'numeric');
            app.baselinelowerendEditField.ValueChangedFcn = createCallbackFcn(app, @baselinelowerendEditFieldValueChanged, true);
            app.baselinelowerendEditField.Value = -200;
            app.baselinehigherendEditFieldLabel = uilabel(app.TFsettingsTab);
            app.baselinehigherendEditFieldLabel.Text = 'baseline higher end';
            app.baselinehigherendEditField = uieditfield(app.TFsettingsTab, 'numeric');
            app.baselinehigherendEditField.ValueChangedFcn = createCallbackFcn(app, @baselinehigherendEditFieldValueChanged, true);
            app.ofcycleslowerEditFieldLabel = uilabel(app.TFsettingsTab);
            app.ofcycleslowerEditFieldLabel.Text = '# of cycles lower';
            app.ofcycleslowerEditField = uieditfield(app.TFsettingsTab, 'numeric');
            app.ofcycleslowerEditField.ValueChangedFcn = createCallbackFcn(app, @ofcycleslowerEditFieldValueChanged, true);
            app.ofcycleslowerEditField.Value = 3;
            app.ofcycleshigherEditFieldLabel = uilabel(app.TFsettingsTab);
            app.ofcycleshigherEditFieldLabel.Text = '# of cycles higher';
            app.ofcycleshigherEditField = uieditfield(app.TFsettingsTab, 'numeric');
            app.ofcycleshigherEditField.ValueChangedFcn = createCallbackFcn(app, @ofcycleshigherEditFieldValueChanged, true);
            app.ofcycleshigherEditField.Value = 10;
            app.timelowerEditFieldLabel = uilabel(app.TFsettingsTab);
            app.timelowerEditFieldLabel.Text = 'time lower';
            app.timelowerEditField = uieditfield(app.TFsettingsTab, 'numeric');
            app.timelowerEditField.ValueChangedFcn = createCallbackFcn(app, @timelowerEditFieldValueChanged, true);
            app.timelowerEditField.Value = -200;
            app.timehigherEditFieldLabel = uilabel(app.TFsettingsTab);
            app.timehigherEditFieldLabel.Text = 'time higher';
            app.timehigherEditField = uieditfield(app.TFsettingsTab, 'numeric');
            app.timehigherEditField.ValueChangedFcn = createCallbackFcn(app, @timehigherEditFieldValueChanged, true);
            app.timehigherEditField.Value = 1000;
            app.offrequencybinsEditFieldLabel = uilabel(app.TFsettingsTab);
            app.offrequencybinsEditFieldLabel.Text = '# of frequency bins';
            app.offrequencybinsEditField = uieditfield(app.TFsettingsTab, 'numeric');
            app.offrequencybinsEditField.ValueChangedFcn = createCallbackFcn(app, @offrequencybinsEditFieldValueChanged, true);
            app.offrequencybinsEditField.Value = 100;
            app.SpacingSwitch = uiswitch(app.TFsettingsTab, 'slider');
            app.SpacingSwitch.Items = {'Linear', 'Log'};
            app.SpacingSwitch.ValueChangedFcn = createCallbackFcn(app, @SpacingSwitchValueChanged, true);
            app.SpacingSwitch.Value = 'Linear';
            app.ConversionSwitch = uiswitch(app.TFsettingsTab, 'slider');
            app.ConversionSwitch.Items = {'dB', 'percent change'};
            app.ConversionSwitch.ValueChangedFcn = createCallbackFcn(app, @ConversionSwitchValueChanged, true);
            app.ConversionSwitch.Value = 'dB';
            app.SpacingSwitchLabel = uilabel(app.TFsettingsTab);
            app.SpacingSwitchLabel.Text = 'Spacing';
            app.ConversionSwitchLabel = uilabel(app.TFsettingsTab);
            app.ConversionSwitchLabel.Text = 'Conversion';
            app.DataSelectionTab = uitab(app.TabGroup3);
            app.DataSelectionTab.Title = 'Data Selection';
            app.selectrectanglemanuallyButton = uibutton(app.DataSelectionTab, 'push');
            app.selectrectanglemanuallyButton.ButtonPushedFcn = createCallbackFcn(app, @selectrectanglemanuallyButtonPushed, true);
            app.selectrectanglemanuallyButton.Text = 'select rectangle manually';
            app.TimeLowEditFieldLabel = uilabel(app.DataSelectionTab);
            app.TimeLowEditFieldLabel.Text = 'Time Low';
            app.TimeLowEditField = uieditfield(app.DataSelectionTab, 'numeric');
            app.TimeHighEditFieldLabel = uilabel(app.DataSelectionTab);
            app.TimeHighEditFieldLabel.Text = 'Time High';
            app.TimeHighEditField = uieditfield(app.DataSelectionTab, 'numeric');
            app.FreqLowEditFieldLabel = uilabel(app.DataSelectionTab);
            app.FreqLowEditFieldLabel.Text = 'Freq Low';
            app.FreqLowEditField = uieditfield(app.DataSelectionTab, 'numeric');
            app.FreqHighEditFieldLabel = uilabel(app.DataSelectionTab);
            app.FreqHighEditFieldLabel.Text = 'Freq High';
            app.FreqHighEditField = uieditfield(app.DataSelectionTab, 'numeric');
            app.ExtractInfoButton = uibutton(app.DataSelectionTab, 'push');
            app.ExtractInfoButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractInfoButtonPushed, true);
            app.ExtractInfoButton.Text = 'Extract Info';
            app.Image2 = uiimage(app.DataSelectionTab);
            app.Image2.ImageSource = 'logoo_light_square.jpg';
            app.UITable2 = uitable(app.DataSelectionTab);
            app.UITable2.ColumnName = {'Channel'; 'Name'; 'Mean Activity'; 'Peak Activity'; 'Peak Frequency (Hz)'; 'Peak Latency (ms)'; 'Lowest Activity (ms)'; 'Min Frequency (Hz)'; 'Min Latency(ms)'; 'Metric'};
            app.UITable2.RowName = {};
            app.StatisticsTab = uitab(app.TabGroup3);
            app.StatisticsTab.Title = 'Statistics';
            app.IdentifysignificantresponsesButton = uibutton(app.StatisticsTab, 'push');
            app.IdentifysignificantresponsesButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifysignificantresponsesButtonPushed, true);
            app.IdentifysignificantresponsesButton.Text = 'Identify significant responses';
            app.npermutationsEditFieldLabel = uilabel(app.StatisticsTab);
            app.npermutationsEditFieldLabel.Text = 'n permutations';
            app.npermutationsEditField = uieditfield(app.StatisticsTab, 'numeric');
            app.npermutationsEditField.Value = 1000;
            app.WaveletfilteringTab = uitab(app.TabGroup);
            app.WaveletfilteringTab.Title = 'Wavelet filtering ';
            app.UIAxes2_7 = uiaxes(app.WaveletfilteringTab);
            xlabel(app.UIAxes2_7, 'Time')
            ylabel(app.UIAxes2_7, 'Amplitude')
            zlabel(app.UIAxes2_7, 'Z')
            app.UIAxes7_2 = uiaxes(app.WaveletfilteringTab);
            ylabel(app.UIAxes7_2, 'Trials')
            zlabel(app.UIAxes7_2, 'Z')
            app.wavelet_filterButton = uibutton(app.WaveletfilteringTab, 'push');
            app.wavelet_filterButton.ButtonPushedFcn = createCallbackFcn(app, @wavelet_filterButtonPushed, true);
            app.wavelet_filterButton.Text = 'wavelet_filter';
            app.npermutationsEditField_2Label = uilabel(app.WaveletfilteringTab);
            app.npermutationsEditField_2Label.Text = 'n permutations';
            app.npermutationsEditField_2 = uieditfield(app.WaveletfilteringTab, 'numeric');
            app.npermutationsEditField_2.Value = 1000;
            app.ExtractSingletrialPeaksTBFButton = uibutton(app.WaveletfilteringTab, 'push');
            app.ExtractSingletrialPeaksTBFButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractSingletrialPeaksTBFButtonPushed, true);
            app.ExtractSingletrialPeaksTBFButton.Text = 'Extract Single trial Peaks (TBF)';
            app.UITable3 = uitable(app.WaveletfilteringTab);
            app.UITable3.ColumnName = {'Trial'; 'Name'; 'Amplitude'; 'Latency'; 'Channel'};
            app.UITable3.RowName = {};
            app.numberofpeakstoextractEditField_2Label = uilabel(app.WaveletfilteringTab);
            app.numberofpeakstoextractEditField_2Label.Text = 'number of peaks to extract';
            app.numberofpeakstoextractEditField_2 = uieditfield(app.WaveletfilteringTab, 'numeric');
            app.numberofpeakstoextractEditField_2.Value = 2;
            app.ClearButton_2 = uibutton(app.WaveletfilteringTab, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = brain_vis_tool_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end