classdef brainviz_app_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SetUpTab                        matlab.ui.container.Tab
        DropDown_2                      matlab.ui.control.DropDown
        Panel_3                         matlab.ui.container.Panel
        DateDatePicker                  matlab.ui.control.DatePicker
        DateDatePickerLabel             matlab.ui.control.Label
        SexEditField                    matlab.ui.control.EditField
        SexEditFieldLabel               matlab.ui.control.Label
        AgeEditField                    matlab.ui.control.EditField
        AgeEditFieldLabel               matlab.ui.control.Label
        IDEditField                     matlab.ui.control.EditField
        IDEditFieldLabel                matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Button_3                        matlab.ui.control.Button
        InitializeEEGLabButton          matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        EEGLabFolderEditField           matlab.ui.control.EditField
        EEGLabFolderEditFieldLabel      matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        SamplingRateEditField           matlab.ui.control.NumericEditField
        SamplingRateEditFieldLabel      matlab.ui.control.Label
        EpochsEditField                 matlab.ui.control.NumericEditField
        EpochsEditFieldLabel            matlab.ui.control.Label
        FramesPerEpochEditField         matlab.ui.control.NumericEditField
        FramesPerEpochEditFieldLabel    matlab.ui.control.Label
        NChannelsEditField              matlab.ui.control.NumericEditField
        NChannelsEditFieldLabel         matlab.ui.control.Label
        DataNotLoadedLamp               matlab.ui.control.Lamp
        DataNotLoadedLampLabel          matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        NotesTextArea                   matlab.ui.control.TextArea
        NotesTextAreaLabel              matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        DataInEditField                 matlab.ui.control.EditField
        DataInEditFieldLabel            matlab.ui.control.Label
        AnalysisTab                     matlab.ui.container.Tab
        UpdateButton                    matlab.ui.control.Button
        BaselineNormSwitch              matlab.ui.control.Switch
        BaselineNormSwitchLabel         matlab.ui.control.Label
        WARMFilterSwitch                matlab.ui.control.Switch
        WARMFilterSwitchLabel           matlab.ui.control.Label
        Panel_7                         matlab.ui.container.Panel
        ClearAllButton_2                matlab.ui.control.Button
        ExtractParametersButton         matlab.ui.control.Button
        UITable2_2                      matlab.ui.control.Table
        FreqHighEditField_2             matlab.ui.control.NumericEditField
        FreqHighEditField_2Label        matlab.ui.control.Label
        TimeHighEditField_2             matlab.ui.control.NumericEditField
        TimeHighEditField_2Label        matlab.ui.control.Label
        FreqLowEditField_2              matlab.ui.control.NumericEditField
        FreqLowEditField_2Label         matlab.ui.control.Label
        TimeLowEditField_2              matlab.ui.control.NumericEditField
        TimeLowEditField_2Label         matlab.ui.control.Label
        Panel_6                         matlab.ui.container.Panel
        selectmanuallyButton            matlab.ui.control.Button
        ChannelsListBoxLabel_4          matlab.ui.control.Label
        tf_output_plot                  matlab.ui.control.ListBox
        ChannelsListBoxLabel_3          matlab.ui.control.Label
        typeoferp                       matlab.ui.control.ListBox
        ChannelsListBoxLabel_2          matlab.ui.control.Label
        typeofstim                      matlab.ui.control.ListBox
        Panel_5                         matlab.ui.container.Panel
        ExtractManuallyButton           matlab.ui.control.Button
        ClearAllButton                  matlab.ui.control.Button
        ExtractpeaksButton              matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        TrialSelectionLabel             matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        baseline_end                    matlab.ui.control.NumericEditField
        EndmsEditFieldLabel             matlab.ui.control.Label
        baseline_start                  matlab.ui.control.NumericEditField
        SignalStartmsEditFieldLabel_2   matlab.ui.control.Label
        AutoSwitch                      matlab.ui.control.Switch
        AutoSwitchLabel                 matlab.ui.control.Label
        SignalEndmsEditField            matlab.ui.control.NumericEditField
        SignalEndmsEditFieldLabel       matlab.ui.control.Label
        SignalStartmsEditField          matlab.ui.control.NumericEditField
        SignalStartmsEditFieldLabel     matlab.ui.control.Label
        YmaxEditField                   matlab.ui.control.NumericEditField
        YmaxEditFieldLabel              matlab.ui.control.Label
        YminEditField                   matlab.ui.control.NumericEditField
        YminLabel                       matlab.ui.control.Label
        TimeHighmsEditField             matlab.ui.control.NumericEditField
        TimeHighmsEditFieldLabel        matlab.ui.control.Label
        TimeLowmsEditField              matlab.ui.control.NumericEditField
        TimeLowmsEditFieldLabel         matlab.ui.control.Label
        Tree                            matlab.ui.container.CheckBoxTree
        TabGroup4                       matlab.ui.container.TabGroup
        AverageTab                      matlab.ui.container.Tab
        ColorMapDropDown                matlab.ui.control.DropDown
        TrialsDisplayedListBox          matlab.ui.control.ListBox
        TrialsDisplayedListBoxLabel     matlab.ui.control.Label
        ButterflyPlotCheckBox           matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
        TrialTab                        matlab.ui.container.Tab
        UIAxes8                         matlab.ui.control.UIAxes
        UIAxes7                         matlab.ui.control.UIAxes
        MultitrialViewTab               matlab.ui.container.Tab
        Panel_8                         matlab.ui.container.Panel
        PageSlider                      matlab.ui.control.Slider
        PageSliderLabel                 matlab.ui.control.Label
        TimeFrequencyTab_2              matlab.ui.container.Tab
        UIAxes10                        matlab.ui.control.UIAxes
        UIAxes9                         matlab.ui.control.UIAxes
        LowpassSwitch                   matlab.ui.control.Switch
        LowpassSwitchLabel              matlab.ui.control.Label
        ChannelsListBox                 matlab.ui.control.ListBox
        ChannelsListBoxLabel            matlab.ui.control.Label
        SettingsTab                     matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        TFsettingsTab                   matlab.ui.container.Tab
        ConversionSwitchLabel           matlab.ui.control.Label
        SpacingSwitchLabel              matlab.ui.control.Label
        ConversionSwitch                matlab.ui.control.Switch
        SpacingSwitch                   matlab.ui.control.Switch
        offrequencybinsEditField        matlab.ui.control.NumericEditField
        offrequencybinsEditFieldLabel   matlab.ui.control.Label
        timehigherEditField             matlab.ui.control.NumericEditField
        timehigherEditFieldLabel        matlab.ui.control.Label
        timelowerEditField              matlab.ui.control.NumericEditField
        timelowerEditFieldLabel         matlab.ui.control.Label
        ofcycleshigherEditField         matlab.ui.control.NumericEditField
        ofcycleshigherEditFieldLabel    matlab.ui.control.Label
        ofcycleslowerEditField          matlab.ui.control.NumericEditField
        ofcycleslowerEditFieldLabel     matlab.ui.control.Label
        baselinehigherendEditField      matlab.ui.control.NumericEditField
        baselinehigherendEditFieldLabel  matlab.ui.control.Label
        baselinelowerendEditField       matlab.ui.control.NumericEditField
        baselinelowerendEditFieldLabel  matlab.ui.control.Label
        maxfreqEditField                matlab.ui.control.NumericEditField
        maxfreqEditFieldLabel           matlab.ui.control.Label
        minfreqEditField                matlab.ui.control.NumericEditField
        minfreqEditFieldLabel           matlab.ui.control.Label
        BadEpochsEditField_2            matlab.ui.control.NumericEditField
        BadChannelsTBFEditField_2       matlab.ui.control.EditField
        BadEpochsEditField_2Label       matlab.ui.control.Label
        BadChannelsTBFEditField_2Label  matlab.ui.control.Label
        EpochsListBox_2                 matlab.ui.control.ListBox
        EpochsListBox_2Label            matlab.ui.control.Label
        ChannelsListBox_2               matlab.ui.control.ListBox
        ChannelsListBox_2Label          matlab.ui.control.Label
        DataSelectionTab                matlab.ui.container.Tab
        UITable2                        matlab.ui.control.Table
        Image2                          matlab.ui.control.Image
        ExtractInfoButton               matlab.ui.control.Button
        FreqLowEditField                matlab.ui.control.NumericEditField
        FreqLowEditFieldLabel           matlab.ui.control.Label
        FreqHighEditField               matlab.ui.control.NumericEditField
        TimeHighEditField               matlab.ui.control.NumericEditField
        TimeLowEditField                matlab.ui.control.NumericEditField
        TimeLowEditFieldLabel           matlab.ui.control.Label
        FreqHighEditFieldLabel          matlab.ui.control.Label
        TimeHighEditFieldLabel          matlab.ui.control.Label
        selectrectanglemanuallyButton   matlab.ui.control.Button
        MultitrialviewingTab            matlab.ui.container.Tab
        ncolumnsEditField               matlab.ui.control.NumericEditField
        ncolumnsEditFieldLabel          matlab.ui.control.Label
        nrowsEditField                  matlab.ui.control.NumericEditField
        nrowsEditFieldLabel             matlab.ui.control.Label
        StatisticsTab                   matlab.ui.container.Tab
        npermutationsEditField          matlab.ui.control.NumericEditField
        npermutationsEditFieldLabel     matlab.ui.control.Label
        IdentifysignificantresponsesButton  matlab.ui.control.Button
        WaveletfilteringSettingsTab     matlab.ui.container.Tab
        ClearButton_2                   matlab.ui.control.Button
        numberofpeakstoextractEditField_2  matlab.ui.control.NumericEditField
        numberofpeakstoextractEditField_2Label  matlab.ui.control.Label
        UITable3                        matlab.ui.control.Table
        ExtractSingletrialPeaksTBFButton  matlab.ui.control.Button
        npermutationsEditField_2        matlab.ui.control.NumericEditField
        npermutationsEditField_2Label   matlab.ui.control.Label
        wavelet_filterButton            matlab.ui.control.Button
        UIAxes7_2                       matlab.ui.control.UIAxes
        UIAxes2_7                       matlab.ui.control.UIAxes
        Tab                             matlab.ui.container.Tab
    end

    
    properties (Access = private)
        extraction_state = 0% Has data been extracted?
        file_type = 0 % type of file you want to load
        plot_single_trial = 0; % Description
        low_pass_toggle = 0; % apply low pass filter for time domain analysis
        i_peaks = 0; % Description
        n_roi = 0; % Description
        i_peaks_st =  0 % Description
        peps_range = [0 500];
        cheps_range = [200 600];
        leps_range = [150 550];
        ceps_range = [150 700];
        stim_range = [200 600]; % Description
        ieeps_range = [111 383]; % Description
        settings = struct; % Description
        current_data_id = []; % Description
    end
    
    methods (Access = private)
        
        function [EEG_dat,time_out,sd2,SNR,SNR_michelle] = plot_tdat(app)
            current_data = evalin('base','current_data');
            current_ylim_low = app.YminEditField.Value;
            current_ylim_high = app.YmaxEditField.Value;

            % check if we are doing the warm filter and if we have 
            if app.WARMFilterSwitch.Value == "On"
                try
                    EEG_dat = current_data.WARM_filter.single_trial_waveforms;
                    x = current_data.WARM_filter.time;
                catch % if its not a field, then calculate and get
                    select_channel(app);
                    preprocc_eeg(app);
                    calc_tf_data(app);
                    compute_warm_signals(app);
                    current_data = evalin('base','current_data');
                    EEG_dat = current_data.WARM_filter.single_trial_waveforms;
                    x = current_data.WARM_filter.time;
                end

   
            else
                EEG_dat = current_data.data;
                x = current_data.time;
            end


            if std(std(EEG_dat)) == 0
                % do nothing
                sd2 = [];
                SNR = [];

                cla(app.UIAxes)
                xlim(app.UIAxes, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])
                ylim(app.UIAxes, [app.YminEditField.Value app.YmaxEditField.Value])
                xline(app.UIAxes,0,'--');

            else % go ahead and plot that shit
                
                [sd2,SNR,SNR_michelle] = calc_params_peak_extract(app,EEG_dat,x);
                avg2plot = double(squeeze(mean(EEG_dat,2,"omitnan")));

                % find the xlim idx so that the plotting parameters work 
                [~,x_min_idx] = min(abs(x-app.TimeLowmsEditField.Value));
                [~,x_max_idx] = min(abs(x-app.TimeHighmsEditField.Value));


                % find y lims to plot better
                avg_min = min(avg2plot(x_min_idx:x_max_idx));
                avg_max = max(avg2plot(x_min_idx:x_max_idx));

                app.YminEditField.Value = avg_min + avg_min*0.1;
                app.YmaxEditField.Value = avg_max + avg_max*0.1;


                % find pre baseline noise
                % define baseline noise
                %[~,low_base] = (min(abs(x - app.TimeLowmsEditField.Value)));
                %[~,zero_base] = (min(abs(x - 0)));


                cla(app.UIAxes)

                rectangle(app.UIAxes,'Position', [...
                    -10000,...
                    -sd2,...
                    100000,...
                    2*sd2], ...
                    'FaceColor', [.5, .5, .5, 0.5], ...
                    'EdgeColor', [1, 1, 1, 1]);

                rectangle(app.UIAxes,'Position', [...
                    app.stim_range(1),...
                   -10000,...
                    app.stim_range(2)- app.stim_range(1),...
                    20000], ...
                    'FaceColor', [0.75,0.20,0.23, 0.8], ...
                    'EdgeColor', [1, 1, 1, 1], ...
                    'Curvature',[0.2, 0.2]);

                hold(app.UIAxes,'on');


                if app.ButterflyPlotCheckBox.Value ==1 && ~isempty(app.TrialsDisplayedListBox.Value)

                    trials_idx = str2double(app.TrialsDisplayedListBox.Value);
                    trials2plot = EEG_dat(:,trials_idx);

                    if any(std(trials2plot,"omitnan") > 0)

                        % check the color of the linr
                        if string (app.ColorMapDropDown.Value) == "red"
                            plot(app.UIAxes,x,trials2plot,"LineWidth",2,"Color",[1,0,0,.7]);
                        elseif (app.ColorMapDropDown.Value) == "blue"
                            plot(app.UIAxes,x,trials2plot,"LineWidth",2,"Color",[0,1,1,.7]);
                        elseif (app.ColorMapDropDown.Value) == "green"
                            plot(app.UIAxes,x,trials2plot,"LineWidth",2,"Color",[0,1,0,.7]);
                        else
                            my_map = eval([char(app.ColorMapDropDown.Value) '(numel(trials_idx))']);
                            for j = 1:numel(trials_idx)
                                %plot(app.UIAxes,x,trials2plot(:,j),"LineWidth",3,"Color",[.7 .7 .7 lev]);
                                plot(app.UIAxes,x,trials2plot(:,j),"LineWidth",2,"Color",[my_map(j,:) , .7]);
                            end
                        end

                        % find y lims to plot better
                        subset_trials =trials2plot(x_min_idx:x_max_idx,:);
                        trial_min = double(min(subset_trials(:)));
                        trial_max = double(max(subset_trials(:)));

                        app.YminEditField.Value = trial_min + trial_min*0.1;
                        app.YmaxEditField.Value = trial_max + trial_max*0.1;

                    end

                end

                if app.AutoSwitch.Value == "Off"
                     app.YminEditField.Value = current_ylim_low;
                     app.YmaxEditField.Value = current_ylim_high;
                end


                plot(app.UIAxes,x,avg2plot,'LineWidth',5,"Color", [.7 .7 .7]);
                plot(app.UIAxes,x,avg2plot,'LineWidth',3,"Color","w");


                %set(app.UIAxes, 'YDir','reverse')
                xlim(app.UIAxes, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value]);
                ylim(app.UIAxes, [app.YminEditField.Value app.YmaxEditField.Value]);
                xline(app.UIAxes,0,'w--');
                yline(app.UIAxes,sd2);
                yline(app.UIAxes,-sd2);
                app.UIAxes.Color = 'k';
                % CHECK IF DATA HAS BEEN EXTRACTED FOR THIS CHANNEL AND
                % THIS FILTER TYPE
                my_table = app.UITable.Data;

                if ~ isempty(my_table)
                    points2plot = my_table(strcmp(string(app.ChannelsListBox.Value) ,my_table.Channel) ...
                        & strcmp(string(app.LowpassSwitch.Value) ,my_table.filter_on),:);

                    if ~isempty(points2plot)
                        for i = 1: size(points2plot,1)
                            name = points2plot.Name(i);
                            amp = points2plot.Amplitude(i);
                            lat = points2plot.Latency(i);
                            plot(app.UIAxes,lat,amp,'g*','LineWidth',5)
                            text(app.UIAxes,lat+lat*0.1,amp,name,'FontSize',20,'Color','g')
                        end

                    end
                end

                hold(app.UIAxes,'off')

            end
            time_out = x;
           

        end
        
        function [tf , frex,x,convol_pwr_ind,sing_wt,total_convol_result,cwt_outputs] = calc_tf_data(app)

            current_data = evalin('base','current_data');
            EEG = evalin('base',"EEG");

            data = current_data.data;
            x = current_data.time;


            time_l = app.TimeLowmsEditField.Value;
            time_h = app.TimeHighmsEditField.Value;
            freq_lims = [app.minfreqEditField.Value app.maxfreqEditField.Value];
            
            % subset eeg data to decrease computing time
            [~,i_low] = (min(abs(x - time_l)));
            [~,i_high] = (min(abs(x - time_h)));
            i_high = i_high-1;
            
            
            %avg2plot = squeeze(mean(EEG.data(chan_idx,:,:),3));
            
            dat_size = size(data);
            
            n_trials = dat_size(2);


            % compute ERP
            erp = squeeze(mean(data,2,"omitnan"));

            % compute induced power by subtracting ERP from each trial
            induced_EEG = squeeze(data - repmat(erp,1,n_trials));

            % perform wavelet transformations
            % for erp
            % fb = cwtfilterbank(SignalLength=numel(erp),SamplingFrequency=EEG.srate,...
            %     FrequencyLimits=freq_lims);
            % freqz(fb)
            % 
            % [erp_wt,frex,erp_scalingcfs] = cwt(erp,FilterBank=fb);
            % 
            % [erp_wt,frex,erp_scalingcfs] = cwt(erp);


            [erp_wt,frex,erp_scalingcfs]  = cwt(erp,'amor',EEG.srate,FrequencyLimits = freq_lims);

            cwt_outputs.erp.wt = erp_wt;
            cwt_outputs.erp.frex = frex;
            cwt_outputs.erp.scalingcfs = erp_scalingcfs; 

            % for single trials
            for i = 1:n_trials
                dat2convert =data(:,i);
                if ~ anynan(dat2convert)
                    [c_w,frex,sing_scalingcfs] = cwt(dat2convert,'amor',EEG.srate,FrequencyLimits = freq_lims);
                    if i == 1
                        sing_wt = zeros(length(frex),length(dat2convert),n_trials);
                        sing_wt(:,:,i) = c_w;
                    else
                        sing_wt(:,:,i)  = c_w;
                    end
                else
                    if i == 1
                        sing_wt = zeros(length(frex),length(dat2convert),n_trials);
                        sing_wt(:,:,i) = NaN;
                    else
                        sing_wt(:,:,i)  = NaN;
                    end

                end

            end

            sing_wt = sing_wt(:,i_low:i_high,:); % cut to epoch size
            cwt_outputs.single_trial.wt = sing_wt;
            cwt_outputs.single_trial.frex = frex;
            cwt_outputs.single_trial.scalingcfs = sing_scalingcfs; 

            % for induced
            for i = 1:n_trials
                dat2convert =induced_EEG(:,i);
                if ~ anynan(dat2convert)
                    [c_w,frex,~] = cwt(dat2convert,'amor',EEG.srate,FrequencyLimits = freq_lims);
                    if i == 1
                        ind_wt = zeros(length(frex),length(dat2convert),n_trials);
                        ind_wt(:,:,i) = c_w;
                    else
                        ind_wt(:,:,i)  = c_w;
                    end
                else
                    if i == 1
                        ind_wt = zeros(length(frex),length(dat2convert),n_trials);
                        ind_wt(:,:,i) = NaN;
                    else
                        ind_wt(:,:,i)  = NaN;
                    end
                end
            end


            % calculating power and baseline correcting

            % convert baseline from ms to indices
            x = x(i_low:i_high);
            [~,baseidx(1)] = min(abs(x-app.baseline_start.Value));
            [~,baseidx(2)] = min(abs(x-app.baseline_end.Value));

            % for erp power
            pow_erp = abs(erp_wt).^2;
            pow_erp = pow_erp(:,i_low:i_high);

            % for total
            pow_total = mean(abs(sing_wt).^2,3,"omitnan");
            convol_pwr_ind = abs(sing_wt).^2;
            cwt_outputs.convol_pwr_ind = convol_pwr_ind;
            total_convol_result = mean(sing_wt,3,"omitnan");
            cwt_outputs.total_convol_results = total_convol_result;

            % non-phase locked
            pow_npl = pow_total - pow_erp;

            % phase-locked power
            pow_plp = pow_total - pow_npl;

            % ITPC 
            ITPC = abs(mean(exp(1i*angle(sing_wt)),3,"omitnan"));

            %% initiate TF structure 

            tf = zeros(5,size(pow_erp,1),size(pow_erp,2));
            tf(1,:,:) = pow_total;
            tf(2,:,:) = pow_npl;
            tf(3,:,:) = pow_erp;
            tf(4,:,:) = ITPC;
            tf(5,:,:) = pow_plp;

            % baseline correct
            if string(app.BaselineNormSwitch.Value) == "On"
                if string(app.ConversionSwitch.Value) == "dB" %log scaled data

                    tf = 10*log10(tf ./ mean(tf(:,:,baseidx(1):baseidx(2)),3,"omitnan"));

                elseif string(app.SpacingSwitch.Value) == "percent change" %log scaled data
                    my_baseline = mean(tf(:,:,baseidx(1):baseidx(2)),3,"omitnan");

                    tf = ((tf-my_baseline)./my_baseline)*100;
                end
            end


            % chunk into correct size
            % re introduce non_normalized ITPC,plp and erp power
            tf(4,:,:) = ITPC;
            tf(3,:,:) = pow_erp;
            tf(5,:,:) = pow_plp;
            
            %tf = tf(:,:,i_low:i_high);
            %ind_wt = ind_wt(:,i_low:i_high,:);
            %convol_pwr_ind = convol_pwr_ind(:,i_low:i_high,:);
            %total_convol_result = total_convol_result(:,i_low:i_high,:);
            %times = x(i_low:i_high);

%             for i = 1:5
%                 figure
%                 imagesc(times,frex,squeeze(tf(i,:,:)))
%                 set(gca, 'YDir','normal')
%             end

            current_data.frequencies = frex;
            current_data.tf_times = x;
            current_data.tf = tf;
            current_data.cwt_outputs = cwt_outputs;
            current_data.tf_variables = ["Total","Non-phase-locked","ERP","ITPC","Phase locked"];

            EEG.frequencies = frex;
            EEG.tf_times = x;
            EEG.tf = tf;
            EEG.cwt_outputs = cwt_outputs;
            EEG.tf_variables = ["Total","Non-phase-locked","ERP","ITPC","Phase locked"];


            assignin('base','tf',tf)
            assignin('base','EEG',EEG)
            assignin('base','current_data',current_data)
            %assignin('base','ALLEEG',ALLEEG)
            assignin('base','cwt_outputs',cwt_outputs)
            
            
        end
        
        
        function plot_tfdata(app)
            %EEG = evalin('base','EEG');
            current_data = evalin('base','current_data');

            % determine the thing to plot
            var2plot = app.tf_output_plot.Value;


            if app.WARMFilterSwitch.Value == "On"
                EEG_dat = current_data.WARM_filter.single_trial_waveforms;
                x = current_data.WARM_filter.time;
                tf_mask= current_data.zmaps.zmapthresh_clc;
                frequencies = current_data.zmaps.frequencies;
                x_tf = current_data.zmaps.times;
                tf = current_data.zmaps.tf_resampled;


            else
                EEG_dat = current_data.data;
                x_tf = current_data.tf_times;
                x = current_data.time;
                tf = current_data.tf;
                frequencies = current_data.frequencies;
            end


            % get climits for plotting
            %[~,~] = (min(abs(x_tf- app.timelowerEditField.Value)));
            %[~,high_index] = (min(abs(x_tf - app.timehigherEditField.Value)));
            %high_index = high_index -1;


            % calculate time signal vars to plot later
            avg_data = mean(EEG_dat,2,"omitnan");
            %max_sign = max(max(abs(avg_data)));
            [sd2,SNR,SNR_michelle] = calc_params_peak_extract(app,EEG_dat,x);


            min_vect = min(min(tf(:,:,:,:),[],2),[],3);
            max_vect = max(max(tf(:,:,:),[],2),[],3);
           % itcp_ceil = max_vect(4);
            c_vector = max([abs(min_vect),abs(max_vect)],[],2);
            % temp
            min_vect = -c_vector;
            min_vect(4) = 0; % for ITPC
            % for emg
            % min_vect(1) = 0; % for ITPC
            % min_vect(2) = 0; % for ITPC
            % min_vect(3) = 0; % for ITPC
            % min_vect(4) = 0; % for ITPC

            max_vect= c_vector;
            %max_vect(4) = itcp_ceil;
            
            %analysis_labels = {'Total';'Non-phase-locked';'ERP power';'ITPC';'Phase-locked'};
            if string(app.ConversionSwitch.Value) == "dB"
                cbar_labels = {'dB';'Power (dB)';'dB';'ITPC';'Power (dB)'};
            elseif string(app.ConversionSwitch.Value) == "percent change"
                cbar_labels = {'% change';'% change';'% change';'ITPC';'% change'};
            end
            
            % total
            %lims = [min_vect(1) max_vect(1)];

            % determine the vars and the data to plot
            if var2plot == "Total"
                dat2plot = squeeze(tf(1,:,:));
                cbar_label = cbar_labels(1);
                color_edges = [min_vect(1) max_vect(1)];

            elseif var2plot == "Phase locked"
                dat2plot = squeeze(tf(5,:,:));
                cbar_label = cbar_labels(1);
                color_edges = [min_vect(5) max_vect(5)];


            elseif var2plot == "Non-phase-locked"
                dat2plot = squeeze(tf(2,:,:));
                cbar_label = cbar_labels(1);
                color_edges = [min_vect(2) max_vect(2)];


            elseif var2plot == "ITPC"
                dat2plot = squeeze(tf(4,:,:));
                cbar_label = cbar_labels(4);
                color_edges = [min_vect(4) max_vect(4)];

            end


            % apply the mask to the tf data being plotted
            if app.WARMFilterSwitch.Value == "On"
                mask = ~tf_mask == 0;
                dat2plot = dat2plot .* mask;
            end


            if ~isempty(SNR) && ~isnan(SNR)
                contourf(app.UIAxes9,x_tf,frequencies,dat2plot,40,'linecolor','none')
                hold(app.UIAxes9,'on')
                xline(app.UIAxes9,app.stim_range(1),'k');
                xline(app.UIAxes9,app.stim_range(2),'k');
                xline(app.UIAxes9,0,'k--');
                hold(app.UIAxes9,'on')

                if string(app.SpacingSwitch.Value) == "Log" %log scaled data
                    set(app.UIAxes9,'clim',color_edges,'xlim',[app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value],...
                        'xtick',[round(app.TimeLowmsEditField.Value:100:-100,-1) 0 round(100:100:app.TimeHighmsEditField.Value,-1)],...
                        'yscale','log','ytick',logspace(log10(app.minfreqEditField.Value),log10(app.maxfreqEditField.Value),6),...
                        'yticklabel',round(logspace(log10(app.minfreqEditField.Value),log10(app.maxfreqEditField.Value),6)*10)/10);
                elseif string(app.SpacingSwitch.Value) == "Linear" % not log scaled data
                    set(app.UIAxes9,'clim',color_edges,'xlim',[app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value],...
                        'xtick',[round(app.TimeLowmsEditField.Value:100:-100,-1) 0 round(100:100:app.TimeHighmsEditField.Value,-1)],...
                        'yscale','linear','ytick',linspace(app.minfreqEditField.Value,app.maxfreqEditField.Value,6),...
                        'yticklabel',round(linspace(app.minfreqEditField.Value,app.maxfreqEditField.Value,6)*10)/10);
                end
                c = colorbar(app.UIAxes9,"east");
                colormap(app.UIAxes9,"jet");
                c.Label.String = string(cbar_label);
                hold(app.UIAxes9,'off')

                % plot the time domain data underneath
                cla(app.UIAxes10)

                hold(app.UIAxes10,"on");
                rectangle(app.UIAxes10,'Position', [...
                    app.TimeLowmsEditField.Value,...
                    -sd2,...
                    app.TimeHighmsEditField.Value - app.TimeLowmsEditField.Value,...
                    2*sd2], ...
                    'FaceColor', [.5, .5, .5, 0.2], ...
                    'EdgeColor', [1, 1, 1, 1]);
                rectangle(app.UIAxes10,'Position', [...
                    app.stim_range(1),...
                    app.YminEditField.Value,...
                    app.stim_range(2)- app.stim_range(1),...
                    app.YmaxEditField.Value-app.YminEditField.Value], ...
                    'FaceColor', [0.75,0.20,0.23, 0.8], ...
                    'EdgeColor', [1, 1, 1, 1], ...
                    'Curvature',[0.2, 0.2]);

                plot(app.UIAxes10,x,avg_data,'LineWidth',2,"Color","w");

                [~,max_idx] = min(abs(x-app.TimeHighmsEditField.Value));
                [~,min_idx] = min(abs(x-app.TimeLowmsEditField.Value));
                % get new ylims for the signal
                high_y = max(avg_data(min_idx:max_idx));
                high_y = high_y+high_y*0.1;
                min_y = min(avg_data(min_idx:max_idx));
                min_y = min_y+min_y*0.1;

                %set(app.UIAxes10, 'YDir','reverse')
                xlim(app.UIAxes10, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])
                ylim(app.UIAxes10, [min_y high_y])
                xline(app.UIAxes10,0,'w--');
                yline(app.UIAxes10,sd2)
                yline(app.UIAxes10,-sd2)
                app.UIAxes10.Color = 'k';
                hold(app.UIAxes10,"off");
            else
                contourf(app.UIAxes9,x_tf,frequencies,zeros(size(dat2plot)),40,'linecolor','none')
                hold(app.UIAxes9,'on')
                xline(app.UIAxes9,app.stim_range(1),'k');
                xline(app.UIAxes9,app.stim_range(2),'k');
                xline(app.UIAxes9,0,'k--');
                c = colorbar(app.UIAxes9,"east");
                colormap(app.UIAxes9,"jet");
                c.Label.String = string(cbar_label);
                hold(app.UIAxes9,'off')

                cla(app.UIAxes10)
                hold(app.UIAxes10,"on");
                xline(app.UIAxes10,0,'--');
                xlim(app.UIAxes10, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])
                ylim(app.UIAxes10, [app.YminEditField.Value app.YmaxEditField.Value])
                xline(app.UIAxes10,0,'--');
                hold(app.UIAxes10,"off");
            end


        end
        
        function my_output = selectDatapoints(app)
           
            % Remove any pre-existing rectanlges (if any)
            delete(findall(app.UIAxes9, 'Type',  'images.roi.Rectangle'))
            temp_roi = drawrectangle(app.UIAxes9,'StripeColor','b','InteractionsAllowed','none');
  
            my_output = temp_roi.Position;
   
        end


        function [my_output, extraction_state]= extract_vals(app,tf,frequencies,x)

           
            % interpolate so that you get a bit more resolution
            % Define interpolation resolution (e.g., 5x finer)
            factor = 5;
            
            % Create finer time and frequency vectors
            fine_freq = linspace(max(frequencies), min(frequencies), length(frequencies)*factor)';
            fine_time = linspace(min(x), max(x), length(x)*factor);
            
            % Interpolate
            [Time, Freq] = meshgrid(x, frequencies);
            [FineTime, FineFreq] = meshgrid(fine_time, fine_freq);
            data_out = zeros(size(tf,1),numel(fine_freq),numel(fine_time));


            for i = 1:size(tf,1)
                data_out(i,:,:)= interp2(Time, Freq, squeeze(tf(i,:,:)), FineTime, FineFreq, 'spline');
            end

            % integrate back to code 
            tf = data_out;


            x_min = app.TimeLowEditField_2.Value;
            x_max = app.TimeHighEditField_2.Value;
            y_min = app.FreqLowEditField_2.Value;
            y_max = app.FreqHighEditField_2.Value;


            % have to make sure the indicies are within the specified
            % frequency, even if its closer to the outside range 
            [~,low_x] = (min(abs(fine_time - x_min)));
            [~,high_x] = (min(abs(fine_time - x_max)));
            [~,low_y] = (min(abs(fine_freq- y_min)));
            [~,high_y] = (min(abs(fine_freq- y_max)));

            % Ensure low_x is the first index where fine_time >= x_min
            low_x = find(fine_time >= x_min, 1, 'first');

            high_x = find(fine_time <= x_max, 1, 'last');
            high_y = find(fine_freq <= y_max, 1, 'first');
            low_y = find(fine_freq >= y_min, 1, 'last');


            % cutting data
            data_cut = tf(:,high_y:low_y,low_x:high_x);
            time_cut = fine_time(low_x:high_x);
            freq_cut = fine_freq(high_y:low_y);


            % extracting important values 
            max_vect = max(max(data_cut,[],3),[],2);
            mean_vect = mean(mean(data_cut,3,"omitnan"),2,"omitnan");
            min_vect = min(min(data_cut,[],3),[],2);

            % extracting indices where the min and max on the cut data
            temp = squeeze(max(data_cut,[],3));
            [ ~ , maxif_vect] = max(temp,[],2); % for frequency it works
            temp = squeeze(min(data_cut,[],3));
            [ ~ , minif_vect] = min(temp,[],2); % for freqency it works
            temp = squeeze(max(data_cut,[],2));
            [ ~ , maxit_vect] = max(temp,[],2); % for time it works
            temp = squeeze(min(data_cut,[],2));
            [ ~ , minit_vect] = min(temp,[],2); % for time it works


            % caclulating peak time and freq for min and max on the whole
            peak_t_vector = zeros(1,5);
            min_t_vector = zeros(1,5);
            peak_f_vector = zeros(1,5);
            min_f_vector = zeros(1,5);
           
            for i = 1:5
                peak_t_vector(i) = time_cut(maxit_vect(i));
                min_t_vector(i) = time_cut(minit_vect(i));

                peak_f_vector(i) = freq_cut(maxif_vect(i));
                min_f_vector(i) = freq_cut(minif_vect(i));
            end

            my_output.mean_activity = mean_vect;
            my_output.max_activity = max_vect;
            my_output.min_activity = min_vect;
            my_output.peak_frequency = peak_f_vector;
            my_output.lowest_frequency = min_f_vector;
            my_output.peak_time = peak_t_vector;
            my_output.lowest_time = min_t_vector;
            my_output.metric = app.tf_output_plot.Value;

            extraction_state = 1;
            
        end
        
        function populate_extraction_vals(app,my_output)
            c_tf_data = app.tf_output_plot.Value;
            current_data = evalin("base","current_data");
            tf_vars = current_data.tf_variables;

            for i = 1:length(tf_vars)
                if string(tf_vars(i)) == c_tf_data
                    tab_idx = i;
                end
            end
                        
            prompt = {'Enter name of ROI'};
            dlgtitle = 'Name of ROI';
            dims = [1 35];
            definput = {['ROI ' num2str(app.n_roi+1)],'hsv'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            
             my_table = app.UITable2_2.Data;
             m_data.Channel = string(app.ChannelsListBox.Value);
             m_data.Name = string(answer);
             m_data.mean_act = round(my_output.mean_activity(tab_idx),3);
             m_data.peak_act = round(my_output.max_activity(tab_idx),3);
             m_data.peak_freq = round(my_output.peak_frequency(tab_idx),3);
             m_data.peak_lat = round(my_output.peak_time(tab_idx),3);
             m_data.low_act = round(my_output.min_activity(tab_idx),3);
             m_data.low_freq = round(my_output.lowest_frequency(tab_idx),3);
             m_data.low_lat = round(my_output.lowest_time(tab_idx),3);
             m_data.metric = string(my_output.metric);
             

                if app.n_roi > 0
                    my_table_add = struct2table(m_data);
                    my_table_add.Properties.VariableNames = ["Channel" ,"Name","Mean Activity (dB)","Peak Activity (dB)","Peak Frequency (Hz)","Peak Latency (ms)","Lowest Activity (ms)","Min Frequency (Hz)","Min Latency(ms)","Metric"];
                    my_table = [my_table;my_table_add];
                else
                    my_table = struct2table(m_data);
                    my_table.Properties.VariableNames = ["Channel" ,"Name","Mean Activity (dB)","Peak Activity (dB)","Peak Frequency (Hz)","Peak Latency (ms)","Lowest Activity (ms)","Min Frequency (Hz)","Min Latency(ms)","Metric"];
                    
                end
                app.UITable2_2.Data = my_table;    
                
% 
%             app.meanactivitydBEditField.Value = round(my_output.mean_activity(tab_idx),3);
%             app.peakactivitydBEditField.Value = round(my_output.max_activity(tab_idx),3);
%             app.peakfrequencyHzEditField.Value = round(my_output.peak_frequency(tab_idx),3);
%             app.peaklatencymsEditField.Value = round(my_output.peak_time(tab_idx),3);
%             app.lowestactivitydBEditField.Value = round(my_output.min_activity(tab_idx),3);
%             app.minfrequencyHzEditField.Value = round(my_output.lowest_frequency(tab_idx),3);
%             app.minlatencymsEditField.Value = round(my_output.lowest_time(tab_idx),3);
             app.n_roi = app.n_roi +1;

            
        end
        
        function EEG = convert_txt_to_eeg_struct(app,data_in,chan_id,srate,baseline_low)
            load('chan_info.mat',"chanlocs");
            
            EEG.data = data_in;
            dims_in = size(data_in);
            
            %calculate the time of the stim
            l_data = length(data_in);
            t_data = l_data/srate*1000; %in ms
            my_baseline = baseline_low; %in ms
            time_v = linspace(my_baseline,t_data+my_baseline,length(data_in));


            for k = 1:length(chanlocs)
                C_chan = lower(string(chanlocs(k).labels));
                if C_chan == lower(chan_id)
                    chan1_IDX=k;
                end
                
            end


            % find the correct chanlocs id and change the names 


            temp_data = zeros(1,dims_in(1),dims_in(2));
            temp_data(1,:,:) = data_in;
            EEG.data = temp_data;
            new_chanlocs = chanlocs(chan1_IDX);
            EEG.chanlocs = new_chanlocs;
            EEG.srate = srate;
            EEG.times = time_v; 
            [filepath,name,ext] = fileparts(app.DataInEditField.Value); 
            EEG.setname = name;
            EEG.filename = [name,ext];
            EEG.filepath = filepath;
            EEG.comments = ['Original file: ' filepath, filesep(), name, ext, ". Converted from text file." ];
            EEG.nbchan = size(EEG.data,1);
            EEG.trials = size(EEG.data,3);
            EEG.pnts = size(EEG.data,2);
            EEG.xmin = min(EEG.times);
            EEG.xmax = max(EEG.times);
            EEG.icawinv = [];
            EEG.icaweights = [];
            EEG.icasphere = [];


        end
        
        function my_table = extract_peaks(app,EEG_dat_plotted,x,SNR,SNR_michelle,sd2)
           EEG = evalin('base','EEG');
           c_dat = evalin('base','current_data');
           setname = EEG.setname;

           erp = mean(EEG_dat_plotted,2,"omitnan");
           win_min = app.SignalStartmsEditField.Value;
           win_max = app.SignalEndmsEditField.Value;
           
           [~,low_idx] = (min(abs(x - win_min)));
           [~,high_idx] = (min(abs(x - win_max)));

           cut_erp = erp(low_idx:high_idx); % cut the erp to the size of window
           cut_time = x(low_idx:high_idx); % cut the time as well

           if app.typeoferp.Value == "N2/P2"
               % check first if there is an p2
               [~, max_out_idx] = max(cut_erp);
               if and(max_out_idx ~= 0,max_out_idx ~= length(cut_erp)) % if its not in the edges
                   df_out.p2_amp =max(cut_erp);
                   df_out.p2_lat = cut_time(max_out_idx);
                   df_out.p2_chan = app.ChannelsListBox.Value;

                   % see if there is an n2 before the p2
                   [~, min_out_idx] = min(cut_erp(1:max_out_idx));
                   if and(min_out_idx ~= 0,min_out_idx ~= length(cut_erp(1:max_out_idx))) % if it isnt at the edges
                       df_out.n2_amp =min(cut_erp);
                       df_out.n2_lat = cut_time(min_out_idx);
                       df_out.n2_chan = app.ChannelsListBox.Value;

                   else % if it doesnt exist in the edges
                       df_out.n2_amp = nan;
                       df_out.n2_lat = nan;
                       df_out.n2_chan = app.ChannelsListBox.Value;
                   end

               else % if there is no inital p2
                   df_out.p2_amp =[];
                   df_out.p2_lat = [];
                   df_out.p2_chan = app.ChannelsListBox.Value;
                   
                   [~, min_out_idx] = min(cut_erp);
                   % see if there is an n2
                   if and(min_out_idx ~= 0,min_out_idx ~= length(cut_erp)) % if it isnt at the edges
                       df_out.n2_amp =min(cut_erp);
                       df_out.n2_lat = cut_time(min_out_idx);

                   else % if it doesnt exist in the edges
                       df_out.n2_amp = nan;
                       df_out.n2_lat = nan;

                   end
               end

           elseif app.typeoferp.Value == "N1" % if the signal is the n1
               % check if the n2p2 was extracted
               my_table = app.UITable.Data; % read in data table
               % read in only n2 trials that match that are from the same
               % dataset and that use the same number of trials and have
               % been filtered the same. 
               if ~isempty(my_table)
                   n2s = my_table(my_table.Name == "N2" & ...
                       string(my_table.ID) == string(c_dat.data_name) & ...
                       string(my_table.trials) == strjoin(string(c_dat.trials_plotted),",") & ...
                       string(my_table.filter_on) == string(c_dat.filtered),:);

                   if isempty(n2s)
                       [~, val_out_idx] = min(cut_erp);
                       if and(val_out_idx ~= 0,val_out_idx ~= length(cut_erp)) % checking its not the edge
                           df_out.n1_amp = min(cut_erp);
                           df_out.n1_lat = cut_time(val_out_idx);
                           df_out.n1_chan = app.ChannelsListBox.Value;

                       else
                           df_out.n1_amp = nan;
                           df_out.n1_lat = nan;
                           df_out.n1_chan = app.ChannelsListBox.Value;
                       end
                   else % if there is an n2 that works
                       win_min = app.SignalStartmsEditField.Value; % keep the same min but
                       win_max = n2s.Latency;

                       [~,low_idx] = (min(abs(x - win_min)));
                       [~,high_idx] = (min(abs(x - win_max)));

                       cut_erp = erp(low_idx:high_idx); % cut the erp to the size of window
                       cut_time = x(low_idx:high_idx); % cut the time as well
                       [~, val_out_idx] = min(cut_erp);
                       if and(val_out_idx ~= 0,val_out_idx ~= length(cut_erp)) % checking its not the edge
                           df_out.n1_amp = min(cut_erp);
                           df_out.n1_lat = cut_time(val_out_idx);
                           df_out.n1_chan = app.ChannelsListBox.Value;
                       else
                           df_out.n1_amp = nan;
                           df_out.n1_lat = nan;
                           df_out.n1_chan = app.ChannelsListBox.Value;
                       end
                   end
               else
                   [~, val_out_idx] = min(cut_erp);
                   if and(val_out_idx ~= 0,val_out_idx ~= length(cut_erp)) % checking its not the edge
                       df_out.n1_amp = min(cut_erp);
                       df_out.n1_lat = cut_time(val_out_idx);
                       df_out.n1_chan = app.ChannelsListBox.Value;

                   else
                       df_out.n1_amp = nan;
                       df_out.n1_lat = nan;
                       df_out.n1_chan = app.ChannelsListBox.Value;
                   end
               end

           end

           my_table = app.UITable.Data;

           if app.typeoferp.Value == "N2/P2"
                m_data.Channel = df_out.n2_chan;
                m_data.Name = "N2";
                m_data.Amplitude = df_out.n2_amp;
                m_data.Latency =  df_out.n2_lat;
                m_data.filter_on = string(app.LowpassSwitch.Value);
                m_data.trials = strjoin(string(c_dat.trials_plotted),",");
                m_data.n_trials = length(c_dat.trials_plotted);
                m_data.Stim = app.typeofstim.Value;
                m_data.SNR_oddeve = SNR;  
                m_data.SNR_ps = SNR_michelle;
                m_data.sd2 = sd2;
                m_data.ID = string(c_dat.data_name);

                my_table = [my_table; struct2table(m_data)];

                m_data.Channel = df_out.p2_chan;
                m_data.Name = "P2";
                m_data.Amplitude = df_out.p2_amp;
                m_data.Latency =  df_out.p2_lat;
                m_data.filter_on = string(app.LowpassSwitch.Value);
                m_data.trials = strjoin(string(c_dat.trials_plotted),",");
                m_data.n_trials = length(c_dat.trials_plotted);
                m_data.Stim = app.typeofstim.Value;
                m_data.SNR_oddeve = SNR;  
                m_data.SNR_ps = SNR_michelle;
                m_data.sd2 = sd2;
                m_data.ID = string(c_dat.data_name);
                my_table = [my_table; struct2table(m_data)];

           elseif app.typeoferp.Value == "N1"
                m_data.Channel = df_out.n1_chan;
                m_data.Name = "N1";
                m_data.Amplitude = df_out.n1_amp;
                m_data.Latency =  df_out.n1_lat;
                m_data.filter_on = string(app.LowpassSwitch.Value);
                m_data.trials = strjoin(string(c_dat.trials_plotted),",");
                m_data.n_trials = length(c_dat.trials_plotted);
                m_data.Stim = app.typeofstim.Value;
                m_data.SNR_oddeve = SNR; 
                m_data.SNR_ps = SNR_michelle;
                m_data.sd2 = sd2;
                m_data.ID = string(c_dat.data_name);
                my_table = [my_table; struct2table(m_data)];
                
           end

           app.UITable.Data = my_table;   


        end
        
        function mark_peaks_in_fig(app)
            hold(app.UIAxes,"on")
            table_info = app.UITable.Data;
            for i = 1: size(table_info,1)
                
                name = table_info.Name(i);
                amp = table_info.Amplitude(i);
                lat = table_info.Latency(i);
                plot(app.UIAxes,lat,amp,'g*','LineWidth',5)
                text(app.UIAxes,lat,amp,name,'FontSize',20)
            end
            hold(app.UIAxes,"off");    
        end
        
        
        function [zmap, zmapthresh, zmapthresh_plc, zmapthresh_clc, tfv_time, v_freq] = npp_stb_2(app,input_mat,n_permutes,v_time,v_freq,time_window,baseline_range,plotting_input,my_title)

            %% Description:
            % Takes a time frequency matrix (dimensions = n_subs, frequencies, times)
            % and peforms the non perametric permutation comparing the signal to the
            % baseline. Inputs include input mat, number of permutations (n_permutes),
            % vectors for time and frequency(v_time and v_freq), limits in the time 
            % axis where you want to perform the analysis (time_window), and the
            % plotting_input variable, in which if is set to 1, it will plot the 
            % responses. The output consist of the z_map, the logical mask for the 
            % zmap uncorrected for multple comparisons (zmapthresh), the logical mask
            % with a pixel level correction for multiple comparisons (zmapthresh_plc),
            % and the logical threshold map with cluster correction for mutliple
            % comparisons (zmapthesh_clc). The last output is the new time vector
            % based on time_window. 
            
            
            %% getting parameters for permutations
            time_s = dsearchn(v_time',baseline_range(1)); % start time for tf window
            time_0 = dsearchn(v_time',baseline_range(2));
            time_e = dsearchn(v_time',time_window(2)); % end zerostime for tf window
            tfv_time  = v_time(time_s:time_e);
            nTimepoints = numel(tfv_time);
            avg_mat =squeeze(mean(input_mat,"omitnan"));
            temp = size(input_mat);
            n_subs = temp(1);
            
            % statistical significant values for voxel and cluster analysis
            voxel_pval   = 0.05;
            cluster_pval = 0.05;
            mcc_voxel_pval = 0.05; % mcc = multiple comparisons correction
            mcc_cluster_pval = 0.05;
            %% Running actual T test on data
            % need to run it per frequency bin

            test_prestim_estimate =  mean(input_mat(:,:,time_s:time_0),3);
            test_poststim_points = squeeze(input_mat(:,:,time_s:time_e));
            test_prestim_points = repmat(test_prestim_estimate,1,1,length(test_poststim_points));
            [~,~,~,stats] = ttest(test_poststim_points,test_prestim_points);
            t_mat = squeeze(stats.tstat);
            mat_size = size(avg_mat(:,time_s:time_e));
            % t_mat = zeros(mat_size);
            % 
            % for i = 1:mat_size(1) %run per frequency bin
            %     prestim_estimate = mean(input_mat(:,i,time_s:time_0),3,"omitnan");
            %     poststim_points = squeeze(input_mat(:,i,time_s:time_e));
            %     prestim_points = repmat(prestim_estimate, 1,length(poststim_points));
            %     [~,~,~,stats] = ttest(poststim_points,prestim_points);
            %     results = stats.tstat;
            %     t_mat(i,:) = results;
            % end
            
            %% running the premutations
            
            % initialize null hypothesis matrices
            %permuted_maxvals = zeros(n_permutes,2,length(v_freq));
            max_clust_info   = zeros(n_permutes,1);
            max_pixel_pvals = zeros(n_permutes,2);
            %% permutation loop 
            curr_perm = zeros(mat_size); % initializing a variable to temporary store the results of current permutation before reshaping back into tf spectograph
            permuted_vals = zeros([n_permutes, mat_size]);
            
            % waitbar
            x_wait = 0;
            f = waitbar(x_wait,'Performing the permutations...','Name','npp_stb',...
                'CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
            
            setappdata(f,'canceling',0);
            
            
            for permi=1:n_permutes
                 if getappdata(f,'canceling')
                    delete(f)
                    msg = 'Error, user terminated the function.';
                    error(msg)
                 end
                
                x_wait = permi/n_permutes;
                waitbar(x_wait,f,'performing the permutations...');
                

                test_prestim_estimate =  mean(input_mat(:,:,time_s:time_0),3);
                test_poststim_points = squeeze(input_mat(:,:,time_s:time_e));
                test_prestim_points = repmat(test_prestim_estimate,1,1,length(test_poststim_points));

                % split the dataframes into pre and post randomly
                v = [ones(n_subs, 1); 2*ones(n_subs, 1)];
                vshuffle = v(randperm(2*n_subs));

                % first concatenate pre and post estimates
                test_cont = cat(1,test_poststim_points,test_prestim_points);
                pre_alloc = test_cont(vshuffle == 1,:,:);
                pos_alloc = test_cont(vshuffle == 2,:,:);


                % perform the ttest
                [~,~,~,stats] = ttest(pos_alloc,pre_alloc);
                curr_perm = stats.tstat;
                permuted_vals(permi,:,:) = curr_perm;


                % 
                % 
                % for i = 1:mat_size(1) % per frequency bin 
                %     prestim_estimate = squeeze(input_mat(:,i,time_s:time_0));
                %     poststim_points = squeeze(input_mat(:,i,time_s:time_e));
                %     pre_stim_estimate_r = zeros([n_subs,1]);
                %     temp1 = size(prestim_estimate);
                %     for m = 1:n_subs
                %         r_index = randi([1,temp1(2)]);
                %         pre_stim_estimate_r(m) = prestim_estimate(m,r_index);
                %     end
                %     %prestim_points = repmat(pre_stim_estimate_r, 1,length(poststim_points));
                %     shuffled_data_1 = zeros(size(poststim_points));
                %     shuffled_data_2 = zeros(size(poststim_points));
                %     for j = 1:mat_size(2) % per timepoint
                %         c_data = poststim_points(:,j);
                %         c_data_out_1 = zeros(size(c_data));
                %         c_baseline = pre_stim_estimate_r;
                %         c_data_out_2 = zeros(size(c_data));
                %         conditions = randi([1, 2], n_subs,1); %% create a vector that randomly assings a 1 or a 2 to each subject
                %         for k = 1:n_subs % switching labels per subject
                %             if conditions(k) == 1 % if the subject is assigned condition 1
                %                 c_data_out_1(k) = c_data(k);
                %                 c_data_out_2(k) = c_baseline(k);
                %             elseif conditions(k) == 2
                %                 c_data_out_1(k) = c_baseline(k);
                %                 c_data_out_2(k) = c_data(k);
                %             end
                %         end
                %         shuffled_data_1(:,j) = c_data_out_1;
                %         shuffled_data_2(:,j) = c_data_out_2;   
                %     end
                %     [~,~,~,stats] = ttest(shuffled_data_1,shuffled_data_2);
                %     curr_perm(i,:) = stats.tstat;
                % end
                % permuted_vals(permi,:,:) = curr_perm;
                % 
                
                % saving max for correction for multiple comparisons
                max_pixel_pvals(permi,:) = [ min(curr_perm(:)) max(curr_perm(:)) ];
            
                % for cluster correction, apply uncorrected threshold and get maximum cluster sizes
                % note that here, clusters were obtained by parametrically thresholding
                % the t-maps
                curr_perm(abs(curr_perm)<tinv(1-voxel_pval,n_subs-1))=0;
                clustinfo = bwconncomp(curr_perm);
                max_clust_info(permi) = max([ 0 cellfun(@numel,clustinfo.PixelIdxList) ]); % notes: cellfun is superfast, and the zero accounts for empty maps
            end
            delete(f)
            
            %% getting zmaps
            zmap = (t_mat-squeeze(mean(permuted_vals,1,"omitnan")))./squeeze(std(permuted_vals));
            zmapthresh = zmap;
            % appliying uncorrected threshold
            zmapthresh(abs(zmapthresh)<norminv(1-voxel_pval))=false;
            zmapthresh=logical(zmapthresh);
            
            
            % apply pixel-level corrected threshold
            lower_threshold = prctile(max_pixel_pvals(:,1),    mcc_voxel_pval*100/2);
            upper_threshold = prctile(max_pixel_pvals(:,2),100-mcc_voxel_pval*100/2);
            zmapthresh_plc = zmap;
            mask1 = zmap>lower_threshold;
            mask2 = zmap<upper_threshold;
            mask = logical(mask1 + mask2-1);
            zmapthresh_plc(mask) = 0;
            
            %% apply cluster-level corrected threshold
            zmapthresh_clc = zmap;
            % apply uncorrected pixel-level threshold
            zmapthresh_clc(abs(zmapthresh_clc)<norminv(1-cluster_pval))=false;
            % find islands and remove those smaller than cluster size threshold
            clustinfo = bwconncomp(zmapthresh_clc);
            clust_info = cellfun(@numel,clustinfo.PixelIdxList);
            clust_threshold = prctile(max_clust_info,100-mcc_cluster_pval*100);
            
            % identify clusters to remove
            whichclusters2remove = find(clust_info<clust_threshold);
            
            % remove clusters
            for i=1:length(whichclusters2remove)
                zmapthresh_clc(clustinfo.PixelIdxList{whichclusters2remove(i)})=0;
            end
            my_clims = [-9 9];
            if plotting_input == 1
                figure
                my_lxlim = time_window(1)+10;
                my_hxlim = time_window(2);
                subplot(2,2,1)
                contourf(tfv_time,v_freq,zmap,40,'linecolor','none')
                axis square
                set(gca,'clim',my_clims,'xlim',[my_lxlim my_hxlim])
                title('Unthresholded Z map')
                xlabel('Time (ms)'), ylabel('Frequency (Hz)')
                colormap jet

                c = colorbar;
                c.Label.String = 'Z score';
            
                subplot(2,2,2)
                contourf(tfv_time,v_freq,zmap,40,'linecolor','none')
                hold on
                contour(tfv_time,v_freq,zmapthresh,1,'linecolor','k')
                c = colorbar;
                c.Label.String = 'Z score';
                axis square
                set(gca,'clim',my_clims,'xlim',[my_lxlim my_hxlim])
                title('Uncorrected threshold Z map')
                xlabel('Time (ms)'), ylabel('Frequency (Hz)')
            
            
                subplot(2,2,3)
                contourf(tfv_time,v_freq,zmapthresh_plc,40,'linecolor','none')
                axis square
                set(gca,'clim',my_clims,'xlim',[my_lxlim my_hxlim])
                title('Pixel-corrected Z map')
                xlabel('Time (ms)'), ylabel('Frequency (Hz)')
                c = colorbar;
                c.Label.String = 'Z score';
            
                subplot(2,2,4)
                contourf(tfv_time,v_freq,zmapthresh_clc,40,'linecolor','none')
                axis square
                set(gca,'clim',my_clims,'xlim',[my_lxlim my_hxlim])
                title('Cluster-corrected Z map')
                xlabel('Time (ms)'), ylabel('Frequency (Hz)')
                c = colorbar;
                c.Label.String = 'Z score';
                %colormap(rbw_good_one_2)
                sgtitle(my_title)
            end

            
        end
        
        
        function plot_trial_data(app)
            current_data = evalin('base','current_data');
            load('new_color_map_2.mat');

            % check if we are doing the warm filter
            if app.WARMFilterSwitch.Value == "On"
                EEG_dat = current_data.WARM_filter.single_trial_waveforms;
                x_current = current_data.WARM_filter.time;
   
            else
                EEG_dat = current_data.data;
                x_current = current_data.time;
            end


            [~,low_index] = (min(abs(x_current - app.TimeLowmsEditField.Value)));
            [~,high_index] = (min(abs(x_current - app.TimeHighmsEditField.Value)));
            subset_data = EEG_dat(low_index:high_index,:);
            max_sign = max(max(abs(subset_data)));
            x = x_current(low_index:high_index);

            [sd2,SNR,SNR_michelle] = calc_params_peak_extract(app,EEG_dat,x);

            if ~isempty(SNR) && ~isnan(SNR)

                %
                % finding the max to plot
                cla(app.UIAxes7)

                %trials = size(data2plot,2);
                imagesc(app.UIAxes7,x,1:size(subset_data,2),subset_data')
                rectangle(app.UIAxes7,'Position', [...
                    app.stim_range(1),...
                    0,...
                    app.stim_range(2)- app.stim_range(1),...
                    size(subset_data,2)], ...
                    'FaceColor', 'none', ...
                    'EdgeColor', [0, 0, 0, 1], ...
                    'Curvature',[0, 0]);
                xline(app.UIAxes7,0,'--');
                hold(app.UIAxes7,'off')
                colormap(app.UIAxes7,new_color_map_2)
                caxis(app.UIAxes7,[-max_sign*0.8 max_sign*0.8]);
                c = colorbar(app.UIAxes7,"east");
                c.Label.String = "Amplitude (mV)";
                app.UIAxes7.YTick = 1:size(subset_data,2);
                hold(app.UIAxes7,'off')
                xlim(app.UIAxes7, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])
                ylim(app.UIAxes7, [1 size(subset_data,2)])
                avg_data = squeeze(mean(subset_data,2,"omitnan"));

                cla(app.UIAxes8)

                hold(app.UIAxes8,"on");
                rectangle(app.UIAxes8,'Position', [...
                    app.TimeLowmsEditField.Value,...
                    -sd2,...
                    app.TimeHighmsEditField.Value - app.TimeLowmsEditField.Value,...
                    2*sd2], ...
                    'FaceColor', [.5, .5, .5, 0.2], ...
                    'EdgeColor', [1, 1, 1, 1]);
                rectangle(app.UIAxes8,'Position', [...
                    app.stim_range(1),...
                    app.YminEditField.Value,...
                    app.stim_range(2)- app.stim_range(1),...
                    app.YmaxEditField.Value-app.YminEditField.Value], ...
                    'FaceColor', [0.75,0.20,0.23, 0.8], ...
                    'EdgeColor', [1, 1, 1, 1], ...
                    'Curvature',[0.2, 0.2]);

                plot(app.UIAxes8,x,avg_data,'LineWidth',2,"Color","w");
                %set(app.UIAxes8, 'YDir','reverse')

                [~,max_idx] = min(abs(x-app.TimeHighmsEditField.Value));
                [~,min_idx] = min(abs(x-app.TimeLowmsEditField.Value));
                % get new ylims for the signal
                high_y = max(avg_data(min_idx:max_idx));
                high_y = high_y+high_y*0.1;
                min_y = min(avg_data(min_idx:max_idx));
                min_y = min_y+min_y*0.1;

                xlim(app.UIAxes8, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])
                ylim(app.UIAxes8, [min_y high_y])
                xline(app.UIAxes8,0,'w--');
                yline(app.UIAxes8,sd2)
                yline(app.UIAxes8,-sd2)
                app.UIAxes8.Color = 'k';


            else
                cla(app.UIAxes7)
                cla(app.UIAxes8)
                xline(app.UIAxes7,0,'--');
                xlim(app.UIAxes7, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])
                ylim(app.UIAxes7, [1 size(subset_data,2)])
                xlim(app.UIAxes8, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])
                ylim(app.UIAxes8, [app.YminEditField.Value app.YmaxEditField.Value])
                xline(app.UIAxes8,0,'--');

            end
        end

        
        function my_settings = compile_settings(app)
            %time domain settings
            %%% plotting parementers
            ttlow = {'Time Low', app.TimeLowEditField};

            %time frequency settings 
            
        end
        
        function results = extract_single_trial_data(app)
           EEG = evalin('base','EEG');
           n_peaks = app.numberofpeakstoextractEditField_2.Value;
           fig = figure(); 
           plot(EEG.WARM_filter.time,EEG.WARM_filter.mean_waveform)
           %set(gca, 'YDir','reverse')
           hold on
%            
%            appAxes = app.UIAxes2_7;
%            
%            ax = copyobj(appAxes, fig); 


           for i  = 1:n_peaks
               prompt = {'Enter name of peak'};
               dlgtitle = 'Name of peak';
               dims = [1 35];
               definput = {['Peak ',num2str(i)],'hsv'};
               answer = inputdlg(prompt,dlgtitle,dims,definput);


                temp_roi = drawrectangle(fig.CurrentAxes,'StripeColor','b','InteractionsAllowed','none');
                position = temp_roi.Position;  
                x_min = position(1);
                x_max  = position(1) + position(3);
                [~,low_index] = (min(abs(EEG.WARM_filter.time - x_min)));
                [~,high_index] = (min(abs(EEG.WARM_filter.time - x_max)));
                % determining if its a negative or a positive peak 
                erp = EEG.WARM_filter.mean_waveform;
                erp_cut = erp(low_index:high_index);
                [highest_point , highest_index]  = max(erp_cut);
                [lowest_point , lowest_index]  = min(erp_cut);
                
                pgn = abs(highest_point) > abs(lowest_point); % check to see if the highest positive point has a greater magnitude than the lowerst negative point. reurn zero if it is true, and return 1 if it is not true.
                
                if pgn == 0 % if my positive point is not greater than negative point
                    % check that the negative dip is not in the end
                    if and(lowest_index ~= 0,lowest_index ~= length(erp_cut))
                        pont = 0; % positiv or negative toggle (1 = positive peak, -0 = negative peak)
                    elseif and(highest_index ~= 0,highest_index ~= length(erp_cut)) % if it is in the end, check to see if the positive is in the end or not 
                        pont  = 1;
                    else 
                        warning("The region that you selected did not contain a peak - please try again ")
                    end
                elseif pgn == 1 % if my positive point is greater than my negative point
                    % check tha the positive dip is not at the end
                    if and(highest_index ~= 0,highest_index ~= length(erp_cut))
                        pont = 1;
                    elseif and(lowest_index ~= 0,lowest_index ~= length(erp_cut)) 
                        pont = 0;
                    else
                        warning("The region that you selected did not contain a peak - please try again ")
                    end
                    
                end
                
                if pont == 1
                    my_peak = highest_point ;
                    my_latency = EEG.WARM_filter.time(low_index+highest_index);
                elseif pont == 0
                    my_peak = lowest_point ;
                    my_latency = EEG.WARM_filter.time(low_index+lowest_index);
                else
                    warning("Please re select the region")
                end
                
                my_table = app.UITable3.Data;

                % Extracting for single trial data
                st_data = EEG.WARM_filter.single_trial_waveforms;
                % window in ms
                wind = 0;

                m_data.Channel = string(app.ChannelsListBox.Value);
                m_data.Name = string(answer);
                m_data.Amplitude =my_peak;
                m_data.Latency =  my_latency;
                if app.i_peaks_st >= 1
                    my_table = [my_table;struct2table(m_data)];
                else
                    my_table = struct2table(m_data);
                end
                app.UITable3.Data = my_table; 

                app.i_peaks_st = app.i_peaks_st + 1;

           end
           
           close(fig)


        end
        
        function app_setup(app,new_dat_switch)

            current_ylim_low = app.YminEditField.Value;
            current_ylim_high = app.YmaxEditField.Value;


            app.BaselineNormSwitch.Value = "On";
        
            app.extraction_state = 0;% Has data been extracted?
            app.Tree.CheckedNodes = [];% unselect everything
            app.SignalStartmsEditField.Value = app.stim_range(1);
            app.SignalEndmsEditField.Value = app.stim_range(2);
            a = app.Tree.Children;
            a.delete; % delete all nodes on tree to avoid overpopulating them 
            app.settings.baseline_norm = 1; % by default, normalize the data


            data_in_info = app.DataInEditField.Value;
            [filepath,name,ext] = fileparts(data_in_info);

            if new_dat_switch  == 1

                if app.settings.file_type == 0 % for .set file
                    EEG = pop_loadset('filename',[name, ext],'filepath',filepath); %loads the specified file into eeglab
                    assignin('base','EEG',EEG)
                    ALLEEG = EEG;
                    assignin('base','ALLEEG',ALLEEG);
                elseif app.settings.file_type == 1 % for .mat files
                    load([filepath filesep() name ext],"EEG");
                    assignin('base','EEG',EEG)
                    ALLEEG = EEG;
                    assignin('base','ALLEEG',ALLEEG);
                elseif app.settings.file_type == 2 % for txt files

                    [ALLEEG, EEG] = load_txt_single_channel(app,filepath,name,ext);

                    assignin('base','EEG',EEG)
                    assignin('base','ALLEEG',ALLEEG)

                elseif app.settings.file_type== 3 % for keypoint files

                    [ALLEEG, EEG] = load_fnct_keypont(app,[filepath filesep() name]);
                    EEG = ALLEEG(1);
                    assignin('base','EEG',EEG)
                    assignin('base','ALLEEG',ALLEEG)

                elseif app.settings.file_type == 4 % for keypoint files
                    [ALLEEG, EEG] = load_fnct_pathway(app,[filepath filesep() name]);
                    assignin('base','EEG',EEG)
                    assignin('base','ALLEEG',ALLEEG)

                end
            else
                EEG = evalin("base","EEG");
                if app.settings.file_type == 3
                    if app.current_data_id ~= string(EEG.setname)
                        ALLEEG = evalin('base','ALLEEG');
                        chan2plot = app.ChannelsListBox.Value;
                        % figure out which dermatome is being trying to be plotted
                        % and load the correct EEG file and the right chanloc index
                        % relative to that file
                        for i_EEG = 1:length(ALLEEG)
                            EEG = ALLEEG(i_EEG);
                            current_chanlocs = EEG.chanlocs;
                            for j = 1:length(current_chanlocs)
                                if string(current_chanlocs(j).labels) == string(chan2plot)
                                    EEG_idx = i_EEG;
                                    chan_idx = j;
                                    break
                                end
                            end
                        end
                        EEG = ALLEEG(EEG_idx);
                        current_channel.data_name = EEG.setname;
                        app.current_data_id = current_channel.data_name;
                    end 
                end
            end

            size_data = size(EEG.data);
            
           if length(size_data) == 2 % if it is only a single channel 
                n_trials = size_data(2);
                n_points = size_data(1);
                n_channels = 1;
                %n_chans = 1;
                %sensoridx = 1;
   
            elseif length(size_data) == 3
                n_trials = size_data(3);
                n_points = size_data(2);
                n_channels = size_data(1);
                %sensoridx = find(strcmpi(chan2plot,{chanlocs.labels}));
           end
           
            app.SamplingRateEditField.Value = EEG.srate;
            app.EpochsEditField.Value = n_trials;
            app.FramesPerEpochEditField.Value = n_points;
            app.NChannelsEditField.Value = n_channels;
            app.current_data_id = string(EEG.setname);
            n_trials2plot =  app.nrowsEditField.Value * app.ncolumnsEditField.Value;
            %app.baselinelowerendEditField.Value = app.TimeLowmsEditField.Value;
            %app.baselinehigherendEditField.Value = 0;

            app.PageSlider.Limits = [1 ceil(n_trials/n_trials2plot)];
            

            if app.settings.file_type == 3
                %ALLEEG = evalin("base","ALLEEG");
                chanloc_count = 1;
                curr_EEG = EEG;
                for i  = 1:length(ALLEEG)
                    EEG = ALLEEG(i);
                    for j = 1:length(EEG.chanlocs)
                        my_chanlocs(chanloc_count) = cellstr(EEG.chanlocs(j).labels);
                        chanloc_count = chanloc_count+1;
                    end
                end
                app.ChannelsListBox.Items =my_chanlocs;
                EEG = curr_EEG; % index the first eeg structure again
            else
                for i  = 1:length(EEG.chanlocs)
                    my_chanlocs(i) = cellstr(EEG.chanlocs(i).labels); 
                end
                app.ChannelsListBox.Items =my_chanlocs;

            end

            % create nodes for all the data
            p = uitreenode(app.Tree,"Text","All Trials");
            for i = 1:n_trials
                c1 = uitreenode(p,"Text",string(i));
            end

            % expand the nodes
            expand(p);

            % select all nodes by default
           app.Tree.CheckedNodes = app.Tree.Children;          

            %app.TrialOverlayListBox.Items = string(1: n_trials);
            app.ChannelsListBox_2.Items =my_chanlocs;
            app.EpochsListBox_2.Items = string(1: n_trials);
            app.EpochsListBox_2.Value = app.EpochsListBox_2.Items;

            app.TrialsDisplayedListBox.Items =string(1:n_trials);
            app.TrialsDisplayedListBox.Value = app.TrialsDisplayedListBox.Items;


            %EEG = ALLEEG(1); % index the first eeg structure again
            chan2plot = string(app.ChannelsListBox.Value);
            for i = 1:length(EEG.chanlocs)
                if chan2plot == string(EEG.chanlocs(i).labels)
                    chan_idx = i;
                end
            end
            [~,low_index] = (min(abs(EEG.times - app.TimeLowmsEditField.Value)));
            [~,high_index] = (min(abs(EEG.times - app.TimeHighmsEditField.Value)));
            
            avg2plot = squeeze(mean(EEG.data(chan_idx,low_index:high_index,:),3,"omitnan"));
            
            my_lim = max(abs(avg2plot)) + max(abs(avg2plot))*0.10 ;
            app.YmaxEditField.Value = double(my_lim);
            app.YminEditField.Value = double(-my_lim);
            

            if app.typeofstim.Value == "CHEPs"
                app.stim_range = app.cheps_range;
            elseif app.typeofstim.Value == "CEPs"
                app.stim_range = app.ceps_range;
            elseif app.typeofstim.Value == "LEPs"
                app.stim_range = app.leps_range;
            elseif app.typeofstim.Value == "PEPs"
                app.stim_range = app.peps_range;
            end

            if app.AutoSwitch.Value == "Off"
                app.YminEditField.Value = current_ylim_low;
                app.YmaxEditField.Value = current_ylim_high;
            end


        end
        
        function adjust_yaxis(app)
            EEG = evalin('base','EEG');
            chan2plot = string(app.ChannelsListBox.Value);


            % check if its keypoint
            if app.settings.file_type == 3 
                ALLEEG = evalin('base','ALLEEG');
                % figure out which dermatome is being trying to be plotted
                % and load the correct EEG file and the right chanloc index
                % relative to that file
                for i_EEG = 1:length(ALLEEG)
                    EEG = ALLEEG(i_EEG);
                    current_chanlocs = EEG.chanlocs;
                    for j = 1:length(current_chanlocs)
                        if string(current_chanlocs(j).labels) == string(chan2plot)
                            EEG_idx = i_EEG;
                            chan_idx = j;
                        end
                    end
                end
                EEG = ALLEEG(EEG_idx);
            else
                for i = 1:length(EEG.chanlocs)
                    if chan2plot == string(EEG.chanlocs(i).labels)
                        chan_idx = i;
                    end
                end
            end

     
            [~,low_index] = (min(abs(EEG.times - app.TimeLowmsEditField.Value)));
            [~,high_index] = (min(abs(EEG.times - app.TimeHighmsEditField.Value)));
            
            avg2plot = squeeze(mean(EEG.data(chan_idx,low_index:high_index,:),3,"omitnan"));
            
            my_lim = max(abs(avg2plot)) + max(abs(avg2plot))*0.10 ;
            app.YmaxEditField.Value = double(my_lim);
            app.YminEditField.Value = double(-my_lim);

        end
        
        function [EEG, erp, filt_recon,filt_resampled_ind_data,resampled_time] = warm_filter(app,EEG)

            
            [~ , frequencies,x,convol_pwr_ind,individual_convol_result,total_convol_result] = calc_tf_data(app);

            % resample everything and put into same time scale
            time_window = [app.timelowerEditField.Value app.timehigherEditField.Value]; % time window that you want to analyze 
            [~,i_low] = (min(abs(x - time_window(1))));
            [~,i_high] = (min(abs(x - time_window(2))));
            %x1 = x(i_low:i_high); % cut and resampled time 


            % convol power individual 
            [p,q] = rat(floor(app.SamplingRateEditField.Value/10)/app.SamplingRateEditField.Value);
            resampled_pwr_ind = resample(double(convol_pwr_ind),p,q,'Dimension',2);
            resampled_time = linspace(min(x),max(x),size(resampled_pwr_ind,2));

            % find chan IDX
            chan2plot = string(app.ChannelsListBox_2.Value);
            for i = 1:length(EEG.chanlocs)
                if chan2plot == string(EEG.chanlocs(i).labels)
                    chan_idx = i;
                end
            end

            % calculate ERP
            erp = squeeze(mean(EEG.data(chan_idx,:,:),3,"omitnan"));  
            [~,i_low_erp] = (min(abs(EEG.times - time_window(1))));
            [~,i_high_erp] = (min(abs(EEG.times - time_window(2))));
            erp = erp(i_low_erp:i_high_erp-1);
            erp = resample(double(erp),p,q,'Dimension',1);

            % some of the input parameters for the function
            input_mat = permute(resampled_pwr_ind,[3 1 2]);% rearange input matrix containing all the trials
            n_permutes = app.npermutationsEditField_2.Value; % number of permutations
            v_time = resampled_time; % vector of time
            v_freq = frequencies; % vector of frequencies in 
            plotting_input = 0; % make 1 if you want to plot the responses or zero if you don't.
            baseline_range = [app.baseline_start.Value app.baseline_end.Value];
        
            my_title = ['testing function']; % required for the npp_stb_2 function
            
            % plotting the significant responses
            cla(app.UIAxes2_6) ; % dont know if i need this? clearing signficant response

            % check if the zmaps have been done
            if ~isfield(EEG,'zmaps')
                 [zmap, zmapthresh, zmapthresh_plc, zmapthresh_clc, tfv_time, v_freq] = npp_stb_2(app,input_mat...
                    ,n_permutes,v_time,v_freq,time_window,baseline_range,plotting_input,my_title);
            
                EEG.zmaps.zmapthresh_clc = zmapthresh_clc;
                EEG.zmaps.times = tfv_time;
                EEG.zmaps.frequencies = v_freq;
                EEG.zmaps.zmap = zmap;
                EEG.zmaps.zmapthresh = zmapthresh;
                EEG.zmaps.zmapthresh_plc = zmapthresh_plc;

                assignin('base','EEG',EEG)  
            else
                zmapthresh_clc = EEG.zmaps.zmapthresh_clc; % read clc zmaps from eeg structure
            end


            % transform the average ERP 
            % first resample
            resampled_convol_mean = resample(double(total_convol_result),p,q,'Dimension',2);

            freq_lims= [app.minfreqEditField.Value app.maxfreqEditField.Value];
            % do i need the next line?
            [~,~,resamp_scalingf] = cwt(erp,'amor',floor(EEG.srate/10),FrequencyLimits = freq_lims); % do it on a single trial to get the scaling factors 

            % apply the cwt filter based on the zmap
            % convert mask into logical
            logical_clc_zmap = zmapthresh_clc ~= 0;
            % multiply convol matrix in tf domain to filter out non
            % significant regions
            filtered_mean_convol = resampled_convol_mean .* double(logical_clc_zmap);
            % reconstruct filtered convol signal in the time domain
            filt_recon= icwt(filtered_mean_convol,"amor");

            % repeat the same thing with the individual trials
           resampled_ind_convol = resample(double(individual_convol_result),p,q,'Dimension',2);
           filt_resampled_ind_data = zeros(length(filtered_mean_convol),size(resampled_ind_convol,3));
           resampled_ind_data = zeros(length(filtered_mean_convol),size(resampled_ind_convol,3));

                      % loop through each trial to reconstruct filtered time signal
           for i = 1:size(resampled_ind_convol,3)
               if current_data.trials_mask(i)
                   c_dat = squeeze(resampled_ind_convol(:,:,i));
                   filtered_convol = c_dat .* double(logical_clc_zmap);
                   recon_ind = icwt(c_dat,"amor");
                   filt_recon_ind= icwt(filtered_convol,"amor");
                   filt_resampled_ind_data(:,i) =filt_recon_ind';
                   resampled_ind_data(:,i) =recon_ind';
               else
                   resampled_ind_data(:,i) = nan;
               end

           end


           %% save in EEG structure
           EEG.WARM_filter.single_trial_waveforms = filt_resampled_ind_data;
           EEG.WARM_filter.mean_waveform = filt_recon;
           EEG.WARM_filter.time = resampled_time;
            
        end
        
        function [ALLEEG,EEG] = load_fnct_keypont(app,file_path)
            header_info = readtable([file_path, filesep(),'Header.txt']);
            load('chan_info.mat',"chanlocs");

            % check sampling frequency
            if std(header_info.SmplFrq) == 0
                sr = header_info.SmplFrq(1);
            else
            end

            % check length of files
            if std(header_info.Samples) == 0
                l = header_info.Samples(1);
            else
            end

            % check trigger offset
            if std(header_info.TrigOff) == 0
                t_off = header_info.TrigOff(1);
            else
            end

             
            % create time vector
            x = linspace(0,(1000/sr)*l,l)-t_off/sr*1000; % in ms

            % load and organize all the text files into EEG structure
            %%% get list of dermatomes

            files = header_info.File;

            % try reading the types of headers 
            try
                % header split differently
                temp = split(string(files),"__");
                info_split = split(temp(:,1),"-");
                chans_in = unique(info_split(:,3)); %chanel names
                derms_in = unique(info_split(:,4)); %derms tested'
            catch
                % header split differently 
                temp = split(string(files),"__");
                info_split = split(temp(:,1),"-");
                chans_in = unique(info_split(:,3)); %chanel names
                derms_in = unique(info_split(:,1)); %derms tested'

            end


            % create an EEGLAB structure per dermatome
            for i= 1:length(derms_in) % iterate for all dermatomes 
                c_derm = derms_in(i);
                try
                    tf_mask = contains(info_split(:,4),c_derm);
                catch
                    tf_mask = contains(info_split(:,1),c_derm);
                end

                c_files = files(tf_mask);
                % get only the first bit of the file format to check for
                % names

                data_in = zeros(length(chans_in),l,ceil(length(c_files)/length(chans_in))); % make a strucutre to store the data
    
                for j = 1:length(chans_in) % iterate for all channels available per dermatome
                    c_chan = chans_in(j);
                    temp_1 = split(string(c_files),"__");
                    info_split_1 = split(temp_1(:,1),"-");
                    tf_mask_chan = contains(info_split_1(:,3),c_chan);
                    chan_files = c_files(tf_mask_chan);% get list of channels to load
                    all_chans = blanks(length(chanlocs));

                    % get channel names 
                    for k =1:length(chanlocs)
                        if k == 1
                            all_chans = string(chanlocs(k).labels);
                        else
                            all_chans = [all_chans,string(chanlocs(k).labels)];
                        end

                    end

                     % getting the channels and adding dermatome info to them
                     if c_chan == "N1"
                         chan2id = 'C3';% choose either c3 or c4. 
                         chan_info = chanlocs(all_chans == chan2id);
                         chan_info.labels = ['N1-',char(c_derm)];
                    
                     elseif c_chan == "EOG"
                         chan2id = 'Fp1'; % choose something that would normally be chosen for blink correctio
                         chan_info = chanlocs(all_chans == chan2id);
                         chan_info.labels = ['EOG-',char(c_derm)];
                     else
                         try
                             chan_info = chanlocs(all_chans == c_chan);
                             chan_info.labels = [char(c_chan),'-',char(c_derm)];
                         catch
                             chan_info = chanlocs(32); % for the EOG
                             chan_info.labels = [char(c_chan),'-',char(c_derm)];
                         end
                     end
                     

                    for k = 1:length(chan_files) % iterate through all files to load
                        data_in(j,:,k) = load([file_path,filesep(),char(chan_files(k))]);
                    end

                    if j == 1
                        chanlocs_out =chan_info;

                    else
                        chanlocs_out =[chanlocs_out;chan_info];

                    end


                end


                % getting the channels and adding dermatome info to them


                % creating the eeg structure
                EEG.setname = ['Dermatome-', char(c_derm)];
                EEG.filename = [];
                EEG.fielpath = file_path;
                EEG.nbchan = length(chans_in);
                EEG.trials = length(chan_files);
                EEG.pnts = l;
                EEG.srate = sr;
                EEG.xmin = min(x)/1000;
                EEG.xmax= max(x)/1000;
                EEG.times = x;
                EEG.data = -data_in;
                EEG.icaact = [];
                EEG.icawinv = [];
                EEG.icasphere = [];
                EEG.icaweights = [];
                EEG.icachansind = [];
                EEG.chanlocs = chanlocs_out;
                EEG.ref = "unknown";
                
               ALLEEG(i) = EEG;
            end


        end
        
        function EEG_out = preprocc_eeg(app)

            EEG = evalin('base','EEG');
            c_dat = evalin('base','current_data');
            EEG_in = c_dat.data;
            x = EEG.times;

        
            % baseline correct
           baseline_range = [app.TimeLowmsEditField.Value 0];
           [~,low_idx] = (min(abs(x- baseline_range(1))));
           [~,high_idx] = (min(abs(x - baseline_range(2))));

           mean_baseline = mean(EEG_in(low_idx:high_idx,:),"omitnan");

           EEG_out = EEG_in - mean_baseline;


            if app.low_pass_toggle == 1

                EEG_out_temp = EEG_out;
                EEG_out(isnan(EEG_out)) = 0;

                % bandpass
                nyquist = EEG.srate/2;
                fc_low = 0.5;
                fc_high =30;
                [bp,ap]=butter(3,[fc_low,fc_high]/nyquist,'bandpass');
                EEG_out=filtfilt(bp,ap,EEG_out);

                EEG_out(EEG_out==0) = EEG_out_temp(EEG_out==0);
                c_dat.filtered = "On";

            else
                EEG_out = EEG_out;
            end

            c_dat.data = EEG_out;

            assignin('base','current_data',c_dat);


        end
        
        function [sd2,SNR_oddeven,SNR_michelle]= calc_params_peak_extract(app,EEG_dat,x)


            %baseline correct each trial
            % define baseline noise
            [~,low_base] = (min(abs(x - app.TimeLowmsEditField.Value)));
            [~,zero_base] = (min(abs(x - 0)));
            [~,high_base] = (min(abs(x - app.TimeHighmsEditField.Value)));
            
            % calculate baseline
            baseline_mean = mean(EEG_dat(low_base:zero_base,:),1,"omitnan");

            % cut the trials data to the window of interest and also baseline
            % correct
            chan_dat = EEG_dat(low_base:high_base,:)-baseline_mean;

             % extract erp
             erp = squeeze(mean(chan_dat,2,"omitnan"));


            % calculate 2sd from the baseline corrected data
            % new zero
            new_x = x(low_base:high_base);
            [~,new_zero] = (min(abs(new_x - 0)));

            sd2 = 2*std(erp(1:new_zero));

            % calculate SNR method 1 - way michelle calculates
            N = rms(erp(1:new_zero));
            S = rms(erp(new_zero+1:end));
            SNR_michelle = 20*log10(S/N);


            % calculate SNR method 2 - odd even method
            odd_trials = mean(chan_dat(:,1:2:size(chan_dat,2)),2,"omitnan");
            even_trials = mean(chan_dat(:,2:2:size(chan_dat,2)),2,"omitnan");
            noise_estimate = odd_trials-even_trials;

            N = rms(noise_estimate);
            S = rms(erp);
            SNR_oddeven = 10*log10(S/N);

            % SNR from window from 0 to 1000 ms for signal for noise from
            % -500 to 0, for the SNr calc use a 20*log10 instead


        end
        
        function populate_figures(app)

            [tf , frequencies] = calc_tf_data(app);


            %is_f_chan_empt = max(isnan(tf(:))); % check if  channel is empty
            %if is_f_chan_empt == 1
                %app.ChannelsListBox.Value = char(app.ChannelsListBox.Items(2));
                %app.ChannelsListBox_2.Value = char(app.ChannelsListBox_2.Items(2));
                %[tf , frequencies] = calc_tf_data(app);
            %end

           % if app.WARMFilterSwitch.Value == "On"
            %    compute_warm_signals(app)
            %end

            plot_tdat(app);
            plot_multi_trials(app);
            plot_trial_data(app);
            plot_tfdata(app);
        end
        

        function [EEG_dat,sd2,SNR] = prep_data(app)
            EEG = evalin('base','EEG');
            
          select_channel(app);
          preprocc_eeg(app);


            % extract parameters to plot the extaction protocol

            x = EEG.times;
            chan2plot = string(app.ChannelsListBox.Value);


            % check if its keypoint
            if app.settings.file_type == 3 
                ALLEEG = evalin('base','ALLEEG');
                % figure out which dermatome is being trying to be plotted
                % and load the correct EEG file and the right chanloc index
                % relative to that file
                for i_EEG = 1:length(ALLEEG)
                    EEG = ALLEEG(i_EEG);
                    current_chanlocs = EEG.chanlocs;
                    for j = 1:length(current_chanlocs)
                        if string(current_chanlocs(j).labels) == string(chan2plot)
                            EEG_idx = i_EEG;
                            chan_idx = j;
                            break
                        end
                    end
                end
                EEG = ALLEEG(EEG_idx);
            else
                for i = 1:length(EEG.chanlocs)
                    if chan2plot == string(EEG.chanlocs(i).labels)
                        chan_idx = i;
                    end
                end
            end

            EEG_dat = squeeze(EEG.data(chan_idx,:,:));


            % determine the trials to plot based on the tree box
            if ~isempty(app.Tree.CheckedNodes) % if the nodes are not empty
                if length(app.Tree.CheckedNodes) > 1 % if its not only a single trial selected
                    if app.Tree.CheckedNodes(1).Text =="All Trials" % if all trials are selected
                        trials2plot = 1:size(EEG_dat,2);
                    else % if more than 1 trial is selected
                        trials2plot = zeros(1,length(app.Tree.CheckedNodes));
                        for i = 1: length(app.Tree.CheckedNodes)
                            trials2plot(i) = str2double(app.Tree.CheckedNodes(i).Text);
                        end
                    end
                else % if its a single trial
                trials2plot = str2double(app.Tree.CheckedNodes.Text);
                end
                % subset only trials that needed
                %EEG_dat = squeeze(EEG_dat(:,trials2plot));
                trial_mask = ismember(1:size(EEG_dat,2), trials2plot);
                EEG_dat(:,~trial_mask) = 0;
                
                if app.low_pass_toggle == 1
                    EEG_dat = lowpass(EEG_dat,25,EEG.srate,'ImpulseResponse','iir'); %% can change to change the smoothing
                end


                [sd2,SNR,SNR_michelle] = calc_params_peak_extract(app,EEG_dat,x);


                avg2plot = squeeze(mean(EEG_dat,2,"omitnan"));

                % find y lims to plot better
                avg_min = min(avg2plot);
                avg_max = max(avg2plot);

                if app.AutoSwitch.Value == "Off"
                    app.YminEditField.Value = avg_min + avg_min*0.1;
                    app.YmaxEditField.Value = avg_max + avg_max*0.1;
                end


                % find pre baseline noise
                % define baseline noise
                [~,low_base] = (min(abs(x - app.TimeLowmsEditField.Value)));
                [~,zero_base] = (min(abs(x - 0)));


                cla(app.UIAxes) 
                
                rectangle(app.UIAxes,'Position', [...
                    app.TimeLowmsEditField.Value,...
                    -sd2,...
                    app.TimeHighmsEditField.Value - app.TimeLowmsEditField.Value,...
                    2*sd2], ...
                    'FaceColor', [.5, .5, .5, 0.5], ...
                    'EdgeColor', [1, 1, 1, 1]);

%                 if app.plot_single_trial ==1 && ~isempty(app.TrialOverlayListBox.Value) 
%                     trials2plot_disp = cellfun(@str2num,app.TrialOverlayListBox.Value);
%                     c_map = cool(length(trials2plot_disp));
%                     y = squeeze(EEG_dat(:,trials2plot_disp));
%                     new_min = min(min(y));
%                     new_max = max(max(y));
% 
%                     if new_max ~= new_min
%                         app.YminEditField.Value = new_min + new_min*0.1;
%                         app.YmaxEditField.Value = new_max + new_max*0.1;
%                     end
%    
%                 end


                hold(app.UIAxes,"on");
                rectangle(app.UIAxes,'Position', [...
                    app.TimeLowmsEditField.Value,...
                    -sd2,...
                    app.TimeHighmsEditField.Value - app.TimeLowmsEditField.Value,...
                    2*sd2], ...
                    'FaceColor', [.5, .5, .5, 0.2], ...
                    'EdgeColor', [1, 1, 1, 1]);                
                rectangle(app.UIAxes,'Position', [...
                    stim_range(1),...
                    app.YminEditField.Value,...
                    stim_range(2)- stim_range(1),...
                    app.YmaxEditField.Value-app.YminEditField.Value], ...
                    'FaceColor', [0.75,0.20,0.23, 0.8], ...
                    'EdgeColor', [1, 1, 1, 1], ...
                    'Curvature',[0.2, 0.2]);

%                 if app.plot_single_trial ==1 && ~isempty(app.TrialOverlayListBox.Value)
%                     if std(y) > 0 
%                         plot(app.UIAxes,x,y);
%                         colororder(app.UIAxes,c_map)
%                     end
%                 end

                plot(app.UIAxes,x,avg2plot,'LineWidth',2,"Color","w");
                %set(app.UIAxes, 'YDir','reverse')
                xlim(app.UIAxes, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])
                ylim(app.UIAxes, [app.YminEditField.Value app.YmaxEditField.Value])
                xline(app.UIAxes,0,'w--');
                yline(app.UIAxes,sd2)
                yline(app.UIAxes,-sd2)
                app.UIAxes.Color = 'k';

                % CHECK IF DATA HAS BEEN EXTRACTED FOR THIS CHANNEL AND
                % THIS FILTER TYPE
                my_table = app.UITable.Data;

                if ~ isempty(my_table)
                    points2plot = my_table(strcmp(string(app.ChannelsListBox.Value) ,my_table.Channel) ...
                        & strcmp(string(app.LowpassSwitch.Value) ,my_table.filter_on),:);

                    if ~isempty(points2plot)
                        for i = 1: size(points2plot,1)
                            name = points2plot.Name(i);
                            amp = points2plot.Amplitude(i);
                            lat = points2plot.Latency(i);
                            plot(app.UIAxes,lat,amp,'g*','LineWidth',5)
                            text(app.UIAxes,lat+lat*0.1,amp,name,'FontSize',20,'Color','g')
                        end

                    end
                end

                hold(app.UIAxes,'off')


            else % if the nodes are empty 
                trials2plot = [];
                sd2 = [];
                SNR = [];

                cla(app.UIAxes) 
                xlim(app.UIAxes, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])
                ylim(app.UIAxes, [app.YminEditField.Value app.YmaxEditField.Value])
                xline(app.UIAxes,0,'--');

            end
            
            
        end
        
        function trials2plot = select_channel(app,varargin)

            % only want 3 optional inputs at most
            numvarargs = length(varargin);
            if numvarargs > 1
                error('select_channel:TooManyInputs', ...
                    'requires at most 1 optional input');
            end

            % set defaults for optional input
            optargs = {false};

            % now put these defaults into the valuesToUse cell array,
            % and overwrite the ones specified in varargin.
            optargs(1:numvarargs) = varargin;

            % Place optional args in memorable variable names
            [recomp_warm] = optargs{:};


            EEG = evalin('base','EEG');
   

            % extract parameters to plot the extaction protocol

            x = EEG.times;
            chan2plot = string(app.ChannelsListBox.Value);


            % check if its keypoint
            if app.settings.file_type == 3 
                ALLEEG = evalin('base','ALLEEG');
                % figure out which dermatome is being trying to be plotted
                % and load the correct EEG file and the right chanloc index
                % relative to that file
                for i_EEG = 1:length(ALLEEG)
                    EEG = ALLEEG(i_EEG);
                    current_chanlocs = EEG.chanlocs;
                    for j = 1:length(current_chanlocs)
                        if string(current_chanlocs(j).labels) == string(chan2plot)
                            EEG_idx = i_EEG;
                            chan_idx = j;
                            break
                        end
                    end
                end
                EEG = ALLEEG(EEG_idx);
                
                current_channel.data_name = EEG.setname;

            else
                current_channel.data_name = EEG.setname;
                for i = 1:length(EEG.chanlocs)
                    if chan2plot == string(EEG.chanlocs(i).labels)
                        chan_idx = i;
                    end
                end
            end

            EEG_dat = squeeze(EEG.data(chan_idx,:,:));
            EEG.plotted_chan_idx = chan_idx;


            % determine the trials to plot based on the tree box
            if ~isempty(app.Tree.CheckedNodes) % if the nodes are not empty
                if length(app.Tree.CheckedNodes) > 1 % if its not only a single trial selected
                    if app.Tree.CheckedNodes(1).Text =="All Trials" % if all trials are selected
                        trials2plot = 1:size(EEG_dat,2);
                    else % if more than 1 trial is selected
                        trials2plot = zeros(1,length(app.Tree.CheckedNodes));
                        for i = 1: length(app.Tree.CheckedNodes)
                            trials2plot(i) = str2double(app.Tree.CheckedNodes(i).Text);
                        end
                    end
                else % if its a single trial
                    trials2plot = str2double(app.Tree.CheckedNodes.Text);
                end
                % subset only trials that needed
                %EEG_dat = squeeze(EEG_dat(:,trials2plot));
                trial_mask = ismember(1:size(EEG_dat,2), trials2plot);
                EEG_dat(:,~trial_mask) = NaN;
            else
                trial_mask = zeros(1,size(EEG_dat,2));
                EEG_dat = zeros(size(EEG_dat));
                trials2plot = [];
            end

            
            current_channel.data = EEG_dat;
            current_channel.time = x;
            current_channel.chan_name = chan2plot;
            current_channel.trials_plotted = trials2plot;
            current_channel.trials_mask = trial_mask;
            current_channel.filtered = app.LowpassSwitch.Value;
            EEG.good_trials = trial_mask;
            EEG.n_good_trials = sum(trial_mask);

            EEG.plotted_data = EEG_dat;
            EEG.plotted_time = x;
            EEG.plotted_chan_name = chan2plot;
            EEG.trials_plotted = trials2plot;
            EEG.trials_mask = trial_mask;
            EEG.filtered = app.LowpassSwitch.Value;
            EEG.good_trials = trial_mask;
            EEG.n_good_trials = sum(trial_mask);


            assignin('base','current_data',current_channel);
            assignin('base','EEG',EEG);


        end
        
        function compute_warm_signals(app)
            current_data = evalin('base','current_data');
            EEG = evalin('base','EEG');


            % load tf results
            frequencies = EEG.frequencies;
            x = EEG.tf_times;
            convol_pwr_ind = EEG.cwt_outputs.convol_pwr_ind;
            individual_convol_result = EEG.cwt_outputs.single_trial.wt;
            total_convol_results = EEG.cwt_outputs.total_convol_results;
            tf_in = EEG.tf;


            % resample everything and put into same time scale
            time_window = [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value]; % time window that you want to analyze 
            [~,i_low] = (min(abs(x - time_window(1))));
            [~,i_high] = (min(abs(x - time_window(2))));
         
            %x1 = x(i_low:i_high); % cut and resampled time 


            % convol power individual 
            [p,q] = rat(floor(app.SamplingRateEditField.Value/10)/app.SamplingRateEditField.Value);
            resampled_pwr_ind = resample(double(convol_pwr_ind),p,q,'Dimension',2);
            resampled_time = linspace(min(x),max(x),size(resampled_pwr_ind,2));
            resampled_tf = resample(tf_in,p,q,'Dimension',3);

            % calculate ERP
            erp =squeeze(mean(current_data.data,2,"omitnan"));

            [~,i_low_erp] = (min(abs(EEG.times - time_window(1))));
            [~,i_high_erp] = (min(abs(EEG.times - time_window(2))));
            erp = erp(i_low_erp:i_high_erp-1);
            erp = resample(double(erp),p,q,'Dimension',1);

            % some of the input parameters for the function
            input_mat = permute(resampled_pwr_ind,[3 1 2]);% rearange input matrix containing all the trials
            n_permutes = app.npermutationsEditField_2.Value; % number of permutations
            v_time = resampled_time; % vector of time
            v_freq = frequencies; % vector of frequencies in 
            plotting_input = 0; % make 1 if you want to plot the responses or zero if you don't.
            baseline_range = [app.baseline_start.Value app.baseline_end.Value];
        
            my_title = ['testing function']; % required for the npp_stb_2 function
            
            % plotting the significant responses

            % check if the zmaps haveapp_s been done
            if ~isfield(current_data,'zmaps')
                [zmap, zmapthresh, zmapthresh_plc, zmapthresh_clc, tfv_time, v_freq] = npp_stb_2(app,input_mat...
                    ,n_permutes,v_time,v_freq,time_window,baseline_range,plotting_input,my_title);

                current_data.zmaps.zmapthresh_clc = zmapthresh_clc;
                current_data.zmaps.times = tfv_time;
                current_data.zmaps.frequencies = v_freq;
                current_data.zmaps.zmap = zmap;
                current_data.zmaps.zmapthresh = zmapthresh;
                current_data.zmaps.zmapthresh_plc = zmapthresh_plc;
                current_data.zmaps.trials_used = current_data.trials_mask;
                current_data.zmaps.chan_used = current_data.chan_name;
                current_data.zmaps.tf_resampled = resampled_tf;
                current_data.zmaps.time_low = app.TimeLowmsEditField.Value;
                current_data.zmaps.time_high = app.TimeHighmsEditField.Value;

               
            elseif ~ max(current_data.trials_mask == current_data.zmaps.trials_used) &...
                    ~(current_data.chan_name == current_data.zmaps.chan_used) &...
                    ~(app.TimeLowmsEditField.Value == current_data.zmaps.time_low) & ...
                    ~(app.TimeHighmsEditField.Value == current_data.zmaps.time_high)

                [zmap, zmapthresh, zmapthresh_plc, zmapthresh_clc, tfv_time, v_freq] = npp_stb_2(app,input_mat...
                    ,n_permutes,v_time,v_freq,time_window,baseline_range,plotting_input,my_title);

                current_data.zmaps.zmapthresh_clc = zmapthresh_clc;
                current_data.zmaps.times = tfv_time;
                current_data.zmaps.frequencies = v_freq;
                current_data.zmaps.zmap = zmap;
                current_data.zmaps.zmapthresh = zmapthresh;
                current_data.zmaps.zmapthresh_plc = zmapthresh_plc;
                current_data.zmaps.trials_used = current_data.trials_mask;
                current_data.zmaps.chan_used = current_data.chan_name;
                current_data.zmaps.tf_resampled = resampled_tf;
                current_data.zmaps.time_low = app.TimeLowmsEditField.Value;
                current_data.zmaps.time_high = app.TimeHighmsEditField.Value;

            else
      
                zmapthresh_clc = current_data.zmaps.zmapthresh_clc; % read clc zmaps from eeg structure
            end


            % transform the average ERP 
            % first resample
            resampled_convol_mean = resample(double(total_convol_results),p,q,'Dimension',2);

            freq_lims= [app.minfreqEditField.Value app.maxfreqEditField.Value];
            % do i need the next line?

            % apply the cwt filter based on the zmap
            % convert mask into logical
            logical_clc_zmap = zmapthresh_clc ~= 0;
            % multiply convol matrix in tf domain to filter out non
            % significant regions
            filtered_mean_convol = resampled_convol_mean .* double(logical_clc_zmap);
            % reconstruct filtered convol signal in the time domain
            filt_recon= icwt(filtered_mean_convol,"amor");

            % repeat the same thing with the individual trials
           resampled_ind_convol = resample(double(individual_convol_result),p,q,'Dimension',2);
           filt_resampled_ind_data = zeros(length(filtered_mean_convol),size(resampled_ind_convol,3));
           resampled_ind_data = zeros(length(filtered_mean_convol),size(resampled_ind_convol,3));

           % loop through each trial to reconstruct filtered time signal
           for i = 1:size(resampled_ind_convol,3)
               if current_data.trials_mask(i)
                   c_dat = squeeze(resampled_ind_convol(:,:,i));
                   filtered_convol = c_dat .* double(logical_clc_zmap);
                   recon_ind = icwt(c_dat,"amor");
                   filt_recon_ind= icwt(filtered_convol,"amor");
                   filt_resampled_ind_data(:,i) =filt_recon_ind';
                   resampled_ind_data(:,i) =recon_ind';
               else
                   filt_resampled_ind_data(:,i) = nan;
                   resampled_ind_data(:,i) =nan';
               end

           end

                       
           %% save in EEG structure
           current_data.WARM_filter.single_trial_waveforms = filt_resampled_ind_data;
           current_data.WARM_filter.mean_waveform = filt_recon;
           current_data.WARM_filter.time = resampled_time;
           

           assignin('base','current_data',current_data);


        end
        
        function update_figs(app)

            plot_tdat(app);
            plot_trial_data(app);
            plot_tfdata(app);
            
        end
        
        function plot_multi_trials(app)


            EEGin = evalin("base","current_data"); % read in current data

            % check if we are doing the warm filter and if we have
            if app.WARMFilterSwitch.Value == "On"
                datain = EEGin.WARM_filter.single_trial_waveforms;
                time2plot = EEGin.WARM_filter.time;

            else
                datain = EEGin.data; % select data
                time2plot = EEGin.time;
            end


            selected_page = round(app.PageSlider.Value);
            y = app.nrowsEditField.Value; % define number of rows
            x = app.ncolumnsEditField.Value; % define number of cols


            n_trials = size(datain,2); % get number of trials
            n_trials_pp = x*y;
            n_pages = ceil(n_trials/n_trials_pp);

            if selected_page == 1 % logic to select correct trials to plot 
                trial_index = 1:n_trials_pp; 
            elseif selected_page < n_pages
                trial_index = n_trials_pp*(selected_page-1)+1 : n_trials_pp*(selected_page-1)+n_trials_pp;
            elseif selected_page == n_pages
                trial_index = n_trials_pp*(selected_page-1)+1 : n_trials;
            end

            data2plot = datain(:,trial_index);
            t = tiledlayout(app.Panel_8,y,x); % create tiled layout
            for i = 1:size(data2plot,2)
                ax1 = nexttile(t);
                plot(ax1,time2plot,squeeze(data2plot(:,i)),"Color","w");
                xline(ax1,0,'--',"Color","w");
                xlim(ax1, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])
                ax1.YColor = 'w';
                ax1.XColor = 'w';
                title(ax1,['Trial ' num2str(trial_index(i))], "Color",[.7 .7 .7]);
                set(ax1,'Color','k');
            end
            xlabel(t,'Time (ms)','Color',"w");
            ylabel(t,'Amplitude (mV)','Color',"w");
            t.TileSpacing = 'compact';
            t.Padding = 'compact';

        end
        
        function [ALLOUT, EEG_structout] = load_fnct_pathway(app,file_path)
            s = dir(file_path);
            sc = s(contains({s.name}, '.txt'));


            for i = 1:length(sc)
                c_dat = sc(i);
                [filepath,name,ext] = fileparts([c_dat.folder, filesep(),c_dat.name]);
                [ALLEEG,EEG] = load_txt_single_channel(app,filepath,name,ext);

                if i == 1
                    EEG_out = squeeze(zeros([length(sc),size(EEG.data)]));
                    EEG_out(i,:,:) = squeeze(EEG.data);
                    chanlocs = EEG.chanlocs;
                    nbchan =1;
                else
                    EEG_out(i,:,:) = squeeze(EEG.data);
                    chanlocs = [chanlocs,EEG.chanlocs];
                    nbchan =nbchan+1;
                end

            end

            EEG_structout = EEG;
            EEG_structout.data = EEG_out;
            EEG_structout.chanlocs = chanlocs;
            EEG_structout.nbchan = nbchan;
            ALLOUT = EEG_structout;

        end
        
        function [ALLEEG,EEG] = load_txt_single_channel(app,file_path,name,ext)
                %getting name of channel
                my_data = readtable([file_path filesep() name ext],'HeaderLines', 2);
                temp = char(my_data.Properties.VariableNames(1));
                C = strsplit(temp,'_');
                chan_name = char(C(1));
                if string(chan_name) == "N1"
                    chan_name = "C3";
                end
                % get user input for missing data
                prompt = {'Sampling Rate:',"Baseline duration (in ms)"};
                dlgtitle = 'Please input parameters';
                fieldsize = [1 45; 1 45];
                definput = {'2000','1000'};
                answer = inputdlg(prompt,dlgtitle,fieldsize,definput);

                srate = str2double(answer(1)); % need a way to define this
                baseline_low =-str2double(answer(2)); % need a way to define this. In ms.
                
                %my_data = table2array(readtable([file_path filesep() name ext],'HeaderLines', 3));

                % Amplipfy it so thats its in mV not volts
                my_data = table2array(my_data) *1000000;
                EEG = convert_txt_to_eeg_struct(app,my_data,chan_name,srate,baseline_low);
                ALLEEG = EEG;
            
        end
        
        function my_output = selectDatapoints_time(app)

            % Remove any pre-existing rectanlges (if any)
            delete(findall(app.UIAxes, 'Type',  'images.roi.Rectangle'))
            temp_roi = drawrectangle(app.UIAxes,'StripeColor','b','InteractionsAllowed','none');
  
            my_output = temp_roi.Position;
            
        end
        
        function my_table = extract_peaks_manually(app,EEG_dat_plotted,x,SNR,SNR_michelle,sd2)
            EEG = evalin('base','EEG');
            c_dat = evalin('base','current_data');
            setname = EEG.setname;

            erp = mean(EEG_dat_plotted,2,"omitnan");

            prompt = {'How many peaks do you want to extract?'};
            dlgtitle = 'number of peaks:';
            dims = [1 35];
            definput = {'1','hsv'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            n_peaks = str2double(cell2mat(answer));


            for i  = 1:n_peaks
                app.i_peaks = app.i_peaks +1;
                prompt = {'Enter name of peak'};
                dlgtitle = 'Name of peak';
                dims = [1 35];
                definput = {'name','hsv'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);


                delete(findall(app.UIAxes, 'Type',  'images.roi.Rectangle'))
                temp_roi = drawrectangle(app.UIAxes,'StripeColor','b','InteractionsAllowed','none');

                position = temp_roi.Position;
                x_min = position(1);
                x_max  = position(1) + position(3);

                [~,low_idx] = (min(abs(x - x_min)));
                [~,high_idx] = (min(abs(x - x_max)));

                data = erp(low_idx:high_idx); % cut the erp to the size of window
                cut_time = x(low_idx:high_idx); % cut the time as well


                % determining if its a negative or a positive peak
                [highest_point , highest_index]  = max(data);
                [lowest_point , lowest_index]  = min(data);

                pgn = abs(highest_point) > abs(lowest_point); % check to see if the highest positive point has a greater magnitude than the lowerst negative point. reurn zero if it is true, and return 1 if it is not true.

                if pgn == 0 % if my positive point is not greater than negative point
                    % check that the negative dip is not in the end
                    if and(lowest_index ~= 0,lowest_index ~= length(data))
                        pont = 0; % positiv or negative toggle (1 = positive peak, -0 = negative peak)
                    elseif and(highest_index ~= 0,highest_index ~= length(data)) % if it is in the end, check to see if the positive is in the end or not
                        pont  = 1;
                    else
                        warning("The region that you selected did not contain a peak - please try again ")
                    end
                elseif pgn == 1 % if my positive point is greater than my negative point
                    % check tha the positive dip is not at the end
                    if and(highest_index ~= 0,highest_index ~= length(data))
                        pont = 1;
                    elseif and(lowest_index ~= 0,lowest_index ~= length(data))
                        pont = 0;
                    else
                        warning("The region that you selected did not contain a peak - please try again ")
                    end

                end

                if pont == 1
                    my_peak = highest_point ;
                    my_latency = cut_time(highest_index);
                elseif pont == 0
                    my_peak = lowest_point ;
                    my_latency = cut_time(lowest_index);
                else
                    warning("Please re select the region")
                end

                my_table = app.UITable.Data;
                m_data.Channel = string(app.ChannelsListBox.Value);
                m_data.Name = string(answer);
                m_data.Amplitude =my_peak;
                m_data.Latency =  my_latency;
                m_data.filter_on = string(app.LowpassSwitch.Value);
                m_data.trials = strjoin(string(c_dat.trials_plotted),",");
                m_data.n_trials = length(c_dat.trials_plotted);
                m_data.Stim = app.typeofstim.Value;
                m_data.SNR_oddeve = SNR;
                m_data.SNR_ps = SNR_michelle;
                m_data.sd2 = sd2;
                m_data.ID = string(c_dat.data_name);

                my_table = [my_table; struct2table(m_data)];
                app.UITable.Data = my_table;

            end
        end

        
        function update_data_set_name(app,ALLEEG,chan2plot)
            % figure out which dermatome is being trying to be plotted
            % and load the correct EEG file and the right chanloc index
            % relative to that file
            for i_EEG = 1:length(ALLEEG)
                EEG = ALLEEG(i_EEG);
                current_chanlocs = EEG.chanlocs;
                for j = 1:length(current_chanlocs)
                    if string(current_chanlocs(j).labels) == string(chan2plot)
                        EEG_idx = i_EEG;
                        chan_idx = j;
                        break
                    end
                end
            end
            EEG = ALLEEG(EEG_idx);
            app.current_data_id =  EEG.setname; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.settings.file_type =1 ;
        end

        % Callback function: Button, UITable
        function ButtonPushed(app, event)
            if app.settings.file_type == 0
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                [file_in,path_in] = uigetfile('*.set');
                delete(f); %delete the dummy figure
                

            elseif app.settings.file_type == 1
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                [file_in,path_in] = uigetfile('*.mat');
                delete(f); %delete the dummy figure

            elseif app.settings.file_type == 2

                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                [file_in,path_in] = uigetfile('*.txt');
                delete(f); %delete the dummy figure

                
            elseif app.settings.file_type == 3
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                path_in = uigetdir();
                delete(f); %delete the dummy figure

            elseif app.settings.file_type == 4
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                path_in = uigetdir();
                delete(f); %delete the dummy figure

            end


            try
                if app.settings.file_type == 0 || app.settings.file_type == 1 || app.settings.file_type == 2
                    app.DataInEditField.Value = [path_in,file_in];
                elseif app.settings.file_type == 3 || app.settings.file_type == 4
                    app.DataInEditField.Value = path_in;
                end
            catch

            end


        end

        % Value changed function: DataInEditField
        function DataInEditFieldValueChanged(app, event)

        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)

            d_eeglab = uigetdir('C:\');
            app.EEGLabFolderEditField.Value = d_eeglab;
            addpath app.SavingFolderEditField.Value;

        end

        % Value changed function: EEGLabFolderEditField
        function EEGLabFolderEditFieldValueChanged(app, event)
            eeglab_path = app.EEGLabFolderEditField.Value;

        end

        % Callback function
        function Button_2Pushed(app, event)
            d_save = uigetdir('C:\');
            app.SavingFolderEditField.Value = d_save;
        end

        % Callback function
        function SavingFolderEditFieldValueChanged(app, event)
            addpath app.SavingFolderEditField.Value;

        end

        % Button pushed function: InitializeEEGLabButton
        function InitializeEEGLabButtonPushed(app, event)
            addpath(app.EEGLabFolderEditField.Value);
           eeglab;
        end

        % Callback function
        function ChannelDropDownOpening(app, event)
            
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)

            try 
               app.DataNotLoadedLamp.Enable = "off";
               app.DataNotLoadedLampLabel.Text= "Data Not Loaded";
               app_setup(app,1);
               select_channel(app);
               preprocc_eeg(app);
               populate_figures(app);
               app.DataNotLoadedLamp.Enable = "on";
               app.DataNotLoadedLampLabel.Text= [app.current_data_id, ' Loaded'];
            catch 
                app.DataNotLoadedLamp.Enable = "off";
                app.DataNotLoadedLampLabel.Text= "Data Not Loaded";
                fprintf(2,'The identifier was:\n%s',e.identifier);
                fprintf(2,'There was an error! The message was:\n%s',e.message);
            end

        end

        % Callback function
        function TrialOverlayListBoxValueChanged(app, event)

            plot_tdat(app);

        end

        % Value changed function: ChannelsListBox
        function ChannelsListBoxValueChanged(app, event)
            %select_channel(app);
            %preprocc_eeg(app);
            %populate_figures(app);
        end

        % Value changed function: ChannelsListBox_2
        function ChannelsListBox_2ValueChanged(app, event)
            [tf , frequencies] = calc_tf_data(app);
            % plot_tfdata(app,tf,frequencies);
        end

        % Value changed function: SpacingSwitch
        function SpacingSwitchValueChanged(app, event)
            [tf , frequencies] = calc_tf_data(app);
            % plot_tfdata(app,tf,frequencies)  
        end

        % Value changed function: maxfreqEditField
        function maxfreqEditFieldValueChanged(app, event)
           [tf , frequencies] = calc_tf_data(app);
            % plot_tfdata(app,tf,frequencies)  
            
        end

        % Value changed function: ConversionSwitch
        function ConversionSwitchValueChanged(app, event)
            [tf , frequencies] = calc_tf_data(app);
            % plot_tfdata(app,tf,frequencies);             
            
        end

        % Value changed function: minfreqEditField
        function minfreqEditFieldValueChanged(app, event)
            [tf , frequencies] = calc_tf_data(app);
            % plot_tfdata(app,tf,frequencies); 
            
        end

        % Value changed function: baselinehigherendEditField
        function baselinehigherendEditFieldValueChanged(app, event)
            [tf , frequencies] = calc_tf_data(app);
            % plot_tfdata(app,tf,frequencies); 
                
        end

        % Value changed function: baselinelowerendEditField
        function baselinelowerendEditFieldValueChanged(app, event)
            [tf , frequencies] = calc_tf_data(app);
            % plot_tfdata(app,tf,frequencies);  
            
        end

        % Value changed function: ofcycleslowerEditField
        function ofcycleslowerEditFieldValueChanged(app, event)
            [tf , frequencies] = calc_tf_data(app);
            % plot_tfdata(app,tf,frequencies);
            
        end

        % Value changed function: ofcycleshigherEditField
        function ofcycleshigherEditFieldValueChanged(app, event)
            value = app.ofcycleshigherEditField.Value;
            
        end

        % Value changed function: timelowerEditField
        function timelowerEditFieldValueChanged(app, event)
            [tf , frequencies] = calc_tf_data(app);
            % plot_tfdata(app,tf,frequencies) ; 
        end

        % Value changed function: timehigherEditField
        function timehigherEditFieldValueChanged(app, event)
            [tf , frequencies] = calc_tf_data(app);
            % plot_tfdata(app,tf,frequencies) ; 
            
        end

        % Value changed function: offrequencybinsEditField
        function offrequencybinsEditFieldValueChanged(app, event)
            [tf , frequencies] = calc_tf_data(app);
           % plot_tfdata(app,tf,frequencies) ; 
            
        end

        % Button pushed function: selectrectanglemanuallyButton
        function selectrectanglemanuallyButtonPushed(app, event)

            %app.SelectedtabEditField.Value  = app.TabGroup2.SelectedTab;
            c_tab = string(app.TabGroup2.SelectedTab.Title);
            p_tabs = ["Total","Phase locked","Non-phase locked","ITPC","ERP Power"];
            p_UI = ["UIAxes2","UIAxes2_5","UIAxes2_2","UIAxes2_4","UIAxes2_3"];
            for i = 1:length(p_tabs)
                if p_tabs(i) == c_tab
                    tab_idx = i;
                end
            end
            
            my_return = selectDatapoints(app,p_UI(tab_idx));
            app.TimeLowEditField.Value = my_return(1);
            app.TimeHighEditField.Value = my_return(1)+ my_return(3);
            app.FreqLowEditField.Value = my_return(2);
            app.FreqHighEditField.Value = my_return(2)+my_return(4);


            %app.test2EditField.Value =app.TabGroup2.SelectedTab;
            
       
        end

        % Callback function
        function SelectbottomrightpointButtonPushed(app, event)
           [x,y] = ginput(1);
           app.FreqHighEditField.Value =y;
           app.TimeHighEditField.Value = x;            
        end

        % Button pushed function: ExtractInfoButton
        function ExtractInfoButtonPushed(app, event)
            delete(findall(app.UIAxes2, 'Type',  'images.roi.Rectangle'))
            delete(findall(app.UIAxes2_5, 'Type',  'images.roi.Rectangle'))
            delete(findall(app.UIAxes2_2, 'Type',  'images.roi.Rectangle'))
            delete(findall(app.UIAxes2_4, 'Type',  'images.roi.Rectangle'))
            delete(findall(app.UIAxes2_3, 'Type',  'images.roi.Rectangle'))


            x_min = app.TimeLowEditField.Value;
            y_min = app.FreqLowEditField.Value;
            x_length = app.TimeHighEditField.Value - x_min;
            y_length = app.FreqHighEditField.Value - y_min;
            position_v = [x_min y_min x_length y_length];

            roi = drawrectangle((app.UIAxes2),'Position',position_v,'Color','r'); 
            roi = drawrectangle((app.UIAxes2_5),'Position',position_v,'Color','r'); 
            roi = drawrectangle((app.UIAxes2_2),'Position',position_v,'Color','r'); 
            roi = drawrectangle((app.UIAxes2_4),'Position',position_v,'Color','r'); 
            roi = drawrectangle((app.UIAxes2_3),'Position',position_v,'Color','r'); 


            [tf , frequencies , x] = calc_tf_data(app);
            [my_output,app.extraction_state] = extract_vals(app,tf,frequencies,x);

            poulate_extraction_vals(app,my_output);

        end

        % Callback function
        function TabGroup2SelectionChanged(app, event)
%             if app.extraction_state == 1
%                 [tf , frequencies , x] = calc_tf_data(app);
%                 [my_output,app.extraction_state] = extract_vals(app,tf,frequencies,x);
%                 poulate_extraction_vals(app,my_output);
%             end
            
        end

        % Callback function
        function ExportDatatoexcelButtonPushed(app, event)
            SID = app.IDEditField.Value;
            Age = str2double(app.AgeEditField.Value);
            Sex = app.SexEditField.Value;
            date = app.DateDatePicker.Value;
            
            t_range = [app.TimeLowEditField.Value, app.TimeHighEditField.Value];
            f_range = [app.FreqLowEditField.Value, app.FreqHighEditField.Value];
            mean_a = app.meanactivitydBEditField.Value;
            peak_act = app.peakactivitydBEditField.Value;
            peak_freq = app.peakfrequencyHzEditField.Value;
            peak_time = app.peaklatencymsEditField.Value;
            lowest_act = app.lowestactivitydBEditField.Value;time
            lowest_time = app.minfreqEditField.Value;
            lowest_freq= app.minfrequencyHzEditField.Value;
            notes = string(app.NotesTextArea.Value);
            
            table_time = app.UITable.Data;
            table_tf = app.UITable2.Data;
            
            C = {"Subject","Age","Sex","Date","Mean Activity (dB)","Peak Activity (dB)",...
                "Lowest Activity (dB)","Peak Frequency (Hz)","Lowest Frequency (Hz)",...
                "Peak Latency (ms)","Lowest Latency (ms)","Frequency Range Lowe End (Hz)",...
                "Frequency Range High End (Hz)","Time range Low End(ms)","Time range High End(ms)","Notes";...
                SID,Age,Sex,date,mean_a,peak_act,lowest_act,peak_freq,lowest_freq,peak_time,lowest_time,f_range(1),f_range(2),t_range(1),t_range(2),notes};
           
          
          % time frequency
          C = repmat({SID,Age,Sex,date,notes},size(table_tf,1),1);
          T = cell2table(C,...
                "VariableNames",["Subject","Age","Sex","Date","Notes"]);
          output_tf = [T,table_tf];
          
          % peaks
          C = repmat({SID,Age,Sex,date,notes},size(table_time,1),1);
          T = cell2table(C,...
                "VariableNames",["Subject","Age","Sex","Date","Notes"]);
          output_time = [T,table_time];


          filename = char(app.SavingNameEditField.Value);
          writecell(C,[(app.SavingFolderEditField.Value) filesep() filename '.xls']);
          writetable(output_time,[(app.SavingFolderEditField.Value) filesep() app.SavingNameEditField.Value '.xls'],'Sheet','Time Domain Features');
          writetable(output_tf,[(app.SavingFolderEditField.Value) filesep() app.SavingNameEditField.Value '.xls'],'Sheet','Time Frequency Domain Fatures');


        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.DropDown.Value;
            if string(app.DropDown.Value) == ".set"
                app.settings.file_type = 0;
            elseif string(app.DropDown.Value) == ".mat"
                app.settings.file_type = 1;
            elseif string(app.DropDown.Value) == ".txt"
                app.settings.file_type = 2;
            elseif string(app.DropDown.Value) == "KeyPoint"
                app.settings.file_type = 3;
            elseif string(app.DropDown.Value) == "Pathway"
                app.settings.file_type = 4;
            end

            
        end

        % Value changed function: TimeLowmsEditField
        function TimeLowmsEditFieldValueChanged(app, event)
            %app.baselinelowerendEditField.Value = app.TimeLowmsEditField.Value;
            
            % select_channel(app);
            % preprocc_eeg(app);
            % calc_tf_data(app);

            if app.WARMFilterSwitch.Value == "On"
                compute_warm_signals(app)
            end

            % plot_tdat(app);
            % plot_trial_data(app);
            % plot_tfdata(app);


        end

        % Value changed function: TimeHighmsEditField
        function TimeHighmsEditFieldValueChanged(app, event)
            %app.baselinehigherendEditField.Value = app.TimeHighmsEditField.Value;
            % select_channel(app);
            % preprocc_eeg(app);
            % calc_tf_data(app);

            if app.WARMFilterSwitch.Value == "On"
                compute_warm_signals(app)
            end

            % plot_tdat(app);
            % plot_trial_data(app);
            % plot_tfdata(app);

        end

        % Value changed function: YminEditField
        function YminEditFieldValueChanged(app, event)
            % plot_tdat(app);
            % plot_trial_data(app);
            % plot_tfdata(app);

        end

        % Value changed function: YmaxEditField
        function YmaxEditFieldValueChanged(app, event)
            % plot_tdat(app);
            % plot_trial_data(app);
            % plot_tfdata(app);

        end

        % Value changed function: LowpassSwitch
        function LowpassSwitchValueChanged(app, event)
            value = app.LowpassSwitch.Value;
            if value == "Off"
                app.low_pass_toggle = 0;
            elseif value == "On"
                app.low_pass_toggle = 1;
            end
            
            % preprocc_eeg(app);
            % populate_figures(app)
        end

        % Button pushed function: ExtractpeaksButton
        function ExtractpeaksButtonPushed(app, event)
            %clf(app.UIAxes)
            [EEG_dat,x,sd2,SNR,SNR_michelle] = plot_tdat(app);
            EEG_dat_plotted = EEG_dat;
            table_out = extract_peaks(app,EEG_dat_plotted,x,SNR,SNR_michelle,sd2);
            assignin("base","peaks_extracted",table_out)
            plot_tdat(app);

        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.UITable.Data = {};
            app.i_peaks = 0;

            plot_tdat(app);


        end

        % Button pushed function: IdentifysignificantresponsesButton
        function IdentifysignificantresponsesButtonPushed(app, event)
            EEG = evalin('base','EEG');
            [tf , frequencies,x,convol_pwr_ind,individual_convol_result,total_convol_result] = calc_tf_data(app);
            
             % resample time to reduce computing time
            [p,q] = rat(floor(app.SamplingRateEditField.Value/10)/app.SamplingRateEditField.Value);
            resampled_signals = resample(double(convol_pwr_ind),p,q,'Dimension',2);
            resampled_time = linspace(min(x),max(x),size(resampled_signals,2));


             % some of the input parameters for the function
            input_mat = permute(resampled_signals,[3 1 2]);% input matrix containing all the subjects
            n_permutes = app.npermutationsEditField.Value; % number of permutations
            v_time = resampled_time; % vector of time
            v_freq = frequencies; % vector of frequencies in 
            time_window = [app.timelowerEditField.Value app.timehigherEditField.Value]; % time window that you want to analyze 
            plotting_input = 0; % make 1 if you want to plot the responses or zero if you don't.
            baseline_range = [app.baseline_start.Value app.baseline_end.Value];
        
            my_title = ['testing function'];
            
            % plotting the significant responses
            cla(app.UIAxes2_6) ;
            [zmap, zmapthresh, zmapthresh_plc, zmapthresh_clc, tfv_time, v_freq] = npp_stb_2(app,input_mat...
                ,n_permutes,v_time,v_freq,time_window,baseline_range,plotting_input,my_title);

            EEG.zmaps.zmapthresh_clc = zmapthresh_clc;
            EEG.zmaps.times = tfv_time;
            EEG.zmaps.frequencies = v_freq;
            EEG.zmaps.zmap = zmap;
            EEG.zmaps.zmapthresh = zmapthresh;
            EEG.zmaps.zmapthresh_plc = zmapthresh_plc;
            assignin('base','EEG',EEG)  


            %% plotting the responses
            contourf(app.UIAxes2_6, tfv_time,v_freq,zmapthresh_clc,100,'linecolor','none')
            hold(app.UIAxes2_6,'on')
            %axis square
            set(app.UIAxes2_6,'clim',[-5 5],'xlim',[app.timelowerEditField.Value app.timehigherEditField.Value])
            %title('Cluster-corrected Z map')
            %xlabel('Time (ms)'), ylabel('Frequency (Hz)')
            c = colorbar(app.UIAxes2_6,"east");
            colormap(app.UIAxes2_6,"jet");
            c.Label.String = 'z-score';
            hold(app.UIAxes2,'off')
            
            % expand the regions to the other masks
           hold(app.UIAxes2,'on')
           [~,cont] =  contour(app.UIAxes2,tfv_time,v_freq,logical(zmapthresh_clc),1,'linecolor','k');
           cont.LineWidth = 2;
           hold(app.UIAxes2,'off')
           
           hold(app.UIAxes2_5,'on')
           [~,cont] =  contour(app.UIAxes2_5,tfv_time,v_freq,logical(zmapthresh_clc),1,'linecolor','k');
           cont.LineWidth = 2;
           hold(app.UIAxes2_5,'off')
           
           hold(app.UIAxes2_2,'on')
           [~,cont] =  contour(app.UIAxes2_2,tfv_time,v_freq,logical(zmapthresh_clc),1,'linecolor','k');
           cont.LineWidth = 2;
           hold(app.UIAxes2_2,'off')
            
           hold(app.UIAxes2_4,'on')
           [~,cont] =  contour(app.UIAxes2_4,tfv_time,v_freq,logical(zmapthresh_clc),1,'linecolor','k');
           cont.LineWidth = 2;
           hold(app.UIAxes2_4,'off')
           
           hold(app.UIAxes2_3,'on')
           [~,cont] =  contour(app.UIAxes2_3,tfv_time,v_freq,logical(zmapthresh_clc),1,'linecolor','k');
           cont.LineWidth = 2;
           hold(app.UIAxes2_3,'off')
           
            %% plot outlines in the rest of the responses
            

        end

        % Callback function
        function Export_settingsButtonPushed(app, event)
            compile_settings(app)
            export_settings(app)
        end

        % Button pushed function: wavelet_filterButton
        function wavelet_filterButtonPushed(app, event)

            % do the warm filter on the data
           EEG = evalin('base','EEG');
           [EEG, erp, filt_recon,filt_resampled_ind_data,resampled_time] = warm_filter(app,EEG);
           assignin('base','EEG',EEG);
           
           % plotting the filtered response on the app
           x = resampled_time;
           plot(app.UIAxes2_7,x,filt_recon);
           hold(app.UIAxes2_7,'on')
           plot(app.UIAxes2_7,x,erp);
           hold(app.UIAxes2_7,'off')
           legend(app.UIAxes2_7,'WARM filtered signal','original signal') 
           xlim(app.UIAxes2_7, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])
           %set(app.UIAxes2_7, 'YDir','reverse')
             
             
           load('Z:\30_Oscar_Ortiz\apps\dependencies\new_color_map_2.mat'); %change to adjustable path
           
           %plot indivudal responses
           max_sign = max(max(abs(filt_resampled_ind_data)));
           %imagesc(app.UIAxes7_2,x,1:size(resampled_ind_convol,3),filt_resampled_ind_data')
           imagesc(app.UIAxes7_2,x,1:size(filt_resampled_ind_data,2),filt_resampled_ind_data')
           colormap(app.UIAxes7_2,new_color_map_2)
           caxis(app.UIAxes7_2,[-max_sign*0.8 max_sign*0.8]);
           c = colorbar(app.UIAxes7_2,"east");
           c.Label.String = "Amplitude (mV)";
           hold(app.UIAxes7_2,'off')
           xlim(app.UIAxes7_2, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])
           %ylim(app.UIAxes7_2, [0 max(size(resampled_ind_convol,3))+1])
           ylim(app.UIAxes7_2, [.5 max(size(filt_resampled_ind_data,2))+.5])


        end

        % Button pushed function: ExtractSingletrialPeaksTBFButton
        function ExtractSingletrialPeaksTBFButtonPushed(app, event)
            %clf(app.UIAxes2_7)
            %plot_tdat(app);
            extract_single_trial_data(app)
            mark_peaks_in_fig(app)
        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
            app.UITable3.Data = {};
            app.i_peaks_st = 0;
        end

        % Callback function: Tree
        function treeselectionchange(app, event)
            
            
            %trials2plot = select_channel(app);
            %select_channel(app);
            %preprocc_eeg(app);
            %populate_figures(app);
            %[~,trials2plot,~,~]= plot_tdat(app);
            %app.TrialOverlayListBox.Items = string(trials2plot);


        end

        % Value changed function: typeofstim
        function typeofstimValueChanged(app, event)
                
            if app.typeofstim.Value == "CHEPs"
                app.stim_range = app.cheps_range;
            elseif app.typeofstim.Value == "CEPs"
                app.stim_range = app.ceps_range;
            elseif app.typeofstim.Value == "LEPs" 
                app.stim_range = app.leps_range;
            elseif app.typeofstim.Value == "PEPs"
                app.stim_range = app.peps_range;
            elseif app.typeofstim.Value == "IEEPs"
                app.stim_range = app.ieeps_range;
            end
            
            %populate_figures(app)
            app.SignalStartmsEditField.Value = app.stim_range(1);
            app.SignalEndmsEditField.Value = app.stim_range(2);

            % plot_tdat(app);
            % plot_trial_data(app);
            % plot_tfdata(app);
            
        end

        % Value changed function: SignalStartmsEditField
        function SignalStartmsEditFieldValueChanged(app, event)
            value = app.SignalStartmsEditField.Value;
            app.stim_range(1) = value;
            app.stim_range(2) = app.SignalEndmsEditField.Value;
            app.stim_range = app.stim_range;
            % populate_figures(app);

            
        end

        % Value changed function: SignalEndmsEditField
        function SignalEndmsEditFieldValueChanged(app, event)
            value = app.SignalStartmsEditField.Value;
            app.stim_range(1) = value;
            app.stim_range(2) = app.SignalEndmsEditField.Value;
            app.stim_range = app.stim_range;
            % select_channel(app);
            % preprocc_eeg(app);
            % populate_figures(app);


        end

        % Key press function: UITable
        function UITableKeyPress(app, event)
            key = event.Key;
            table = app.UITable.Data;
            selection_idx = app.UITable.Selection;
            if key == "delete" || key == "backspace" && ~isempty(selection_idx)
                table(selection_idx(1),:) = [];
                app.UITable.Data = table;
            end

            plot_tdat(app);

        end

        % Value changed function: tf_output_plot
        function tf_output_plotValueChanged(app, event)
            %populate_figures(app)
            plot_tfdata(app);
        end

        % Button pushed function: selectmanuallyButton
        function selectmanuallyButtonPushed(app, event)
            %app.SelectedtabEditField.Value  = app.TabGroup2.SelectedTab;
            
            my_return = selectDatapoints(app);


            app.TimeLowEditField_2.Value = my_return(1);
            app.TimeHighEditField_2.Value = my_return(1)+ my_return(3);
            app.FreqLowEditField_2.Value = my_return(2);
            app.FreqHighEditField_2.Value = my_return(2)+my_return(4);


        end

        % Button pushed function: ExtractParametersButton
        function ExtractParametersButtonPushed(app, event)
            

            x_min = app.TimeLowEditField_2.Value;
            y_min = app.FreqLowEditField_2.Value;
            x_length = app.TimeHighEditField_2.Value - x_min;
            y_length = app.FreqHighEditField_2.Value - y_min;
            position_v = [x_min y_min x_length y_length];

            current_data = evalin("base","current_data");

            if app.WARMFilterSwitch.Value == "On"

                tf_mask= current_data.zmaps.zmapthresh_clc;
                mask = ~tf_mask == 0;
                frequencies = current_data.zmaps.frequencies;
                x = current_data.zmaps.times;
                tf_notmasked = current_data.zmaps.tf_resampled;

                tf = zeros(size(tf_notmasked));
                for i = 1:size(tf,1)
                    tf(i,:,:) = squeeze(tf_notmasked(i,:,:)) .* mask;
                end

            else
                tf = current_data.tf;
                frequencies = current_data.frequencies;
                x = current_data.tf_times;
            end


             delete(findall(app.UIAxes9, 'Type',  'images.roi.Rectangle'))


            [my_output,app.extraction_state] = extract_vals(app,tf,frequencies,x);

            populate_extraction_vals(app,my_output);

        end

        % Button pushed function: ClearAllButton_2
        function ClearAllButton_2Pushed(app, event)
            app.UITable2_2.Data = {};
            app.n_roi = 0;
        end

        % Button down function: UITable2_2
        function UITable2_2ButtonDown(app, event)
            key = event.Key;
            table = app.UITable2_2.Data;
            selection_idx = app.UITable2_2.Selection;
            if key == "delete" || key == "backspace" && ~isempty(selection_idx)
                table(selection_idx(1),:) = [];
                app.UITable2_2.Data = table;
            end

        end

        % Key press function: UITable2_2
        function UITable2_2KeyPress(app, event)
            key = event.Key;
            table = app.UITable2_2.Data;
            selection_idx = app.UITable2_2.Selection;
            if key == "delete" || key == "backspace" && ~isempty(selection_idx)
                table(selection_idx(1),:) = [];
                app.UITable2_2.Data = table;
            end
        end

        % Value changed function: WARMFilterSwitch
        function WARMFilterSwitchValueChanged(app, event)


        end

        % Value changed function: EpochsListBox_2
        function EpochsListBox_2ValueChanged(app, event)
            value = app.EpochsListBox_2.Value;
            
        end

        % Value changed function: PageSlider
        function PageSliderValueChanged(app, event)
            plot_multi_trials(app);
            
        end

        % Button pushed function: ExtractManuallyButton
        function ExtractManuallyButtonPushed(app, event)
            [EEG_dat,x,sd2,SNR,SNR_michelle] = plot_tdat(app);
            table_out = extract_peaks_manually(app,EEG_dat,x,SNR,SNR_michelle,sd2);
            assignin("base","peaks_extracted",table_out)
            plot_tdat(app);


        end

        % Value changed function: BaselineNormSwitch
        function BaselineNormSwitchValueChanged(app, event)
            if app.BaselineNormSwitch.Value == "Off"
                app.settings.baseline_norm = 0;
            elseif app.BaselineNormSwitch.Value == "On"
                app.settings.baseline_norm = 1;
            end

        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)

            EEG = evalin('base','EEG');
            ALLEEG = evalin('base','ALLEEG');
            update_data_set_name(app,ALLEEG,string(app.ChannelsListBox.Value))

           
            % check if the channels habe been loaded
            % determine if a new dataset is loaded

            if ~isempty(app.current_data_id) % if its not empty
                if app.current_data_id ~= string(EEG.setname) % and if it doesnt match
                    app_setup(app,0);
                end
            else % if it is empty, then set it up
                app_setup(app,0);
            end
            
            select_channel(app);
            %preprocc_eeg(app);
            populate_figures(app);
        end

        % Value changed function: typeoferp
        function typeoferpValueChanged(app, event)
            value = app.typeoferp.Value;
            
        end

        % Value changed function: ButterflyPlotCheckBox
        function ButterflyPlotCheckBoxValueChanged(app, event)
            
        end

        % Value changed function: ColorMapDropDown
        function ColorMapDropDownValueChanged(app, event)
            if app.ButterflyPlotCheckBox.Value == 1
                plot_tdat(app);
            end
        end

        % Value changed function: TrialsDisplayedListBox
        function TrialsDisplayedListBoxValueChanged(app, event)
            if app.ButterflyPlotCheckBox.Value == 1
                plot_tdat(app);
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: baseline_start
        function baseline_startValueChanged(app, event)
            value = app.baseline_start.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetUpTab = uitab(app.TabGroup);
            app.SetUpTab.Title = 'Set Up';
            app.DataInEditFieldLabel = uilabel(app.SetUpTab);
            app.DataInEditFieldLabel.Text = 'Data In';
            app.DataInEditField = uieditfield(app.SetUpTab, 'text');
            app.DataInEditField.ValueChangedFcn = createCallbackFcn(app, @DataInEditFieldValueChanged, true);
            app.Button = uibutton(app.SetUpTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '...';
            app.LoadDataButton = uibutton(app.SetUpTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.NotesTextAreaLabel = uilabel(app.SetUpTab);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.SetUpTab);
            app.DropDown = uidropdown(app.SetUpTab);
            app.DropDown.Items = {'.set', '.mat', '.txt', 'KeyPoint', 'Pathway'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '.set';
            app.DataNotLoadedLampLabel = uilabel(app.SetUpTab);
            app.DataNotLoadedLampLabel.Text = 'Data Not Loaded';
            app.DataNotLoadedLamp = uilamp(app.SetUpTab);
            app.DataNotLoadedLamp.Enable = 'off';
            app.Panel = uipanel(app.SetUpTab);
            app.NChannelsEditFieldLabel = uilabel(app.Panel);
            app.NChannelsEditFieldLabel.Text = 'N Channels';
            app.NChannelsEditField = uieditfield(app.Panel, 'numeric');
            app.FramesPerEpochEditFieldLabel = uilabel(app.Panel);
            app.FramesPerEpochEditFieldLabel.Text = 'Frames Per Epoch';
            app.FramesPerEpochEditField = uieditfield(app.Panel, 'numeric');
            app.EpochsEditFieldLabel = uilabel(app.Panel);
            app.EpochsEditFieldLabel.Text = 'Epochs';
            app.EpochsEditField = uieditfield(app.Panel, 'numeric');
            app.SamplingRateEditFieldLabel = uilabel(app.Panel);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.SamplingRateEditField = uieditfield(app.Panel, 'numeric');
            app.Panel_2 = uipanel(app.SetUpTab);
            app.EEGLabFolderEditFieldLabel = uilabel(app.Panel_2);
            app.EEGLabFolderEditFieldLabel.Text = 'EEGLab Folder';
            app.EEGLabFolderEditField = uieditfield(app.Panel_2, 'text');
            app.EEGLabFolderEditField.ValueChangedFcn = createCallbackFcn(app, @EEGLabFolderEditFieldValueChanged, true);
            app.InitializeEEGLabButton = uibutton(app.SetUpTab, 'push');
            app.InitializeEEGLabButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeEEGLabButtonPushed, true);
            app.InitializeEEGLabButton.Text = 'Initialize EEGLab';
            app.Button_3 = uibutton(app.SetUpTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '...';
            app.Image3 = uiimage(app.SetUpTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Artboard 1@2x.png');
            app.Panel_3 = uipanel(app.SetUpTab);
            app.IDEditFieldLabel = uilabel(app.Panel_3);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.Panel_3, 'text');
            app.AgeEditFieldLabel = uilabel(app.Panel_3);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.Panel_3, 'text');
            app.SexEditFieldLabel = uilabel(app.Panel_3);
            app.SexEditFieldLabel.Text = 'Sex';
            app.SexEditField = uieditfield(app.Panel_3, 'text');
            app.DateDatePickerLabel = uilabel(app.Panel_3);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.Panel_3);
            app.DropDown_2 = uidropdown(app.SetUpTab);
            app.DropDown_2.Items = {'CHEPs', 'CEPs', 'LEPs', 'dSSEPs'};
            app.DropDown_2.Value = 'CHEPs';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.ChannelsListBoxLabel = uilabel(app.AnalysisTab);
            app.ChannelsListBoxLabel.Text = 'Channels';
            app.ChannelsListBox = uilistbox(app.AnalysisTab);
            app.ChannelsListBox.Items = {'None '};
            app.ChannelsListBox.ValueChangedFcn = createCallbackFcn(app, @ChannelsListBoxValueChanged, true);
            app.ChannelsListBox.Value = 'None ';
            app.LowpassSwitchLabel = uilabel(app.AnalysisTab);
            app.LowpassSwitchLabel.Text = 'Low pass';
            app.LowpassSwitch = uiswitch(app.AnalysisTab, 'slider');
            app.LowpassSwitch.ValueChangedFcn = createCallbackFcn(app, @LowpassSwitchValueChanged, true);
            app.TabGroup4 = uitabgroup(app.AnalysisTab);
            app.AverageTab = uitab(app.TabGroup4);
            app.AverageTab.Title = 'Average';
            app.UIAxes = uiaxes(app.AverageTab);
            xlabel(app.UIAxes, 'Time (ms)')
            ylabel(app.UIAxes, 'Amplitude (mV)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.ButterflyPlotCheckBox = uicheckbox(app.AverageTab);
            app.ButterflyPlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @ButterflyPlotCheckBoxValueChanged, true);
            app.ButterflyPlotCheckBox.Text = 'Butterfly Plot';
            app.TrialsDisplayedListBoxLabel = uilabel(app.AverageTab);
            app.TrialsDisplayedListBoxLabel.Text = 'Trials Displayed';
            app.TrialsDisplayedListBox = uilistbox(app.AverageTab);
            app.TrialsDisplayedListBox.Items = {'1', '2', '3', '4'};
            app.TrialsDisplayedListBox.Multiselect = 'on';
            app.TrialsDisplayedListBox.ValueChangedFcn = createCallbackFcn(app, @TrialsDisplayedListBoxValueChanged, true);
            app.TrialsDisplayedListBox.Value = {'1'};
            app.ColorMapDropDown = uidropdown(app.AverageTab);
            app.ColorMapDropDown.Items = {'red', 'green', 'blue', 'parula', 'turbo', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'jet', 'lines', 'colorcube', 'prism', 'flag'};
            app.ColorMapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorMapDropDownValueChanged, true);
            app.ColorMapDropDown.Placeholder = 'autum';
            app.ColorMapDropDown.Value = 'autumn';
            app.TrialTab = uitab(app.TabGroup4);
            app.TrialTab.Title = 'Trial';
            app.UIAxes7 = uiaxes(app.TrialTab);
            ylabel(app.UIAxes7, 'Trials')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.TrialTab);
            xlabel(app.UIAxes8, 'Time (ms)')
            ylabel(app.UIAxes8, 'Amplitdue (mV)')
            zlabel(app.UIAxes8, 'Z')
            app.MultitrialViewTab = uitab(app.TabGroup4);
            app.MultitrialViewTab.Title = 'Multi-trial View';
            app.PageSliderLabel = uilabel(app.MultitrialViewTab);
            app.PageSliderLabel.Text = 'Page';
            app.PageSlider = uislider(app.MultitrialViewTab);
            app.PageSlider.Limits = [1 3];
            app.PageSlider.MajorTicks = [1 2 3];
            app.PageSlider.ValueChangedFcn = createCallbackFcn(app, @PageSliderValueChanged, true);
            app.PageSlider.MinorTicks = [];
            app.PageSlider.Value = 1;
            app.Panel_8 = uipanel(app.MultitrialViewTab);
            app.TimeFrequencyTab_2 = uitab(app.TabGroup4);
            app.TimeFrequencyTab_2.Title = 'Time Frequency';
            app.UIAxes9 = uiaxes(app.TimeFrequencyTab_2);
            ylabel(app.UIAxes9, 'Frequency')
            zlabel(app.UIAxes9, 'Z')
            app.UIAxes10 = uiaxes(app.TimeFrequencyTab_2);
            xlabel(app.UIAxes10, 'Time')
            ylabel(app.UIAxes10, 'Amplitude (mV)')
            zlabel(app.UIAxes10, 'Z')
            app.Tree = uitree(app.AnalysisTab, 'checkbox');
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @treeselectionchange, true);
            app.Panel_4 = uipanel(app.AnalysisTab);
            app.TimeLowmsEditFieldLabel = uilabel(app.Panel_4);
            app.TimeLowmsEditFieldLabel.Text = 'Time Low (ms)';
            app.TimeLowmsEditField = uieditfield(app.Panel_4, 'numeric');
            app.TimeLowmsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeLowmsEditFieldValueChanged, true);
            app.TimeLowmsEditField.Value = -500;
            app.TimeHighmsEditFieldLabel = uilabel(app.Panel_4);
            app.TimeHighmsEditFieldLabel.Text = 'Time High (ms)';
            app.TimeHighmsEditField = uieditfield(app.Panel_4, 'numeric');
            app.TimeHighmsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeHighmsEditFieldValueChanged, true);
            app.TimeHighmsEditField.Value = 1500;
            app.YminLabel = uilabel(app.Panel_4);
            app.YminLabel.Text = 'Y min ';
            app.YminEditField = uieditfield(app.Panel_4, 'numeric');
            app.YminEditField.ValueChangedFcn = createCallbackFcn(app, @YminEditFieldValueChanged, true);
            app.YminEditField.Value = -20;
            app.YmaxEditFieldLabel = uilabel(app.Panel_4);
            app.YmaxEditFieldLabel.Text = 'Y max';
            app.YmaxEditField = uieditfield(app.Panel_4, 'numeric');
            app.YmaxEditField.ValueChangedFcn = createCallbackFcn(app, @YmaxEditFieldValueChanged, true);
            app.YmaxEditField.Value = -20;
            app.SignalStartmsEditFieldLabel = uilabel(app.Panel_4);
            app.SignalStartmsEditFieldLabel.Text = 'Signal Start (ms)';
            app.SignalStartmsEditField = uieditfield(app.Panel_4, 'numeric');
            app.SignalStartmsEditField.ValueChangedFcn = createCallbackFcn(app, @SignalStartmsEditFieldValueChanged, true);
            app.SignalEndmsEditFieldLabel = uilabel(app.Panel_4);
            app.SignalEndmsEditFieldLabel.Text = 'Signal End (ms)';
            app.SignalEndmsEditField = uieditfield(app.Panel_4, 'numeric');
            app.SignalEndmsEditField.ValueChangedFcn = createCallbackFcn(app, @SignalEndmsEditFieldValueChanged, true);
            app.SignalEndmsEditField.Value = 500;
            app.AutoSwitchLabel = uilabel(app.Panel_4);
            app.AutoSwitchLabel.Text = 'Auto';
            app.AutoSwitch = uiswitch(app.Panel_4, 'slider');
            app.AutoSwitch.Value = 'On';
            app.SignalStartmsEditFieldLabel_2 = uilabel(app.Panel_4);
            app.SignalStartmsEditFieldLabel_2.Text = 'Baseline Start (ms)';
            app.baseline_start = uieditfield(app.Panel_4, 'numeric');
            app.baseline_start.ValueChangedFcn = createCallbackFcn(app, @baseline_startValueChanged, true);
            app.baseline_start.Value = -500;
            app.EndmsEditFieldLabel = uilabel(app.Panel_4);
            app.EndmsEditFieldLabel.Text = 'End (ms)';
            app.baseline_end = uieditfield(app.Panel_4, 'numeric');
            app.TrialSelectionLabel = uilabel(app.AnalysisTab);
            app.TrialSelectionLabel.Text = 'Trial Selection';
            app.Panel_5 = uipanel(app.AnalysisTab);
            app.UITable = uitable(app.Panel_5);
            app.UITable.ColumnName = {'Channel'; 'Name'; 'Amplitude'; 'Latency'; 'Filter'; 'Trials'; 'Number of Trials'; 'Stim'; 'SNR_oddeve'; 'SNR_ps'; '2SD'; 'ID'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @ButtonPushed, true);
            app.UITable.KeyPressFcn = createCallbackFcn(app, @UITableKeyPress, true);
            app.ExtractpeaksButton = uibutton(app.Panel_5, 'push');
            app.ExtractpeaksButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractpeaksButtonPushed, true);
            app.ExtractpeaksButton.Text = 'Extract peaks';
            app.ClearAllButton = uibutton(app.Panel_5, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.ExtractManuallyButton = uibutton(app.Panel_5, 'push');
            app.ExtractManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractManuallyButtonPushed, true);
            app.ExtractManuallyButton.Text = 'Extract Manually';
            app.typeofstim = uilistbox(app.AnalysisTab);
            app.typeofstim.Items = {'CHEPs', 'CEPs', 'PEPs', 'LEPs', 'IEEPs'};
            app.typeofstim.ValueChangedFcn = createCallbackFcn(app, @typeofstimValueChanged, true);
            app.typeofstim.Value = 'CHEPs';
            app.ChannelsListBoxLabel_2 = uilabel(app.AnalysisTab);
            app.ChannelsListBoxLabel_2.Text = 'Type of stim';
            app.typeoferp = uilistbox(app.AnalysisTab);
            app.typeoferp.Items = {'N2/P2', 'N1'};
            app.typeoferp.ValueChangedFcn = createCallbackFcn(app, @typeoferpValueChanged, true);
            app.typeoferp.Value = 'N2/P2';
            app.ChannelsListBoxLabel_3 = uilabel(app.AnalysisTab);
            app.ChannelsListBoxLabel_3.Text = 'ERP ';
            app.tf_output_plot = uilistbox(app.AnalysisTab);
            app.tf_output_plot.Items = {'Total', 'Phase locked', 'Non-phase-locked', 'ITPC'};
            app.tf_output_plot.ValueChangedFcn = createCallbackFcn(app, @tf_output_plotValueChanged, true);
            app.tf_output_plot.Value = 'Total';
            app.ChannelsListBoxLabel_4 = uilabel(app.AnalysisTab);
            app.ChannelsListBoxLabel_4.Text = 'Time-frequency output';
            app.Panel_6 = uipanel(app.AnalysisTab);
            app.selectmanuallyButton = uibutton(app.Panel_6, 'push');
            app.selectmanuallyButton.ButtonPushedFcn = createCallbackFcn(app, @selectmanuallyButtonPushed, true);
            app.selectmanuallyButton.Text = 'select manually';
            app.TimeLowEditField_2Label = uilabel(app.AnalysisTab);
            app.TimeLowEditField_2Label.Text = 'Time Low';
            app.TimeLowEditField_2 = uieditfield(app.AnalysisTab, 'numeric');
            app.FreqLowEditField_2Label = uilabel(app.AnalysisTab);
            app.FreqLowEditField_2Label.Text = 'Freq Low';
            app.FreqLowEditField_2 = uieditfield(app.AnalysisTab, 'numeric');
            app.TimeHighEditField_2Label = uilabel(app.AnalysisTab);
            app.TimeHighEditField_2Label.Text = 'Time High';
            app.TimeHighEditField_2 = uieditfield(app.AnalysisTab, 'numeric');
            app.FreqHighEditField_2Label = uilabel(app.AnalysisTab);
            app.FreqHighEditField_2Label.Text = 'Freq High';
            app.FreqHighEditField_2 = uieditfield(app.AnalysisTab, 'numeric');
            app.Panel_7 = uipanel(app.AnalysisTab);
            app.UITable2_2 = uitable(app.Panel_7);
            app.UITable2_2.ColumnName = {'Channel'; 'Name'; 'Mean Activity'; 'Peak Activity'; 'Peak Frequency (Hz)'; 'Peak Latency (ms)'; 'Lowest Activity (ms)'; 'Min Frequency (Hz)'; 'Min Latency(ms)'; 'Metric'};
            app.UITable2_2.RowName = {};
            app.UITable2_2.ButtonDownFcn = createCallbackFcn(app, @UITable2_2ButtonDown, true);
            app.UITable2_2.KeyPressFcn = createCallbackFcn(app, @UITable2_2KeyPress, true);
            app.ExtractParametersButton = uibutton(app.Panel_7, 'push');
            app.ExtractParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractParametersButtonPushed, true);
            app.ExtractParametersButton.Text = 'Extract Parameters';
            app.ClearAllButton_2 = uibutton(app.Panel_7, 'push');
            app.ClearAllButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButton_2Pushed, true);
            app.ClearAllButton_2.Text = 'Clear All';
            app.WARMFilterSwitchLabel = uilabel(app.AnalysisTab);
            app.WARMFilterSwitchLabel.Text = 'WARM Filter';
            app.WARMFilterSwitch = uiswitch(app.AnalysisTab, 'slider');
            app.WARMFilterSwitch.ValueChangedFcn = createCallbackFcn(app, @WARMFilterSwitchValueChanged, true);
            app.BaselineNormSwitchLabel = uilabel(app.AnalysisTab);
            app.BaselineNormSwitchLabel.Text = 'Baseline  Norm';
            app.BaselineNormSwitch = uiswitch(app.AnalysisTab, 'slider');
            app.BaselineNormSwitch.ValueChangedFcn = createCallbackFcn(app, @BaselineNormSwitchValueChanged, true);
            app.UpdateButton = uibutton(app.AnalysisTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.TabGroup3 = uitabgroup(app.SettingsTab);
            app.TFsettingsTab = uitab(app.TabGroup3);
            app.TFsettingsTab.Title = 'TF settings';
            app.ChannelsListBox_2Label = uilabel(app.TFsettingsTab);
            app.ChannelsListBox_2Label.Text = 'Channels';
            app.ChannelsListBox_2 = uilistbox(app.TFsettingsTab);
            app.ChannelsListBox_2.Items = {'None '};
            app.ChannelsListBox_2.ValueChangedFcn = createCallbackFcn(app, @ChannelsListBox_2ValueChanged, true);
            app.ChannelsListBox_2.Value = 'None ';
            app.EpochsListBox_2Label = uilabel(app.TFsettingsTab);
            app.EpochsListBox_2Label.Text = 'Epochs';
            app.EpochsListBox_2 = uilistbox(app.TFsettingsTab);
            app.EpochsListBox_2.Items = {'None'};
            app.EpochsListBox_2.Multiselect = 'on';
            app.EpochsListBox_2.ValueChangedFcn = createCallbackFcn(app, @EpochsListBox_2ValueChanged, true);
            app.EpochsListBox_2.Value = {'None'};
            app.BadChannelsTBFEditField_2Label = uilabel(app.TFsettingsTab);
            app.BadChannelsTBFEditField_2Label.Text = 'Bad Channels (TBF)';
            app.BadEpochsEditField_2Label = uilabel(app.TFsettingsTab);
            app.BadEpochsEditField_2Label.Text = 'Bad Epochs';
            app.BadChannelsTBFEditField_2 = uieditfield(app.TFsettingsTab, 'text');
            app.BadEpochsEditField_2 = uieditfield(app.TFsettingsTab, 'numeric');
            app.minfreqEditFieldLabel = uilabel(app.TFsettingsTab);
            app.minfreqEditFieldLabel.Text = 'min freq';
            app.minfreqEditField = uieditfield(app.TFsettingsTab, 'numeric');
            app.minfreqEditField.ValueChangedFcn = createCallbackFcn(app, @minfreqEditFieldValueChanged, true);
            app.minfreqEditField.Value = 1;
            app.maxfreqEditFieldLabel = uilabel(app.TFsettingsTab);
            app.maxfreqEditFieldLabel.Text = 'max freq';
            app.maxfreqEditField = uieditfield(app.TFsettingsTab, 'numeric');
            app.maxfreqEditField.ValueChangedFcn = createCallbackFcn(app, @maxfreqEditFieldValueChanged, true);
            app.maxfreqEditField.Value = 25;
            app.baselinelowerendEditFieldLabel = uilabel(app.TFsettingsTab);
            app.baselinelowerendEditFieldLabel.Text = 'baseline lower end';
            app.baselinelowerendEditField = uieditfield(app.TFsettingsTab, 'numeric');
            app.baselinelowerendEditField.ValueChangedFcn = createCallbackFcn(app, @baselinelowerendEditFieldValueChanged, true);
            app.baselinelowerendEditField.Value = -1000;
            app.baselinehigherendEditFieldLabel = uilabel(app.TFsettingsTab);
            app.baselinehigherendEditFieldLabel.Text = 'baseline higher end';
            app.baselinehigherendEditField = uieditfield(app.TFsettingsTab, 'numeric');
            app.baselinehigherendEditField.ValueChangedFcn = createCallbackFcn(app, @baselinehigherendEditFieldValueChanged, true);
            app.ofcycleslowerEditFieldLabel = uilabel(app.TFsettingsTab);
            app.ofcycleslowerEditFieldLabel.Text = '# of cycles lower';
            app.ofcycleslowerEditField = uieditfield(app.TFsettingsTab, 'numeric');
            app.ofcycleslowerEditField.ValueChangedFcn = createCallbackFcn(app, @ofcycleslowerEditFieldValueChanged, true);
            app.ofcycleslowerEditField.Value = 3;
            app.ofcycleshigherEditFieldLabel = uilabel(app.TFsettingsTab);
            app.ofcycleshigherEditFieldLabel.Text = '# of cycles higher';
            app.ofcycleshigherEditField = uieditfield(app.TFsettingsTab, 'numeric');
            app.ofcycleshigherEditField.ValueChangedFcn = createCallbackFcn(app, @ofcycleshigherEditFieldValueChanged, true);
            app.ofcycleshigherEditField.Value = 10;
            app.timelowerEditFieldLabel = uilabel(app.TFsettingsTab);
            app.timelowerEditFieldLabel.Text = 'time lower';
            app.timelowerEditField = uieditfield(app.TFsettingsTab, 'numeric');
            app.timelowerEditField.ValueChangedFcn = createCallbackFcn(app, @timelowerEditFieldValueChanged, true);
            app.timelowerEditField.Value = -200;
            app.timehigherEditFieldLabel = uilabel(app.TFsettingsTab);
            app.timehigherEditFieldLabel.Text = 'time higher';
            app.timehigherEditField = uieditfield(app.TFsettingsTab, 'numeric');
            app.timehigherEditField.ValueChangedFcn = createCallbackFcn(app, @timehigherEditFieldValueChanged, true);
            app.timehigherEditField.Value = 1000;
            app.offrequencybinsEditFieldLabel = uilabel(app.TFsettingsTab);
            app.offrequencybinsEditFieldLabel.Text = '# of frequency bins';
            app.offrequencybinsEditField = uieditfield(app.TFsettingsTab, 'numeric');
            app.offrequencybinsEditField.ValueChangedFcn = createCallbackFcn(app, @offrequencybinsEditFieldValueChanged, true);
            app.offrequencybinsEditField.Value = 100;
            app.SpacingSwitch = uiswitch(app.TFsettingsTab, 'slider');
            app.SpacingSwitch.Items = {'Linear', 'Log'};
            app.SpacingSwitch.ValueChangedFcn = createCallbackFcn(app, @SpacingSwitchValueChanged, true);
            app.SpacingSwitch.Value = 'Linear';
            app.ConversionSwitch = uiswitch(app.TFsettingsTab, 'slider');
            app.ConversionSwitch.Items = {'dB', 'percent change'};
            app.ConversionSwitch.ValueChangedFcn = createCallbackFcn(app, @ConversionSwitchValueChanged, true);
            app.ConversionSwitch.Value = 'dB';
            app.SpacingSwitchLabel = uilabel(app.TFsettingsTab);
            app.SpacingSwitchLabel.Text = 'Spacing';
            app.ConversionSwitchLabel = uilabel(app.TFsettingsTab);
            app.ConversionSwitchLabel.Text = 'Conversion';
            app.DataSelectionTab = uitab(app.TabGroup3);
            app.DataSelectionTab.Title = 'Data Selection';
            app.selectrectanglemanuallyButton = uibutton(app.DataSelectionTab, 'push');
            app.selectrectanglemanuallyButton.ButtonPushedFcn = createCallbackFcn(app, @selectrectanglemanuallyButtonPushed, true);
            app.selectrectanglemanuallyButton.Text = 'select rectangle manually';
            app.TimeHighEditFieldLabel = uilabel(app.DataSelectionTab);
            app.TimeHighEditFieldLabel.Text = 'Time High';
            app.FreqHighEditFieldLabel = uilabel(app.DataSelectionTab);
            app.FreqHighEditFieldLabel.Text = 'Freq High';
            app.TimeLowEditFieldLabel = uilabel(app.DataSelectionTab);
            app.TimeLowEditFieldLabel.Text = 'Time Low';
            app.TimeLowEditField = uieditfield(app.DataSelectionTab, 'numeric');
            app.TimeHighEditField = uieditfield(app.DataSelectionTab, 'numeric');
            app.FreqHighEditField = uieditfield(app.DataSelectionTab, 'numeric');
            app.FreqLowEditFieldLabel = uilabel(app.DataSelectionTab);
            app.FreqLowEditFieldLabel.Text = 'Freq Low';
            app.FreqLowEditField = uieditfield(app.DataSelectionTab, 'numeric');
            app.ExtractInfoButton = uibutton(app.DataSelectionTab, 'push');
            app.ExtractInfoButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractInfoButtonPushed, true);
            app.ExtractInfoButton.Text = 'Extract Info';
            app.Image2 = uiimage(app.DataSelectionTab);
            app.Image2.ImageSource = 'logoo_light_square.jpg';
            app.UITable2 = uitable(app.DataSelectionTab);
            app.UITable2.ColumnName = {'Channel'; 'Name'; 'Mean Activity'; 'Peak Activity'; 'Peak Frequency (Hz)'; 'Peak Latency (ms)'; 'Lowest Activity (ms)'; 'Min Frequency (Hz)'; 'Min Latency(ms)'; 'Metric'};
            app.UITable2.RowName = {};
            app.MultitrialviewingTab = uitab(app.TabGroup3);
            app.MultitrialviewingTab.Title = 'Multi trial viewing ';
            app.nrowsEditFieldLabel = uilabel(app.MultitrialviewingTab);
            app.nrowsEditFieldLabel.Text = 'n rows';
            app.nrowsEditField = uieditfield(app.MultitrialviewingTab, 'numeric');
            app.nrowsEditField.Value = 5;
            app.ncolumnsEditFieldLabel = uilabel(app.MultitrialviewingTab);
            app.ncolumnsEditFieldLabel.Text = 'n columns';
            app.ncolumnsEditField = uieditfield(app.MultitrialviewingTab, 'numeric');
            app.ncolumnsEditField.Value = 2;
            app.StatisticsTab = uitab(app.TabGroup3);
            app.StatisticsTab.Title = 'Statistics';
            app.IdentifysignificantresponsesButton = uibutton(app.StatisticsTab, 'push');
            app.IdentifysignificantresponsesButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifysignificantresponsesButtonPushed, true);
            app.IdentifysignificantresponsesButton.Text = 'Identify significant responses';
            app.npermutationsEditFieldLabel = uilabel(app.StatisticsTab);
            app.npermutationsEditFieldLabel.Text = 'n permutations';
            app.npermutationsEditField = uieditfield(app.StatisticsTab, 'numeric');
            app.npermutationsEditField.Value = 1000;
            app.WaveletfilteringSettingsTab = uitab(app.TabGroup);
            app.WaveletfilteringSettingsTab.Title = 'Wavelet filtering Settings';
            app.UIAxes2_7 = uiaxes(app.WaveletfilteringSettingsTab);
            xlabel(app.UIAxes2_7, 'Time')
            ylabel(app.UIAxes2_7, 'Amplitude')
            zlabel(app.UIAxes2_7, 'Z')
            app.UIAxes7_2 = uiaxes(app.WaveletfilteringSettingsTab);
            ylabel(app.UIAxes7_2, 'Trials')
            zlabel(app.UIAxes7_2, 'Z')
            app.wavelet_filterButton = uibutton(app.WaveletfilteringSettingsTab, 'push');
            app.wavelet_filterButton.ButtonPushedFcn = createCallbackFcn(app, @wavelet_filterButtonPushed, true);
            app.wavelet_filterButton.Text = 'wavelet_filter';
            app.npermutationsEditField_2Label = uilabel(app.WaveletfilteringSettingsTab);
            app.npermutationsEditField_2Label.Text = 'n permutations';
            app.npermutationsEditField_2 = uieditfield(app.WaveletfilteringSettingsTab, 'numeric');
            app.npermutationsEditField_2.Value = 1000;
            app.ExtractSingletrialPeaksTBFButton = uibutton(app.WaveletfilteringSettingsTab, 'push');
            app.ExtractSingletrialPeaksTBFButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractSingletrialPeaksTBFButtonPushed, true);
            app.ExtractSingletrialPeaksTBFButton.Text = 'Extract Single trial Peaks (TBF)';
            app.UITable3 = uitable(app.WaveletfilteringSettingsTab);
            app.UITable3.ColumnName = {'Trial'; 'Name'; 'Amplitude'; 'Latency'; 'Channel'};
            app.UITable3.RowName = {};
            app.numberofpeakstoextractEditField_2Label = uilabel(app.WaveletfilteringSettingsTab);
            app.numberofpeakstoextractEditField_2Label.Text = 'number of peaks to extract';
            app.numberofpeakstoextractEditField_2 = uieditfield(app.WaveletfilteringSettingsTab, 'numeric');
            app.numberofpeakstoextractEditField_2.Value = 2;
            app.ClearButton_2 = uibutton(app.WaveletfilteringSettingsTab, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Text = 'Clear';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = brainviz_app_V2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end