classdef brainviz_app_V2_v1_15 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SetUpTab                        matlab.ui.container.Tab
        DropDown_2                      matlab.ui.control.DropDown
        Panel_3                         matlab.ui.container.Panel
        DateDatePicker                  matlab.ui.control.DatePicker
        DateDatePickerLabel             matlab.ui.control.Label
        SexEditField                    matlab.ui.control.EditField
        SexEditFieldLabel               matlab.ui.control.Label
        AgeEditField                    matlab.ui.control.EditField
        AgeEditFieldLabel               matlab.ui.control.Label
        IDEditField                     matlab.ui.control.EditField
        IDEditFieldLabel                matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Button_3                        matlab.ui.control.Button
        InitializeEEGLabButton          matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        EEGLabFolderEditField           matlab.ui.control.EditField
        EEGLabFolderEditFieldLabel      matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        SamplingRateEditField           matlab.ui.control.NumericEditField
        SamplingRateEditFieldLabel      matlab.ui.control.Label
        EpochsEditField                 matlab.ui.control.NumericEditField
        EpochsEditFieldLabel            matlab.ui.control.Label
        FramesPerEpochEditField         matlab.ui.control.NumericEditField
        FramesPerEpochEditFieldLabel    matlab.ui.control.Label
        NChannelsEditField              matlab.ui.control.NumericEditField
        NChannelsEditFieldLabel         matlab.ui.control.Label
        DataNotLoadedLamp               matlab.ui.control.Lamp
        DataNotLoadedLampLabel          matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        NotesTextArea                   matlab.ui.control.TextArea
        NotesTextAreaLabel              matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        DataInEditField                 matlab.ui.control.EditField
        DataInEditFieldLabel            matlab.ui.control.Label
        AnalysisTab                     matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        PlottingSettingsTab             matlab.ui.container.Tab
        Panel_4                         matlab.ui.container.Panel
        baseline_end                    matlab.ui.control.NumericEditField
        EndmsEditFieldLabel             matlab.ui.control.Label
        baseline_start                  matlab.ui.control.NumericEditField
        SignalStartmsEditFieldLabel_2   matlab.ui.control.Label
        AutoSwitch                      matlab.ui.control.Switch
        AutoSwitchLabel                 matlab.ui.control.Label
        SignalEndmsEditField            matlab.ui.control.NumericEditField
        SignalEndmsEditFieldLabel       matlab.ui.control.Label
        SignalStartmsEditField          matlab.ui.control.NumericEditField
        SignalStartmsEditFieldLabel     matlab.ui.control.Label
        YmaxEditField                   matlab.ui.control.NumericEditField
        YmaxEditFieldLabel              matlab.ui.control.Label
        YminEditField                   matlab.ui.control.NumericEditField
        YminLabel                       matlab.ui.control.Label
        TimeHighmsEditField             matlab.ui.control.NumericEditField
        TimeHighmsEditFieldLabel        matlab.ui.control.Label
        TimeLowmsEditField              matlab.ui.control.NumericEditField
        TimeLowmsEditFieldLabel         matlab.ui.control.Label
        TimeFrequencySettingsTab        matlab.ui.container.Tab
        offrequencybinsEditField_2      matlab.ui.control.NumericEditField
        offrequencybinsEditField_2Label  matlab.ui.control.Label
        ConversionSwitch_2              matlab.ui.control.Switch
        ConversionSwitch_2Label         matlab.ui.control.Label
        SpacingSwitch_2                 matlab.ui.control.Switch
        SpacingSwitch_2Label            matlab.ui.control.Label
        baselinehighendEditField        matlab.ui.control.NumericEditField
        baselinehighendEditFieldLabel   matlab.ui.control.Label
        maxfreqEditField_2              matlab.ui.control.NumericEditField
        maxfreqEditField_2Label         matlab.ui.control.Label
        timehigherEditField_2           matlab.ui.control.NumericEditField
        timehigherEditField_2Label      matlab.ui.control.Label
        timelowerEditField_2            matlab.ui.control.NumericEditField
        timelowerEditField_2Label       matlab.ui.control.Label
        ofcycleslowerEditField_2        matlab.ui.control.NumericEditField
        ofcycleslowerEditField_2Label   matlab.ui.control.Label
        ofcycleshigherEditField_2       matlab.ui.control.NumericEditField
        ofcycleshigherEditField_2Label  matlab.ui.control.Label
        baselinelowerendEditField_2     matlab.ui.control.NumericEditField
        baselinelowerendEditField_2Label  matlab.ui.control.Label
        minfreqEditField_2              matlab.ui.control.NumericEditField
        minfreqEditField_2Label         matlab.ui.control.Label
        UpdateButton                    matlab.ui.control.Button
        BaselineNormSwitch              matlab.ui.control.Switch
        BaselineNormSwitchLabel         matlab.ui.control.Label
        WARMFilterSwitch                matlab.ui.control.Switch
        WARMFilterSwitchLabel           matlab.ui.control.Label
        Panel_7                         matlab.ui.container.Panel
        ClearAllButton_2                matlab.ui.control.Button
        ExtractParametersButton         matlab.ui.control.Button
        UITable2_2                      matlab.ui.control.Table
        FreqHighEditField_2             matlab.ui.control.NumericEditField
        FreqHighEditField_2Label        matlab.ui.control.Label
        TimeHighEditField_2             matlab.ui.control.NumericEditField
        TimeHighEditField_2Label        matlab.ui.control.Label
        FreqLowEditField_2              matlab.ui.control.NumericEditField
        FreqLowEditField_2Label         matlab.ui.control.Label
        TimeLowEditField_2              matlab.ui.control.NumericEditField
        TimeLowEditField_2Label         matlab.ui.control.Label
        Panel_6                         matlab.ui.container.Panel
        selectmanuallyButton            matlab.ui.control.Button
        ChannelsListBoxLabel_4          matlab.ui.control.Label
        tf_output_plot                  matlab.ui.control.ListBox
        ChannelsListBoxLabel_3          matlab.ui.control.Label
        typeoferp                       matlab.ui.control.ListBox
        ChannelsListBoxLabel_2          matlab.ui.control.Label
        typeofstim                      matlab.ui.control.ListBox
        Panel_5                         matlab.ui.container.Panel
        ExtractManuallyButton           matlab.ui.control.Button
        ClearAllButton                  matlab.ui.control.Button
        ExtractpeaksButton              matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        TrialSelectionLabel             matlab.ui.control.Label
        Tree                            matlab.ui.container.CheckBoxTree
        TabGroup4                       matlab.ui.container.TabGroup
        AverageTab                      matlab.ui.container.Tab
        ColorMapDropDown                matlab.ui.control.DropDown
        TrialsDisplayedListBox          matlab.ui.control.ListBox
        TrialsDisplayedListBoxLabel     matlab.ui.control.Label
        ButterflyPlotCheckBox           matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
        TrialTab                        matlab.ui.container.Tab
        resetButton                     matlab.ui.control.Button
        climsSlider                     matlab.ui.control.RangeSlider
        climsSliderLabel                matlab.ui.control.Label
        UIAxes8                         matlab.ui.control.UIAxes
        UIAxes7                         matlab.ui.control.UIAxes
        MultitrialViewTab               matlab.ui.container.Tab
        Panel_8                         matlab.ui.container.Panel
        PageSlider                      matlab.ui.control.Slider
        PageSliderLabel                 matlab.ui.control.Label
        TimeFrequencyTab_2              matlab.ui.container.Tab
        resetButton_2                   matlab.ui.control.Button
        climsSlider_2                   matlab.ui.control.RangeSlider
        climsSlider_2Label              matlab.ui.control.Label
        UIAxes10                        matlab.ui.control.UIAxes
        UIAxes9                         matlab.ui.control.UIAxes
        LowpassSwitch                   matlab.ui.control.Switch
        LowpassSwitchLabel              matlab.ui.control.Label
        ChannelsListBox                 matlab.ui.control.ListBox
        ChannelsListBoxLabel            matlab.ui.control.Label
        SettingsTab                     matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        DataSelectionTab                matlab.ui.container.Tab
        UITable2                        matlab.ui.control.Table
        Image2                          matlab.ui.control.Image
        ExtractInfoButton               matlab.ui.control.Button
        FreqLowEditField                matlab.ui.control.NumericEditField
        FreqLowEditFieldLabel           matlab.ui.control.Label
        FreqHighEditField               matlab.ui.control.NumericEditField
        TimeHighEditField               matlab.ui.control.NumericEditField
        TimeLowEditField                matlab.ui.control.NumericEditField
        TimeLowEditFieldLabel           matlab.ui.control.Label
        FreqHighEditFieldLabel          matlab.ui.control.Label
        TimeHighEditFieldLabel          matlab.ui.control.Label
        selectrectanglemanuallyButton   matlab.ui.control.Button
        MultitrialviewingTab            matlab.ui.container.Tab
        ncolumnsEditField               matlab.ui.control.NumericEditField
        ncolumnsEditFieldLabel          matlab.ui.control.Label
        nrowsEditField                  matlab.ui.control.NumericEditField
        nrowsEditFieldLabel             matlab.ui.control.Label
        StatisticsTab                   matlab.ui.container.Tab
        npermutationsEditField          matlab.ui.control.NumericEditField
        npermutationsEditFieldLabel     matlab.ui.control.Label
        IdentifysignificantresponsesButton  matlab.ui.control.Button
        WaveletfilteringSettingsTab     matlab.ui.container.Tab
        ClearButton_2                   matlab.ui.control.Button
        numberofpeakstoextractEditField_2  matlab.ui.control.NumericEditField
        numberofpeakstoextractEditField_2Label  matlab.ui.control.Label
        UITable3                        matlab.ui.control.Table
        ExtractSingletrialPeaksTBFButton  matlab.ui.control.Button
        npermutationsEditField_2        matlab.ui.control.NumericEditField
        npermutationsEditField_2Label   matlab.ui.control.Label
        wavelet_filterButton            matlab.ui.control.Button
        UIAxes7_2                       matlab.ui.control.UIAxes
        UIAxes2_7                       matlab.ui.control.UIAxes
        Tab                             matlab.ui.container.Tab
    end

    
    properties (Access = private)
        extraction_state = 0% Has data been extracted?
        file_type = 0 % type of file you want to load
        plot_single_trial = 0; % Description
        low_pass_toggle = 0; % apply low pass filter for time domain analysis
        i_peaks = 0; % Description
        n_roi = 0; % Description
        i_peaks_st =  0 % Description
        peps_range = [0 500];
        cheps_range = [200 600];
        leps_range = [150 550];
        ceps_range = [150 700];
        ssep_epoch_range = [-100 200];
        ssep_signal_range = [30 50];
        ssep_broad_window = [25 70];
        ssep_tf_freq_range = [10 150];
        ssep_tf_n_freqs = 40;
        ssep_tf_cycles = [3 7];
        ssep_tf_baseline = [-100 -10];
        standard_tf_freq_range = [1 25];
        standard_tf_n_freqs = 100;
        standard_tf_cycles = [3 10];
        standard_tf_baseline = [-500 0];
        standard_tf_time_range = [-200 1000];
        stim_range = [200 600]; % Description
        ieeps_range = [111 383]; % Description
        settings = struct; % Description
        saving_folder = ''; % replaces the SavingFolderEditField component, which no longer exists
        clim_store = struct(); % remembered colour limits, one field per TF metric + unit
        clim_reset_trial = false; % consumed by the next plot_trial_data
        clim_reset_tf = false;    % consumed by the next plot_tfdata
        current_data_id = []; % Description
    end
    
    methods (Access = private)

        function [lo,hi] = pad_limits(~,lo_in,hi_in)
            % 10% padding around a data range, robust to sign, ties and NaN.
            lo_in = double(lo_in);
            hi_in = double(hi_in);
            if isempty(lo_in) || isempty(hi_in) || ~isfinite(lo_in) || ~isfinite(hi_in)
                lo = -1; hi = 1;
                return
            end
            if hi_in < lo_in
                tmp = lo_in; lo_in = hi_in; hi_in = tmp;
            end
            span = hi_in - lo_in;
            if span <= 0
                span = max(abs(hi_in),1);
            end
            lo = lo_in - 0.1*span;
            hi = hi_in + 0.1*span;
        end

        function reset_trial_clims(app)
            % forget the hand-set scale and redraw at the default for this data
            app.clim_reset_trial = true;
            plot_trial_data(app);
        end

        function reset_tf_clims(app)
            % forget this metric's remembered scale, then redraw at its default
            key = clim_key(app,app.tf_output_plot.Value);
            if isfield(app.clim_store,key)
                app.clim_store = rmfield(app.clim_store,key);
            end
            app.clim_reset_tf = true;
            plot_tfdata(app);
        end

        function k = clim_key(app,metric)
            % One entry per metric AND per unit, so dB, % change, raw power and
            % the unitless ITPC index never share a colour range.
            if string(metric) == "ITPC"
                unit = "index";
            elseif string(app.BaselineNormSwitch.Value) ~= "On"
                unit = "raw";
            else
                unit = string(app.ConversionSwitch_2.Value);
            end
            k = matlab.lang.makeValidName(char(string(metric) + "_" + unit));
        end

        function v = sync_clim_slider(app,sld,data,symmetric,frac,preferred)
            % Point a clims RangeSlider at the data currently being drawn: its
            % travel spans the full range of that data, and its two thumbs ARE
            % the colour limits. A setting already dialled in by hand is kept
            % whenever it still fits the new data, so replotting does not undo
            % what the user just did.
            if nargin < 4 || isempty(symmetric), symmetric = true; end
            if nargin < 5 || isempty(frac),      frac = 1;         end
            if nargin < 6,                       preferred = [];   end

            m = max(abs(double(data(:))));      % max() already skips NaN
            if isempty(m) || ~isfinite(m) || m <= 0
                m = 1;
            end
            if symmetric
                lim = [-m m];   def = frac*lim;
            else
                lim = [0 m];    def = [0 frac*m];
            end

            if ~isempty(preferred)
                v = double(preferred(:))';   % this metric's remembered limits
            else
                v = double(sld.Value(:))';
            end
            if numel(v) ~= 2 || any(~isfinite(v)) || v(2) <= v(1) ...
                    || v(1) < lim(1) || v(2) > lim(2)
                v = def;
            end
            set_slider_range(app,sld,lim,v);
        end

        function set_slider_range(~,sld,lim,v)
            % Limits and Value must never be inconsistent, even for an instant,
            % or the assignment errors. Widen to the union first, move the
            % thumbs, then narrow to the range we actually want.
            cur = double(sld.Value(:))';
            sld.Limits = [min([lim(1) cur v]) max([lim(2) cur v])];
            sld.Value  = v;
            sld.Limits = lim;
            try   % tick styling is cosmetic - never let it break the plot
                ticks = linspace(lim(1),lim(2),5);
                sld.MajorTicks = ticks;
                sld.MajorTickLabels = compose('%.3g',ticks);
            catch
            end
        end

        function v = clean_clim(~,v)
            % colour limits must be finite and strictly increasing
            v = double(v(:))';
            if numel(v) ~= 2 || any(~isfinite(v)) || v(2) <= v(1)
                v = [];
            end
        end

        function apply_trial_clims(app,v)
            % called by the clims slider on the Trial tab
            if nargin < 2, v = app.climsSlider.Value; end
            v = clean_clim(app,v);
            if isempty(v), return, end
            caxis(app.UIAxes7,v);
        end

        function apply_tf_clims(app,v)
            % called by the clims slider on the Time Frequency tab
            if nargin < 2, v = app.climsSlider_2.Value; end
            v = clean_clim(app,v);
            if isempty(v), return, end
            set(app.UIAxes9,'clim',v);
            % remember this setting for the metric currently displayed
            app.clim_store.(clim_key(app,app.tf_output_plot.Value)) = v;
        end

        function safe_ylim(app,ax,lo,hi)
            % ylim() errors on equal/inverted/NaN limits - repair only those.
            lo = double(lo); hi = double(hi);
            if isempty(lo) || isempty(hi) || ~isfinite(lo) || ~isfinite(hi) || hi <= lo
                [lo,hi] = pad_limits(app,lo,hi);
            end
            if ~isfinite(lo) || ~isfinite(hi) || hi <= lo
                lo = -1; hi = 1;
            end
            ylim(ax,[lo hi]);
        end

        function apply_ylim(app,ax)
            safe_ylim(app,ax,app.YminEditField.Value,app.YmaxEditField.Value);
        end

        function [EEG_dat,time_out,sd2,SNR,SNR_michelle] = plot_tdat(app)
            current_data = evalin('base','current_data');
            current_ylim_low = app.YminEditField.Value;
            current_ylim_high = app.YmaxEditField.Value;

            % check if we are doing the warm filter and if we have 
            if app.WARMFilterSwitch.Value == "On"
                try
                    EEG_dat = current_data.WARM_filter.single_trial_waveforms;
                    x = current_data.WARM_filter.time;
                catch % if its not a field, then calculate and get
                    select_channel(app);
                    preprocc_eeg(app);
                    calc_tf_data(app);
                    compute_warm_signals(app);
                    current_data = evalin('base','current_data');
                    EEG_dat = current_data.WARM_filter.single_trial_waveforms;
                    x = current_data.WARM_filter.time;
                end

   
            else
                EEG_dat = current_data.data;
                x = current_data.time;
            end


            % A single selected trial made std(std(...)) == 0, so valid data was
            % treated as empty. Test whether ANY trace actually varies instead.
            chan_sd = std(EEG_dat,0,1,'omitnan');
            if isempty(EEG_dat) || all(~isfinite(EEG_dat(:))) || ~any(chan_sd > 0)
                % do nothing
                sd2 = [];
                SNR = [];
                SNR_michelle = [];

                cla(app.UIAxes)
                xlim(app.UIAxes, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])
                apply_ylim(app,app.UIAxes)
                xline(app.UIAxes,0,'--');

            else % go ahead and plot that shit

                [sd2,SNR,SNR_michelle] = calc_params_peak_extract(app,EEG_dat,x);
                avg2plot = double(squeeze(mean(EEG_dat,2,"omitnan")));

                % find the xlim idx so that the plotting parameters work 
                [~,x_min_idx] = min(abs(x-app.TimeLowmsEditField.Value));
                [~,x_max_idx] = min(abs(x-app.TimeHighmsEditField.Value));


                % find y lims to plot better
                avg_min = min(avg2plot(x_min_idx:x_max_idx));
                avg_max = max(avg2plot(x_min_idx:x_max_idx));

                [y_lo_tmp,y_hi_tmp] = pad_limits(app,avg_min,avg_max);
                app.YminEditField.Value = y_lo_tmp;
                app.YmaxEditField.Value = y_hi_tmp;


                % find pre baseline noise
                % define baseline noise
                %[~,low_base] = (min(abs(x - app.TimeLowmsEditField.Value)));
                %[~,zero_base] = (min(abs(x - 0)));


                cla(app.UIAxes)

                rectangle(app.UIAxes,'Position', [...
                    -10000,...
                    -sd2,...
                    100000,...
                    2*sd2], ...
                    'FaceColor', [.5, .5, .5, 0.5], ...
                    'EdgeColor', [1, 1, 1, 1]);

                rectangle(app.UIAxes,'Position', [...
                    app.stim_range(1),...
                   -10000,...
                    app.stim_range(2)- app.stim_range(1),...
                    20000], ...
                    'FaceColor', [0.75,0.20,0.23, 0.8], ...
                    'EdgeColor', [1, 1, 1, 1], ...
                    'Curvature',[0.2, 0.2]);

                hold(app.UIAxes,'on');


                if app.ButterflyPlotCheckBox.Value ==1 && ~isempty(app.TrialsDisplayedListBox.Value)

                    trials_idx = str2double(app.TrialsDisplayedListBox.Value);
                    trials2plot = EEG_dat(:,trials_idx);

                    if any(std(trials2plot,"omitnan") > 0)

                        % check the color of the linr
                        if string (app.ColorMapDropDown.Value) == "red"
                            plot(app.UIAxes,x,trials2plot,"LineWidth",2,"Color",[1,0,0,.7]);
                        elseif (app.ColorMapDropDown.Value) == "blue"
                            plot(app.UIAxes,x,trials2plot,"LineWidth",2,"Color",[0,1,1,.7]);
                        elseif (app.ColorMapDropDown.Value) == "green"
                            plot(app.UIAxes,x,trials2plot,"LineWidth",2,"Color",[0,1,0,.7]);
                        else
                            my_map = eval([char(app.ColorMapDropDown.Value) '(numel(trials_idx))']);
                            for j = 1:numel(trials_idx)
                                %plot(app.UIAxes,x,trials2plot(:,j),"LineWidth",3,"Color",[.7 .7 .7 lev]);
                                plot(app.UIAxes,x,trials2plot(:,j),"LineWidth",2,"Color",[my_map(j,:) , .7]);
                            end
                        end

                        % find y lims to plot better
                        subset_trials =trials2plot(x_min_idx:x_max_idx,:);
                        trial_min = double(min(subset_trials(:)));
                        trial_max = double(max(subset_trials(:)));

                        [y_lo_tmp,y_hi_tmp] = pad_limits(app,trial_min,trial_max);
                        app.YminEditField.Value = y_lo_tmp;
                        app.YmaxEditField.Value = y_hi_tmp;

                    end

                end

                if app.AutoSwitch.Value == "Off"
                     app.YminEditField.Value = current_ylim_low;
                     app.YmaxEditField.Value = current_ylim_high;
                end


                plot(app.UIAxes,x,avg2plot,'LineWidth',5,"Color", [.7 .7 .7]);
                plot(app.UIAxes,x,avg2plot,'LineWidth',3,"Color","w");


                set(app.UIAxes, 'YDir','reverse')
                xlim(app.UIAxes, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value]);
                apply_ylim(app,app.UIAxes);
                xline(app.UIAxes,0,'w--');
                yline(app.UIAxes,sd2);
                yline(app.UIAxes,-sd2);
                app.UIAxes.Color = 'k';
                % CHECK IF DATA HAS BEEN EXTRACTED FOR THIS CHANNEL AND
                % THIS FILTER TYPE
                my_table = app.UITable.Data;

                if istable(my_table) && ~isempty(my_table)
                    points2plot = my_table(strcmp(string(app.ChannelsListBox.Value) ,my_table.Channel) ...
                        & strcmp(string(app.LowpassSwitch.Value) ,my_table.filter_on),:);

                    if ~isempty(points2plot)
                        for i = 1: size(points2plot,1)
                            name = points2plot.Name(i);
                            amp = points2plot.Amplitude(i);
                            lat = points2plot.Latency(i);
                            plot(app.UIAxes,lat,amp,'g*','LineWidth',5)
                            text(app.UIAxes,lat+lat*0.1,amp,name,'FontSize',20,'Color','g')
                        end

                    end
                end

                hold(app.UIAxes,'off')

            end
            time_out = x;
           

        end
        
        function [tf , frex,x,convol_pwr_ind,sing_wt,total_convol_result,cwt_outputs] = calc_tf_data(app)

            current_data = evalin('base','current_data');
            EEG = evalin('base',"EEG");

            data = current_data.data;
            x = current_data.time;


            % every TF parameter comes from the Time Frequency Settings tab,
            % and the shared range is mirrored back into Plotting Settings
            s = tf_settings(app);
            sync_tf_settings(app,s);
            time_l = s.time_lims(1);
            time_h = s.time_lims(2);
            freq_lims = s.freq_lims;
            % is_ssep_tf only selects which transform is used below. The SSEP
            % defaults are applied when the stimulus type changes, and when a
            % file is loaded - re-applying them here overwrote any manual change
            % to the frequency range, cycles, bins or Linear/Log spacing every
            % time the time-frequency data was recomputed.
            is_ssep_tf = is_ssep_stim(app,app.typeofstim.Value);
            
            % subset eeg data to decrease computing time
            [~,i_low] = (min(abs(x - time_l)));
            [~,i_high] = (min(abs(x - time_h)));
            i_high = i_high-1;
            
            
            %avg2plot = squeeze(mean(EEG.data(chan_idx,:,:),3));
            
            dat_size = size(data);
            
            n_trials = dat_size(2);
            if is_ssep_tf
                [tf,frex,x,convol_pwr_ind,sing_wt,total_convol_result,cwt_outputs] = calc_ssep_tf_data(app,data,x,EEG.srate,i_low,i_high,freq_lims);
                current_data.frequencies = frex;
                current_data.tf_times = x;
                current_data.tf = tf;
                current_data.cwt_outputs = cwt_outputs;
                current_data.tf_variables = ["Total","Non-phase-locked","Evoked power","ITPC","Phase locked"];
                EEG.frequencies = frex;
                EEG.tf_times = x;
                EEG.tf = tf;
                EEG.cwt_outputs = cwt_outputs;
                EEG.tf_variables = current_data.tf_variables;
                assignin('base','tf',tf)
                assignin('base','EEG',EEG)
                assignin('base','current_data',current_data)
                assignin('base','cwt_outputs',cwt_outputs)
                return
            end


            % compute ERP
            erp = squeeze(mean(data,2,"omitnan"));

            % compute induced power by subtracting ERP from each trial
            induced_EEG = squeeze(data - repmat(erp,1,n_trials));

            % perform wavelet transformations
            % for erp
            % fb = cwtfilterbank(SignalLength=numel(erp),SamplingFrequency=EEG.srate,...
            %     FrequencyLimits=freq_lims);
            % freqz(fb)
            % 
            % [erp_wt,frex,erp_scalingcfs] = cwt(erp,FilterBank=fb);
            % 
            % [erp_wt,frex,erp_scalingcfs] = cwt(erp);


            [erp_wt,frex,erp_scalingcfs]  = cwt(erp,'amor',EEG.srate,FrequencyLimits = freq_lims);

            cwt_outputs.erp.wt = erp_wt;
            cwt_outputs.erp.frex = frex;
            cwt_outputs.erp.scalingcfs = erp_scalingcfs; 

            % for single trials
            sing_wt = [];
            ind_wt = [];
            sing_scalingcfs = [];
            for i = 1:n_trials
                dat2convert =data(:,i);
                if ~ anynan(dat2convert)
                    [c_w,frex,sing_scalingcfs] = cwt(dat2convert,'amor',EEG.srate,FrequencyLimits = freq_lims);
                    if i == 1
                        sing_wt = zeros(length(frex),length(dat2convert),n_trials);
                        sing_wt(:,:,i) = c_w;
                    else
                        sing_wt(:,:,i)  = c_w;
                    end
                else
                    if i == 1
                        sing_wt = zeros(length(frex),length(dat2convert),n_trials);
                        sing_wt(:,:,i) = NaN;
                    else
                        sing_wt(:,:,i)  = NaN;
                    end

                end

            end

            sing_wt = sing_wt(:,i_low:i_high,:); % cut to epoch size
            cwt_outputs.single_trial.wt = sing_wt;
            cwt_outputs.single_trial.frex = frex;
            cwt_outputs.single_trial.scalingcfs = sing_scalingcfs; 

            % for induced
            for i = 1:n_trials
                dat2convert =induced_EEG(:,i);
                if ~ anynan(dat2convert)
                    [c_w,frex,~] = cwt(dat2convert,'amor',EEG.srate,FrequencyLimits = freq_lims);
                    if i == 1
                        ind_wt = zeros(length(frex),length(dat2convert),n_trials);
                        ind_wt(:,:,i) = c_w;
                    else
                        ind_wt(:,:,i)  = c_w;
                    end
                else
                    if i == 1
                        ind_wt = zeros(length(frex),length(dat2convert),n_trials);
                        ind_wt(:,:,i) = NaN;
                    else
                        ind_wt(:,:,i)  = NaN;
                    end
                end
            end


            % calculating power and baseline correcting

            % convert baseline from ms to indices
            x = x(i_low:i_high);
            [~,baseidx(1)] = min(abs(x-s.baseline(1)));
            [~,baseidx(2)] = min(abs(x-s.baseline(2)));

            % for erp power
            pow_erp = abs(erp_wt).^2;
            pow_erp = pow_erp(:,i_low:i_high);

            % for total
            convol_pwr_ind = abs(sing_wt).^2;
            pow_total = mean(convol_pwr_ind,3,"omitnan");
            cwt_outputs.convol_pwr_ind = convol_pwr_ind;
            total_convol_result = mean(sing_wt,3,"omitnan");
            cwt_outputs.total_convol_results = total_convol_result;

            % non-phase locked (induced) power = mean power of the single trials
            % after the ERP has been subtracted from each of them. This is what
            % ind_wt was computed for; cut it to the same epoch as everything else.
            ind_wt = ind_wt(:,i_low:i_high,:);
            pow_npl = mean(abs(ind_wt).^2,3,"omitnan");
            clear ind_wt

            % phase-locked power = the part of total power that is not induced
            pow_plp = max(pow_total - pow_npl,0);

            % ITPC 
            ITPC = abs(mean(exp(1i*angle(sing_wt)),3,"omitnan"));

            %% initiate TF structure 

            tf = zeros(5,size(pow_erp,1),size(pow_erp,2));
            tf(1,:,:) = pow_total;
            tf(2,:,:) = pow_npl;
            tf(3,:,:) = pow_erp;
            tf(4,:,:) = ITPC;
            tf(5,:,:) = pow_plp;

            % baseline correct
            if s.normalise
                if baseidx(1) > baseidx(2)
                    baseidx = fliplr(baseidx);
                end
                my_baseline = mean(tf(:,:,baseidx(1):baseidx(2)),3,"omitnan");
                if s.conversion == "dB" %log scaled data
                    % guard against zero/negative power before taking the log
                    safe_tf = tf;
                    safe_tf(safe_tf <= 0 | isnan(safe_tf)) = eps;
                    safe_base = my_baseline;
                    safe_base(safe_base <= 0 | isnan(safe_base)) = eps;
                    tf = 10*log10(safe_tf ./ safe_base);
                elseif s.conversion == "percent change"
                    my_baseline(my_baseline == 0 | isnan(my_baseline)) = eps;
                    tf = ((tf-my_baseline)./my_baseline)*100;
                end
            end


            % ITPC is a bounded [0 1] index, so it is never baseline-normalised.
            % Evoked and phase-locked power stay normalised so that they agree
            % with the colour-bar units (dB / % change) used when plotting.
            tf(4,:,:) = ITPC;
            
            %tf = tf(:,:,i_low:i_high);
            %ind_wt = ind_wt(:,i_low:i_high,:);
            %convol_pwr_ind = convol_pwr_ind(:,i_low:i_high,:);
            %total_convol_result = total_convol_result(:,i_low:i_high,:);
            %times = x(i_low:i_high);

%             for i = 1:5
%                 figure
%                 imagesc(times,frex,squeeze(tf(i,:,:)))
%                 set(gca, 'YDir','normal')
%             end

            current_data.frequencies = frex;
            current_data.tf_times = x;
            current_data.tf = tf;
            current_data.cwt_outputs = cwt_outputs;
            current_data.tf_variables = ["Total","Non-phase-locked","Evoked power","ITPC","Phase locked"];

            EEG.frequencies = frex;
            EEG.tf_times = x;
            EEG.tf = tf;
            EEG.cwt_outputs = cwt_outputs;
            EEG.tf_variables = current_data.tf_variables;


            assignin('base','tf',tf)
            assignin('base','EEG',EEG)
            assignin('base','current_data',current_data)
            %assignin('base','ALLEEG',ALLEEG)
            assignin('base','cwt_outputs',cwt_outputs)
            
            
        end
        
        
        function plot_tfdata(app)
            %EEG = evalin('base','EEG');
            s = tf_settings(app);
            current_data = evalin('base','current_data');

            % determine the thing to plot
            var2plot = app.tf_output_plot.Value;


            if app.WARMFilterSwitch.Value == "On"
                EEG_dat = current_data.WARM_filter.single_trial_waveforms;
                x = current_data.WARM_filter.time;
                tf_mask= current_data.zmaps.zmapthresh_clc;
                frequencies = current_data.zmaps.frequencies;
                x_tf = current_data.zmaps.times;
                tf = current_data.zmaps.tf_resampled;


            else
                EEG_dat = current_data.data;
                x_tf = current_data.tf_times;
                x = current_data.time;
                tf = current_data.tf;
                frequencies = current_data.frequencies;
            end


            % get climits for plotting
            %[~,~] = (min(abs(x_tf- app.timelowerEditField_2.Value)));
            %[~,high_index] = (min(abs(x_tf - app.timehigherEditField_2.Value)));
            %high_index = high_index -1;


            % calculate time signal vars to plot later
            avg_data = mean(EEG_dat,2,"omitnan");
            %max_sign = max(max(abs(avg_data)));
            [sd2,SNR,SNR_michelle] = calc_params_peak_extract(app,EEG_dat,x);


            %analysis_labels = {'Total';'Non-phase-locked';'ERP power';'ITPC';'Phase-locked'};
            % cbar_labels used to be undefined whenever normalisation was off,
            % which made the colour-bar label line error out
            if string(app.BaselineNormSwitch.Value) ~= "On"
                cbar_labels = {'Power';'Power';'Power';'ITPC';'Power'};
            elseif s.conversion == "dB"
                cbar_labels = {'dB';'Power (dB)';'dB';'ITPC';'Power (dB)'};
            elseif s.conversion == "percent change"
                cbar_labels = {'% change';'% change';'% change';'ITPC';'% change'};
            else
                cbar_labels = {'Power';'Power';'Power';'ITPC';'Power'};
            end
            
            % determine the vars and the data to plot.
            % (the min_vect / max_vect / c_vector block that used to set
            % color_edges here is gone - the clims slider owns the colour scale
            % now, so those values were computed only to be overwritten)
            if var2plot == "Total"
                dat2plot = squeeze(tf(1,:,:));
                cbar_label = cbar_labels(1);

            elseif var2plot == "Evoked power" || var2plot == "ERP Power" || var2plot == "ERP"
                dat2plot = squeeze(tf(3,:,:));
                cbar_label = cbar_labels(3);

            elseif var2plot == "Phase locked"
                dat2plot = squeeze(tf(5,:,:));
                cbar_label = cbar_labels(5);

            elseif var2plot == "Non-phase-locked"
                dat2plot = squeeze(tf(2,:,:));
                cbar_label = cbar_labels(1);

            elseif var2plot == "ITPC"
                dat2plot = squeeze(tf(4,:,:));
                cbar_label = cbar_labels(4);

            else % unknown selection - fall back to total power
                dat2plot = squeeze(tf(1,:,:));
                cbar_label = cbar_labels(1);
            end

            % The clims slider is the colour scale, and every metric keeps its
            % OWN limits: ITPC is a unitless 0..1 index while dB is a power
            % ratio, so carrying one metric's limits across to another is
            % meaningless. Stickiness still applies within a metric, across
            % channels and Updates, which is what it was for.
            is_itpc = var2plot == "ITPC";
            key = clim_key(app,var2plot);
            stored = [];
            if isfield(app.clim_store,key)
                stored = app.clim_store.(key);
            end
            if app.clim_reset_tf
                stored = NaN;   % NaN is never valid -> forces the default
                app.clim_reset_tf = false;
            end
            color_edges = sync_clim_slider(app,app.climsSlider_2,dat2plot,~is_itpc,1,stored);
            app.clim_store.(key) = color_edges;


            % apply the mask to the tf data being plotted
            if app.WARMFilterSwitch.Value == "On"
                mask = ~tf_mask == 0;
                dat2plot = dat2plot .* mask;
            end


            if ~isempty(SNR) && ~isnan(SNR)
                contourf(app.UIAxes9,x_tf,frequencies,dat2plot,40,'linecolor','none')
                hold(app.UIAxes9,'on')
                xline(app.UIAxes9,app.stim_range(1),'k');
                xline(app.UIAxes9,app.stim_range(2),'k');
                xline(app.UIAxes9,0,'k--');
                hold(app.UIAxes9,'on')

                if s.spacing == "Log" %log scaled data
                    set(app.UIAxes9,'clim',color_edges,'xlim',s.time_lims,...
                        'xtick',[round(s.time_lims(1):100:-100,-1) 0 round(100:100:s.time_lims(2),-1)],...
                        'yscale','log','ytick',logspace(log10(s.freq_lims(1)),log10(s.freq_lims(2)),6),...
                        'yticklabel',round(logspace(log10(s.freq_lims(1)),log10(s.freq_lims(2)),6)*10)/10);
                elseif s.spacing == "Linear" % not log scaled data
                    set(app.UIAxes9,'clim',color_edges,'xlim',s.time_lims,...
                        'xtick',[round(s.time_lims(1):100:-100,-1) 0 round(100:100:s.time_lims(2),-1)],...
                        'yscale','linear','ytick',linspace(s.freq_lims(1),s.freq_lims(2),6),...
                        'yticklabel',round(linspace(s.freq_lims(1),s.freq_lims(2),6)*10)/10);
                end
                c = colorbar(app.UIAxes9,"east");
                colormap(app.UIAxes9,"jet");
                c.Label.String = string(cbar_label);
                hold(app.UIAxes9,'off')

                % plot the time domain data underneath
                cla(app.UIAxes10)

                hold(app.UIAxes10,"on");
                rectangle(app.UIAxes10,'Position', [...
                    s.time_lims(1),...
                    -sd2,...
                    s.time_lims(2) - s.time_lims(1),...
                    2*sd2], ...
                    'FaceColor', [.5, .5, .5, 0.2], ...
                    'EdgeColor', [1, 1, 1, 1]);
                rectangle(app.UIAxes10,'Position', [...
                    app.stim_range(1),...
                    app.YminEditField.Value,...
                    app.stim_range(2)- app.stim_range(1),...
                    app.YmaxEditField.Value-app.YminEditField.Value], ...
                    'FaceColor', [0.75,0.20,0.23, 0.8], ...
                    'EdgeColor', [1, 1, 1, 1], ...
                    'Curvature',[0.2, 0.2]);

                plot(app.UIAxes10,x,avg_data,'LineWidth',2,"Color","w");

                [~,max_idx] = min(abs(x-s.time_lims(2)));
                [~,min_idx] = min(abs(x-s.time_lims(1)));
                % get new ylims for the signal
                high_y = max(avg_data(min_idx:max_idx));
                min_y = min(avg_data(min_idx:max_idx));
                [min_y,high_y] = pad_limits(app,min_y,high_y);

                set(app.UIAxes10, 'YDir','reverse')
                xlim(app.UIAxes10, s.time_lims)
                safe_ylim(app,app.UIAxes10,min_y,high_y)
                xline(app.UIAxes10,0,'w--');
                yline(app.UIAxes10,sd2)
                yline(app.UIAxes10,-sd2)
                app.UIAxes10.Color = 'k';
                hold(app.UIAxes10,"off");
            else
                contourf(app.UIAxes9,x_tf,frequencies,zeros(size(dat2plot)),40,'linecolor','none')
                hold(app.UIAxes9,'on')
                xline(app.UIAxes9,app.stim_range(1),'k');
                xline(app.UIAxes9,app.stim_range(2),'k');
                xline(app.UIAxes9,0,'k--');
                c = colorbar(app.UIAxes9,"east");
                colormap(app.UIAxes9,"jet");
                c.Label.String = string(cbar_label);
                hold(app.UIAxes9,'off')

                cla(app.UIAxes10)
                hold(app.UIAxes10,"on");
                xline(app.UIAxes10,0,'--');
                xlim(app.UIAxes10, s.time_lims)
                apply_ylim(app,app.UIAxes10)
                xline(app.UIAxes10,0,'--');
                hold(app.UIAxes10,"off");
            end


        end
        
        function my_output = selectDatapoints(app)
           
            % Remove any pre-existing rectanlges (if any)
            delete(findall(app.UIAxes9, 'Type',  'images.roi.Rectangle'))
            temp_roi = drawrectangle(app.UIAxes9,'StripeColor','b','InteractionsAllowed','none');
            if isempty(temp_roi) || isempty(temp_roi.Position)
                error('selectDatapoints:cancelled','No region was selected.');
            end

            my_output = temp_roi.Position;
   
        end


        function [my_output, extraction_state]= extract_vals(app,tf,frequencies,x)

            % Define interpolation resolution (e.g., 5x finer)
            factor = 5;

            % interp2 needs increasing grid vectors, and the two TF back-ends
            % disagree: cwt() returns DESCENDING frequencies while the Morlet
            % (SSEP) path returns ASCENDING ones. The original code assumed
            % descending, so the SSEP path selected an empty region. Normalise.
            frequencies = double(frequencies(:));
            x = double(x(:))';
            [freq_sorted, f_order] = sort(frequencies,'ascend');
            n_metrics = size(tf,1);
            tf_sorted = tf(:,f_order,:);

            fine_freq = linspace(min(freq_sorted), max(freq_sorted), numel(freq_sorted)*factor)';
            fine_time = linspace(min(x), max(x), numel(x)*factor);

            % Interpolate
            [Time, Freq] = meshgrid(x, freq_sorted);
            [FineTime, FineFreq] = meshgrid(fine_time, fine_freq);
            data_out = zeros(n_metrics,numel(fine_freq),numel(fine_time));

            for i = 1:n_metrics
                data_out(i,:,:)= interp2(Time, Freq, squeeze(tf_sorted(i,:,:)), FineTime, FineFreq, 'spline');
            end

            tf = data_out;

            x_min = app.TimeLowEditField_2.Value;
            x_max = app.TimeHighEditField_2.Value;
            y_min = app.FreqLowEditField_2.Value;
            y_max = app.FreqHighEditField_2.Value;
            if x_max < x_min
                [x_min,x_max] = deal(x_max,x_min);
            end
            if y_max < y_min
                [y_min,y_max] = deal(y_max,y_min);
            end

            % index the requested window, falling back to the nearest sample when
            % the window sits outside the data so the selection is never empty
            t_idx = find(fine_time >= x_min & fine_time <= x_max);
            if isempty(t_idx)
                [~,t_idx] = min(abs(fine_time - (x_min+x_max)/2));
            end
            f_idx = find(fine_freq >= y_min & fine_freq <= y_max);
            if isempty(f_idx)
                [~,f_idx] = min(abs(fine_freq - (y_min+y_max)/2));
            end

            % cutting data
            data_cut = tf(:,f_idx,t_idx);
            time_cut = fine_time(t_idx);
            freq_cut = fine_freq(f_idx);

            % extracting important values
            max_vect = max(max(data_cut,[],3),[],2);
            mean_vect = mean(mean(data_cut,3,"omitnan"),2,"omitnan");
            min_vect = min(min(data_cut,[],3),[],2);

            % indices of the min/max within the cut region. reshape (not squeeze)
            % so a single-row or single-column selection keeps its orientation.
            temp = reshape(max(data_cut,[],3),n_metrics,[]);
            [ ~ , maxif_vect] = max(temp,[],2); % frequency of the maximum
            temp = reshape(min(data_cut,[],3),n_metrics,[]);
            [ ~ , minif_vect] = min(temp,[],2); % frequency of the minimum
            temp = reshape(max(data_cut,[],2),n_metrics,[]);
            [ ~ , maxit_vect] = max(temp,[],2); % time of the maximum
            temp = reshape(min(data_cut,[],2),n_metrics,[]);
            [ ~ , minit_vect] = min(temp,[],2); % time of the minimum

            peak_t_vector = zeros(1,n_metrics);
            min_t_vector = zeros(1,n_metrics);
            peak_f_vector = zeros(1,n_metrics);
            min_f_vector = zeros(1,n_metrics);

            for i = 1:n_metrics
                peak_t_vector(i) = time_cut(maxit_vect(i));
                min_t_vector(i) = time_cut(minit_vect(i));

                peak_f_vector(i) = freq_cut(maxif_vect(i));
                min_f_vector(i) = freq_cut(minif_vect(i));
            end

            my_output.mean_activity = mean_vect;
            my_output.max_activity = max_vect;
            my_output.min_activity = min_vect;
            my_output.peak_frequency = peak_f_vector;
            my_output.lowest_frequency = min_f_vector;
            my_output.peak_time = peak_t_vector;
            my_output.lowest_time = min_t_vector;
            my_output.metric = app.tf_output_plot.Value;

            extraction_state = 1;

        end
        
        function populate_extraction_vals(app,my_output)
            c_tf_data = app.tf_output_plot.Value;
            current_data = evalin("base","current_data");
            tf_vars = current_data.tf_variables;

            tab_idx = 1; % fall back to the first metric if nothing matches
            for i = 1:length(tf_vars)
                if string(tf_vars(i)) == string(c_tf_data)
                    tab_idx = i;
                end
            end
                        
            prompt = {'Enter name of ROI'};
            dlgtitle = 'Name of ROI';
            dims = [1 35];
            definput = {['ROI ' num2str(app.n_roi+1)],'hsv'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if isempty(answer) % user cancelled
                return
            end

             my_table = app.UITable2_2.Data;
             m_data.Channel = string(app.ChannelsListBox.Value);
             m_data.Name = string(answer);
             m_data.mean_act = round(my_output.mean_activity(tab_idx),3);
             m_data.peak_act = round(my_output.max_activity(tab_idx),3);
             m_data.peak_freq = round(my_output.peak_frequency(tab_idx),3);
             m_data.peak_lat = round(my_output.peak_time(tab_idx),3);
             m_data.low_act = round(my_output.min_activity(tab_idx),3);
             m_data.low_freq = round(my_output.lowest_frequency(tab_idx),3);
             m_data.low_lat = round(my_output.lowest_time(tab_idx),3);
             m_data.metric = string(my_output.metric);
             

                if app.n_roi > 0 && istable(my_table) && ~isempty(my_table)
                    my_table_add = struct2table(m_data);
                    my_table_add.Properties.VariableNames = ["Channel" ,"Name","Mean Activity (dB)","Peak Activity (dB)","Peak Frequency (Hz)","Peak Latency (ms)","Lowest Activity (ms)","Min Frequency (Hz)","Min Latency(ms)","Metric"];
                    my_table = [my_table;my_table_add];
                else
                    my_table = struct2table(m_data);
                    my_table.Properties.VariableNames = ["Channel" ,"Name","Mean Activity (dB)","Peak Activity (dB)","Peak Frequency (Hz)","Peak Latency (ms)","Lowest Activity (ms)","Min Frequency (Hz)","Min Latency(ms)","Metric"];
                    
                end
                app.UITable2_2.Data = my_table;    
                
% 
%             app.meanactivitydBEditField.Value = round(my_output.mean_activity(tab_idx),3);
%             app.peakactivitydBEditField.Value = round(my_output.max_activity(tab_idx),3);
%             app.peakfrequencyHzEditField.Value = round(my_output.peak_frequency(tab_idx),3);
%             app.peaklatencymsEditField.Value = round(my_output.peak_time(tab_idx),3);
%             app.lowestactivitydBEditField.Value = round(my_output.min_activity(tab_idx),3);
%             app.minfrequencyHzEditField.Value = round(my_output.lowest_frequency(tab_idx),3);
%             app.minlatencymsEditField.Value = round(my_output.lowest_time(tab_idx),3);
             app.n_roi = app.n_roi +1;

            
        end
        
        function EEG = convert_txt_to_eeg_struct(app,data_in,chan_id,srate,baseline_low)
            load('chan_info.mat',"chanlocs");
            
            EEG.data = data_in;
            dims_in = size(data_in);
            
            %calculate the time of the stim
            l_data = length(data_in);
            t_data = l_data/srate*1000; %in ms
            my_baseline = baseline_low; %in ms
            time_v = linspace(my_baseline,t_data+my_baseline,length(data_in));


            chan1_IDX = [];
            for k = 1:length(chanlocs)
                C_chan = lower(string(chanlocs(k).labels));
                if C_chan == lower(string(chan_id))
                    chan1_IDX=k;
                end
                
            end
            if isempty(chan1_IDX)
                error('convert_txt_to_eeg_struct:unknownChannel', ...
                    'Channel "%s" is not present in chan_info.mat.', char(string(chan_id)));
            end


            % find the correct chanlocs id and change the names 


            temp_data = zeros(1,dims_in(1),dims_in(2));
            temp_data(1,:,:) = data_in;
            EEG.data = temp_data;
            new_chanlocs = chanlocs(chan1_IDX);
            EEG.chanlocs = new_chanlocs;
            EEG.srate = srate;
            EEG.times = time_v; 
            [filepath,name,ext] = fileparts(app.DataInEditField.Value); 
            EEG.setname = name;
            EEG.filename = [name,ext];
            EEG.filepath = filepath;
            EEG.comments = ['Original file: ' filepath, filesep(), name, ext, ". Converted from text file." ];
            EEG.nbchan = size(EEG.data,1);
            EEG.trials = size(EEG.data,3);
            EEG.pnts = size(EEG.data,2);
            EEG.xmin = min(EEG.times);
            EEG.xmax = max(EEG.times);
            EEG.icawinv = [];
            EEG.icaweights = [];
            EEG.icasphere = [];


        end
        
        function my_table = extract_peaks(app,EEG_dat_plotted,x,SNR,SNR_michelle,sd2)
           EEG = evalin('base','EEG');
           c_dat = evalin('base','current_data');
           setname = EEG.setname;

           erp = mean(EEG_dat_plotted,2,"omitnan");
           win_min = app.SignalStartmsEditField.Value;
           win_max = app.SignalEndmsEditField.Value;
           
           [~,low_idx] = (min(abs(x - win_min)));
           [~,high_idx] = (min(abs(x - win_max)));

           cut_erp = erp(low_idx:high_idx); % cut the erp to the size of window
           cut_time = x(low_idx:high_idx); % cut the time as well

           % every field is pre-set, so no branch can leave one undefined
           % (df_out.n2_chan used to be missing whenever there was no P2, and
           % empty [] amplitudes broke struct2table further down)
           df_out.p2_amp = nan;  df_out.p2_lat = nan;  df_out.p2_chan = app.ChannelsListBox.Value;
           df_out.n2_amp = nan;  df_out.n2_lat = nan;  df_out.n2_chan = app.ChannelsListBox.Value;
           df_out.n1_amp = nan;  df_out.n1_lat = nan;  df_out.n1_chan = app.ChannelsListBox.Value;

           if isempty(cut_erp)
               warning('extract_peaks:emptyWindow','The signal window contains no samples.');
           elseif app.typeoferp.Value == "N2/P2"
               % check first if there is a p2 that is not sitting on an edge
               [p2_val, max_out_idx] = max(cut_erp);
               if max_out_idx ~= 1 && max_out_idx ~= length(cut_erp)
                   df_out.p2_amp = p2_val;
                   df_out.p2_lat = cut_time(max_out_idx);

                   % an n2 before the p2: amplitude and latency must both come
                   % from that same pre-P2 segment (the amplitude used to be
                   % taken from the whole window, so it could disagree with the
                   % latency reported next to it)
                   pre_p2 = cut_erp(1:max_out_idx);
                   [n2_val, min_out_idx] = min(pre_p2);
                   if min_out_idx ~= 1 && min_out_idx ~= length(pre_p2)
                       df_out.n2_amp = n2_val;
                       df_out.n2_lat = cut_time(min_out_idx);
                   end
               else % no usable p2 - look for an n2 across the whole window
                   [n2_val, min_out_idx] = min(cut_erp);
                   if min_out_idx ~= 1 && min_out_idx ~= length(cut_erp)
                       df_out.n2_amp = n2_val;
                       df_out.n2_lat = cut_time(min_out_idx);
                   end
               end

           elseif app.typeoferp.Value == "N1" % if the signal is the n1
               % check if the n2p2 was extracted
               my_table = app.UITable.Data; % read in data table
               % read in only n2 trials that match that are from the same
               % dataset and that use the same number of trials and have
               % been filtered the same. 
               if ~isempty(my_table)
                   n2s = my_table(my_table.Name == "N2" & ...
                       string(my_table.ID) == string(c_dat.data_name) & ...
                       string(my_table.trials) == strjoin(string(c_dat.trials_plotted),",") & ...
                       string(my_table.filter_on) == string(c_dat.filtered),:);

                   if isempty(n2s) || all(isnan(n2s.Latency))
                       [~, val_out_idx] = min(cut_erp);
                       if val_out_idx ~= 1 && val_out_idx ~= length(cut_erp) % checking its not the edge
                           df_out.n1_amp = min(cut_erp);
                           df_out.n1_lat = cut_time(val_out_idx);
                           df_out.n1_chan = app.ChannelsListBox.Value;

                       else
                           df_out.n1_amp = nan;
                           df_out.n1_lat = nan;
                           df_out.n1_chan = app.ChannelsListBox.Value;
                       end
                   else % if there is an n2 that works
                       win_min = app.SignalStartmsEditField.Value; % keep the same min but
                       win_max = n2s.Latency(1); % several N2 rows can match

                       [~,low_idx] = (min(abs(x - win_min)));
                       [~,high_idx] = (min(abs(x - win_max)));

                       cut_erp = erp(low_idx:high_idx); % cut the erp to the size of window
                       cut_time = x(low_idx:high_idx); % cut the time as well
                       [~, val_out_idx] = min(cut_erp);
                       if val_out_idx ~= 1 && val_out_idx ~= length(cut_erp) % checking its not the edge
                           df_out.n1_amp = min(cut_erp);
                           df_out.n1_lat = cut_time(val_out_idx);
                           df_out.n1_chan = app.ChannelsListBox.Value;
                       else
                           df_out.n1_amp = nan;
                           df_out.n1_lat = nan;
                           df_out.n1_chan = app.ChannelsListBox.Value;
                       end
                   end
               else
                   [~, val_out_idx] = min(cut_erp);
                   if val_out_idx ~= 1 && val_out_idx ~= length(cut_erp) % checking its not the edge
                       df_out.n1_amp = min(cut_erp);
                       df_out.n1_lat = cut_time(val_out_idx);
                       df_out.n1_chan = app.ChannelsListBox.Value;

                   else
                       df_out.n1_amp = nan;
                       df_out.n1_lat = nan;
                       df_out.n1_chan = app.ChannelsListBox.Value;
                   end
               end

           end

           my_table = app.UITable.Data;

           if app.typeoferp.Value == "N2/P2"
                m_data.Channel = df_out.n2_chan;
                m_data.Name = "N2";
                m_data.Amplitude = df_out.n2_amp;
                m_data.Latency =  df_out.n2_lat;
                m_data.filter_on = string(app.LowpassSwitch.Value);
                m_data.trials = strjoin(string(c_dat.trials_plotted),",");
                m_data.n_trials = length(c_dat.trials_plotted);
                m_data.Stim = app.typeofstim.Value;
                m_data.SNR_oddeve = SNR;  
                m_data.SNR_ps = SNR_michelle;
                m_data.sd2 = sd2;
                m_data.ID = string(c_dat.data_name);

                if istable(my_table) && ~isempty(my_table)
                    my_table = [my_table; struct2table(m_data)];
                else
                    my_table = struct2table(m_data);
                end

                m_data.Channel = df_out.p2_chan;
                m_data.Name = "P2";
                m_data.Amplitude = df_out.p2_amp;
                m_data.Latency =  df_out.p2_lat;
                m_data.filter_on = string(app.LowpassSwitch.Value);
                m_data.trials = strjoin(string(c_dat.trials_plotted),",");
                m_data.n_trials = length(c_dat.trials_plotted);
                m_data.Stim = app.typeofstim.Value;
                m_data.SNR_oddeve = SNR;  
                m_data.SNR_ps = SNR_michelle;
                m_data.sd2 = sd2;
                m_data.ID = string(c_dat.data_name);
                if istable(my_table) && ~isempty(my_table)
                    my_table = [my_table; struct2table(m_data)];
                else
                    my_table = struct2table(m_data);
                end

           elseif app.typeoferp.Value == "N1"
                m_data.Channel = df_out.n1_chan;
                m_data.Name = "N1";
                m_data.Amplitude = df_out.n1_amp;
                m_data.Latency =  df_out.n1_lat;
                m_data.filter_on = string(app.LowpassSwitch.Value);
                m_data.trials = strjoin(string(c_dat.trials_plotted),",");
                m_data.n_trials = length(c_dat.trials_plotted);
                m_data.Stim = app.typeofstim.Value;
                m_data.SNR_oddeve = SNR; 
                m_data.SNR_ps = SNR_michelle;
                m_data.sd2 = sd2;
                m_data.ID = string(c_dat.data_name);
                if istable(my_table) && ~isempty(my_table)
                    my_table = [my_table; struct2table(m_data)];
                else
                    my_table = struct2table(m_data);
                end
                
           end

           app.UITable.Data = my_table;   


        end
        
        function mark_peaks_in_fig(app)
            hold(app.UIAxes,"on")
            table_info = app.UITable.Data;
            for i = 1: size(table_info,1)
                
                name = table_info.Name(i);
                amp = table_info.Amplitude(i);
                lat = table_info.Latency(i);
                plot(app.UIAxes,lat,amp,'g*','LineWidth',5)
                text(app.UIAxes,lat,amp,name,'FontSize',20)
            end
            hold(app.UIAxes,"off");    
        end
        
        
        function [zmap, zmapthresh, zmapthresh_plc, zmapthresh_clc, tfv_time, v_freq] = npp_stb_2(app,input_mat,n_permutes,v_time,v_freq,time_window,baseline_range,plotting_input,my_title)

            %% Description:
            % Takes a time frequency matrix (dimensions = n_subs, frequencies, times)
            % and peforms the non perametric permutation comparing the signal to the
            % baseline. Inputs include input mat, number of permutations (n_permutes),
            % vectors for time and frequency(v_time and v_freq), limits in the time 
            % axis where you want to perform the analysis (time_window), and the
            % plotting_input variable, in which if is set to 1, it will plot the 
            % responses. The output consist of the z_map, the logical mask for the 
            % zmap uncorrected for multple comparisons (zmapthresh), the logical mask
            % with a pixel level correction for multiple comparisons (zmapthresh_plc),
            % and the logical threshold map with cluster correction for mutliple
            % comparisons (zmapthesh_clc). The last output is the new time vector
            % based on time_window. 
            
            
            %% getting parameters for permutations
            time_s = dsearchn(v_time',baseline_range(1)); % start time for tf window
            time_0 = dsearchn(v_time',baseline_range(2));
            time_e = dsearchn(v_time',time_window(2)); % end zerostime for tf window
            tfv_time  = v_time(time_s:time_e);
            nTimepoints = numel(tfv_time);
            avg_mat =squeeze(mean(input_mat,"omitnan"));
            temp = size(input_mat);
            n_subs = temp(1);
            
            % statistical significant values for voxel and cluster analysis
            voxel_pval   = 0.05;
            cluster_pval = 0.05;
            mcc_voxel_pval = 0.05; % mcc = multiple comparisons correction
            mcc_cluster_pval = 0.05;
            %% Running actual T test on data
            % need to run it per frequency bin

            test_prestim_estimate =  mean(input_mat(:,:,time_s:time_0),3);
            test_poststim_points = squeeze(input_mat(:,:,time_s:time_e));
            test_prestim_points = repmat(test_prestim_estimate,1,1,size(test_poststim_points,3));
            [~,~,~,stats] = ttest(test_poststim_points,test_prestim_points);
            t_mat = squeeze(stats.tstat);
            mat_size = size(avg_mat(:,time_s:time_e));
            % t_mat = zeros(mat_size);
            % 
            % for i = 1:mat_size(1) %run per frequency bin
            %     prestim_estimate = mean(input_mat(:,i,time_s:time_0),3,"omitnan");
            %     poststim_points = squeeze(input_mat(:,i,time_s:time_e));
            %     prestim_points = repmat(prestim_estimate, 1,length(poststim_points));
            %     [~,~,~,stats] = ttest(poststim_points,prestim_points);
            %     results = stats.tstat;
            %     t_mat(i,:) = results;
            % end
            
            %% running the premutations
            
            % initialize null hypothesis matrices
            %permuted_maxvals = zeros(n_permutes,2,length(v_freq));
            max_clust_info   = zeros(n_permutes,1);
            max_pixel_pvals = zeros(n_permutes,2);
            %% permutation loop 
            curr_perm = zeros(mat_size); % initializing a variable to temporary store the results of current permutation before reshaping back into tf spectograph
            permuted_vals = zeros([n_permutes, mat_size]);
            
            % waitbar
            x_wait = 0;
            f = waitbar(x_wait,'Performing the permutations...','Name','npp_stb',...
                'CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
            
            setappdata(f,'canceling',0);
            
            
            for permi=1:n_permutes
                 if getappdata(f,'canceling')
                    delete(f)
                    msg = 'Error, user terminated the function.';
                    error(msg)
                 end
                
                x_wait = permi/n_permutes;
                waitbar(x_wait,f,'performing the permutations...');
                

                % (pre/post matrices are invariant across permutations and are
                % built once before the loop)

                % split the dataframes into pre and post randomly
                v = [ones(n_subs, 1); 2*ones(n_subs, 1)];
                vshuffle = v(randperm(2*n_subs));

                % first concatenate pre and post estimates
                test_cont = cat(1,test_poststim_points,test_prestim_points);
                pre_alloc = test_cont(vshuffle == 1,:,:);
                pos_alloc = test_cont(vshuffle == 2,:,:);


                % perform the ttest
                [~,~,~,stats] = ttest(pos_alloc,pre_alloc);
                curr_perm = squeeze(stats.tstat); % keep it 2-D for bwconncomp
                permuted_vals(permi,:,:) = curr_perm;


                % 
                % 
                % for i = 1:mat_size(1) % per frequency bin 
                %     prestim_estimate = squeeze(input_mat(:,i,time_s:time_0));
                %     poststim_points = squeeze(input_mat(:,i,time_s:time_e));
                %     pre_stim_estimate_r = zeros([n_subs,1]);
                %     temp1 = size(prestim_estimate);
                %     for m = 1:n_subs
                %         r_index = randi([1,temp1(2)]);
                %         pre_stim_estimate_r(m) = prestim_estimate(m,r_index);
                %     end
                %     %prestim_points = repmat(pre_stim_estimate_r, 1,length(poststim_points));
                %     shuffled_data_1 = zeros(size(poststim_points));
                %     shuffled_data_2 = zeros(size(poststim_points));
                %     for j = 1:mat_size(2) % per timepoint
                %         c_data = poststim_points(:,j);
                %         c_data_out_1 = zeros(size(c_data));
                %         c_baseline = pre_stim_estimate_r;
                %         c_data_out_2 = zeros(size(c_data));
                %         conditions = randi([1, 2], n_subs,1); %% create a vector that randomly assings a 1 or a 2 to each subject
                %         for k = 1:n_subs % switching labels per subject
                %             if conditions(k) == 1 % if the subject is assigned condition 1
                %                 c_data_out_1(k) = c_data(k);
                %                 c_data_out_2(k) = c_baseline(k);
                %             elseif conditions(k) == 2
                %                 c_data_out_1(k) = c_baseline(k);
                %                 c_data_out_2(k) = c_data(k);
                %             end
                %         end
                %         shuffled_data_1(:,j) = c_data_out_1;
                %         shuffled_data_2(:,j) = c_data_out_2;   
                %     end
                %     [~,~,~,stats] = ttest(shuffled_data_1,shuffled_data_2);
                %     curr_perm(i,:) = stats.tstat;
                % end
                % permuted_vals(permi,:,:) = curr_perm;
                % 
                
                % saving max for correction for multiple comparisons
                max_pixel_pvals(permi,:) = [ min(curr_perm(:)) max(curr_perm(:)) ];
            
                % for cluster correction, apply uncorrected threshold and get maximum cluster sizes
                % note that here, clusters were obtained by parametrically thresholding
                % the t-maps
                curr_perm(abs(curr_perm)<tinv(1-voxel_pval,n_subs-1))=0;
                clustinfo = bwconncomp(curr_perm);
                max_clust_info(permi) = max([ 0 cellfun(@numel,clustinfo.PixelIdxList) ]); % notes: cellfun is superfast, and the zero accounts for empty maps
            end
            delete(f)
            
            %% getting zmaps
            perm_sd = squeeze(std(permuted_vals,0,1,"omitnan"));
            perm_sd(perm_sd == 0 | isnan(perm_sd)) = eps; % avoid divide-by-zero
            zmap = (t_mat-squeeze(mean(permuted_vals,1,"omitnan")))./perm_sd;
            zmapthresh = zmap;
            % appliying uncorrected threshold
            zmapthresh(abs(zmapthresh)<norminv(1-voxel_pval))=false;
            zmapthresh=logical(zmapthresh);
            
            
            % apply pixel-level corrected threshold
            lower_threshold = prctile(max_pixel_pvals(:,1),    mcc_voxel_pval*100/2);
            upper_threshold = prctile(max_pixel_pvals(:,2),100-mcc_voxel_pval*100/2);
            zmapthresh_plc = zmap;
            mask1 = zmap>lower_threshold;
            mask2 = zmap<upper_threshold;
            mask = logical(mask1 + mask2-1);
            zmapthresh_plc(mask) = 0;
            
            %% apply cluster-level corrected threshold
            zmapthresh_clc = zmap;
            % apply uncorrected pixel-level threshold
            zmapthresh_clc(abs(zmapthresh_clc)<norminv(1-cluster_pval))=false;
            % find islands and remove those smaller than cluster size threshold
            clustinfo = bwconncomp(zmapthresh_clc);
            clust_info = cellfun(@numel,clustinfo.PixelIdxList);
            clust_threshold = prctile(max_clust_info,100-mcc_cluster_pval*100);
            
            % identify clusters to remove
            whichclusters2remove = find(clust_info<clust_threshold);
            
            % remove clusters
            for i=1:length(whichclusters2remove)
                zmapthresh_clc(clustinfo.PixelIdxList{whichclusters2remove(i)})=0;
            end
            my_clims = [-9 9];
            if plotting_input == 1
                figure
                my_lxlim = time_window(1)+10;
                my_hxlim = time_window(2);
                subplot(2,2,1)
                contourf(tfv_time,v_freq,zmap,40,'linecolor','none')
                axis square
                set(gca,'clim',my_clims,'xlim',[my_lxlim my_hxlim])
                title('Unthresholded Z map')
                xlabel('Time (ms)'), ylabel('Frequency (Hz)')
                colormap jet

                c = colorbar;
                c.Label.String = 'Z score';
            
                subplot(2,2,2)
                contourf(tfv_time,v_freq,zmap,40,'linecolor','none')
                hold on
                contour(tfv_time,v_freq,zmapthresh,1,'linecolor','k')
                c = colorbar;
                c.Label.String = 'Z score';
                axis square
                set(gca,'clim',my_clims,'xlim',[my_lxlim my_hxlim])
                title('Uncorrected threshold Z map')
                xlabel('Time (ms)'), ylabel('Frequency (Hz)')
            
            
                subplot(2,2,3)
                contourf(tfv_time,v_freq,zmapthresh_plc,40,'linecolor','none')
                axis square
                set(gca,'clim',my_clims,'xlim',[my_lxlim my_hxlim])
                title('Pixel-corrected Z map')
                xlabel('Time (ms)'), ylabel('Frequency (Hz)')
                c = colorbar;
                c.Label.String = 'Z score';
            
                subplot(2,2,4)
                contourf(tfv_time,v_freq,zmapthresh_clc,40,'linecolor','none')
                axis square
                set(gca,'clim',my_clims,'xlim',[my_lxlim my_hxlim])
                title('Cluster-corrected Z map')
                xlabel('Time (ms)'), ylabel('Frequency (Hz)')
                c = colorbar;
                c.Label.String = 'Z score';
                %colormap(rbw_good_one_2)
                sgtitle(my_title)
            end

            
        end
        
        
        function plot_trial_data(app)
            current_data = evalin('base','current_data');
            new_color_map_2 = get_colormap(app);

            % check if we are doing the warm filter
            if app.WARMFilterSwitch.Value == "On"
                EEG_dat = current_data.WARM_filter.single_trial_waveforms;
                x_current = current_data.WARM_filter.time;
   
            else
                EEG_dat = current_data.data;
                x_current = current_data.time;
            end


            [~,low_index] = (min(abs(x_current - app.TimeLowmsEditField.Value)));
            [~,high_index] = (min(abs(x_current - app.TimeHighmsEditField.Value)));
            subset_data = EEG_dat(low_index:high_index,:);
            max_sign = max(max(abs(subset_data)));
            x = x_current(low_index:high_index);

            % EEG_dat is NOT cut here, so the SNR helper must get the full-length
            % time vector or every index it derives is wrong.
            [sd2,SNR,SNR_michelle] = calc_params_peak_extract(app,EEG_dat,x_current);

            if ~isempty(SNR) && ~isnan(SNR)

                %
                % finding the max to plot
                cla(app.UIAxes7)

                %trials = size(data2plot,2);
                imagesc(app.UIAxes7,x,1:size(subset_data,2),subset_data')
                rectangle(app.UIAxes7,'Position', [...
                    app.stim_range(1),...
                    0,...
                    app.stim_range(2)- app.stim_range(1),...
                    size(subset_data,2)], ...
                    'FaceColor', 'none', ...
                    'EdgeColor', [0, 0, 0, 1], ...
                    'Curvature',[0, 0]);
                xline(app.UIAxes7,0,'--');
                hold(app.UIAxes7,'off')
                colormap(app.UIAxes7,new_color_map_2)
                % the clims slider is the colour scale, and is re-pointed at
                % this data set each time the trial image is redrawn
                prefer_trial = [];
                if app.clim_reset_trial
                    prefer_trial = NaN;   % NaN is never valid -> forces the default
                    app.clim_reset_trial = false;
                end
                caxis(app.UIAxes7, sync_clim_slider(app,app.climsSlider,subset_data,true,0.8,prefer_trial));
                c = colorbar(app.UIAxes7,"east");
                c.Label.String = "Amplitude (mV)";
                app.UIAxes7.YTick = 1:size(subset_data,2);
                hold(app.UIAxes7,'off')
                xlim(app.UIAxes7, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])
                ylim(app.UIAxes7, [1 size(subset_data,2)])
                avg_data = squeeze(mean(subset_data,2,"omitnan"));

                cla(app.UIAxes8)

                hold(app.UIAxes8,"on");
                rectangle(app.UIAxes8,'Position', [...
                    app.TimeLowmsEditField.Value,...
                    -sd2,...
                    app.TimeHighmsEditField.Value - app.TimeLowmsEditField.Value,...
                    2*sd2], ...
                    'FaceColor', [.5, .5, .5, 0.2], ...
                    'EdgeColor', [1, 1, 1, 1]);
                rectangle(app.UIAxes8,'Position', [...
                    app.stim_range(1),...
                    app.YminEditField.Value,...
                    app.stim_range(2)- app.stim_range(1),...
                    app.YmaxEditField.Value-app.YminEditField.Value], ...
                    'FaceColor', [0.75,0.20,0.23, 0.8], ...
                    'EdgeColor', [1, 1, 1, 1], ...
                    'Curvature',[0.2, 0.2]);

                plot(app.UIAxes8,x,avg_data,'LineWidth',2,"Color","w");
                set(app.UIAxes8, 'YDir','reverse')

                [~,max_idx] = min(abs(x-app.TimeHighmsEditField.Value));
                [~,min_idx] = min(abs(x-app.TimeLowmsEditField.Value));
                % get new ylims for the signal
                high_y = max(avg_data(min_idx:max_idx));
                min_y = min(avg_data(min_idx:max_idx));
                [min_y,high_y] = pad_limits(app,min_y,high_y);

                xlim(app.UIAxes8, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])
                safe_ylim(app,app.UIAxes8,min_y,high_y)
                xline(app.UIAxes8,0,'w--');
                yline(app.UIAxes8,sd2)
                yline(app.UIAxes8,-sd2)
                app.UIAxes8.Color = 'k';


            else
                cla(app.UIAxes7)
                cla(app.UIAxes8)
                xline(app.UIAxes7,0,'--');
                xlim(app.UIAxes7, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])
                ylim(app.UIAxes7, [1 size(subset_data,2)])
                xlim(app.UIAxes8, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])
                apply_ylim(app,app.UIAxes8)
                xline(app.UIAxes8,0,'--');

            end
        end

        
        function my_settings = compile_settings(app)
            % used to declare an output and never assign it (and referenced a
            % component that no longer exists), so it errored when called
            my_settings = struct();

            % time domain settings
            my_settings.time_low_ms = app.TimeLowmsEditField.Value;
            my_settings.time_high_ms = app.TimeHighmsEditField.Value;
            my_settings.y_min = app.YminEditField.Value;
            my_settings.y_max = app.YmaxEditField.Value;
            my_settings.signal_start_ms = app.SignalStartmsEditField.Value;
            my_settings.signal_end_ms = app.SignalEndmsEditField.Value;
            my_settings.auto_scale = string(app.AutoSwitch.Value);
            my_settings.low_pass = string(app.LowpassSwitch.Value);
            my_settings.stim_type = string(app.typeofstim.Value);
            my_settings.erp_type = string(app.typeoferp.Value);
            my_settings.stim_range = app.stim_range;

            % time frequency settings
            my_settings.freq_min = app.minfreqEditField_2.Value;
            my_settings.freq_max = app.maxfreqEditField_2.Value;
            my_settings.n_freq_bins = app.offrequencybinsEditField_2.Value;
            my_settings.cycles_low = app.ofcycleslowerEditField_2.Value;
            my_settings.cycles_high = app.ofcycleshigherEditField_2.Value;
            my_settings.tf_time_low = app.timelowerEditField_2.Value;
            my_settings.tf_time_high = app.timehigherEditField_2.Value;
            my_settings.baseline_start = app.baseline_start.Value;
            my_settings.baseline_end = app.baseline_end.Value;
            my_settings.baseline_norm = string(app.BaselineNormSwitch.Value);
            my_settings.conversion = string(app.ConversionSwitch_2.Value);
            my_settings.frequency_spacing = string(app.SpacingSwitch_2.Value);
            my_settings.warm_filter = string(app.WARMFilterSwitch.Value);
            my_settings.n_permutations = app.npermutationsEditField.Value;

            % subject info
            my_settings.subject_id = app.IDEditField.Value;
            my_settings.age = app.AgeEditField.Value;
            my_settings.sex = app.SexEditField.Value;
            my_settings.notes = string(app.NotesTextArea.Value);
        end
        
        function results = extract_single_trial_data(app)
           EEG = evalin('base','EEG');
           n_peaks = app.numberofpeakstoextractEditField_2.Value;
           fig = figure(); 
           plot(EEG.WARM_filter.time,EEG.WARM_filter.mean_waveform)
           %set(gca, 'YDir','reverse')
           hold on
%            
%            appAxes = app.UIAxes2_7;
%            
%            ax = copyobj(appAxes, fig); 


           for i  = 1:n_peaks
               prompt = {'Enter name of peak'};
               dlgtitle = 'Name of peak';
               dims = [1 35];
               definput = {['Peak ',num2str(i)],'hsv'};
               answer = inputdlg(prompt,dlgtitle,dims,definput);


                temp_roi = drawrectangle(fig.CurrentAxes,'StripeColor','b','InteractionsAllowed','none');
                position = temp_roi.Position;  
                x_min = position(1);
                x_max  = position(1) + position(3);
                [~,low_index] = (min(abs(EEG.WARM_filter.time - x_min)));
                [~,high_index] = (min(abs(EEG.WARM_filter.time - x_max)));
                % determining if its a negative or a positive peak 
                erp = EEG.WARM_filter.mean_waveform;
                erp_cut = erp(low_index:high_index);
                [highest_point , highest_index]  = max(erp_cut);
                [lowest_point , lowest_index]  = min(erp_cut);
                
                pont = -1; % reset: an unresolved selection must not reuse the previous peak
                pgn = abs(highest_point) > abs(lowest_point); % check to see if the highest positive point has a greater magnitude than the lowerst negative point. reurn zero if it is true, and return 1 if it is not true.
                
                if pgn == 0 % if my positive point is not greater than negative point
                    % check that the negative dip is not in the end
                    if lowest_index ~= 1 && lowest_index ~= length(erp_cut)
                        pont = 0; % positiv or negative toggle (1 = positive peak, -0 = negative peak)
                    elseif highest_index ~= 1 && highest_index ~= length(erp_cut) % if it is in the end, check to see if the positive is in the end or not 
                        pont  = 1;
                    else 
                        warning("The region that you selected did not contain a peak - please try again ")
                    end
                elseif pgn == 1 % if my positive point is greater than my negative point
                    % check tha the positive dip is not at the end
                    if highest_index ~= 1 && highest_index ~= length(erp_cut)
                        pont = 1;
                    elseif lowest_index ~= 1 && lowest_index ~= length(erp_cut) 
                        pont = 0;
                    else
                        warning("The region that you selected did not contain a peak - please try again ")
                    end
                    
                end
                
                if pont == 1
                    my_peak = highest_point ;
                    my_latency = EEG.WARM_filter.time(low_index+highest_index-1);
                elseif pont == 0
                    my_peak = lowest_point ;
                    my_latency = EEG.WARM_filter.time(low_index+lowest_index-1);
                else
                    warning("Please re select the region")
                    continue
                end
                
                my_table = app.UITable3.Data;

                % Extracting for single trial data
                st_data = EEG.WARM_filter.single_trial_waveforms;
                % window in ms
                wind = 0;

                m_data.Channel = string(app.ChannelsListBox.Value);
                m_data.Name = string(answer);
                m_data.Amplitude =my_peak;
                m_data.Latency =  my_latency;
                if app.i_peaks_st >= 1
                    if istable(my_table) && ~isempty(my_table)
                        my_table = [my_table; struct2table(m_data)];
                    else
                        my_table = struct2table(m_data);
                    end
                else
                    my_table = struct2table(m_data);
                end
                app.UITable3.Data = my_table; 

                app.i_peaks_st = app.i_peaks_st + 1;

           end
           
           close(fig)


        end
        
        function app_setup(app,new_dat_switch)

            current_ylim_low = app.YminEditField.Value;
            current_ylim_high = app.YmaxEditField.Value;


            app.BaselineNormSwitch.Value = "On";
            apply_ssep_loading_defaults(app);
        
            app.extraction_state = 0;% Has data been extracted?
            app.Tree.CheckedNodes = [];% unselect everything
            app.SignalStartmsEditField.Value = app.stim_range(1);
            app.SignalEndmsEditField.Value = app.stim_range(2);
            a = app.Tree.Children;
            a.delete; % delete all nodes on tree to avoid overpopulating them 
            app.settings.baseline_norm = 1; % by default, normalize the data


            data_in_info = app.DataInEditField.Value;
            [filepath,name,ext] = fileparts(data_in_info);

            if new_dat_switch  == 1

                if app.settings.file_type == 0 % for .set file
                    EEG = pop_loadset('filename',[name, ext],'filepath',filepath); %loads the specified file into eeglab
                    assignin('base','EEG',EEG)
                    ALLEEG = EEG;
                    assignin('base','ALLEEG',ALLEEG);
                elseif app.settings.file_type == 1 % for .mat files
                    load([filepath filesep() name ext],"EEG");
                    assignin('base','EEG',EEG)
                    ALLEEG = EEG;
                    assignin('base','ALLEEG',ALLEEG);
                elseif app.settings.file_type == 2 % for txt files

                    [ALLEEG, EEG] = load_txt_single_channel(app,filepath,name,ext);

                    assignin('base','EEG',EEG)
                    assignin('base','ALLEEG',ALLEEG)

                elseif app.settings.file_type== 3 % for keypoint files

                    [ALLEEG, EEG] = load_fnct_keypont(app,[filepath filesep() name]);
                    EEG = ALLEEG(1);
                    assignin('base','EEG',EEG)
                    assignin('base','ALLEEG',ALLEEG)

                elseif app.settings.file_type == 4 % for keypoint files
                    [ALLEEG, EEG] = load_fnct_pathway(app,[filepath filesep() name]);
                    assignin('base','EEG',EEG)
                    assignin('base','ALLEEG',ALLEEG)

                end
            else
                EEG = evalin("base","EEG");
                if app.settings.file_type == 3
                    if ~strcmp(string(app.current_data_id), string(EEG.setname))
                        ALLEEG = evalin('base','ALLEEG');
                        chan2plot = app.ChannelsListBox.Value;
                        % figure out which dermatome is being trying to be plotted
                        % and load the correct EEG file and the right chanloc index
                        % relative to that file
                        EEG_idx = 1; % default when the channel name matches nothing
                        for i_EEG = 1:length(ALLEEG)
                            EEG = ALLEEG(i_EEG);
                            current_chanlocs = EEG.chanlocs;
                            for j = 1:length(current_chanlocs)
                                if string(current_chanlocs(j).labels) == string(chan2plot)
                                    EEG_idx = i_EEG;
                                    chan_idx = j;
                                    break
                                end
                            end
                        end
                        EEG = ALLEEG(EEG_idx);
                        current_channel.data_name = EEG.setname;
                        app.current_data_id = current_channel.data_name;
                    end 
                end
            end

            size_data = size(EEG.data);
            
           if length(size_data) == 2 % if it is only a single channel 
                n_trials = size_data(2);
                n_points = size_data(1);
                n_channels = 1;
                %n_chans = 1;
                %sensoridx = 1;
   
            elseif length(size_data) == 3
                n_trials = size_data(3);
                n_points = size_data(2);
                n_channels = size_data(1);
                %sensoridx = find(strcmpi(chan2plot,{chanlocs.labels}));
           end
           
            app.SamplingRateEditField.Value = EEG.srate;
            app.EpochsEditField.Value = n_trials;
            app.FramesPerEpochEditField.Value = n_points;
            app.NChannelsEditField.Value = n_channels;
            app.current_data_id = string(EEG.setname);
            n_trials2plot =  app.nrowsEditField.Value * app.ncolumnsEditField.Value;
            %app.baselinelowerendEditField_2.Value = app.TimeLowmsEditField.Value;
            %app.baselinehighendEditField.Value = 0;

            % Limits must be strictly increasing - a single page used to error
            app.PageSlider.Limits = [1 max(2,ceil(n_trials/max(n_trials2plot,1)))];
            app.PageSlider.Value = 1;
            

            my_chanlocs = {};
            if app.settings.file_type == 3
                %ALLEEG = evalin("base","ALLEEG");
                chanloc_count = 1;
                curr_EEG = EEG;
                for i  = 1:length(ALLEEG)
                    EEG = ALLEEG(i);
                    for j = 1:length(EEG.chanlocs)
                        my_chanlocs(chanloc_count) = cellstr(EEG.chanlocs(j).labels);
                        chanloc_count = chanloc_count+1;
                    end
                end
                app.ChannelsListBox.Items =my_chanlocs;
                EEG = curr_EEG; % index the first eeg structure again
            else
                for i  = 1:length(EEG.chanlocs)
                    my_chanlocs(i) = cellstr(EEG.chanlocs(i).labels); 
                end
                app.ChannelsListBox.Items =my_chanlocs;

            end

            % create nodes for all the data
            p = uitreenode(app.Tree,"Text","All Trials");
            for i = 1:n_trials
                c1 = uitreenode(p,"Text",string(i));
            end

            % expand the nodes
            expand(p);

            % select all nodes by default
           app.Tree.CheckedNodes = app.Tree.Children;          

            %app.TrialOverlayListBox.Items = string(1: n_trials);

            app.TrialsDisplayedListBox.Items =string(1:n_trials);
            app.TrialsDisplayedListBox.Value = app.TrialsDisplayedListBox.Items;


            %EEG = ALLEEG(1); % index the first eeg structure again
            chan2plot = string(app.ChannelsListBox.Value);
            chan_idx = 1; % default to the first channel if the name is not found
            for i = 1:length(EEG.chanlocs)
                if chan2plot == string(EEG.chanlocs(i).labels)
                    chan_idx = i;
                end
            end
            [~,low_index] = (min(abs(EEG.times - app.TimeLowmsEditField.Value)));
            [~,high_index] = (min(abs(EEG.times - app.TimeHighmsEditField.Value)));
            
            avg2plot = squeeze(mean(EEG.data(chan_idx,low_index:high_index,:),3,"omitnan"));
            
            my_lim = max(abs(avg2plot)) + max(abs(avg2plot))*0.10 ;
            app.YmaxEditField.Value = double(my_lim);
            app.YminEditField.Value = double(-my_lim);
            

            if app.typeofstim.Value == "CHEPs"
                app.stim_range = app.cheps_range;
            elseif app.typeofstim.Value == "CEPs"
                app.stim_range = app.ceps_range;
            elseif app.typeofstim.Value == "LEPs"
                app.stim_range = app.leps_range;
            elseif app.typeofstim.Value == "PEPs"
                app.stim_range = app.peps_range;
            end

            if app.AutoSwitch.Value == "Off"
                app.YminEditField.Value = current_ylim_low;
                app.YmaxEditField.Value = current_ylim_high;
            end


        end
        
        function adjust_yaxis(app)
            EEG = evalin('base','EEG');
            chan2plot = string(app.ChannelsListBox.Value);


            % check if its keypoint
            if app.settings.file_type == 3 
                ALLEEG = evalin('base','ALLEEG');
                % figure out which dermatome is being trying to be plotted
                % and load the correct EEG file and the right chanloc index
                % relative to that file
                EEG_idx = 1; % default when the channel name matches nothing
                for i_EEG = 1:length(ALLEEG)
                    EEG = ALLEEG(i_EEG);
                    current_chanlocs = EEG.chanlocs;
                    for j = 1:length(current_chanlocs)
                        if string(current_chanlocs(j).labels) == string(chan2plot)
                            EEG_idx = i_EEG;
                            chan_idx = j;
                        end
                    end
                end
                EEG = ALLEEG(EEG_idx);
            else
                chan_idx = 1; % default when the channel name is not found
                for i = 1:length(EEG.chanlocs)
                    if chan2plot == string(EEG.chanlocs(i).labels)
                        chan_idx = i;
                    end
                end
            end

     
            [~,low_index] = (min(abs(EEG.times - app.TimeLowmsEditField.Value)));
            [~,high_index] = (min(abs(EEG.times - app.TimeHighmsEditField.Value)));
            
            avg2plot = squeeze(mean(EEG.data(chan_idx,low_index:high_index,:),3,"omitnan"));
            
            my_lim = max(abs(avg2plot)) + max(abs(avg2plot))*0.10 ;
            app.YmaxEditField.Value = double(my_lim);
            app.YminEditField.Value = double(-my_lim);

        end
        

        function [EEG, erp, filt_recon,filt_resampled_ind_data,resampled_time] = warm_filter(app,EEG)

            % kept for backwards compatibility - compute_warm_signals() is the
            % version the app actually uses. current_data was referenced further
            % down without ever being fetched.
            current_data = evalin('base','current_data');
            s = tf_settings(app);

            [~ , frequencies,x,convol_pwr_ind,individual_convol_result,total_convol_result] = calc_tf_data(app);

            % resample everything and put into same time scale
            time_window = s.time_lims; % time window that you want to analyze 
            [~,i_low] = (min(abs(x - time_window(1))));
            [~,i_high] = (min(abs(x - time_window(2))));
            %x1 = x(i_low:i_high); % cut and resampled time 


            % convol power individual 
            [p,q] = rat(floor(app.SamplingRateEditField.Value/10)/app.SamplingRateEditField.Value);
            resampled_pwr_ind = resample(double(convol_pwr_ind),p,q,'Dimension',2);
            resampled_time = linspace(min(x),max(x),size(resampled_pwr_ind,2));

            % find chan IDX
            chan2plot = string(app.ChannelsListBox.Value);
            chan_idx = 1; % default when the channel name is not found
            for i = 1:length(EEG.chanlocs)
                if chan2plot == string(EEG.chanlocs(i).labels)
                    chan_idx = i;
                end
            end

            % calculate ERP
            erp = squeeze(mean(EEG.data(chan_idx,:,:),3,"omitnan"));  
            [~,i_low_erp] = (min(abs(EEG.times - time_window(1))));
            [~,i_high_erp] = (min(abs(EEG.times - time_window(2))));
            erp = erp(i_low_erp:i_high_erp-1);
            erp = resample(double(erp),p,q,'Dimension',1);

            % some of the input parameters for the function
            input_mat = permute(resampled_pwr_ind,[3 1 2]);% rearange input matrix containing all the trials
            n_permutes = app.npermutationsEditField_2.Value; % number of permutations
            v_time = resampled_time; % vector of time
            v_freq = frequencies; % vector of frequencies in 
            plotting_input = 0; % make 1 if you want to plot the responses or zero if you don't.
            baseline_range = s.baseline;
        
            my_title = ['testing function']; % required for the npp_stb_2 function
            
            % (the UIAxes2_6 axes this used to clear no longer exists)

            % check if the zmaps have been done
            if ~isfield(EEG,'zmaps')
                 [zmap, zmapthresh, zmapthresh_plc, zmapthresh_clc, tfv_time, v_freq] = npp_stb_2(app,input_mat...
                    ,n_permutes,v_time,v_freq,time_window,baseline_range,plotting_input,my_title);
            
                EEG.zmaps.zmapthresh_clc = zmapthresh_clc;
                EEG.zmaps.times = tfv_time;
                EEG.zmaps.frequencies = v_freq;
                EEG.zmaps.zmap = zmap;
                EEG.zmaps.zmapthresh = zmapthresh;
                EEG.zmaps.zmapthresh_plc = zmapthresh_plc;

                assignin('base','EEG',EEG)  
            else
                zmapthresh_clc = EEG.zmaps.zmapthresh_clc; % read clc zmaps from eeg structure
            end


            % transform the average ERP 
            % first resample
            resampled_convol_mean = resample(double(total_convol_result),p,q,'Dimension',2);

            freq_lims= s.freq_lims;
            % do i need the next line?
            [~,~,resamp_scalingf] = cwt(erp,'amor',floor(EEG.srate/10),FrequencyLimits = freq_lims); % do it on a single trial to get the scaling factors 

            % apply the cwt filter based on the zmap
            % convert mask into logical
            logical_clc_zmap = zmapthresh_clc ~= 0;
            % multiply convol matrix in tf domain to filter out non
            % significant regions
            filtered_mean_convol = resampled_convol_mean .* double(logical_clc_zmap);
            % reconstruct filtered convol signal in the time domain
            filt_recon= icwt(filtered_mean_convol,"amor");

            % repeat the same thing with the individual trials
           resampled_ind_convol = resample(double(individual_convol_result),p,q,'Dimension',2);
           filt_resampled_ind_data = zeros(length(filtered_mean_convol),size(resampled_ind_convol,3));
           resampled_ind_data = zeros(length(filtered_mean_convol),size(resampled_ind_convol,3));

                      % loop through each trial to reconstruct filtered time signal
           for i = 1:size(resampled_ind_convol,3)
               if current_data.trials_mask(i)
                   c_dat = squeeze(resampled_ind_convol(:,:,i));
                   filtered_convol = c_dat .* double(logical_clc_zmap);
                   recon_ind = icwt(c_dat,"amor");
                   filt_recon_ind= icwt(filtered_convol,"amor");
                   filt_resampled_ind_data(:,i) =filt_recon_ind';
                   resampled_ind_data(:,i) =recon_ind';
               else
                   filt_resampled_ind_data(:,i) = nan;
                   resampled_ind_data(:,i) = nan;
               end

           end


           %% save in EEG structure
           EEG.WARM_filter.single_trial_waveforms = filt_resampled_ind_data;
           EEG.WARM_filter.mean_waveform = filt_recon;
           EEG.WARM_filter.time = resampled_time;
            
        end
        
        function [ALLEEG,EEG] = load_fnct_keypont(app,file_path)
            header_info = readtable([file_path, filesep(),'Header.txt']);
            load('chan_info.mat',"chanlocs");

            % check sampling frequency
            % these used to fall through to an empty else, leaving sr / l / t_off
            % undefined and failing later with a confusing error
            if std(header_info.SmplFrq) == 0
                sr = header_info.SmplFrq(1);
            else
                error('load_fnct_keypont:mixedSampleRate','Header.txt lists more than one sampling rate.');
            end

            % check length of files
            if std(header_info.Samples) == 0
                l = header_info.Samples(1);
            else
                error('load_fnct_keypont:mixedLength','Header.txt lists more than one epoch length.');
            end

            % check trigger offset
            if std(header_info.TrigOff) == 0
                t_off = header_info.TrigOff(1);
            else
                error('load_fnct_keypont:mixedTrigger','Header.txt lists more than one trigger offset.');
            end

             
            % create time vector
            x = linspace(0,(1000/sr)*l,l)-t_off/sr*1000; % in ms

            % load and organize all the text files into EEG structure
            %%% get list of dermatomes

            files = header_info.File;

            % try reading the types of headers 
            try
                % header split differently
                temp = split(string(files),"__");
                info_split = split(temp(:,1),"-");
                chans_in = unique(info_split(:,3)); %chanel names
                derms_in = unique(info_split(:,4)); %derms tested'
            catch
                % header split differently 
                temp = split(string(files),"__");
                info_split = split(temp(:,1),"-");
                chans_in = unique(info_split(:,3)); %chanel names
                derms_in = unique(info_split(:,1)); %derms tested'

            end


            % create an EEGLAB structure per dermatome
            for i= 1:length(derms_in) % iterate for all dermatomes 
                c_derm = derms_in(i);
                try
                    tf_mask = contains(info_split(:,4),c_derm);
                catch
                    tf_mask = contains(info_split(:,1),c_derm);
                end

                c_files = files(tf_mask);
                % get only the first bit of the file format to check for
                % names

                data_in = zeros(length(chans_in),l,ceil(length(c_files)/length(chans_in))); % make a strucutre to store the data
    
                for j = 1:length(chans_in) % iterate for all channels available per dermatome
                    c_chan = chans_in(j);
                    temp_1 = split(string(c_files),"__");
                    info_split_1 = split(temp_1(:,1),"-");
                    tf_mask_chan = contains(info_split_1(:,3),c_chan);
                    chan_files = c_files(tf_mask_chan);% get list of channels to load
                    all_chans = blanks(length(chanlocs));

                    % get channel names 
                    for k =1:length(chanlocs)
                        if k == 1
                            all_chans = string(chanlocs(k).labels);
                        else
                            all_chans = [all_chans,string(chanlocs(k).labels)];
                        end

                    end

                     % getting the channels and adding dermatome info to them
                     if c_chan == "N1"
                         chan2id = 'C3';% choose either c3 or c4. 
                         chan_info = chanlocs(all_chans == chan2id);
                         chan_info.labels = ['N1-',char(c_derm)];
                    
                     elseif c_chan == "EOG"
                         chan2id = 'Fp1'; % choose something that would normally be chosen for blink correctio
                         chan_info = chanlocs(all_chans == chan2id);
                         chan_info.labels = ['EOG-',char(c_derm)];
                     else
                         try
                             chan_info = chanlocs(all_chans == c_chan);
                             chan_info.labels = [char(c_chan),'-',char(c_derm)];
                         catch
                             chan_info = chanlocs(32); % for the EOG
                             chan_info.labels = [char(c_chan),'-',char(c_derm)];
                         end
                     end
                     

                    for k = 1:length(chan_files) % iterate through all files to load
                        data_in(j,:,k) = load([file_path,filesep(),char(chan_files(k))]);
                    end

                    if j == 1
                        chanlocs_out =chan_info;

                    else
                        chanlocs_out =[chanlocs_out;chan_info];

                    end


                end


                % getting the channels and adding dermatome info to them


                % creating the eeg structure
                EEG.setname = ['Dermatome-', char(c_derm)];
                EEG.filename = [];
                EEG.filepath = file_path;
                EEG.nbchan = length(chans_in);
                EEG.trials = length(chan_files);
                EEG.pnts = l;
                EEG.srate = sr;
                EEG.xmin = min(x)/1000;
                EEG.xmax= max(x)/1000;
                EEG.times = x;
                EEG.data = -data_in;
                EEG.icaact = [];
                EEG.icawinv = [];
                EEG.icasphere = [];
                EEG.icaweights = [];
                EEG.icachansind = [];
                EEG.chanlocs = chanlocs_out;
                EEG.ref = "unknown";
                
               ALLEEG(i) = EEG;
            end


        end
        
        function EEG_out = preprocc_eeg(app)

            EEG = evalin('base','EEG');
            c_dat = evalin('base','current_data');
            EEG_in = c_dat.data;
            x = EEG.times;

        
            % baseline correct
           baseline_range = [app.TimeLowmsEditField.Value 0];
           [~,low_idx] = (min(abs(x- baseline_range(1))));
           [~,high_idx] = (min(abs(x - baseline_range(2))));

           mean_baseline = mean(EEG_in(low_idx:high_idx,:),"omitnan");

           EEG_out = EEG_in - mean_baseline;


            if app.low_pass_toggle == 1

                EEG_out_temp = EEG_out;
                EEG_out(isnan(EEG_out)) = 0;

                % bandpass
                nyquist = EEG.srate/2;
                fc_low = 0.5;
                fc_high =30;
                [bp,ap]=butter(3,[fc_low,fc_high]/nyquist,'bandpass');
                EEG_out=filtfilt(bp,ap,EEG_out);

                EEG_out(EEG_out==0) = EEG_out_temp(EEG_out==0);
                c_dat.filtered = "On";

            else
                % the flag has to be cleared as well, otherwise a dataset stays
                % marked as filtered after the low-pass switch is turned off and
                % the peak table matches against the wrong rows
                c_dat.filtered = "Off";
            end

            c_dat.data = EEG_out;

            assignin('base','current_data',c_dat);


        end
        
        function [sd2,SNR_oddeven,SNR_michelle]= calc_params_peak_extract(app,EEG_dat,x)

            sd2 = [];
            SNR_oddeven = [];
            SNR_michelle = [];
            x = double(x(:))';
            if isempty(EEG_dat) || numel(x) ~= size(EEG_dat,1)
                warning('calc_params_peak_extract:sizeMismatch', ...
                    'Time vector (%d) does not match the data (%d samples).', numel(x), size(EEG_dat,1));
                return
            end

            %baseline correct each trial
            % define baseline noise
            [~,low_base] = (min(abs(x - app.TimeLowmsEditField.Value)));
            [~,zero_base] = (min(abs(x - 0)));
            [~,high_base] = (min(abs(x - app.TimeHighmsEditField.Value)));
            
            % calculate baseline
            baseline_mean = mean(EEG_dat(low_base:zero_base,:),1,"omitnan");

            % cut the trials data to the window of interest and also baseline
            % correct
            chan_dat = EEG_dat(low_base:high_base,:)-baseline_mean;

             % extract erp
             erp = squeeze(mean(chan_dat,2,"omitnan"));


            % calculate 2sd from the baseline corrected data
            % new zero
            new_x = x(low_base:high_base);
            [~,new_zero] = (min(abs(new_x - 0)));

            if new_zero < 2 || new_zero >= numel(erp)
                % no usable pre-stimulus (or no post-stimulus) segment
                sd2 = 0;
                SNR_michelle = NaN;
            else
                sd2 = 2*std(erp(1:new_zero),'omitnan');

                % calculate SNR method 1 - way michelle calculates
                N = sqrt(mean(erp(1:new_zero).^2,'omitnan'));
                S = sqrt(mean(erp(new_zero+1:end).^2,'omitnan'));
                SNR_michelle = 20*log10(S/N);
            end


            % calculate SNR method 2 - odd even method
            odd_trials = mean(chan_dat(:,1:2:size(chan_dat,2)),2,"omitnan");
            even_trials = mean(chan_dat(:,2:2:size(chan_dat,2)),2,"omitnan");
            noise_estimate = odd_trials-even_trials;

            N = sqrt(mean(noise_estimate.^2,'omitnan'));
            S = sqrt(mean(erp.^2,'omitnan'));
            SNR_oddeven = 10*log10(S/N);

            % SNR from window from 0 to 1000 ms for signal for noise from
            % -500 to 0, for the SNr calc use a 20*log10 instead


        end
        
        function populate_figures(app)

            [tf , frequencies] = calc_tf_data(app);


            %is_f_chan_empt = max(isnan(tf(:))); % check if  channel is empty
            %if is_f_chan_empt == 1
                %app.ChannelsListBox.Value = char(app.ChannelsListBox.Items(2));
                %app.ChannelsListBox.Value = char(app.ChannelsListBox.Items(2));
                %[tf , frequencies] = calc_tf_data(app);
            %end

           % if app.WARMFilterSwitch.Value == "On"
            %    compute_warm_signals(app)
            %end

            plot_tdat(app);
            plot_multi_trials(app);
            plot_trial_data(app);
            plot_tfdata(app);
        end
        
        function [EEG_dat,sd2,SNR] = prep_data(app)
            EEG = evalin('base','EEG');
            
          select_channel(app);
          preprocc_eeg(app);


            % extract parameters to plot the extaction protocol

            x = EEG.times;
            chan2plot = string(app.ChannelsListBox.Value);


            % check if its keypoint
            if app.settings.file_type == 3 
                ALLEEG = evalin('base','ALLEEG');
                % figure out which dermatome is being trying to be plotted
                % and load the correct EEG file and the right chanloc index
                % relative to that file
                EEG_idx = 1; % default when the channel name matches nothing
                for i_EEG = 1:length(ALLEEG)
                    EEG = ALLEEG(i_EEG);
                    current_chanlocs = EEG.chanlocs;
                    for j = 1:length(current_chanlocs)
                        if string(current_chanlocs(j).labels) == string(chan2plot)
                            EEG_idx = i_EEG;
                            chan_idx = j;
                            break
                        end
                    end
                end
                EEG = ALLEEG(EEG_idx);
            else
                chan_idx = 1; % default when the channel name is not found
                for i = 1:length(EEG.chanlocs)
                    if chan2plot == string(EEG.chanlocs(i).labels)
                        chan_idx = i;
                    end
                end
            end

            EEG_dat = squeeze(EEG.data(chan_idx,:,:));


            % determine the trials to plot based on the tree box
            if ~isempty(app.Tree.CheckedNodes) % if the nodes are not empty
                if length(app.Tree.CheckedNodes) > 1 % if its not only a single trial selected
                    if app.Tree.CheckedNodes(1).Text =="All Trials" % if all trials are selected
                        trials2plot = 1:size(EEG_dat,2);
                    else % if more than 1 trial is selected
                        trials2plot = zeros(1,length(app.Tree.CheckedNodes));
                        for i = 1: length(app.Tree.CheckedNodes)
                            trials2plot(i) = str2double(app.Tree.CheckedNodes(i).Text);
                        end
                    end
                else % if its a single trial
                trials2plot = str2double(app.Tree.CheckedNodes.Text);
                end
                % subset only trials that needed
                %EEG_dat = squeeze(EEG_dat(:,trials2plot));
                trial_mask = ismember(1:size(EEG_dat,2), trials2plot);
                EEG_dat(:,~trial_mask) = 0;
                
                if app.low_pass_toggle == 1
                    EEG_dat = lowpass(EEG_dat,25,EEG.srate,'ImpulseResponse','iir'); %% can change to change the smoothing
                end


                [sd2,SNR,SNR_michelle] = calc_params_peak_extract(app,EEG_dat,x);


                avg2plot = squeeze(mean(EEG_dat,2,"omitnan"));

                % find y lims to plot better
                avg_min = min(avg2plot);
                avg_max = max(avg2plot);

                if app.AutoSwitch.Value == "On" % auto-scaling belongs to "On"
                    [y_lo_tmp,y_hi_tmp] = pad_limits(app,avg_min,avg_max);
                    app.YminEditField.Value = y_lo_tmp;
                    app.YmaxEditField.Value = y_hi_tmp;
                end


                % find pre baseline noise
                % define baseline noise
                [~,low_base] = (min(abs(x - app.TimeLowmsEditField.Value)));
                [~,zero_base] = (min(abs(x - 0)));


                cla(app.UIAxes) 
                
                rectangle(app.UIAxes,'Position', [...
                    app.TimeLowmsEditField.Value,...
                    -sd2,...
                    app.TimeHighmsEditField.Value - app.TimeLowmsEditField.Value,...
                    2*sd2], ...
                    'FaceColor', [.5, .5, .5, 0.5], ...
                    'EdgeColor', [1, 1, 1, 1]);

%                 if app.plot_single_trial ==1 && ~isempty(app.TrialOverlayListBox.Value) 
%                     trials2plot_disp = cellfun(@str2num,app.TrialOverlayListBox.Value);
%                     c_map = cool(length(trials2plot_disp));
%                     y = squeeze(EEG_dat(:,trials2plot_disp));
%                     new_min = min(min(y));
%                     new_max = max(max(y));
% 
%                     if new_max ~= new_min
%                         app.YminEditField.Value = new_min + new_min*0.1;
%                         app.YmaxEditField.Value = new_max + new_max*0.1;
%                     end
%    
%                 end


                hold(app.UIAxes,"on");
                rectangle(app.UIAxes,'Position', [...
                    app.TimeLowmsEditField.Value,...
                    -sd2,...
                    app.TimeHighmsEditField.Value - app.TimeLowmsEditField.Value,...
                    2*sd2], ...
                    'FaceColor', [.5, .5, .5, 0.2], ...
                    'EdgeColor', [1, 1, 1, 1]);                
                rectangle(app.UIAxes,'Position', [...
                    app.stim_range(1),...
                    app.YminEditField.Value,...
                    app.stim_range(2)- app.stim_range(1),...
                    app.YmaxEditField.Value-app.YminEditField.Value], ...
                    'FaceColor', [0.75,0.20,0.23, 0.8], ...
                    'EdgeColor', [1, 1, 1, 1], ...
                    'Curvature',[0.2, 0.2]);

%                 if app.plot_single_trial ==1 && ~isempty(app.TrialOverlayListBox.Value)
%                     if std(y) > 0 
%                         plot(app.UIAxes,x,y);
%                         colororder(app.UIAxes,c_map)
%                     end
%                 end

                plot(app.UIAxes,x,avg2plot,'LineWidth',2,"Color","w");
                set(app.UIAxes, 'YDir','reverse')
                xlim(app.UIAxes, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])
                apply_ylim(app,app.UIAxes)
                xline(app.UIAxes,0,'w--');
                yline(app.UIAxes,sd2)
                yline(app.UIAxes,-sd2)
                app.UIAxes.Color = 'k';

                % CHECK IF DATA HAS BEEN EXTRACTED FOR THIS CHANNEL AND
                % THIS FILTER TYPE
                my_table = app.UITable.Data;

                if istable(my_table) && ~isempty(my_table)
                    points2plot = my_table(strcmp(string(app.ChannelsListBox.Value) ,my_table.Channel) ...
                        & strcmp(string(app.LowpassSwitch.Value) ,my_table.filter_on),:);

                    if ~isempty(points2plot)
                        for i = 1: size(points2plot,1)
                            name = points2plot.Name(i);
                            amp = points2plot.Amplitude(i);
                            lat = points2plot.Latency(i);
                            plot(app.UIAxes,lat,amp,'g*','LineWidth',5)
                            text(app.UIAxes,lat+lat*0.1,amp,name,'FontSize',20,'Color','g')
                        end

                    end
                end

                hold(app.UIAxes,'off')


            else % if the nodes are empty 
                trials2plot = [];
                sd2 = [];
                SNR = [];

                cla(app.UIAxes) 
                xlim(app.UIAxes, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])
                apply_ylim(app,app.UIAxes)
                xline(app.UIAxes,0,'--');

            end
            
            
        end
        
        function trials2plot = select_channel(app,varargin)

            % only want 3 optional inputs at most
            numvarargs = length(varargin);
            if numvarargs > 1
                error('select_channel:TooManyInputs', ...
                    'requires at most 1 optional input');
            end

            % set defaults for optional input
            optargs = {false};

            % now put these defaults into the valuesToUse cell array,
            % and overwrite the ones specified in varargin.
            optargs(1:numvarargs) = varargin;

            % Place optional args in memorable variable names
            [recomp_warm] = optargs{:};


            EEG = evalin('base','EEG');
   

            % extract parameters to plot the extaction protocol

            x = EEG.times;
            chan2plot = string(app.ChannelsListBox.Value);


            % check if its keypoint
            if app.settings.file_type == 3 
                ALLEEG = evalin('base','ALLEEG');
                % figure out which dermatome is being trying to be plotted
                % and load the correct EEG file and the right chanloc index
                % relative to that file
                EEG_idx = 1; % default when the channel name matches nothing
                for i_EEG = 1:length(ALLEEG)
                    EEG = ALLEEG(i_EEG);
                    current_chanlocs = EEG.chanlocs;
                    for j = 1:length(current_chanlocs)
                        if string(current_chanlocs(j).labels) == string(chan2plot)
                            EEG_idx = i_EEG;
                            chan_idx = j;
                            break
                        end
                    end
                end
                EEG = ALLEEG(EEG_idx);
                
                current_channel.data_name = EEG.setname;

            else
                current_channel.data_name = EEG.setname;
                chan_idx = 1; % default when the channel name is not found
                for i = 1:length(EEG.chanlocs)
                    if chan2plot == string(EEG.chanlocs(i).labels)
                        chan_idx = i;
                    end
                end
            end

            EEG_dat = squeeze(EEG.data(chan_idx,:,:));
            EEG.plotted_chan_idx = chan_idx;


            % determine the trials to plot based on the tree box
            if ~isempty(app.Tree.CheckedNodes) % if the nodes are not empty
                if length(app.Tree.CheckedNodes) > 1 % if its not only a single trial selected
                    if app.Tree.CheckedNodes(1).Text =="All Trials" % if all trials are selected
                        trials2plot = 1:size(EEG_dat,2);
                    else % if more than 1 trial is selected
                        trials2plot = zeros(1,length(app.Tree.CheckedNodes));
                        for i = 1: length(app.Tree.CheckedNodes)
                            trials2plot(i) = str2double(app.Tree.CheckedNodes(i).Text);
                        end
                    end
                else % if its a single trial
                    trials2plot = str2double(app.Tree.CheckedNodes.Text);
                end
                % subset only trials that needed
                %EEG_dat = squeeze(EEG_dat(:,trials2plot));
                trial_mask = ismember(1:size(EEG_dat,2), trials2plot);
                EEG_dat(:,~trial_mask) = NaN;
            else
                trial_mask = zeros(1,size(EEG_dat,2));
                EEG_dat = zeros(size(EEG_dat));
                trials2plot = [];
            end

            
            current_channel.data = EEG_dat;
            current_channel.time = x;
            current_channel.chan_name = chan2plot;
            current_channel.trials_plotted = trials2plot;
            current_channel.trials_mask = trial_mask;
            current_channel.filtered = app.LowpassSwitch.Value;
            EEG.good_trials = trial_mask;
            EEG.n_good_trials = sum(trial_mask);

            EEG.plotted_data = EEG_dat;
            EEG.plotted_time = x;
            EEG.plotted_chan_name = chan2plot;
            EEG.trials_plotted = trials2plot;
            EEG.trials_mask = trial_mask;
            EEG.filtered = app.LowpassSwitch.Value;
            EEG.good_trials = trial_mask;
            EEG.n_good_trials = sum(trial_mask);


            assignin('base','current_data',current_channel);
            assignin('base','EEG',EEG);


        end
        
        function compute_warm_signals(app)
            s = tf_settings(app);
            current_data = evalin('base','current_data');
            EEG = evalin('base','EEG');


            % load tf results
            frequencies = EEG.frequencies;
            x = EEG.tf_times;
            convol_pwr_ind = EEG.cwt_outputs.convol_pwr_ind;
            individual_convol_result = EEG.cwt_outputs.single_trial.wt;
            total_convol_results = EEG.cwt_outputs.total_convol_results;
            tf_in = EEG.tf;


            % resample everything and put into same time scale
            time_window = s.time_lims; % time window that you want to analyze 
            [~,i_low] = (min(abs(x - time_window(1))));
            [~,i_high] = (min(abs(x - time_window(2))));
         
            %x1 = x(i_low:i_high); % cut and resampled time 


            % convol power individual 
            [p,q] = rat(floor(app.SamplingRateEditField.Value/10)/app.SamplingRateEditField.Value);
            resampled_pwr_ind = resample(double(convol_pwr_ind),p,q,'Dimension',2);
            resampled_time = linspace(min(x),max(x),size(resampled_pwr_ind,2));
            resampled_tf = resample(tf_in,p,q,'Dimension',3);

            % calculate ERP
            erp =squeeze(mean(current_data.data,2,"omitnan"));

            [~,i_low_erp] = (min(abs(EEG.times - time_window(1))));
            [~,i_high_erp] = (min(abs(EEG.times - time_window(2))));
            erp = erp(i_low_erp:i_high_erp-1);
            erp = resample(double(erp),p,q,'Dimension',1);

            % some of the input parameters for the function
            input_mat = permute(resampled_pwr_ind,[3 1 2]);% rearange input matrix containing all the trials
            n_permutes = app.npermutationsEditField_2.Value; % number of permutations
            v_time = resampled_time; % vector of time
            v_freq = frequencies; % vector of frequencies in 
            plotting_input = 0; % make 1 if you want to plot the responses or zero if you don't.
            baseline_range = s.baseline;
        
            my_title = ['testing function']; % required for the npp_stb_2 function
            
            % plotting the significant responses

            % check if the zmaps haveapp_s been done
            if ~isfield(current_data,'zmaps')
                [zmap, zmapthresh, zmapthresh_plc, zmapthresh_clc, tfv_time, v_freq] = npp_stb_2(app,input_mat...
                    ,n_permutes,v_time,v_freq,time_window,baseline_range,plotting_input,my_title);

                current_data.zmaps.zmapthresh_clc = zmapthresh_clc;
                current_data.zmaps.times = tfv_time;
                current_data.zmaps.frequencies = v_freq;
                current_data.zmaps.zmap = zmap;
                current_data.zmaps.zmapthresh = zmapthresh;
                current_data.zmaps.zmapthresh_plc = zmapthresh_plc;
                current_data.zmaps.trials_used = current_data.trials_mask;
                current_data.zmaps.chan_used = current_data.chan_name;
                current_data.zmaps.tf_resampled = resampled_tf;
                current_data.zmaps.time_low = s.time_lims(1);
                current_data.zmaps.time_high = s.time_lims(2);

               
            elseif ~isequal(current_data.trials_mask, current_data.zmaps.trials_used) || ...
                    ~strcmp(string(current_data.chan_name), string(current_data.zmaps.chan_used)) || ...
                    s.time_lims(1) ~= current_data.zmaps.time_low || ...
                    s.time_lims(2) ~= current_data.zmaps.time_high
                % recompute when ANY of the inputs changed. The original test
                % ANDed four negated comparisons, so the permutation test was
                % effectively never re-run after the first time.

                [zmap, zmapthresh, zmapthresh_plc, zmapthresh_clc, tfv_time, v_freq] = npp_stb_2(app,input_mat...
                    ,n_permutes,v_time,v_freq,time_window,baseline_range,plotting_input,my_title);

                current_data.zmaps.zmapthresh_clc = zmapthresh_clc;
                current_data.zmaps.times = tfv_time;
                current_data.zmaps.frequencies = v_freq;
                current_data.zmaps.zmap = zmap;
                current_data.zmaps.zmapthresh = zmapthresh;
                current_data.zmaps.zmapthresh_plc = zmapthresh_plc;
                current_data.zmaps.trials_used = current_data.trials_mask;
                current_data.zmaps.chan_used = current_data.chan_name;
                current_data.zmaps.tf_resampled = resampled_tf;
                current_data.zmaps.time_low = s.time_lims(1);
                current_data.zmaps.time_high = s.time_lims(2);

            else
      
                zmapthresh_clc = current_data.zmaps.zmapthresh_clc; % read clc zmaps from eeg structure
            end


            % transform the average ERP 
            % first resample
            resampled_convol_mean = resample(double(total_convol_results),p,q,'Dimension',2);

            freq_lims= s.freq_lims;
            % do i need the next line?

            % apply the cwt filter based on the zmap
            % convert mask into logical
            logical_clc_zmap = zmapthresh_clc ~= 0;
            % multiply convol matrix in tf domain to filter out non
            % significant regions
            filtered_mean_convol = resampled_convol_mean .* double(logical_clc_zmap);
            % reconstruct filtered convol signal in the time domain
            filt_recon= icwt(filtered_mean_convol,"amor");

            % repeat the same thing with the individual trials
           resampled_ind_convol = resample(double(individual_convol_result),p,q,'Dimension',2);
           filt_resampled_ind_data = zeros(length(filtered_mean_convol),size(resampled_ind_convol,3));
           resampled_ind_data = zeros(length(filtered_mean_convol),size(resampled_ind_convol,3));

           % loop through each trial to reconstruct filtered time signal
           for i = 1:size(resampled_ind_convol,3)
               if current_data.trials_mask(i)
                   c_dat = squeeze(resampled_ind_convol(:,:,i));
                   filtered_convol = c_dat .* double(logical_clc_zmap);
                   recon_ind = icwt(c_dat,"amor");
                   filt_recon_ind= icwt(filtered_convol,"amor");
                   filt_resampled_ind_data(:,i) =filt_recon_ind';
                   resampled_ind_data(:,i) =recon_ind';
               else
                   filt_resampled_ind_data(:,i) = nan;
                   resampled_ind_data(:,i) =nan';
               end

           end

                       
           %% save in EEG structure
           current_data.WARM_filter.single_trial_waveforms = filt_resampled_ind_data;
           current_data.WARM_filter.mean_waveform = filt_recon;
           current_data.WARM_filter.time = resampled_time;
           current_data.WARM_filter.unfiltered_waveforms = resampled_ind_data;
           current_data.WARM_filter.unfiltered_mean = mean(resampled_ind_data,2,"omitnan");
           

           assignin('base','current_data',current_data);


        end
        
        function update_figs(app)

            plot_tdat(app);
            plot_trial_data(app);
            plot_tfdata(app);
            
        end
        
        function plot_multi_trials(app)


            EEGin = evalin("base","current_data"); % read in current data

            % check if we are doing the warm filter and if we have
            if app.WARMFilterSwitch.Value == "On"
                datain = EEGin.WARM_filter.single_trial_waveforms;
                time2plot = EEGin.WARM_filter.time;

            else
                datain = EEGin.data; % select data
                time2plot = EEGin.time;
            end


            selected_page = round(app.PageSlider.Value);
            y = app.nrowsEditField.Value; % define number of rows
            x = app.ncolumnsEditField.Value; % define number of cols


            n_trials = size(datain,2); % get number of trials
            n_trials_pp = x*y;
            n_pages = ceil(n_trials/n_trials_pp);

            % clamp the page and the trial range: a single page (or a page past
            % the end) used to index past the last trial, or leave trial_index
            % undefined entirely.
            selected_page = min(max(selected_page,1),max(n_pages,1));
            first_trial = n_trials_pp*(selected_page-1)+1;
            last_trial = min(first_trial+n_trials_pp-1, n_trials);
            trial_index = first_trial:last_trial;
            if isempty(trial_index)
                return
            end

            data2plot = datain(:,trial_index);
            delete(app.Panel_8.Children); % otherwise every call stacks a new layout
            t = tiledlayout(app.Panel_8,y,x); % create tiled layout
            for i = 1:size(data2plot,2)
                ax1 = nexttile(t);
                plot(ax1,time2plot,squeeze(data2plot(:,i)),"Color","w");
                set(ax1,'YDir','reverse');
                xline(ax1,0,'--',"Color","w");
                xlim(ax1, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])
                ax1.YColor = 'w';
                ax1.XColor = 'w';
                title(ax1,['Trial ' num2str(trial_index(i))], "Color",[.7 .7 .7]);
                set(ax1,'Color','k');
            end
            xlabel(t,'Time (ms)','Color',"w");
            ylabel(t,'Amplitude (mV)','Color',"w");
            t.TileSpacing = 'compact';
            t.Padding = 'compact';

        end
        
        function [ALLOUT, EEG_structout] = load_fnct_pathway(app,file_path)
            s = dir(file_path);
            sc = s(contains({s.name}, '.txt'));


            for i = 1:length(sc)
                c_dat = sc(i);
                [filepath,name,ext] = fileparts([c_dat.folder, filesep(),c_dat.name]);
                [ALLEEG,EEG] = load_txt_single_channel(app,filepath,name,ext);

                if i == 1
                    EEG_out = squeeze(zeros([length(sc),size(EEG.data)]));
                    EEG_out(i,:,:) = squeeze(EEG.data);
                    chanlocs = EEG.chanlocs;
                    nbchan =1;
                else
                    EEG_out(i,:,:) = squeeze(EEG.data);
                    chanlocs = [chanlocs,EEG.chanlocs];
                    nbchan =nbchan+1;
                end

            end

            EEG_structout = EEG;
            EEG_structout.data = EEG_out;
            EEG_structout.chanlocs = chanlocs;
            EEG_structout.nbchan = nbchan;
            ALLOUT = EEG_structout;

        end
        
        function [ALLEEG,EEG] = load_txt_single_channel(app,file_path,name,ext)
                %getting name of channel
                my_data = readtable([file_path filesep() name ext],'HeaderLines', 2);
                temp = char(my_data.Properties.VariableNames(1));
                C = strsplit(temp,'_');
                chan_name = char(C(1));
                if string(chan_name) == "N1"
                    chan_name = "C3";
                end
                % get user input for missing data
                prompt = {'Sampling Rate:',"Baseline duration (in ms)"};
                dlgtitle = 'Please input parameters';
                fieldsize = [1 45; 1 45];
                definput = {'2000','1000'};
                answer = inputdlg(prompt,dlgtitle,fieldsize,definput);

                srate = str2double(answer(1)); % need a way to define this
                baseline_low =-str2double(answer(2)); % need a way to define this. In ms.
                
                %my_data = table2array(readtable([file_path filesep() name ext],'HeaderLines', 3));

                % Amplipfy it so thats its in mV not volts
                my_data = table2array(my_data) *1000000;
                EEG = convert_txt_to_eeg_struct(app,my_data,chan_name,srate,baseline_low);
                ALLEEG = EEG;
            
        end
        
        function my_output = selectDatapoints_time(app)

            % Remove any pre-existing rectanlges (if any)
            delete(findall(app.UIAxes, 'Type',  'images.roi.Rectangle'))
            temp_roi = drawrectangle(app.UIAxes,'StripeColor','b','InteractionsAllowed','none');
            if isempty(temp_roi) || isempty(temp_roi.Position)
                error('selectDatapoints:cancelled','No region was selected.');
            end

            my_output = temp_roi.Position;
            
        end
        
        function my_table = extract_peaks_manually(app,EEG_dat_plotted,x,SNR,SNR_michelle,sd2)
            EEG = evalin('base','EEG');
            c_dat = evalin('base','current_data');
            setname = EEG.setname;

            erp = mean(EEG_dat_plotted,2,"omitnan");

            prompt = {'How many peaks do you want to extract?'};
            dlgtitle = 'number of peaks:';
            dims = [1 35];
            definput = {'1','hsv'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            n_peaks = str2double(cell2mat(answer));


            for i  = 1:n_peaks
                app.i_peaks = app.i_peaks +1;
                prompt = {'Enter name of peak'};
                dlgtitle = 'Name of peak';
                dims = [1 35];
                definput = {'name','hsv'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);


                delete(findall(app.UIAxes, 'Type',  'images.roi.Rectangle'))
                temp_roi = drawrectangle(app.UIAxes,'StripeColor','b','InteractionsAllowed','none');

                position = temp_roi.Position;
                x_min = position(1);
                x_max  = position(1) + position(3);

                [~,low_idx] = (min(abs(x - x_min)));
                [~,high_idx] = (min(abs(x - x_max)));

                data = erp(low_idx:high_idx); % cut the erp to the size of window
                cut_time = x(low_idx:high_idx); % cut the time as well


                % determining if its a negative or a positive peak
                [highest_point , highest_index]  = max(data);
                [lowest_point , lowest_index]  = min(data);

                pont = -1; % reset: an unresolved selection must not reuse the previous peak
                pgn = abs(highest_point) > abs(lowest_point); % check to see if the highest positive point has a greater magnitude than the lowerst negative point. reurn zero if it is true, and return 1 if it is not true.

                if pgn == 0 % if my positive point is not greater than negative point
                    % check that the negative dip is not in the end
                    if lowest_index ~= 1 && lowest_index ~= length(data)
                        pont = 0; % positiv or negative toggle (1 = positive peak, -0 = negative peak)
                    elseif highest_index ~= 1 && highest_index ~= length(data) % if it is in the end, check to see if the positive is in the end or not
                        pont  = 1;
                    else
                        warning("The region that you selected did not contain a peak - please try again ")
                    end
                elseif pgn == 1 % if my positive point is greater than my negative point
                    % check tha the positive dip is not at the end
                    if highest_index ~= 1 && highest_index ~= length(data)
                        pont = 1;
                    elseif lowest_index ~= 1 && lowest_index ~= length(data)
                        pont = 0;
                    else
                        warning("The region that you selected did not contain a peak - please try again ")
                    end

                end

                if pont == 1
                    my_peak = highest_point ;
                    my_latency = cut_time(highest_index);
                elseif pont == 0
                    my_peak = lowest_point ;
                    my_latency = cut_time(lowest_index);
                else
                    warning("Please re select the region")
                    continue
                end

                my_table = app.UITable.Data;
                m_data.Channel = string(app.ChannelsListBox.Value);
                m_data.Name = string(answer);
                m_data.Amplitude =my_peak;
                m_data.Latency =  my_latency;
                m_data.filter_on = string(app.LowpassSwitch.Value);
                m_data.trials = strjoin(string(c_dat.trials_plotted),",");
                m_data.n_trials = length(c_dat.trials_plotted);
                m_data.Stim = app.typeofstim.Value;
                m_data.SNR_oddeve = SNR;
                m_data.SNR_ps = SNR_michelle;
                m_data.sd2 = sd2;
                m_data.ID = string(c_dat.data_name);

                if istable(my_table) && ~isempty(my_table)
                    my_table = [my_table; struct2table(m_data)];
                else
                    my_table = struct2table(m_data);
                end
                app.UITable.Data = my_table;

            end
        end

        
        function my_cmap = get_colormap(app)
            % load new_color_map_2.mat from next to the app, not from whatever
            % the current folder happens to be (and not from a hard-coded
            % Z:\ network path, which only worked on one machine)
            my_cmap = parula(256);
            candidates = {fullfile(fileparts(mfilename('fullpath')),'new_color_map_2.mat'), ...
                          'new_color_map_2.mat'};
            for k = 1:numel(candidates)
                if isfile(candidates{k})
                    S = load(candidates{k});
                    if isfield(S,'new_color_map_2')
                        my_cmap = S.new_color_map_2;
                        return
                    end
                end
            end
        end

        function s = tf_settings(app)
            % SINGLE SOURCE OF TRUTH for the time-frequency parameters.
            % Every field read here lives on the Time Frequency Settings tab.
            % Anything that computes or plots TF data takes its values from
            % this struct, so there is one place to look and one place to edit.
            s.freq_lims  = [app.minfreqEditField_2.Value app.maxfreqEditField_2.Value];
            s.n_freqs    = max(2,round(app.offrequencybinsEditField_2.Value));
            s.cycle_lims = [app.ofcycleslowerEditField_2.Value app.ofcycleshigherEditField_2.Value];
            s.baseline   = [app.baselinelowerendEditField_2.Value app.baselinehighendEditField.Value];
            s.time_lims  = [app.timelowerEditField_2.Value app.timehigherEditField_2.Value];
            s.conversion = string(app.ConversionSwitch_2.Value);
            s.spacing    = string(app.SpacingSwitch_2.Value);
            s.normalise  = string(app.BaselineNormSwitch.Value) == "On";

            % a reversed range is a typo, not an instruction
            if s.freq_lims(2) < s.freq_lims(1), s.freq_lims = fliplr(s.freq_lims); end
            if s.time_lims(2) < s.time_lims(1), s.time_lims = fliplr(s.time_lims); end
            if s.baseline(2)  < s.baseline(1),  s.baseline  = fliplr(s.baseline);  end
        end

        function sync_tf_settings(app,s)
            % One range governs the whole app: push the Time Frequency Settings
            % values into the Plotting Settings fields that the ERP, trial and
            % multi-trial views read, so the two tabs can never disagree.
            if nargin < 2, s = tf_settings(app); end
            app.TimeLowmsEditField.Value  = s.time_lims(1);
            app.TimeHighmsEditField.Value = s.time_lims(2);
            app.baseline_start.Value      = s.baseline(1);
            app.baseline_end.Value        = s.baseline(2);
        end

        function tf = is_ssep_stim(app,stimValue)
            stimValue = lower(strtrim(string(stimValue)));
            tf = any(stimValue == ["ssep","sseps"]);
        end

        function apply_standard_tf_defaults(app)
            app.minfreqEditField_2.Value = app.standard_tf_freq_range(1);
            app.maxfreqEditField_2.Value = app.standard_tf_freq_range(2);
            app.offrequencybinsEditField_2.Value = app.standard_tf_n_freqs;
            app.ofcycleslowerEditField_2.Value = app.standard_tf_cycles(1);
            app.ofcycleshigherEditField_2.Value = app.standard_tf_cycles(2);
            app.baseline_start.Value = app.standard_tf_baseline(1);
            app.baseline_end.Value = app.standard_tf_baseline(2);
            app.baselinelowerendEditField_2.Value = app.standard_tf_baseline(1);
            app.baselinehighendEditField.Value = app.standard_tf_baseline(2);
            app.timelowerEditField_2.Value = app.standard_tf_time_range(1);
            app.timehigherEditField_2.Value = app.standard_tf_time_range(2);
            app.SpacingSwitch_2.Value = 'Linear';
        end

        function apply_ssep_tf_defaults(app)
            app.minfreqEditField_2.Value = app.ssep_tf_freq_range(1);
            app.maxfreqEditField_2.Value = app.ssep_tf_freq_range(2);
            app.offrequencybinsEditField_2.Value = app.ssep_tf_n_freqs;
            app.ofcycleslowerEditField_2.Value = app.ssep_tf_cycles(1);
            app.ofcycleshigherEditField_2.Value = app.ssep_tf_cycles(2);
            app.baseline_start.Value = app.ssep_tf_baseline(1);
            app.baseline_end.Value = app.ssep_tf_baseline(2);
            app.baselinelowerendEditField_2.Value = app.ssep_tf_baseline(1);
            app.baselinehighendEditField.Value = app.ssep_tf_baseline(2);
            app.timelowerEditField_2.Value = app.ssep_epoch_range(1);
            app.timehigherEditField_2.Value = app.ssep_epoch_range(2);
            app.TimeLowEditField_2.Value = app.ssep_broad_window(1);
            app.TimeHighEditField_2.Value = app.ssep_broad_window(2);
            app.FreqLowEditField_2.Value = app.ssep_tf_freq_range(1);
            app.FreqHighEditField_2.Value = app.ssep_tf_freq_range(2);
            app.TimeLowEditField.Value = app.ssep_broad_window(1);
            app.TimeHighEditField.Value = app.ssep_broad_window(2);
            app.FreqLowEditField.Value = app.ssep_tf_freq_range(1);
            app.FreqHighEditField.Value = app.ssep_tf_freq_range(2);
            app.SpacingSwitch_2.Value = 'Log';
        end

        function tf_ready = has_tf_data(app)
            tf_ready = evalin('base','exist(''current_data'',''var'')') && evalin('base','exist(''EEG'',''var'')');
        end

        function recalc_tf_if_ready(app)
            if has_tf_data(app)
                calc_tf_data(app);
                plot_tfdata(app);
            end
        end

        function [tf,frex,x_out,convol_pwr_ind,sing_wt,total_convol_result,cwt_outputs] = calc_ssep_tf_data(app,data,x,srate,i_low,i_high,freq_lims)
            s = tf_settings(app);
            n_freqs = s.n_freqs;
            cycle_lims = s.cycle_lims;
            erp = squeeze(mean(data,2,"omitnan"));

            [sing_wt_full,frex,cycles,pad_pts] = morlet_transform_reflect(app,data,srate,freq_lims,n_freqs,cycle_lims);
            [erp_wt_full,~,~,~] = morlet_transform_reflect(app,erp,srate,freq_lims,n_freqs,cycle_lims);

            x_out = x(i_low:i_high);
            sing_wt = sing_wt_full(:,i_low:i_high,:);
            erp_wt = squeeze(erp_wt_full(:,i_low:i_high,1));

            convol_pwr_ind = abs(sing_wt).^2;
            pow_total = mean(convol_pwr_ind,3,"omitnan");
            total_convol_result = mean(sing_wt,3,"omitnan");
            pow_evoked = abs(erp_wt).^2;
            pow_phase_locked = abs(total_convol_result).^2;
            pow_npl = max(pow_total - pow_phase_locked,0);
            ITPC = abs(mean(exp(1i*angle(sing_wt)),3,"omitnan"));

            tf = zeros(5,size(pow_total,1),size(pow_total,2));
            tf(1,:,:) = pow_total;
            tf(2,:,:) = pow_npl;
            tf(3,:,:) = pow_evoked;
            tf(4,:,:) = ITPC;
            tf(5,:,:) = pow_phase_locked;

            if s.normalise
                [~,baseidx(1)] = min(abs(x_out-s.baseline(1)));
                [~,baseidx(2)] = min(abs(x_out-s.baseline(2)));
                if baseidx(1) > baseidx(2)
                    baseidx = fliplr(baseidx);
                end
                base_range = baseidx(1):baseidx(2);
                for metric_idx = [1 2 3 5]
                    metric_data = squeeze(tf(metric_idx,:,:));
                    metric_base = mean(metric_data(:,base_range),2,"omitnan");
                    metric_base(metric_base <= 0 | isnan(metric_base)) = eps;
                    if s.conversion == "dB"
                        metric_data(metric_data <= 0 | isnan(metric_data)) = eps;
                        metric_data = 10*log10(metric_data ./ metric_base);
                    elseif s.conversion == "percent change"
                        metric_data = ((metric_data - metric_base) ./ metric_base) * 100;
                    end
                    tf(metric_idx,:,:) = metric_data;
                end
            end

            cwt_outputs.single_trial.wt = sing_wt;
            cwt_outputs.single_trial.frex = frex;
            cwt_outputs.single_trial.cycles = cycles;
            cwt_outputs.single_trial.padding_samples = pad_pts;
            cwt_outputs.erp.wt = erp_wt;
            cwt_outputs.erp.frex = frex;
            cwt_outputs.erp.cycles = cycles;
            cwt_outputs.convol_pwr_ind = convol_pwr_ind;
            cwt_outputs.total_convol_results = total_convol_result;
            cwt_outputs.settings.method = "morlet_reflect";
            cwt_outputs.settings.frequency_scale = "log";
            cwt_outputs.settings.frequency_limits = freq_lims;
            cwt_outputs.settings.n_frequencies = n_freqs;
            cwt_outputs.settings.cycle_limits = cycle_lims;
            cwt_outputs.settings.baseline = s.baseline;
            cwt_outputs.settings.p37_window = app.ssep_signal_range;
            cwt_outputs.settings.broad_ssep_window = app.ssep_broad_window;
        end

        function [wt,frex,cycles,pad_pts] = morlet_transform_reflect(app,data,srate,freq_lims,n_freqs,cycle_lims)
            data = double(data);
            if isvector(data)
                data = data(:);
            end
            n_time = size(data,1);
            n_trials = size(data,2);
            frex = logspace(log10(freq_lims(1)),log10(freq_lims(2)),n_freqs);
            cycles = interp1(freq_lims,cycle_lims,frex,'linear','extrap');
            max_sigma = max(cycles ./ (2*pi*frex));
            pad_pts = min(max(1,ceil(3.5*max_sigma*srate)),max(0,n_time-1));
            wt = nan(n_freqs,n_time,n_trials);
            valid_trials = ~any(isnan(data),1);
            if ~any(valid_trials)
                return
            end

            padded = reflect_pad_trials(app,data(:,valid_trials),pad_pts);
            for fi = 1:n_freqs
                sigma = cycles(fi) / (2*pi*frex(fi));
                half_width = max(1,ceil(3.5*sigma*srate));
                t = (-half_width:half_width)' / srate;
                wavelet = exp(2*1i*pi*frex(fi).*t) .* exp(-(t.^2) ./ (2*sigma^2));
                wavelet = wavelet ./ sqrt(sum(abs(wavelet).^2));
                conv_out = conv2(padded,wavelet,'same');
                conv_out = conv_out(pad_pts+1:pad_pts+n_time,:);
                wt(fi,:,valid_trials) = reshape(conv_out,1,n_time,[]);
            end
        end

        function padded = reflect_pad_trials(app,data,pad_pts)
            if pad_pts == 0
                padded = data;
                return
            end
            pre_pad = flipud(data(2:pad_pts+1,:));
            post_pad = flipud(data(end-pad_pts:end-1,:));
            padded = [pre_pad; data; post_pad];
        end

        function apply_ssep_loading_defaults(app)
            if ~is_ssep_stim(app,app.DropDown_2.Value) && ~is_ssep_stim(app,app.typeofstim.Value)
                return
            end

            app.TimeLowmsEditField.Value = app.ssep_epoch_range(1);
            app.TimeHighmsEditField.Value = app.ssep_epoch_range(2);
            app.timelowerEditField_2.Value = app.ssep_epoch_range(1);
            app.timehigherEditField_2.Value = app.ssep_epoch_range(2);
            app.stim_range = app.ssep_signal_range;
            app.SignalStartmsEditField.Value = app.ssep_signal_range(1);
            app.SignalEndmsEditField.Value = app.ssep_signal_range(2);

            % fix
            app.TimeLowmsEditField.Value = app.ssep_epoch_range(1);
            app.TimeHighmsEditField.Value = app.ssep_epoch_range(2);
            app.timelowerEditField_2.Value = app.ssep_epoch_range(1);
            app.timehigherEditField_2.Value = app.ssep_epoch_range(2);
            app.stim_range = app.ssep_signal_range;
            app.SignalStartmsEditField.Value = app.ssep_signal_range(1);
            app.SignalEndmsEditField.Value = app.ssep_signal_range(2);


            apply_ssep_tf_defaults(app);

            if any(strcmp(app.typeofstim.Items,'SSEP'))
                app.typeofstim.Value = 'SSEP';
            end
        end

        function update_data_set_name(app,ALLEEG,chan2plot)
            % figure out which dermatome is being trying to be plotted
            % and load the correct EEG file and the right chanloc index
            % relative to that file
            if isempty(ALLEEG)
                return
            end
            EEG_idx = 1; % default when the channel name matches nothing
            for i_EEG = 1:length(ALLEEG)
                EEG = ALLEEG(i_EEG);
                current_chanlocs = EEG.chanlocs;
                for j = 1:length(current_chanlocs)
                    if string(current_chanlocs(j).labels) == string(chan2plot)
                        EEG_idx = i_EEG;
                        chan_idx = j;
                        break
                    end
                end
            end
            EEG = ALLEEG(EEG_idx);
            app.current_data_id =  EEG.setname; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % keep the dropdown and settings.file_type in sync: the dropdown
            % showed '.set' while file_type was hard-set to 1 ('.mat')
            app.DropDown.Value = '.mat';
            DropDownValueChanged(app,[]);
            apply_ssep_loading_defaults(app);
        end

        % Callback function: Button, UITable
        function ButtonPushed(app, event)
            if app.settings.file_type == 0
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                [file_in,path_in] = uigetfile('*.set');
                delete(f); %delete the dummy figure
                

            elseif app.settings.file_type == 1
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                [file_in,path_in] = uigetfile('*.mat');
                delete(f); %delete the dummy figure

            elseif app.settings.file_type == 2

                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                [file_in,path_in] = uigetfile('*.txt');
                delete(f); %delete the dummy figure

                
            elseif app.settings.file_type == 3
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                path_in = uigetdir();
                delete(f); %delete the dummy figure

            elseif app.settings.file_type == 4
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                path_in = uigetdir();
                delete(f); %delete the dummy figure

            end


            try
                if app.settings.file_type == 0 || app.settings.file_type == 1 || app.settings.file_type == 2
                    app.DataInEditField.Value = [path_in,file_in];
                elseif app.settings.file_type == 3 || app.settings.file_type == 4
                    app.DataInEditField.Value = path_in;
                end
            catch

            end


        end

        % Value changed function: DataInEditField
        function DataInEditFieldValueChanged(app, event)

        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)

            d_eeglab = uigetdir('C:\');
            if isequal(d_eeglab,0) % user cancelled
                return
            end
            app.EEGLabFolderEditField.Value = d_eeglab;
            addpath(d_eeglab); % was: addpath app.SavingFolderEditField.Value (command syntax added a literal path)

        end

        % Value changed function: EEGLabFolderEditField
        function EEGLabFolderEditFieldValueChanged(app, event)
            eeglab_path = app.EEGLabFolderEditField.Value;

        end

        % Callback function
        function Button_2Pushed(app, event)
            d_save = uigetdir('C:\');
            if isequal(d_save,0)
                return
            end
            app.saving_folder = d_save;
        end

        % Callback function
        function SavingFolderEditFieldValueChanged(app, event)
            if ~isempty(app.saving_folder) && isfolder(app.saving_folder)
                addpath(app.saving_folder); % was command syntax: addpath app.Saving...
            end
        end

        % Button pushed function: InitializeEEGLabButton
        function InitializeEEGLabButtonPushed(app, event)
            addpath(app.EEGLabFolderEditField.Value);
           eeglab;
        end

        % Callback function
        function ChannelDropDownOpening(app, event)
            
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)

            try 
               app.DataNotLoadedLamp.Enable = "off";
               app.DataNotLoadedLampLabel.Text= "Data Not Loaded";
               app_setup(app,1);
               select_channel(app);
               preprocc_eeg(app);
               populate_figures(app);
               app.DataNotLoadedLamp.Enable = "on";
               % char() first: ["name" ' Loaded'] builds a 1x2 string array,
               % which is not a valid Text value
               app.DataNotLoadedLampLabel.Text = [char(string(app.current_data_id)), ' Loaded'];
            catch ME
                app.DataNotLoadedLamp.Enable = "off";
                app.DataNotLoadedLampLabel.Text= "Data Not Loaded";
                fprintf(2,'The identifier was:\n%s\n',ME.identifier);
                fprintf(2,'There was an error! The message was:\n%s\n',ME.message);
                for k = 1:numel(ME.stack)
                    fprintf(2,'  in %s (line %d)\n',ME.stack(k).name,ME.stack(k).line);
                end
            end

        end

        % Callback function
        function TrialOverlayListBoxValueChanged(app, event)

            plot_tdat(app);

        end

        % Value changed function: ChannelsListBox
        function ChannelsListBoxValueChanged(app, event)
            %select_channel(app);
            %preprocc_eeg(app);
            %populate_figures(app);
        end

        % Callback function
        function ChannelsListBox_2ValueChanged(app, event)
            recalc_tf_if_ready(app);
        end

        % Callback function
        function SpacingSwitchValueChanged(app, event)
            recalc_tf_if_ready(app);
        end

        % Callback function
        function maxfreqEditFieldValueChanged(app, event)
            recalc_tf_if_ready(app);
        end

        % Callback function
        function ConversionSwitchValueChanged(app, event)
            recalc_tf_if_ready(app);
        end

        % Callback function
        function minfreqEditFieldValueChanged(app, event)
            recalc_tf_if_ready(app);
        end

        % Callback function
        function baselinehigherendEditFieldValueChanged(app, event)
            app.baseline_end.Value = app.baselinehighendEditField.Value;
            recalc_tf_if_ready(app);
        end

        % Callback function
        function baselinelowerendEditFieldValueChanged(app, event)
            app.baseline_start.Value = app.baselinelowerendEditField_2.Value;
            recalc_tf_if_ready(app);
        end

        % Callback function
        function ofcycleslowerEditFieldValueChanged(app, event)
            recalc_tf_if_ready(app);
        end

        % Callback function
        function ofcycleshigherEditFieldValueChanged(app, event)
            recalc_tf_if_ready(app);
        end

        % Callback function
        function timelowerEditFieldValueChanged(app, event)
            recalc_tf_if_ready(app);
        end

        % Callback function
        function timehigherEditFieldValueChanged(app, event)
            recalc_tf_if_ready(app);
        end

        % Callback function
        function offrequencybinsEditFieldValueChanged(app, event)
            recalc_tf_if_ready(app);
        end

        % Button pushed function: selectrectanglemanuallyButton
        function selectrectanglemanuallyButtonPushed(app, event)
            % TabGroup2 and the per-metric axes it indexed no longer exist, and
            % selectDatapoints takes no second argument. Draw on the TF axes that
            % is actually displayed.
            my_return = selectDatapoints(app);
            app.TimeLowEditField.Value = my_return(1);
            app.TimeHighEditField.Value = my_return(1)+ my_return(3);
            app.FreqLowEditField.Value = my_return(2);
            app.FreqHighEditField.Value = my_return(2)+my_return(4);
        end

        % Callback function
        function SelectbottomrightpointButtonPushed(app, event)
           [x,y] = ginput(1);
           app.FreqHighEditField.Value =y;
           app.TimeHighEditField.Value = x;            
        end

        % Button pushed function: ExtractInfoButton
        function ExtractInfoButtonPushed(app, event)
            % rewritten: the five per-metric axes were removed, and this called
            % "poulate_extraction_vals" (misspelled - the function is
            % populate_extraction_vals) so it could never have run to the end.
            current_data = evalin("base","current_data");

            x_min = app.TimeLowEditField.Value;
            y_min = app.FreqLowEditField.Value;
            x_length = app.TimeHighEditField.Value - x_min;
            y_length = app.FreqHighEditField.Value - y_min;

            delete(findall(app.UIAxes9, 'Type',  'images.roi.Rectangle'))
            if x_length > 0 && y_length > 0
                drawrectangle(app.UIAxes9,'Position',[x_min y_min x_length y_length], ...
                    'Color','r','InteractionsAllowed','none');
            end

            % extract_vals reads the *_2 window fields, so mirror this tab's
            % window into them before extracting
            app.TimeLowEditField_2.Value = x_min;
            app.TimeHighEditField_2.Value = app.TimeHighEditField.Value;
            app.FreqLowEditField_2.Value = y_min;
            app.FreqHighEditField_2.Value = app.FreqHighEditField.Value;

            [my_output,app.extraction_state] = extract_vals(app,current_data.tf, ...
                current_data.frequencies,current_data.tf_times);

            populate_extraction_vals(app,my_output);

        end

        % Callback function
        function TabGroup2SelectionChanged(app, event)
%             if app.extraction_state == 1
%                 [tf , frequencies , x] = calc_tf_data(app);
%                 [my_output,app.extraction_state] = extract_vals(app,tf,frequencies,x);
%                 poulate_extraction_vals(app,my_output);
%             end
            
        end

        % Callback function
        function ExportDatatoexcelButtonPushed(app, event)
            % NOTE: this callback is not wired to any component in the current
            % layout. It was rewritten because it referenced six edit fields that
            % no longer exist (meanactivitydBEditField, peakactivitydBEditField,
            % ...), a SavingFolder/SavingName field pair that no longer exists,
            % and contained a stray "time" statement.
            SID = app.IDEditField.Value;
            Age = str2double(app.AgeEditField.Value);
            Sex = app.SexEditField.Value;
            the_date = app.DateDatePicker.Value;
            notes = string(app.NotesTextArea.Value);

            table_time = app.UITable.Data;   % peak table
            table_tf = app.UITable2_2.Data;  % time-frequency ROI table

            [f_name,f_path] = uiputfile('*.xlsx','Export data as');
            if isequal(f_name,0)
                return
            end
            out_file = fullfile(f_path,f_name);

            if istable(table_time) && ~isempty(table_time)
                C = repmat({SID,Age,Sex,the_date,notes},height(table_time),1);
                T = cell2table(C,"VariableNames",["Subject","Age","Sex","Date","Notes"]);
                writetable([T,table_time],out_file,'Sheet','Time Domain Features');
            end

            if istable(table_tf) && ~isempty(table_tf)
                C = repmat({SID,Age,Sex,the_date,notes},height(table_tf),1);
                T = cell2table(C,"VariableNames",["Subject","Age","Sex","Date","Notes"]);
                writetable([T,table_tf],out_file,'Sheet','Time Frequency Features');
            end

        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.DropDown.Value;
            if string(app.DropDown.Value) == ".set"
                app.settings.file_type = 0;
            elseif string(app.DropDown.Value) == ".mat"
                app.settings.file_type = 1;
            elseif string(app.DropDown.Value) == ".txt"
                app.settings.file_type = 2;
            elseif string(app.DropDown.Value) == "KeyPoint"
                app.settings.file_type = 3;
            elseif string(app.DropDown.Value) == "Pathway"
                app.settings.file_type = 4;
            end

            
        end

        % Value changed function: TimeLowmsEditField
        function TimeLowmsEditFieldValueChanged(app, event)
            % one range governs everything - mirror into the TF Settings tab
            app.timelowerEditField_2.Value = app.TimeLowmsEditField.Value;
            
            % select_channel(app);
            % preprocc_eeg(app);
            % calc_tf_data(app);

            if app.WARMFilterSwitch.Value == "On"
                compute_warm_signals(app)
            end

            % plot_tdat(app);
            % plot_trial_data(app);
            % plot_tfdata(app);


        end

        % Value changed function: TimeHighmsEditField
        function TimeHighmsEditFieldValueChanged(app, event)
            % one range governs everything - mirror into the TF Settings tab
            app.timehigherEditField_2.Value = app.TimeHighmsEditField.Value;
            % select_channel(app);
            % preprocc_eeg(app);
            % calc_tf_data(app);

            if app.WARMFilterSwitch.Value == "On"
                compute_warm_signals(app)
            end

            % plot_tdat(app);
            % plot_trial_data(app);
            % plot_tfdata(app);

        end

        % Value changed function: YminEditField
        function YminEditFieldValueChanged(app, event)
            % plot_tdat(app);
            % plot_trial_data(app);
            % plot_tfdata(app);

        end

        % Value changed function: YmaxEditField
        function YmaxEditFieldValueChanged(app, event)
            % plot_tdat(app);
            % plot_trial_data(app);
            % plot_tfdata(app);

        end

        % Value changed function: LowpassSwitch
        function LowpassSwitchValueChanged(app, event)
            value = app.LowpassSwitch.Value;
            if value == "Off"
                app.low_pass_toggle = 0;
            elseif value == "On"
                app.low_pass_toggle = 1;
            end
            
            % preprocc_eeg(app);
            % populate_figures(app)
        end

        % Button pushed function: ExtractpeaksButton
        function ExtractpeaksButtonPushed(app, event)
            %clf(app.UIAxes)
            [EEG_dat,x,sd2,SNR,SNR_michelle] = plot_tdat(app);
            EEG_dat_plotted = EEG_dat;
            table_out = extract_peaks(app,EEG_dat_plotted,x,SNR,SNR_michelle,sd2);
            assignin("base","peaks_extracted",table_out)
            plot_tdat(app);

        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.UITable.Data = {};
            app.i_peaks = 0;

            plot_tdat(app);


        end

        % Button pushed function: IdentifysignificantresponsesButton
        function IdentifysignificantresponsesButtonPushed(app, event)
            EEG = evalin('base','EEG');
            s = tf_settings(app);
            [~ , frequencies,x,convol_pwr_ind] = calc_tf_data(app);

             % resample time to reduce computing time
            [p,q] = rat(floor(app.SamplingRateEditField.Value/10)/app.SamplingRateEditField.Value);
            resampled_signals = resample(double(convol_pwr_ind),p,q,'Dimension',2);
            resampled_time = linspace(min(x),max(x),size(resampled_signals,2));

             % some of the input parameters for the function
            input_mat = permute(resampled_signals,[3 1 2]);% input matrix containing all the trials
            n_permutes = app.npermutationsEditField.Value; % number of permutations
            v_time = resampled_time; % vector of time
            v_freq = frequencies; % vector of frequencies in
            time_window = s.time_lims;
            baseline_range = s.baseline;

            % The five per-metric axes this callback used to draw on (UIAxes2,
            % UIAxes2_2 ... UIAxes2_6) were removed from the layout, so the
            % summary is drawn in its own figure by npp_stb_2 instead.
            plotting_input = 1;
            my_title = ['Significant responses - ' char(string(app.ChannelsListBox.Value))];

            [zmap, zmapthresh, zmapthresh_plc, zmapthresh_clc, tfv_time, v_freq] = npp_stb_2(app,input_mat...
                ,n_permutes,v_time,v_freq,time_window,baseline_range,plotting_input,my_title);

            EEG.zmaps.zmapthresh_clc = zmapthresh_clc;
            EEG.zmaps.times = tfv_time;
            EEG.zmaps.frequencies = v_freq;
            EEG.zmaps.zmap = zmap;
            EEG.zmaps.zmapthresh = zmapthresh;
            EEG.zmaps.zmapthresh_plc = zmapthresh_plc;
            assignin('base','EEG',EEG)

        end

        % Callback function
        function Export_settingsButtonPushed(app, event)
            % export_settings() does not exist anywhere in this app; write the
            % settings struct out directly instead
            my_settings = compile_settings(app); %#ok<NASGU>
            [f_name,f_path] = uiputfile('*.mat','Save settings as');
            if isequal(f_name,0)
                return
            end
            save(fullfile(f_path,f_name),'my_settings');
        end

        % Button pushed function: wavelet_filterButton
        function wavelet_filterButtonPushed(app, event)

           % compute_warm_signals() is the maintained implementation and stores
           % its results in current_data, which is what plot_tfdata and the
           % parameter extraction read. warm_filter() stored them in EEG only,
           % so the WARM views never saw them.
           select_channel(app);
           preprocc_eeg(app);
           calc_tf_data(app);
           compute_warm_signals(app);
           current_data = evalin('base','current_data');

           x = current_data.WARM_filter.time;
           filt_recon = current_data.WARM_filter.mean_waveform;
           filt_resampled_ind_data = current_data.WARM_filter.single_trial_waveforms;
           erp = current_data.WARM_filter.unfiltered_mean;

           % plotting the filtered response on the app
           cla(app.UIAxes2_7)
           plot(app.UIAxes2_7,x,filt_recon);
           hold(app.UIAxes2_7,'on')
           plot(app.UIAxes2_7,x,erp);
           hold(app.UIAxes2_7,'off')
           legend(app.UIAxes2_7,'WARM filtered signal','original signal')
           xlim(app.UIAxes2_7, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])

           new_color_map_2 = get_colormap(app);

           %plot indivudal responses
           max_sign = max(max(abs(filt_resampled_ind_data)));
           if ~isfinite(max_sign) || max_sign == 0
               max_sign = 1;
           end
           cla(app.UIAxes7_2)
           imagesc(app.UIAxes7_2,x,1:size(filt_resampled_ind_data,2),filt_resampled_ind_data')
           colormap(app.UIAxes7_2,new_color_map_2)
           caxis(app.UIAxes7_2,[-max_sign*0.8 max_sign*0.8]);
           c = colorbar(app.UIAxes7_2,"east");
           c.Label.String = "Amplitude (mV)";
           hold(app.UIAxes7_2,'off')
           xlim(app.UIAxes7_2, [app.TimeLowmsEditField.Value app.TimeHighmsEditField.Value])
           ylim(app.UIAxes7_2, [.5 size(filt_resampled_ind_data,2)+.5])

        end

        % Button pushed function: ExtractSingletrialPeaksTBFButton
        function ExtractSingletrialPeaksTBFButtonPushed(app, event)
            %clf(app.UIAxes2_7)
            %plot_tdat(app);
            extract_single_trial_data(app)
            mark_peaks_in_fig(app)
        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
            app.UITable3.Data = {};
            app.i_peaks_st = 0;
        end

        % Callback function: Tree
        function treeselectionchange(app, event)
            
            
            %trials2plot = select_channel(app);
            %select_channel(app);
            %preprocc_eeg(app);
            %populate_figures(app);
            %[~,trials2plot,~,~]= plot_tdat(app);
            %app.TrialOverlayListBox.Items = string(trials2plot);


        end

        % Value changed function: typeofstim
        function typeofstimValueChanged(app, event)
            selected_stim = string(app.typeofstim.Value);

            if selected_stim == "CHEPs"
                app.stim_range = app.cheps_range;
            elseif selected_stim == "CEPs"
                app.stim_range = app.ceps_range;
            elseif selected_stim == "LEPs" 
                app.stim_range = app.leps_range;
            elseif is_ssep_stim(app,selected_stim)
                app.DropDown_2.Value = 'SSEP';
                apply_ssep_loading_defaults(app);
                return
            end
            
            if any(strcmp(app.DropDown_2.Items,char(selected_stim)))
                app.DropDown_2.Value = char(selected_stim);
            end
            apply_standard_tf_defaults(app);
            app.SignalStartmsEditField.Value = app.stim_range(1);
            app.SignalEndmsEditField.Value = app.stim_range(2);

            % plot_tdat(app);
            % plot_trial_data(app);
            % plot_tfdata(app);
            
        end

        % Value changed function: SignalStartmsEditField
        function SignalStartmsEditFieldValueChanged(app, event)
            value = app.SignalStartmsEditField.Value;
            app.stim_range(1) = value;
            app.stim_range(2) = app.SignalEndmsEditField.Value;
            app.stim_range = app.stim_range;
            % populate_figures(app);

            
        end

        % Value changed function: SignalEndmsEditField
        function SignalEndmsEditFieldValueChanged(app, event)
            value = app.SignalStartmsEditField.Value;
            app.stim_range(1) = value;
            app.stim_range(2) = app.SignalEndmsEditField.Value;
            app.stim_range = app.stim_range;
            % select_channel(app);
            % preprocc_eeg(app);
            % populate_figures(app);


        end

        % Key press function: UITable
        function UITableKeyPress(app, event)
            key = event.Key;
            table = app.UITable.Data;
            selection_idx = app.UITable.Selection;
            if (key == "delete" || key == "backspace") && ~isempty(selection_idx) && istable(table)
                table(selection_idx(1),:) = [];
                app.UITable.Data = table;
            end

            plot_tdat(app);

        end

        % Value changed function: tf_output_plot
        function tf_output_plotValueChanged(app, event)
            %populate_figures(app)
            plot_tfdata(app);
        end

        % Button pushed function: selectmanuallyButton
        function selectmanuallyButtonPushed(app, event)
            %app.SelectedtabEditField.Value  = app.TabGroup2.SelectedTab;
            
            my_return = selectDatapoints(app);


            app.TimeLowEditField_2.Value = my_return(1);
            app.TimeHighEditField_2.Value = my_return(1)+ my_return(3);
            app.FreqLowEditField_2.Value = my_return(2);
            app.FreqHighEditField_2.Value = my_return(2)+my_return(4);


        end

        % Button pushed function: ExtractParametersButton
        function ExtractParametersButtonPushed(app, event)
            

            x_min = app.TimeLowEditField_2.Value;
            y_min = app.FreqLowEditField_2.Value;
            x_length = app.TimeHighEditField_2.Value - x_min;
            y_length = app.FreqHighEditField_2.Value - y_min;
            position_v = [x_min y_min x_length y_length];

            current_data = evalin("base","current_data");

            if app.WARMFilterSwitch.Value == "On"

                tf_mask= current_data.zmaps.zmapthresh_clc;
                mask = ~tf_mask == 0;
                frequencies = current_data.zmaps.frequencies;
                x = current_data.zmaps.times;
                tf_notmasked = current_data.zmaps.tf_resampled;

                tf = zeros(size(tf_notmasked));
                for i = 1:size(tf,1)
                    tf(i,:,:) = squeeze(tf_notmasked(i,:,:)) .* mask;
                end

            else
                tf = current_data.tf;
                frequencies = current_data.frequencies;
                x = current_data.tf_times;
            end


             delete(findall(app.UIAxes9, 'Type',  'images.roi.Rectangle'))


            [my_output,app.extraction_state] = extract_vals(app,tf,frequencies,x);

            populate_extraction_vals(app,my_output);


        end

        % Button pushed function: ClearAllButton_2
        function ClearAllButton_2Pushed(app, event)
            app.UITable2_2.Data = {};
            app.n_roi = 0;
        end

        % Button down function: UITable2_2
        function UITable2_2ButtonDown(app, event)
            % a ButtonDown event has no Key field - reading it threw every time
            % the table was clicked. Row deletion is handled by UITable2_2KeyPress.
        end

        % Key press function: UITable2_2
        function UITable2_2KeyPress(app, event)
            key = event.Key;
            table = app.UITable2_2.Data;
            selection_idx = app.UITable2_2.Selection;
            if (key == "delete" || key == "backspace") && ~isempty(selection_idx) && istable(table)
                table(selection_idx(1),:) = [];
                app.UITable2_2.Data = table;
            end
        end

        % Value changed function: WARMFilterSwitch
        function WARMFilterSwitchValueChanged(app, event)


        end

        % Value changed function: PageSlider
        function PageSliderValueChanged(app, event)
            plot_multi_trials(app);
            
        end

        % Button pushed function: ExtractManuallyButton
        function ExtractManuallyButtonPushed(app, event)
            [EEG_dat,x,sd2,SNR,SNR_michelle] = plot_tdat(app);
            table_out = extract_peaks_manually(app,EEG_dat,x,SNR,SNR_michelle,sd2);
            assignin("base","peaks_extracted",table_out)
            plot_tdat(app);


        end

        % Value changed function: BaselineNormSwitch
        function BaselineNormSwitchValueChanged(app, event)
            if app.BaselineNormSwitch.Value == "Off"
                app.settings.baseline_norm = 0;
            elseif app.BaselineNormSwitch.Value == "On"
                app.settings.baseline_norm = 1;
            end

        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)

            EEG = evalin('base','EEG');
            if evalin('base','exist(''ALLEEG'',''var'')')
                ALLEEG = evalin('base','ALLEEG');
                update_data_set_name(app,ALLEEG,string(app.ChannelsListBox.Value))
            end

           
            % check if the channels habe been loaded
            % determine if a new dataset is loaded

            if ~isempty(app.current_data_id) % if its not empty
                if ~strcmp(string(app.current_data_id), string(EEG.setname)) % and if it doesnt match
                    app_setup(app,0);
                end
            else % if it is empty, then set it up
                app_setup(app,0);
            end
            
            select_channel(app);
            % select_channel re-reads the raw channel from EEG, so the baseline
            % correction has to be re-applied here exactly as the Load path does
            % it - without this, Update showed uncorrected data (every channel in
            % these files carries a DC offset of 1e4 or more).
            preprocc_eeg(app);
            populate_figures(app);
        end

        % Value changed function: typeoferp
        function typeoferpValueChanged(app, event)
            value = app.typeoferp.Value;
            
        end

        % Value changed function: ButterflyPlotCheckBox
        function ButterflyPlotCheckBoxValueChanged(app, event)
            
        end

        % Value changed function: ColorMapDropDown
        function ColorMapDropDownValueChanged(app, event)
            if app.ButterflyPlotCheckBox.Value == 1
                plot_tdat(app);
            end
        end

        % Value changed function: TrialsDisplayedListBox
        function TrialsDisplayedListBoxValueChanged(app, event)
            if app.ButterflyPlotCheckBox.Value == 1
                plot_tdat(app);
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: baseline_start
        function baseline_startValueChanged(app, event)
            % mirror into the TF Settings tab, which is what tf_settings() reads
            app.baselinelowerendEditField_2.Value = app.baseline_start.Value;
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            if is_ssep_stim(app,app.DropDown_2.Value)
                apply_ssep_loading_defaults(app);
            else
                if any(strcmp(app.typeofstim.Items,app.DropDown_2.Value))
                    app.typeofstim.Value = app.DropDown_2.Value;
                end
                apply_standard_tf_defaults(app);
            end
        end

        % Value changed function: climsSlider
        function climsSliderValueChanged(app, event)
            % commit on release / keyboard nudge
            apply_trial_clims(app);
        end

        % Value changed function: climsSlider_2
        function climsSlider_2ValueChanged(app, event)
            % commit on release / keyboard nudge
            apply_tf_clims(app);
        end

        % Value changing function: climsSlider
        function climsSliderValueChanging(app, event)
            % live update while the thumbs are dragged
            apply_trial_clims(app, event.Value);
        end

        % Value changing function: climsSlider_2
        function climsSlider_2ValueChanging(app, event)
            % live update while the thumbs are dragged
            apply_tf_clims(app, event.Value);
        end

        % Button pushed function: resetButton_2
        function resetButton_2Pushed(app, event)
            reset_tf_clims(app);
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            reset_trial_clims(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetUpTab = uitab(app.TabGroup);
            app.SetUpTab.Title = 'Set Up';
            app.DataInEditFieldLabel = uilabel(app.SetUpTab);
            app.DataInEditFieldLabel.Text = 'Data In';
            app.DataInEditField = uieditfield(app.SetUpTab, 'text');
            app.DataInEditField.ValueChangedFcn = createCallbackFcn(app, @DataInEditFieldValueChanged, true);
            app.Button = uibutton(app.SetUpTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '...';
            app.LoadDataButton = uibutton(app.SetUpTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.NotesTextAreaLabel = uilabel(app.SetUpTab);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.SetUpTab);
            app.DropDown = uidropdown(app.SetUpTab);
            app.DropDown.Items = {'.set', '.mat', '.txt', 'KeyPoint', 'Pathway'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '.set';
            app.DataNotLoadedLampLabel = uilabel(app.SetUpTab);
            app.DataNotLoadedLampLabel.Text = 'Data Not Loaded';
            app.DataNotLoadedLamp = uilamp(app.SetUpTab);
            app.DataNotLoadedLamp.Enable = 'off';
            app.Panel = uipanel(app.SetUpTab);
            app.NChannelsEditFieldLabel = uilabel(app.Panel);
            app.NChannelsEditFieldLabel.Text = 'N Channels';
            app.NChannelsEditField = uieditfield(app.Panel, 'numeric');
            app.FramesPerEpochEditFieldLabel = uilabel(app.Panel);
            app.FramesPerEpochEditFieldLabel.Text = 'Frames Per Epoch';
            app.FramesPerEpochEditField = uieditfield(app.Panel, 'numeric');
            app.EpochsEditFieldLabel = uilabel(app.Panel);
            app.EpochsEditFieldLabel.Text = 'Epochs';
            app.EpochsEditField = uieditfield(app.Panel, 'numeric');
            app.SamplingRateEditFieldLabel = uilabel(app.Panel);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.SamplingRateEditField = uieditfield(app.Panel, 'numeric');
            app.Panel_2 = uipanel(app.SetUpTab);
            app.EEGLabFolderEditFieldLabel = uilabel(app.Panel_2);
            app.EEGLabFolderEditFieldLabel.Text = 'EEGLab Folder';
            app.EEGLabFolderEditField = uieditfield(app.Panel_2, 'text');
            app.EEGLabFolderEditField.ValueChangedFcn = createCallbackFcn(app, @EEGLabFolderEditFieldValueChanged, true);
            app.InitializeEEGLabButton = uibutton(app.SetUpTab, 'push');
            app.InitializeEEGLabButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeEEGLabButtonPushed, true);
            app.InitializeEEGLabButton.Text = 'Initialize EEGLab';
            app.Button_3 = uibutton(app.SetUpTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '...';
            app.Image3 = uiimage(app.SetUpTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Artboard 1@2x.png');
            app.Panel_3 = uipanel(app.SetUpTab);
            app.IDEditFieldLabel = uilabel(app.Panel_3);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.Panel_3, 'text');
            app.AgeEditFieldLabel = uilabel(app.Panel_3);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.Panel_3, 'text');
            app.SexEditFieldLabel = uilabel(app.Panel_3);
            app.SexEditFieldLabel.Text = 'Sex';
            app.SexEditField = uieditfield(app.Panel_3, 'text');
            app.DateDatePickerLabel = uilabel(app.Panel_3);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.Panel_3);
            app.DropDown_2 = uidropdown(app.SetUpTab);
            app.DropDown_2.Items = {'CHEPs', 'CEPs', 'LEPs', 'SSEP'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'CHEPs';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.ChannelsListBoxLabel = uilabel(app.AnalysisTab);
            app.ChannelsListBoxLabel.Text = 'Channels';
            app.ChannelsListBox = uilistbox(app.AnalysisTab);
            app.ChannelsListBox.Items = {'None '};
            app.ChannelsListBox.ValueChangedFcn = createCallbackFcn(app, @ChannelsListBoxValueChanged, true);
            app.ChannelsListBox.Value = 'None ';
            app.LowpassSwitchLabel = uilabel(app.AnalysisTab);
            app.LowpassSwitchLabel.Text = 'Low pass';
            app.LowpassSwitch = uiswitch(app.AnalysisTab, 'slider');
            app.LowpassSwitch.ValueChangedFcn = createCallbackFcn(app, @LowpassSwitchValueChanged, true);
            app.TabGroup4 = uitabgroup(app.AnalysisTab);
            app.AverageTab = uitab(app.TabGroup4);
            app.AverageTab.Title = 'Average';
            app.UIAxes = uiaxes(app.AverageTab);
            xlabel(app.UIAxes, 'Time (ms)')
            ylabel(app.UIAxes, 'Amplitude (mV)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.ButterflyPlotCheckBox = uicheckbox(app.AverageTab);
            app.ButterflyPlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @ButterflyPlotCheckBoxValueChanged, true);
            app.ButterflyPlotCheckBox.Text = 'Butterfly Plot';
            app.TrialsDisplayedListBoxLabel = uilabel(app.AverageTab);
            app.TrialsDisplayedListBoxLabel.Text = 'Trials Displayed';
            app.TrialsDisplayedListBox = uilistbox(app.AverageTab);
            app.TrialsDisplayedListBox.Items = {'1', '2', '3', '4'};
            app.TrialsDisplayedListBox.Multiselect = 'on';
            app.TrialsDisplayedListBox.ValueChangedFcn = createCallbackFcn(app, @TrialsDisplayedListBoxValueChanged, true);
            app.TrialsDisplayedListBox.Value = {'1'};
            app.ColorMapDropDown = uidropdown(app.AverageTab);
            app.ColorMapDropDown.Items = {'red', 'green', 'blue', 'parula', 'turbo', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'jet', 'lines', 'colorcube', 'prism', 'flag'};
            app.ColorMapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorMapDropDownValueChanged, true);
            app.ColorMapDropDown.Placeholder = 'autum';
            app.ColorMapDropDown.Value = 'autumn';
            app.TrialTab = uitab(app.TabGroup4);
            app.TrialTab.Title = 'Trial';
            app.UIAxes7 = uiaxes(app.TrialTab);
            ylabel(app.UIAxes7, 'Trials')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.TrialTab);
            xlabel(app.UIAxes8, 'Time (ms)')
            ylabel(app.UIAxes8, 'Amplitdue (mV)')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes8.YDir = 'reverse';
            app.climsSliderLabel = uilabel(app.TrialTab);
            app.climsSliderLabel.Text = 'clims';
            app.climsSlider = uislider(app.TrialTab, 'range');
            app.climsSlider.ValueChangedFcn = createCallbackFcn(app, @climsSliderValueChanged, true);
            app.climsSlider.ValueChangingFcn = createCallbackFcn(app, @climsSliderValueChanging, true);
            app.resetButton = uibutton(app.TrialTab, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.MultitrialViewTab = uitab(app.TabGroup4);
            app.MultitrialViewTab.Title = 'Multi-trial View';
            app.PageSliderLabel = uilabel(app.MultitrialViewTab);
            app.PageSliderLabel.Text = 'Page';
            app.PageSlider = uislider(app.MultitrialViewTab);
            app.PageSlider.Limits = [1 3];
            app.PageSlider.MajorTicks = [1 2 3];
            app.PageSlider.ValueChangedFcn = createCallbackFcn(app, @PageSliderValueChanged, true);
            app.PageSlider.MinorTicks = [1 1.0125 1.025 1.0375 1.05 1.0625 1.075 1.0875 1.1 1.1125 1.125 1.1375 1.15 1.1625 1.175 1.1875 1.2 1.2125 1.225 1.2375 1.25 1.2625 1.275 1.2875 1.3 1.3125 1.325 1.3375 1.35 1.3625];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
            app.PageSlider.Value = 1;
            app.Panel_8 = uipanel(app.MultitrialViewTab);
            app.TimeFrequencyTab_2 = uitab(app.TabGroup4);
            app.TimeFrequencyTab_2.Title = 'Time Frequency';
            app.UIAxes9 = uiaxes(app.TimeFrequencyTab_2);
            ylabel(app.UIAxes9, 'Frequency')
            zlabel(app.UIAxes9, 'Z')
            app.UIAxes10 = uiaxes(app.TimeFrequencyTab_2);
            xlabel(app.UIAxes10, 'Time')
            ylabel(app.UIAxes10, 'Amplitude (mV)')
            zlabel(app.UIAxes10, 'Z')
            app.UIAxes10.YDir = 'reverse';
            app.climsSlider_2Label = uilabel(app.TimeFrequencyTab_2);
            app.climsSlider_2Label.Text = 'clims';
            app.climsSlider_2 = uislider(app.TimeFrequencyTab_2, 'range');
            app.climsSlider_2.ValueChangedFcn = createCallbackFcn(app, @climsSlider_2ValueChanged, true);
            app.climsSlider_2.ValueChangingFcn = createCallbackFcn(app, @climsSlider_2ValueChanging, true);
            app.resetButton_2 = uibutton(app.TimeFrequencyTab_2, 'push');
            app.resetButton_2.ButtonPushedFcn = createCallbackFcn(app, @resetButton_2Pushed, true);
            app.resetButton_2.Text = 'reset';
            app.Tree = uitree(app.AnalysisTab, 'checkbox');
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @treeselectionchange, true);
            app.TrialSelectionLabel = uilabel(app.AnalysisTab);
            app.TrialSelectionLabel.Text = 'Trial Selection';
            app.Panel_5 = uipanel(app.AnalysisTab);
            app.UITable = uitable(app.Panel_5);
            app.UITable.ColumnName = {'Channel'; 'Name'; 'Amplitude'; 'Latency'; 'Filter'; 'Trials'; 'Number of Trials'; 'Stim'; 'SNR_oddeve'; 'SNR_ps'; '2SD'; 'ID'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @ButtonPushed, true);
            app.UITable.KeyPressFcn = createCallbackFcn(app, @UITableKeyPress, true);
            app.ExtractpeaksButton = uibutton(app.Panel_5, 'push');
            app.ExtractpeaksButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractpeaksButtonPushed, true);
            app.ExtractpeaksButton.Text = 'Extract peaks';
            app.ClearAllButton = uibutton(app.Panel_5, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.ExtractManuallyButton = uibutton(app.Panel_5, 'push');
            app.ExtractManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractManuallyButtonPushed, true);
            app.ExtractManuallyButton.Text = 'Extract Manually';
            app.typeofstim = uilistbox(app.AnalysisTab);
            app.typeofstim.Items = {'CHEPs', 'CEPs', 'LEPs', 'SSEP'};
            app.typeofstim.ValueChangedFcn = createCallbackFcn(app, @typeofstimValueChanged, true);
            app.typeofstim.Value = 'CHEPs';
            app.ChannelsListBoxLabel_2 = uilabel(app.AnalysisTab);
            app.ChannelsListBoxLabel_2.Text = 'Type of stim';
            app.typeoferp = uilistbox(app.AnalysisTab);
            app.typeoferp.Items = {'N2/P2', 'N1'};
            app.typeoferp.ValueChangedFcn = createCallbackFcn(app, @typeoferpValueChanged, true);
            app.typeoferp.Value = 'N2/P2';
            app.ChannelsListBoxLabel_3 = uilabel(app.AnalysisTab);
            app.ChannelsListBoxLabel_3.Text = 'ERP ';
            app.tf_output_plot = uilistbox(app.AnalysisTab);
            app.tf_output_plot.Items = {'Total', 'Evoked power', 'Phase locked', 'Non-phase-locked', 'ITPC'};
            app.tf_output_plot.ValueChangedFcn = createCallbackFcn(app, @tf_output_plotValueChanged, true);
            app.tf_output_plot.Value = 'Total';
            app.ChannelsListBoxLabel_4 = uilabel(app.AnalysisTab);
            app.ChannelsListBoxLabel_4.Text = 'Time-frequency output';
            app.Panel_6 = uipanel(app.AnalysisTab);
            app.selectmanuallyButton = uibutton(app.Panel_6, 'push');
            app.selectmanuallyButton.ButtonPushedFcn = createCallbackFcn(app, @selectmanuallyButtonPushed, true);
            app.selectmanuallyButton.Text = 'select manually';
            app.TimeLowEditField_2Label = uilabel(app.AnalysisTab);
            app.TimeLowEditField_2Label.Text = 'Time Low';
            app.TimeLowEditField_2 = uieditfield(app.AnalysisTab, 'numeric');
            app.FreqLowEditField_2Label = uilabel(app.AnalysisTab);
            app.FreqLowEditField_2Label.Text = 'Freq Low';
            app.FreqLowEditField_2 = uieditfield(app.AnalysisTab, 'numeric');
            app.TimeHighEditField_2Label = uilabel(app.AnalysisTab);
            app.TimeHighEditField_2Label.Text = 'Time High';
            app.TimeHighEditField_2 = uieditfield(app.AnalysisTab, 'numeric');
            app.FreqHighEditField_2Label = uilabel(app.AnalysisTab);
            app.FreqHighEditField_2Label.Text = 'Freq High';
            app.FreqHighEditField_2 = uieditfield(app.AnalysisTab, 'numeric');
            app.Panel_7 = uipanel(app.AnalysisTab);
            app.UITable2_2 = uitable(app.Panel_7);
            app.UITable2_2.ColumnName = {'Channel'; 'Name'; 'Mean Activity'; 'Peak Activity'; 'Peak Frequency (Hz)'; 'Peak Latency (ms)'; 'Lowest Activity (ms)'; 'Min Frequency (Hz)'; 'Min Latency(ms)'; 'Metric'};
            app.UITable2_2.RowName = {};
            app.UITable2_2.ButtonDownFcn = createCallbackFcn(app, @UITable2_2ButtonDown, true);
            app.UITable2_2.KeyPressFcn = createCallbackFcn(app, @UITable2_2KeyPress, true);
            app.ExtractParametersButton = uibutton(app.Panel_7, 'push');
            app.ExtractParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractParametersButtonPushed, true);
            app.ExtractParametersButton.Text = 'Extract Parameters';
            app.ClearAllButton_2 = uibutton(app.Panel_7, 'push');
            app.ClearAllButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButton_2Pushed, true);
            app.ClearAllButton_2.Text = 'Clear All';
            app.WARMFilterSwitchLabel = uilabel(app.AnalysisTab);
            app.WARMFilterSwitchLabel.Text = 'WARM Filter';
            app.WARMFilterSwitch = uiswitch(app.AnalysisTab, 'slider');
            app.WARMFilterSwitch.ValueChangedFcn = createCallbackFcn(app, @WARMFilterSwitchValueChanged, true);
            app.BaselineNormSwitchLabel = uilabel(app.AnalysisTab);
            app.BaselineNormSwitchLabel.Text = 'Baseline  Norm';
            app.BaselineNormSwitch = uiswitch(app.AnalysisTab, 'slider');
            app.BaselineNormSwitch.ValueChangedFcn = createCallbackFcn(app, @BaselineNormSwitchValueChanged, true);
            app.UpdateButton = uibutton(app.AnalysisTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.TabGroup5 = uitabgroup(app.AnalysisTab);
            app.PlottingSettingsTab = uitab(app.TabGroup5);
            app.PlottingSettingsTab.Title = 'Plotting Settings';
            app.Panel_4 = uipanel(app.PlottingSettingsTab);
            app.TimeLowmsEditFieldLabel = uilabel(app.Panel_4);
            app.TimeLowmsEditFieldLabel.Text = 'Time Low (ms)';
            app.TimeLowmsEditField = uieditfield(app.Panel_4, 'numeric');
            app.TimeLowmsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeLowmsEditFieldValueChanged, true);
            app.TimeLowmsEditField.Value = -500;
            app.TimeHighmsEditFieldLabel = uilabel(app.Panel_4);
            app.TimeHighmsEditFieldLabel.Text = 'Time High (ms)';
            app.TimeHighmsEditField = uieditfield(app.Panel_4, 'numeric');
            app.TimeHighmsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeHighmsEditFieldValueChanged, true);
            app.TimeHighmsEditField.Value = 1500;
            app.YminLabel = uilabel(app.Panel_4);
            app.YminLabel.Text = 'Y min ';
            app.YminEditField = uieditfield(app.Panel_4, 'numeric');
            app.YminEditField.ValueChangedFcn = createCallbackFcn(app, @YminEditFieldValueChanged, true);
            app.YminEditField.Value = -20;
            app.YmaxEditFieldLabel = uilabel(app.Panel_4);
            app.YmaxEditFieldLabel.Text = 'Y max';
            app.YmaxEditField = uieditfield(app.Panel_4, 'numeric');
            app.YmaxEditField.ValueChangedFcn = createCallbackFcn(app, @YmaxEditFieldValueChanged, true);
            app.YmaxEditField.Value = -20;
            app.SignalStartmsEditFieldLabel = uilabel(app.Panel_4);
            app.SignalStartmsEditFieldLabel.Text = 'Signal Start (ms)';
            app.SignalStartmsEditField = uieditfield(app.Panel_4, 'numeric');
            app.SignalStartmsEditField.ValueChangedFcn = createCallbackFcn(app, @SignalStartmsEditFieldValueChanged, true);
            app.SignalEndmsEditFieldLabel = uilabel(app.Panel_4);
            app.SignalEndmsEditFieldLabel.Text = 'Signal End (ms)';
            app.SignalEndmsEditField = uieditfield(app.Panel_4, 'numeric');
            app.SignalEndmsEditField.ValueChangedFcn = createCallbackFcn(app, @SignalEndmsEditFieldValueChanged, true);
            app.SignalEndmsEditField.Value = 500;
            app.AutoSwitchLabel = uilabel(app.Panel_4);
            app.AutoSwitchLabel.Text = 'Auto';
            app.AutoSwitch = uiswitch(app.Panel_4, 'slider');
            app.AutoSwitch.Value = 'On';
            app.SignalStartmsEditFieldLabel_2 = uilabel(app.Panel_4);
            app.SignalStartmsEditFieldLabel_2.Text = 'Baseline Start (ms)';
            app.baseline_start = uieditfield(app.Panel_4, 'numeric');
            app.baseline_start.ValueChangedFcn = createCallbackFcn(app, @baseline_startValueChanged, true);
            app.baseline_start.Value = -500;
            app.EndmsEditFieldLabel = uilabel(app.Panel_4);
            app.EndmsEditFieldLabel.Text = 'End (ms)';
            app.baseline_end = uieditfield(app.Panel_4, 'numeric');
            app.TimeFrequencySettingsTab = uitab(app.TabGroup5);
            app.TimeFrequencySettingsTab.Title = 'Time Frequency Settings';
            app.minfreqEditField_2Label = uilabel(app.TimeFrequencySettingsTab);
            app.minfreqEditField_2Label.Text = 'min freq';
            app.minfreqEditField_2 = uieditfield(app.TimeFrequencySettingsTab, 'numeric');
            app.minfreqEditField_2.Value = 1;
            app.baselinelowerendEditField_2Label = uilabel(app.TimeFrequencySettingsTab);
            app.baselinelowerendEditField_2Label.Text = 'baseline lower end';
            app.baselinelowerendEditField_2 = uieditfield(app.TimeFrequencySettingsTab, 'numeric');
            app.baselinelowerendEditField_2.Value = -1000;
            app.ofcycleshigherEditField_2Label = uilabel(app.TimeFrequencySettingsTab);
            app.ofcycleshigherEditField_2Label.Text = '# of cycles higher';
            app.ofcycleshigherEditField_2 = uieditfield(app.TimeFrequencySettingsTab, 'numeric');
            app.ofcycleshigherEditField_2.Value = 10;
            app.ofcycleslowerEditField_2Label = uilabel(app.TimeFrequencySettingsTab);
            app.ofcycleslowerEditField_2Label.Text = '# of cycles lower';
            app.ofcycleslowerEditField_2 = uieditfield(app.TimeFrequencySettingsTab, 'numeric');
            app.ofcycleslowerEditField_2.Value = 3;
            app.timelowerEditField_2Label = uilabel(app.TimeFrequencySettingsTab);
            app.timelowerEditField_2Label.Text = 'time lower';
            app.timelowerEditField_2 = uieditfield(app.TimeFrequencySettingsTab, 'numeric');
            app.timelowerEditField_2.Value = -200;
            app.timehigherEditField_2Label = uilabel(app.TimeFrequencySettingsTab);
            app.timehigherEditField_2Label.Text = 'time higher';
            app.timehigherEditField_2 = uieditfield(app.TimeFrequencySettingsTab, 'numeric');
            app.timehigherEditField_2.Value = 1000;
            app.maxfreqEditField_2Label = uilabel(app.TimeFrequencySettingsTab);
            app.maxfreqEditField_2Label.Text = 'max freq';
            app.maxfreqEditField_2 = uieditfield(app.TimeFrequencySettingsTab, 'numeric');
            app.maxfreqEditField_2.Value = 25;
            app.baselinehighendEditFieldLabel = uilabel(app.TimeFrequencySettingsTab);
            app.baselinehighendEditFieldLabel.Text = 'baseline high end';
            app.baselinehighendEditField = uieditfield(app.TimeFrequencySettingsTab, 'numeric');
            app.SpacingSwitch_2Label = uilabel(app.TimeFrequencySettingsTab);
            app.SpacingSwitch_2Label.Text = 'Spacing';
            app.SpacingSwitch_2 = uiswitch(app.TimeFrequencySettingsTab, 'slider');
            app.SpacingSwitch_2.Items = {'Linear', 'Log'};
            app.SpacingSwitch_2.Value = 'Linear';
            app.ConversionSwitch_2Label = uilabel(app.TimeFrequencySettingsTab);
            app.ConversionSwitch_2Label.Text = 'Conversion';
            app.ConversionSwitch_2 = uiswitch(app.TimeFrequencySettingsTab, 'slider');
            app.ConversionSwitch_2.Items = {'dB', 'percent change'};
            app.ConversionSwitch_2.Value = 'dB';
            app.offrequencybinsEditField_2Label = uilabel(app.TimeFrequencySettingsTab);
            app.offrequencybinsEditField_2Label.Text = '# of frequency bins';
            app.offrequencybinsEditField_2 = uieditfield(app.TimeFrequencySettingsTab, 'numeric');
            app.offrequencybinsEditField_2.Value = 100;
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.TabGroup3 = uitabgroup(app.SettingsTab);
            app.DataSelectionTab = uitab(app.TabGroup3);
            app.DataSelectionTab.Title = 'Data Selection';
            app.selectrectanglemanuallyButton = uibutton(app.DataSelectionTab, 'push');
            app.selectrectanglemanuallyButton.ButtonPushedFcn = createCallbackFcn(app, @selectrectanglemanuallyButtonPushed, true);
            app.selectrectanglemanuallyButton.Text = 'select rectangle manually';
            app.TimeHighEditFieldLabel = uilabel(app.DataSelectionTab);
            app.TimeHighEditFieldLabel.Text = 'Time High';
            app.FreqHighEditFieldLabel = uilabel(app.DataSelectionTab);
            app.FreqHighEditFieldLabel.Text = 'Freq High';
            app.TimeLowEditFieldLabel = uilabel(app.DataSelectionTab);
            app.TimeLowEditFieldLabel.Text = 'Time Low';
            app.TimeLowEditField = uieditfield(app.DataSelectionTab, 'numeric');
            app.TimeHighEditField = uieditfield(app.DataSelectionTab, 'numeric');
            app.FreqHighEditField = uieditfield(app.DataSelectionTab, 'numeric');
            app.FreqLowEditFieldLabel = uilabel(app.DataSelectionTab);
            app.FreqLowEditFieldLabel.Text = 'Freq Low';
            app.FreqLowEditField = uieditfield(app.DataSelectionTab, 'numeric');
            app.ExtractInfoButton = uibutton(app.DataSelectionTab, 'push');
            app.ExtractInfoButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractInfoButtonPushed, true);
            app.ExtractInfoButton.Text = 'Extract Info';
            app.Image2 = uiimage(app.DataSelectionTab);
            app.Image2.ImageSource = 'logoo_light_square.jpg';
            app.UITable2 = uitable(app.DataSelectionTab);
            app.UITable2.ColumnName = {'Channel'; 'Name'; 'Mean Activity'; 'Peak Activity'; 'Peak Frequency (Hz)'; 'Peak Latency (ms)'; 'Lowest Activity (ms)'; 'Min Frequency (Hz)'; 'Min Latency(ms)'; 'Metric'};
            app.UITable2.RowName = {};
            app.MultitrialviewingTab = uitab(app.TabGroup3);
            app.MultitrialviewingTab.Title = 'Multi trial viewing ';
            app.nrowsEditFieldLabel = uilabel(app.MultitrialviewingTab);
            app.nrowsEditFieldLabel.Text = 'n rows';
            app.nrowsEditField = uieditfield(app.MultitrialviewingTab, 'numeric');
            app.nrowsEditField.Value = 5;
            app.ncolumnsEditFieldLabel = uilabel(app.MultitrialviewingTab);
            app.ncolumnsEditFieldLabel.Text = 'n columns';
            app.ncolumnsEditField = uieditfield(app.MultitrialviewingTab, 'numeric');
            app.ncolumnsEditField.Value = 2;
            app.StatisticsTab = uitab(app.TabGroup3);
            app.StatisticsTab.Title = 'Statistics';
            app.IdentifysignificantresponsesButton = uibutton(app.StatisticsTab, 'push');
            app.IdentifysignificantresponsesButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifysignificantresponsesButtonPushed, true);
            app.IdentifysignificantresponsesButton.Text = 'Identify significant responses';
            app.npermutationsEditFieldLabel = uilabel(app.StatisticsTab);
            app.npermutationsEditFieldLabel.Text = 'n permutations';
            app.npermutationsEditField = uieditfield(app.StatisticsTab, 'numeric');
            app.npermutationsEditField.Value = 1000;
            app.WaveletfilteringSettingsTab = uitab(app.TabGroup);
            app.WaveletfilteringSettingsTab.Title = 'Wavelet filtering Settings';
            app.UIAxes2_7 = uiaxes(app.WaveletfilteringSettingsTab);
            xlabel(app.UIAxes2_7, 'Time')
            ylabel(app.UIAxes2_7, 'Amplitude')
            zlabel(app.UIAxes2_7, 'Z')
            app.UIAxes7_2 = uiaxes(app.WaveletfilteringSettingsTab);
            ylabel(app.UIAxes7_2, 'Trials')
            zlabel(app.UIAxes7_2, 'Z')
            app.wavelet_filterButton = uibutton(app.WaveletfilteringSettingsTab, 'push');
            app.wavelet_filterButton.ButtonPushedFcn = createCallbackFcn(app, @wavelet_filterButtonPushed, true);
            app.wavelet_filterButton.Text = 'wavelet_filter';
            app.npermutationsEditField_2Label = uilabel(app.WaveletfilteringSettingsTab);
            app.npermutationsEditField_2Label.Text = 'n permutations';
            app.npermutationsEditField_2 = uieditfield(app.WaveletfilteringSettingsTab, 'numeric');
            app.npermutationsEditField_2.Value = 1000;
            app.ExtractSingletrialPeaksTBFButton = uibutton(app.WaveletfilteringSettingsTab, 'push');
            app.ExtractSingletrialPeaksTBFButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractSingletrialPeaksTBFButtonPushed, true);
            app.ExtractSingletrialPeaksTBFButton.Text = 'Extract Single trial Peaks (TBF)';
            app.UITable3 = uitable(app.WaveletfilteringSettingsTab);
            app.UITable3.ColumnName = {'Trial'; 'Name'; 'Amplitude'; 'Latency'; 'Channel'};
            app.UITable3.RowName = {};
            app.numberofpeakstoextractEditField_2Label = uilabel(app.WaveletfilteringSettingsTab);
            app.numberofpeakstoextractEditField_2Label.Text = 'number of peaks to extract';
            app.numberofpeakstoextractEditField_2 = uieditfield(app.WaveletfilteringSettingsTab, 'numeric');
            app.numberofpeakstoextractEditField_2.Value = 2;
            app.ClearButton_2 = uibutton(app.WaveletfilteringSettingsTab, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Text = 'Clear';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = brainviz_app_V2_v1_15

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end